classdef B_RoughStrength_Apr2022 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        LoadFaults_Button               matlab.ui.control.Button
        Plotfaults_Button               matlab.ui.control.Button
        ElementListBoxLabel             matlab.ui.control.Label
        ElementGrids_ListBox            matlab.ui.control.ListBox
        FaultlistListBoxLabel           matlab.ui.control.Label
        FaultList_ListBox               matlab.ui.control.ListBox
        ViewangleEditFieldLabel         matlab.ui.control.Label
        ViewAngle_EditField             matlab.ui.control.EditField
        FigEditFieldLabel               matlab.ui.control.Label
        FigNum_EditField                matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        RoughSeed_DropDown              matlab.ui.control.DropDown
        RoughType_DropDown              matlab.ui.control.DropDown
        andEditFieldLabel               matlab.ui.control.Label
        RoughCorrLengthStrike_EditField  matlab.ui.control.NumericEditField
        CorrlengthinstrikeanddipkmEditFieldLabel  matlab.ui.control.Label
        RoughCorrLengthDip_EditField    matlab.ui.control.NumericEditField
        CorrlengthvariabilitykmEditFieldLabel  matlab.ui.control.Label
        RoughCorrLengthVari_EditField   matlab.ui.control.NumericEditField
        RoughCorrLengthVariType_DropDown  matlab.ui.control.DropDown
        RoughHurstVariType_DropDown     matlab.ui.control.DropDown
        andEditField_2Label             matlab.ui.control.Label
        RoughHurstStrike_EditField      matlab.ui.control.NumericEditField
        HurstexpinstrikeanddipLabel     matlab.ui.control.Label
        RoughHurstDip_EditField         matlab.ui.control.NumericEditField
        HurstexpvariabilityLabel        matlab.ui.control.Label
        RoughHurstVari_EditField        matlab.ui.control.NumericEditField
        FractaldimensionEditFieldLabel  matlab.ui.control.Label
        RoughFractalDimension_EditField  matlab.ui.control.NumericEditField
        CornerwavenumberEditFieldLabel  matlab.ui.control.Label
        RoughCornerWaveNum_EditField    matlab.ui.control.NumericEditField
        nonlinscalingexpEditFieldLabel  matlab.ui.control.Label
        RoughNonLinScalingExp_EditField  matlab.ui.control.NumericEditField
        PrefactorforunitlengthisfractionEditFieldLabel  matlab.ui.control.Label
        RoughPreFact_EditField          matlab.ui.control.NumericEditField
        PrefactorvariabilityEditFieldLabel  matlab.ui.control.Label
        RoughPreFactVari_EditField      matlab.ui.control.NumericEditField
        RoughPreFactVariType_DropDown   matlab.ui.control.DropDown
        GenerateRoughness_Button        matlab.ui.control.Button
        CombinewGrid_Button             matlab.ui.control.Button
        RMSLEditFieldLabel              matlab.ui.control.Label
        RMSL_EditField                  matlab.ui.control.NumericEditField
        PlotRoughness_CheckBox          matlab.ui.control.CheckBox
        ShowRoughnessStats_CheckBox     matlab.ui.control.CheckBox
        normalizeprefactorwithlengthkmLabel  matlab.ui.control.Label
        RoughNormalizePreFact_EditField  matlab.ui.control.NumericEditField
        RMDamplitudeLabel               matlab.ui.control.Label
        RoughRMD_ampli_EditField        matlab.ui.control.NumericEditField
        RMDdecayLabel                   matlab.ui.control.Label
        RoughRMD_decay_EditField        matlab.ui.control.NumericEditField
        Load_SurfTrace_Button           matlab.ui.control.Button
        Use_SurfTrace_CheckBox          matlab.ui.control.CheckBox
        ExtractSave_SurfTrace_Button    matlab.ui.control.Button
        Use_SurfTraceCopyDown_CheckBox  matlab.ui.control.CheckBox
        kmroughnesslengthscaleLabel     matlab.ui.control.Label
        RoughLengthScale_EditField      matlab.ui.control.NumericEditField
        Use_OnlyCopy_CheckBox           matlab.ui.control.CheckBox
        Panel_3                         matlab.ui.container.Panel
        staticfrictioncoeffEditFieldLabel  matlab.ui.control.Label
        FricStatFric_EditField          matlab.ui.control.NumericEditField
        variationEditFieldLabel         matlab.ui.control.Label
        FricStatFricVari_EditField      matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        FricDynDrop_EditField           matlab.ui.control.NumericEditField
        changeinEQfulldropEditFieldLabel  matlab.ui.control.Label
        FricDynDropVari_EditField       matlab.ui.control.NumericEditField
        FricUseSkempton_CheckBox        matlab.ui.control.CheckBox
        FricSkemptonType_DropDown       matlab.ui.control.DropDown
        FricShowSkempton_ShowButton     matlab.ui.control.Button
        FricUseAmB_CheckBox             matlab.ui.control.CheckBox
        FricAmBType_DropDown            matlab.ui.control.DropDown
        FricShowAmB_ShowButton          matlab.ui.control.Button
        geothermgradientKkmforabprofileEditFieldLabel  matlab.ui.control.Label
        FricGeoTherm_EditField          matlab.ui.control.NumericEditField
        PlotStrength_CheckBox           matlab.ui.control.CheckBox
        PlotWithRoughness_CheckBox      matlab.ui.control.CheckBox
        averagestrengthacrossallcellsEditFieldLabel  matlab.ui.control.Label
        AverageStrength_EditField       matlab.ui.control.NumericEditField
        GenerateStrength_Button         matlab.ui.control.Button
        PlotStrength_Button             matlab.ui.control.Button
        FricFrictionType_DropDown       matlab.ui.control.DropDown
        forweakeinguseLabel             matlab.ui.control.Label
        D_cLabel                        matlab.ui.control.Label
        FricDc_EditField                matlab.ui.control.NumericEditField
        variEditFieldLabel              matlab.ui.control.Label
        FricDcVari_EditField            matlab.ui.control.NumericEditField
        FricAllowReset_CheckBox         matlab.ui.control.CheckBox
        degreestartingtemperatureatsurfaceLabel  matlab.ui.control.Label
        FricGeoThermStartTemp_EditField  matlab.ui.control.NumericEditField
        AssignFricManually_Button       matlab.ui.control.Button
        Strgth_AssignManually_CheckBox  matlab.ui.control.CheckBox
        SaveAllOrOneGrid_DropDown       matlab.ui.control.DropDown
        ofmodelstogenerateEditFieldLabel  matlab.ui.control.Label
        SaveModels2Generate_EditField   matlab.ui.control.NumericEditField
        currentmodelEditFieldLabel      matlab.ui.control.Label
        SaveCurrentModelNum_EditField   matlab.ui.control.NumericEditField
        SaveRoughStrenth_Button         matlab.ui.control.Button
        sameforallsectionsoffaultPanel  matlab.ui.container.Panel
        StrengthUseFractalDistrib_CheckBox  matlab.ui.control.CheckBox
        StrengthSeed_DropDown           matlab.ui.control.DropDown
        andEditField_3Label             matlab.ui.control.Label
        StrengthCorrLengthStrike_EditField  matlab.ui.control.NumericEditField
        CorrlengthinstrikeanddipkmEditField_2Label  matlab.ui.control.Label
        StrengthCorrLengthDip_EditField  matlab.ui.control.NumericEditField
        HurstexponentEditFieldLabel     matlab.ui.control.Label
        StrengthHurstExp_EditField      matlab.ui.control.NumericEditField
        waterlevelcutoffLabel           matlab.ui.control.Label
        StrengthWaterLevel_EditField    matlab.ui.control.NumericEditField
        variabilityEditFieldLabel       matlab.ui.control.Label
        StrengthWaterLevelVari_EditField  matlab.ui.control.NumericEditField
        addLabel                        matlab.ui.control.Label
        tooverburdenstressMPaEditFieldLabel  matlab.ui.control.Label
        HalfspaceOverburden_EditField   matlab.ui.control.NumericEditField
        shearmodulusGPaLabel            matlab.ui.control.Label
        HalfspaceShearMod_EditField     matlab.ui.control.NumericEditField
        PoissonsratioEditFieldLabel     matlab.ui.control.Label
        HalfspacePoisson_EditField      matlab.ui.control.NumericEditField
        SavenameLabel                   matlab.ui.control.Label
        SaveName_EditField              matlab.ui.control.EditField
        LoadRoughnessStrength_Button    matlab.ui.control.Button
        halfspacedensitykgm3EditFieldLabel  matlab.ui.control.Label
        HalfspaceDensity_EditField      matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        %----------------------------------------------
        Color_Blue2Red =  [ 0	0	0.5000;     0	0	0.5313;     0	0	0.5625;     0	0	0.5938;...
                            0	0	0.6250;     0	0	0.6563;     0	0	0.6875;     0	0	0.7188;...
                            0	0	0.7500;     0	0	0.7813;     0	0	0.8125;     0	0	0.8438;...
                            0	0	0.8750;     0	0	0.9063;     0	0	0.9375;     0	0	0.9688;...
                            0	0	1;          0.02580	0.02580	1;  0.05160	0.05160	1;  0.07740	0.07740	1;...
                            0.1032	0.1032	1;  0.1290	0.1290	1;  0.1548	0.1548	1;  0.1806	0.1806	1;...
                            0.2065	0.2065	1;  0.2323	0.2323	1;  0.2581	0.2581	1;  0.2839	0.2839	1;...
                            0.3097	0.3097	1;  0.3355	0.3355	1;  0.3613	0.3613	1;  0.3871	0.3871	1;...
                            0.4129	0.4129	1;  0.4387	0.4387	1;  0.4645	0.4645	1;  0.4903	0.4903	1;...
                            0.5161	0.5161	1;  0.5419	0.5419	1;  0.5677	0.5677	1;  0.5935	0.5935	1;...
                            0.6194	0.6194	1;  0.6452	0.6452	1;  0.6710	0.6710	1;  0.6968	0.6968	1;...
                            0.7226	0.7226	1;  0.7484	0.7484	1;  0.7742	0.7742	1;  0.8000	0.8000	1;...
                            1	0.8000	0.8000; 1	0.7742	0.7742; 1	0.7484	0.7484; 1	0.7226	0.7226;...
                            1	0.6968	0.6968; 1	0.6710	0.6710; 1	0.6452	0.6452; 1	0.6194	0.6194;...
                            1	0.5935	0.5935; 1	0.5677	0.5677; 1	0.5419	0.5419; 1	0.5161	0.5161;...
                            1	0.4903	0.4903; 1	0.4645	0.4645; 1	0.4387	0.4387; 1	0.4129	0.4129;...
                            1	0.3871	0.3871; 1	0.3613	0.3613; 1	0.3355	0.3355; 1	0.3097	0.3097;...
                            1	0.2839	0.2839; 1	0.2581	0.2581; 1	0.2323	0.2323; 1	0.2065	0.2065;...
                            1	0.1806	0.1806; 1	0.1548	0.1548; 1	0.1290	0.1290; 1	0.1032	0.1032;...
                            1	0.07740	0.0774; 1	0.05160	0.05160;1	0.02580	0.02580;    1	0	0;...
                            0.9688	0	0;      0.9375	0	0;      0.9063	0	0;      0.8750	0	0;
                            0.8438	0	0;      0.8125	0	0;      0.7813	0	0;      0.7500	0	0;...
                            0.7188	0	0;      0.6875	0	0;      0.6563	0	0;      0.6250	0	0;
                            0.5938	0	0;      0.5625	0	0;      0.5313	0	0;      0.5000	0	0];
           
        Gravity      = 9.81; %m/s^2
        AmBparabolic = @(x)4.7e-7.*x.^2 -0.00017.*x +0.006;
        AmB_p1       = @(x)-8.47e-5.*x +0.0038;
        AmB_p2       = @(x)-0.0042;
        AmB_p3       = @(x)0.00021.*x -0.0697;
        AmB_p4       = @(x)5.5e-5.*x -0.007;
        AmB_p1_temp  = [0,    93.2];
        AmB_p2_temp  = [93.2, 312];
        AmB_p3_temp  = [312,  401];
        AmB_p4_temp  = [401,  550];
        Skempton_p1  = @(x)0.3;
        Skempton_p2  = @(x)0.5;
        Skempton_p3  = @(x)0.7;


        %----------------------------------------------
        SavePath            = [];
        GridImported        = 0;
        RoughnessGenerated  = 0;
        StrengthGenerated   = 0;
        GridNumber          = [];
        FaultValues         = [];               
        FaultNames          = [];
        GriddedFault        = struct;
        RoughParasPerFault  = [];
        StrgthParasPerSectn = [];
        RandParasRough      = struct; 
        RandParasStrgt      = struct; 
        RMSoverLength       = struct;
        RoughFault          = struct;
        
        CallStrengthFromSave = 0;
        SurfTraceImported    = 0;
        SurfTraceVals        = [];
        %----------------------------------------------
    end
    
    methods (Access = private)
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
        function RoughSurf = SpecSyn2(app, N,samp,corr,acf,NonLinSclExp,PreFact,Rseed)
            %  Written by Martin Mai (martin@seismo.ifg.ethz.ch) 
            %  originally from 07/16/98, based on SRB-toolbox (Ph. Rio)
            %  last changes 03/01/2000; Nov. 2002;
            % Modified by Olaf Zielke for fault roughness generation
            %----------------------------------- % get data values on the correlation length/fractal dimension
            if  (strcmp(acf,'FR'))
               if (length(corr) == 2),              D = corr(1);        kc = corr(2);
               else,                                D = corr(1);        kc = 0.1;     %      disp('** Corner wavenumber kc not given: set to 0.1 **')
               end
            end
            if ((strcmp(acf,'EX')) || (strcmp(acf,'GS'))),      ax = corr(2);   az = corr(1);                   end         
            if (strcmp(acf,'AK')),                              ax = corr(2);   az = corr(1);   H = corr(3);    end
            %---------------------------------- % set size for spectral synthesis tool that generates fields of size (2*rmz+1, 2*rmx+1), i.e. the method requires an ODD number of points in each direction
            nptsX = round(N(2)/samp(2));     %% number of grid-points in X
            nptsZ = round(N(1)/samp(1));     %% number of grid-points in Z
            
            if     mod(nptsX,2) == 0,       rmx = nptsX/2;
            elseif mod(nptsX,2) == 1,       rmx = (nptsX-1)/2;
            end
            if     mod(nptsZ,2) == 0,       rmz = nptsZ/2;
            elseif mod(nptsZ,2) == 1,       rmz = (nptsZ-1)/2;
            end
            %---------------------------------- % compose power spectrum for two of the four quadrant wavenumber vector in [-pi,pi]
            kx = (rmx./((2*rmx+1)*samp(2)))*linspace(-2*pi,2*pi,2*rmx+1);
            kz = (rmz./((2*rmz+1)*samp(1)))*linspace(-2*pi,2*pi,2*rmz+1);
            kr = zeros(rmz+1,2*rmx+1);
            %----------------------------------
            for j = 1:(2*rmx+1)
                for i = 1:(rmz+1)
                    if  (strcmp(acf,'FR'))
                       kr(i,j) = sqrt((kz(i)^2) + (kx(j)^2));
                    else
                       kr(i,j) = sqrt((az^2)*(kz(i)^2) + (ax^2)*(kx(j)^2));
                    end
                end
            end
            %---------------------------------- % calculate power spectral density, depending on selected ACF
            if (strcmp(acf,'GS')),                                                                                  PS = 0.5*ax*az*exp(-0.25*kr.^2);            end
            if (strcmp(acf,'EX')),                                                                                  PS = (ax * az)./(1 + (kr.^2)).^1.5;         end
            if (strcmp(acf,'AK')),      k  = kr(:); k = k(k>0);        coef = 4*pi*H*ax*az./besselk(H,min(k));      PS = coef./(1 + (kr.^2)).^(H+1);            end
            if (strcmp(acf,'FR'))
            	decay = 0.5*(8-2*D); % to ensure proper scaling of the power spectrum  in k-space we cannot allow kr == 0
            	if (min(kr(:)) == 0),  [p,q] = find(kr == 0);          k(p,q)= mean(mean(kr(p-1:p,q-1:q)));        end
            	kr(kr <= pi*kc) = pi*kc;                               PS    = 1./((kr.^2).^decay);    % set values below k< kc to constant pi*kc     	
            end
            
            % the IFFT needs the spectrum normalized to max.amp unity
            PS = PS./max(PS(:));                            AM = sqrt(PS);
            % compose the random phase initialize random number generator
            rng(Rseed);
            % random phase in [0,pi]
            PH = 2*pi*rand(size(kr));	 
            % assemble the random field in FT-domain add small random high-wavenumber components
            %x = (1 + 0.5*randn(size(kr)));
            x = 1;
            RAD = AM.*x;
            % set DC-component to different value, if desired NOTE that this only changes the overall 'level' of
            % the field, not its appearance, but has significant impact on the Fourier transform which reflects the
            % DC-value at the smallest wavenumber ("Nugget Effect")
            Neff             = 0;               % "Nugget" value
            RAD(rmz+1,rmx+1) = Neff;		% "Nugget" effect, zero-mean field
            AM(rmz+1,rmx+1)  = RAD(rmz+1,rmx+1);
            RoughSurf = RAD.*cos(PH) + sqrt(-1)*RAD.*sin(PH);
            
            % the following takes care of the conjugate symmetry condition
            % in order to ensure the final random field is purely real
            U = zeros(2*rmz+1,2*rmx+1);	        %% will be conj. sym. field
            RoughSurf = [RoughSurf ; conj(fliplr(flipud(RoughSurf(1:rmz,:))))];
            %---------------------------------- 
            for i = 1:rmx
                 RoughSurf(rmz+1,-i+2*rmx+2) = conj(RoughSurf(rmz+1,i));
            end
            for i = 1:rmz+1
                for j = 1:rmx+1
                    U(i,j) = RoughSurf(i+rmz,j+rmx);
                end
            end
            for i = rmz+2:2*rmz+1
                for j = rmx+2:2*rmx+1
                    U(i,j) = RoughSurf(i-rmz-1,j-rmx-1);
                end
            end
            for i = 1:rmz+1
                for j = rmx+2:2*rmx+1
                    U(i,j) = RoughSurf(i+rmz,j-rmx-1);
                end
            end
            for i = rmz+2:2*rmz+1
                for j = 1:rmx+1
                    U(i,j) = RoughSurf(i-1-rmz,j+rmx);
                end
            end
            %---------------------------------- 
            % take 2D-inverse FFT to obtain spatial field; imaginary parts
            % of order 1e-13 due to machine precision are removed;
            % also, remove mean and scale to unit variance
            RoughSurf = real(ifft2(U));
            
            %should detrend the surface 
            A         = 1: size(RoughSurf,2);
            B         = 1: size(RoughSurf,1);
            [BB,AA]   = meshgrid(A,B);
            SurfPCA   = pca([AA(:), BB(:), RoughSurf(:)]);
            PlaneNorm = SurfPCA(:,3)./norm(SurfPCA(:,3));
            RoughSurf = (AA.*PlaneNorm(1,1) + BB.*PlaneNorm(2,1) + RoughSurf.*PlaneNorm(3,1));
            %---------------------------------------
            % perform a non-linear transformation, if desired, to create models with higher peak-roughness values
            if (NonLinSclExp ~= 1)
                RoughSurf = RoughSurf - min(RoughSurf);
                RoughSurf = RoughSurf.^NonLinSclExp;
                RoughSurf = RoughSurf - mean(RoughSurf);  
            end
            %---------------------------------------
            RoughSurf = RoughSurf -mean(RoughSurf(:));
            MeanDevi  = sum(abs(RoughSurf(:)))./(size(RoughSurf,1)*size(RoughSurf,2));
            RoughSurf = RoughSurf.*(PreFact/MeanDevi);
            
       %     RoughSurf = RoughSurf -mean(RoughSurf(:));
       %     RoughSurf = RoughSurf./std2(RoughSurf);		% standard deviation of unity
       %     RoughSurf = RoughSurf.*PreFact;
        end    
        
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
        function RoughSurf = Synth2DFault_Candela2009(app, N,H_Stk,H_Dip,NonLinSclExp,PreFact,Rseed)
            
            LStk      = 1/H_Stk;
            LDip      = 1/H_Dip;
            N         = N-1; %my own modification, b/c it seems more plausible that way...
            %--------------------------------
            X         = (-2*2^N:2:2*2^N)/(2^(N+1));              X(2^N+1) = 1/2^N;
            Y         = (-2*2^N:2:2*2^N)/(2^(N+1));              Y(2^N+1) = 1/2^N; 
            XX        = X(ones(1,2*2^N+1),:);                    YY       = Y(ones(1,2*2^N+1),:)';
            clear X  Y;
            %--------------------------------
            rho       = sqrt(abs(XX).^(2/LStk) +abs(YY).^(2/LDip));
            clear XX YY;         
            phi       = rho.^(1 +(LStk +LDip)/2);
            clear rho;
            %--------------------------------
            if isempty(Rseed) == 1,                         Rseed = rng('shuffle');                                      
            end
            rng(Rseed);
            %--------------------------------
            Z         = randn(2*2^N+1,2*2^N+1);
            W         = fftshift(fft2(Z))./phi;
            clear Z;
            %--------------------------------
            T         = real(ifft2(ifftshift(W)));
            RoughSurf = T -T(2^N+1,2^N+1);
            %--------------------------------
            %should detrend the surface 
            A         = 1: size(RoughSurf,2);
            B         = 1: size(RoughSurf,1);
            [BB,AA]   = meshgrid(A,B);
            SurfPCA   = pca([AA(:), BB(:), RoughSurf(:)]);
            PlaneNorm = SurfPCA(:,3)./norm(SurfPCA(:,3));
            RoughSurf = (AA.*PlaneNorm(1,1) + BB.*PlaneNorm(2,1) + RoughSurf.*PlaneNorm(3,1));
            %---------------------------------------
            % perform a non-linear transformation, if desired, to create models with higher peak-roughness values
            if (NonLinSclExp ~= 1)
                RoughSurf = RoughSurf - min(RoughSurf);
                RoughSurf = RoughSurf.^NonLinSclExp;
                RoughSurf = RoughSurf - mean(RoughSurf);  
            end
            %---------------------------------------
            RoughSurf = RoughSurf -mean(RoughSurf(:));
            MeanDevi  = sum(abs(RoughSurf(:)))./(size(RoughSurf,1)*size(RoughSurf,2));
            RoughSurf = RoughSurf.*(PreFact/MeanDevi);
      %      RoughSurf = RoughSurf -mean(RoughSurf(:));
      %      RoughSurf = RoughSurf./std2(RoughSurf);		% standard deviation of unity
      %      RoughSurf = RoughSurf.*PreFact;  
        end
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
        function RoughSurf = RandomMidPointDisplacement(app, ItNum, RMDvals, FaultDim, Rseed, PreFact, sampsize, FaultTrace)

            WeightInX    = 1;
            WeightInY    = 1;
            WeightForStk = (WeightInX/(WeightInX+WeightInY)) /2; %then I don't need to divide by 4 or 2 or so => just multiply with the weight
            WeightForDip = (WeightInY/(WeightInX+WeightInY)) /2;
            %--------------------------------------
            if isempty(Rseed) == 1,                         Rseed = rng('shuffle');                                      
            end
            rng(Rseed);
            %--------------------------------------
            FaultDim(1,1)=FaultDim(1,1)+1;
            ItNumDip     = ceil(log2(FaultDim(1,1)));
            ItNumStk     = ceil(log2(FaultDim(1,2)));
            SurfSize     = 2^ItNum; 
            StkSize      = 2^ItNumStk;
            DipSize      = 2^ItNumDip;
            RoughSurf    = zeros(SurfSize,SurfSize);
            dHght        = SurfSize*mean(sampsize)     *RMDvals(1,1)*2; %is length of generated square*patch size (so the fault length for example)*2*RAmp (the Amplitude,..)  dHght the amplitude, (so that effectively not +/-0.5*dHight but +/-1*dHght)
            dHghtStk     = StkSize *     sampsize(1,2) *RMDvals(1,1)*2;
            dHghtDip     = DipSize *     sampsize(1,1) *RMDvals(1,1)*2;

            if (app.Use_SurfTrace_CheckBox.Value == 1)
                RoughSurf(1,1:FaultDim(1,2))  = interp1(1:size(FaultTrace,2),FaultTrace, 1:FaultDim(1,2),'linear');
                %----------------------------------
                if (app.Use_SurfTraceCopyDown_CheckBox.Value == 1)
                    RoughSurf(FaultDim(1,1),1:FaultDim(1,2))  = RoughSurf(1,1:FaultDim(1,2));  
                  
                    if (app.Use_OnlyCopy_CheckBox.Value == 1)
                        for i = 1 :SurfSize
                            RoughSurf(i,1:FaultDim(1,2))  = RoughSurf(1,1:FaultDim(1,2));   
                        end
                        RoughSurf = RoughSurf(1:FaultDim(1,1)+1,1:FaultDim(1,2));
                        RoughSurf = flipud(RoughSurf);
                        RoughSurf = RoughSurf(1:end-1,:);
                        return;
                    end
                else
                    for run = 0 : ItNumStk-1
                        if (run > 0),       dHghtStk = dHghtStk*2^(-RMDvals(1,2));              end
                        CrnrNum  = 2^run+1;
                        CrnrVal  = linspace(0,StkSize,CrnrNum);           
                        
                        for run1 = 1 : CrnrNum-1
                            CPos_Stk1 = CrnrVal(1,run1);    
                            CPos_Stk2 = CrnrVal(1,run1+1);
                            RandOffs  = (rand(1)*2-1)*dHghtStk;
                            NewStk    = (CPos_Stk1 + CPos_Stk2)/2;
                            if (CPos_Stk1 == 0),    CPos_Stk1 = 1;       end

                            if (CPos_Stk2 <= FaultDim(1,2))
                                RoughSurf(FaultDim(1,1),NewStk) = (RoughSurf(FaultDim(1,1),CPos_Stk1) + RoughSurf(FaultDim(1,1),CPos_Stk2) )/2 + RandOffs;
                    end,end,end
                    RoughSurf(FaultDim(1,1),1:FaultDim(1,2)) = RoughSurf(FaultDim(1,1),1:FaultDim(1,2)) - mean(RoughSurf(FaultDim(1,1),1:FaultDim(1,2)));
                    RoughSurf(FaultDim(1,1),1:FaultDim(1,2)) = detrend(RoughSurf(FaultDim(1,1),1:FaultDim(1,2)));
                end
                %----------------------------------
                for i = FaultDim(1,1)+1 : SurfSize
                        RoughSurf(i,1:FaultDim(1,2))  = RoughSurf(FaultDim(1,1),1:FaultDim(1,2));
                end    
                %----------------------------------
                for run = 0 : ItNumDip-1
                    if (run > 0),           dHghtDip = dHghtDip*2^(-RMDvals(1,2));              end
                    CrnrNum   = 2^run+1;
                    CrnrVal   = linspace(0,DipSize,CrnrNum);           

                    for run1 = 1 : CrnrNum-1
                        CPos_Dip1 = CrnrVal(1,run1);    
                        CPos_Dip2 = CrnrVal(1,run1+1);
                        RandOffs1 = (rand(1)*2-1)*dHghtDip;
                        RandOffs2 = (rand(1)*2-1)*dHghtDip;
                        NewDip    = (CPos_Dip1 + CPos_Dip2)/2;
                        if (CPos_Dip1 == 0),    CPos_Dip1 = 1;       end
                        if (CPos_Dip2 <= FaultDim(1,1))
                            RoughSurf(NewDip,1)             = (RoughSurf(CPos_Dip1,1            ) + RoughSurf(CPos_Dip2,1            ))/2  +  RandOffs1;
                            RoughSurf(NewDip,FaultDim(1,2)) = (RoughSurf(CPos_Dip1,FaultDim(1,2)) + RoughSurf(CPos_Dip2,FaultDim(1,2)))/2  +  RandOffs2;
                end,end,end
                %----------------------------------
                for i = FaultDim(1,2)+1 : SurfSize
                    RoughSurf(1:FaultDim(1,1),i) = RoughSurf(1:FaultDim(1,1),FaultDim(1,2));   
                end
                %----------------------------------
            end
            %-------------------------------------------------------------- 
            %--------------------------------------------------------------
            for run = 0 : ItNum-1
                if (run > 0),               dHght = dHght*2^(-RMDvals(1,2));                    end
                CrnrNum      = 2^run+1;
                CrnrVal      = linspace(0,SurfSize,CrnrNum);    
                
                for run1 = 1: CrnrNum-1
                    CPos_Stk1 = CrnrVal(1,run1);        
                    CPos_Stk2 = CrnrVal(1,run1+1);
                    NewStk    = (CPos_Stk1 +CPos_Stk2)/2;
                    if (CPos_Stk1 == 0),    CPos_Stk1 = 1;       end
                    
                    for run2 = 1 : CrnrNum-1
                        CPos_Dip1 = CrnrVal(1,run2);    
                        CPos_Dip2 = CrnrVal(1,run2+1);
                        NewDip    = (CPos_Dip1 + CPos_Dip2)/2;
                        RandOffs  = (rand(1)*2-1)*dHght;
                        
                        if (CPos_Dip1 == 0),    CPos_Dip1 = 1; end
                        
                        StkMean1 = (RoughSurf(CPos_Dip1, CPos_Stk1) + RoughSurf(CPos_Dip1,CPos_Stk2)) /2;
                        StkMean2 = (RoughSurf(CPos_Dip2, CPos_Stk1) + RoughSurf(CPos_Dip2,CPos_Stk2)) /2;
                        DipMean1 = (RoughSurf(CPos_Dip1, CPos_Stk1) + RoughSurf(CPos_Dip2,CPos_Stk1)) /2;
                        DipMean2 = (RoughSurf(CPos_Dip1, CPos_Stk2) + RoughSurf(CPos_Dip2,CPos_Stk2)) /2;
                       
                        MeanElev = StkMean1*WeightForStk + StkMean2*WeightForStk +DipMean1*WeightForDip +DipMean2*WeightForDip;
                        NewElev  = MeanElev + RandOffs;
                        if  (NewDip < FaultDim(1,1)) && (NewStk < FaultDim(1,2))
                            RoughSurf(NewDip,NewStk) = NewElev;% here I first calculate  the new value for the center of a square (think of it as the point where two diagonals crossing)     
                        end
                end,end  
                %..........................................................
                for run1 = 1 : CrnrNum-1
                    CPos_Stk1 =  CrnrVal(1,run1);
                    CPos_Stk2 =  CrnrVal(1,run1+1);
                    NewStk    = (CPos_Stk1+ CPos_Stk2)/2;
                    StkDist   = abs(CPos_Stk1- CPos_Stk2)/2;
                    if (CPos_Stk1 == 0),    CPos_Stk1 = 1;       end
                    
                    for run2 = 1 : CrnrNum-1
                        CPos_Dip1 =  CrnrVal(1,run2);
                        CPos_Dip2 =  CrnrVal(1,run2+1);
                        NewDip    = (CPos_Dip1+ CPos_Dip2)/2;
                        DipDist   = abs(CPos_Dip1- CPos_Dip2)/2;
                        if (CPos_Dip1 == 0),    CPos_Dip1 = 1;       end
                        %..................................................
                        NewPoints = [NewStk,CPos_Dip1; NewStk,CPos_Dip2; CPos_Stk1,NewDip; CPos_Stk2,NewDip];
                        
                        for run3 = 1 : 4 %here is the square step then...
                            StkCoord = NewPoints(run3,1);
                            DipCoord = NewPoints(run3,2);
                            RandOffs = (rand(1)*2-1)*dHght;

                            if      (run3 == 1)   %((StkCoord == NewStk)&&(DipCoord == CPos_Dip1))
                                ExtraPt = CPos_Dip1 -DipDist;
                                if (ExtraPt < 1)
                                    MeanElev  = RoughSurf(NewDip,NewStk)*WeightForDip + RoughSurf(NewDip,NewStk)*WeightForDip  + RoughSurf(CPos_Dip1,CPos_Stk1)*WeightForStk + RoughSurf(CPos_Dip1,CPos_Stk2)*WeightForStk;
                                else
                                    MeanElev  = RoughSurf(NewDip,NewStk)*WeightForDip + RoughSurf(ExtraPt,NewStk)*WeightForDip + RoughSurf(CPos_Dip1,CPos_Stk1)*WeightForStk + RoughSurf(CPos_Dip1,CPos_Stk2)*WeightForStk;
                                end   
                                if (app.Use_SurfTrace_CheckBox.Value ~= 1)
                                    RoughSurf(DipCoord,StkCoord) = MeanElev + RandOffs;
                                end
                            elseif  (run3 == 2)   %((StkCoord == NewStk)&&(DipCoord == CPos_Dip2))
                                ExtraPt = CPos_Dip2 +DipDist;
                                if (ExtraPt > FaultDim(1,1))
                                    MeanElev = RoughSurf(NewDip,NewStk)*WeightForDip + RoughSurf(NewDip,NewStk)*WeightForDip  + RoughSurf(CPos_Dip2,CPos_Stk1)*WeightForStk + RoughSurf(CPos_Dip2,CPos_Stk2)*WeightForStk;
                                else
                                    MeanElev = RoughSurf(NewDip,NewStk)*WeightForDip + RoughSurf(ExtraPt,NewStk)*WeightForDip + RoughSurf(CPos_Dip2,CPos_Stk1)*WeightForStk + RoughSurf(CPos_Dip2,CPos_Stk2)*WeightForStk;
                                end
                                RoughSurf(DipCoord,StkCoord) = MeanElev + RandOffs;
                            elseif  (run3 == 3)   %((StkCoord == CPos_Stk1)&&(DipCoord == NewDip))
                                ExtraPt  = CPos_Stk1 -StkDist;
                                if (ExtraPt < 1)
                                    MeanElev = RoughSurf(NewDip,NewStk)*WeightForStk + RoughSurf(NewDip,NewStk)*WeightForStk  + RoughSurf(CPos_Dip1,CPos_Stk1)*WeightForDip + RoughSurf(CPos_Dip2,CPos_Stk1)*WeightForDip;
                                else
                                    MeanElev = RoughSurf(NewDip,NewStk)*WeightForStk + RoughSurf(NewDip,ExtraPt)*WeightForStk + RoughSurf(CPos_Dip1,CPos_Stk1)*WeightForDip + RoughSurf(CPos_Dip2,CPos_Stk1)*WeightForDip;
                                end
                                RoughSurf(DipCoord,StkCoord) = MeanElev + RandOffs;
                            elseif  (run3 == 4)  % (run3 == 1)((StkCoord == CPos_Stk2)&&(DipCoord == NewDip))
                                ExtraPt  = CPos_Stk2 +StkDist;
                                if (ExtraPt > FaultDim(1,2))
                                    MeanElev = RoughSurf(NewDip,NewStk)*WeightForStk + RoughSurf(NewDip,NewStk)*WeightForStk  + RoughSurf(CPos_Dip1,CPos_Stk2)*WeightForDip + RoughSurf(CPos_Dip2,CPos_Stk2)*WeightForDip;                            
                                else
                                    MeanElev = RoughSurf(NewDip,NewStk)*WeightForStk + RoughSurf(NewDip,ExtraPt)*WeightForStk + RoughSurf(CPos_Dip1,CPos_Stk2)*WeightForDip + RoughSurf(CPos_Dip2,CPos_Stk2)*WeightForDip;                            
                                end
                                RoughSurf(DipCoord,StkCoord) = MeanElev + RandOffs;
                            end
                        end
                    end  
                end 
            end  
            %--------------------------------------------------------------  
            %--------------------------------------------------------------  
            RoughSurf = RoughSurf(1:FaultDim(1,1)+1,1:FaultDim(1,2));
            RoughSurf = flipud(RoughSurf);
            RoughSurf = RoughSurf(1:end-1,:);
            
            RoughSurf = RoughSurf - mean(RoughSurf(:));
            %should detrend the surface 
            A         = 1: size(RoughSurf,2);
            B         = 1: size(RoughSurf,1);
            [BB,AA]   = meshgrid(A,B);
            SurfPCA   = pca([AA(:), BB(:), RoughSurf(:)]);
            PlaneNorm = SurfPCA(:,3)./norm(SurfPCA(:,3));
            RoughSurf = (AA.*PlaneNorm(1,1) + BB.*PlaneNorm(2,1) + RoughSurf.*PlaneNorm(3,1));
            %-------------------------------------
            RoughSurf = RoughSurf -mean(RoughSurf(:));
            MeanDevi  = sum(abs(RoughSurf(:)))./(size(RoughSurf,1)*size(RoughSurf,2));
            RoughSurf = RoughSurf.*(PreFact/MeanDevi);
            %-------------------------------------

            %--------------------------------------------------------------   
            %--------------------------------------------------------------   
        end
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
        function ShowRoughnessSpectrum(app, ii)                
            figure(1000+ii);  hold on; set(gca,'xscale','log','yscale','log');
 
            Nx        = size(app.RoughFault(ii).FltSurface,1);          Ny        = size(app.RoughFault(ii).FltSurface,2);   
            Nx_mod    = Nx -mod(Nx,2);              Ny_mod    = Ny -mod(Ny,2);
            samp      = [1/size(app.RoughFault(ii).FltSurface,1) 1/size(app.RoughFault(ii).FltSurface,2)];
            %------------------------------
            next_freq = 0:1:Ny_mod/2;               CombPower = zeros(1,size(next_freq,2));
            for i = 1 : Nx_mod 
                next_xdft          = fft(app.RoughFault(ii).FltSurface(i,1:Ny_mod)).*samp(2);
                next_xdft          = next_xdft(1:Ny_mod/2+1);
                next_psdx          = (1/(samp(2)*Ny_mod))*abs(next_xdft).^2;
                next_psdx(2:end-1) = 2*next_psdx(2:end-1);
                CombPower          = CombPower +next_psdx;
            end
            CombPower = CombPower./Nx_mod;
            plot(next_freq,CombPower,'LineWidth',1);hold on;
            %------------------------------
            next_freq = 0:1:Nx_mod/2;               CombPower = zeros(1,size(next_freq,2));
            for i = 1 : Ny_mod 
                next_xdft          = fft(app.RoughFault(ii).FltSurface(1:Nx_mod,i))'.*samp(1);
                next_xdft          = next_xdft(1:Nx_mod/2+1);
                next_psdx          = (1/(samp(1)*Nx_mod))*abs(next_xdft).^2;
                next_psdx(2:end-1) = 2*next_psdx(2:end-1);
                CombPower          = CombPower +next_psdx;
            end
            CombPower = CombPower./Ny_mod;
            plot(next_freq,CombPower,'LineWidth',1);

            title('Periodogram Using FFT');
            xlabel('Wavenumber');
            ylabel('Power/Wavenumber'); 
        end
        %---------------------------------------%---------------------------------------
        %---------------------------------------%---------------------------------------
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: RoughSeed_DropDown
        function RoughSeed_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,1)  = str2double(app.RoughSeed_DropDown.Value);     
            end
        end

        % Value changed function: RoughType_DropDown
        function RoughType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,2) = str2double(app.RoughType_DropDown.Value);  
            end
            
            SelRoughType = str2double(app.RoughType_DropDown.Value);
            if     (SelRoughType== 1)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.8 1 0.8];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.8 1 0.8];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughRMD_ampli_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 2)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.8 1 0.8];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.8 1 0.8];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_ampli_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 3)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.9 0.9 0.9];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_ampli_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.8 1 0.8];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 4)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.8 1 0.8];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.8 1 0.8];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_ampli_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 5)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.9 0.9 0.9];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.8 1 0.8];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughRMD_ampli_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];
            elseif (SelRoughType== 6)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.9 0.9 0.9];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_ampli_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughPreFact_EditField.BackgroundColor          = [0.9 0.9 0.9];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.9 0.9 0.9];
            end     
        end

        % Value changed function: RoughHurstVariType_DropDown
        function RoughHurstVariType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,3) = str2double(app.RoughHurstVariType_DropDown.Value);    
            end
        end

        % Value changed function: RoughPreFactVariType_DropDown
        function RoughPreFactVariType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,4) = str2double(app.RoughPreFactVariType_DropDown.Value); 
            end
        end

        % Value changed function: RoughCorrLengthVariType_DropDown
        function RoughCorrLengthVariType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,5) = str2double(app.RoughCorrLengthVariType_DropDown.Value);   
            end
        end

        % Value changed function: RoughCorrLengthStrike_EditField
        function RoughCorrLengthStrike_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,6) = app.RoughCorrLengthStrike_EditField.Value; 
            end
        end

        % Value changed function: RoughCorrLengthDip_EditField
        function RoughCorrLengthDip_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,7) = app.RoughCorrLengthDip_EditField.Value;  
            end
        end

        % Value changed function: RoughCorrLengthVari_EditField
        function RoughCorrLengthVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,8) = app.RoughCorrLengthVari_EditField.Value;   
            end
        end

        % Value changed function: RoughHurstStrike_EditField
        function RoughHurstStrike_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,9) = app.RoughHurstStrike_EditField.Value;   
            end
        end

        % Value changed function: RoughHurstDip_EditField
        function RoughHurstDip_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,10) = app.RoughHurstDip_EditField.Value;  
            end
        end

        % Value changed function: RoughHurstVari_EditField
        function RoughHurstVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,11) = app.RoughHurstVari_EditField.Value;    
            end
        end

        % Value changed function: RoughFractalDimension_EditField
        function RoughFractalDimension_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,12) = app.RoughFractalDimension_EditField.Value;   
            end
        end

        % Value changed function: RoughCornerWaveNum_EditField
        function RoughCornerWaveNum_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,13) = app.RoughCornerWaveNum_EditField.Value; 
            end
        end

        % Value changed function: RoughRMD_ampli_EditField
        function RoughRMD_ampli_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,14) = app.RoughRMD_ampli_EditField.Value;
            end         
        end

        % Value changed function: RoughRMD_decay_EditField
        function RoughRMD_decay_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,15) = app.RoughRMD_decay_EditField.Value;
            end            
        end

        % Value changed function: RoughNonLinScalingExp_EditField
        function RoughNonLinScalingExp_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,16) = app.RoughNonLinScalingExp_EditField.Value;
            end
        end

        % Value changed function: RoughPreFact_EditField
        function RoughPreFact_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,17) = app.RoughPreFact_EditField.Value;
            end
        end

        % Value changed function: RoughPreFactVari_EditField
        function RoughPreFactVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                SelFlt   = app.FaultValues(SelSection,20);
                SelFault = find(app.RoughParasPerFault(:,19) ==SelFlt);
                app.RoughParasPerFault(SelFault,18) = app.RoughPreFactVari_EditField.Value;
            end
        end

        % Value changed function: Use_SurfTrace_CheckBox
        function Use_SurfTrace_CheckBoxValueChanged(app, event)

            if (app.Use_SurfTrace_CheckBox.Value == 1)
                app.RoughCorrLengthStrike_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCorrLengthDip_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughCorrLengthVari_EditField.BackgroundColor   = [0.9 0.9 0.9];
                app.RoughHurstStrike_EditField.BackgroundColor      = [0.9 0.9 0.9];
                app.RoughHurstDip_EditField.BackgroundColor         = [0.9 0.9 0.9];
                app.RoughHurstVari_EditField.BackgroundColor        = [0.9 0.9 0.9];
                app.RoughRMD_ampli_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughRMD_decay_EditField.BackgroundColor        = [0.8 1 0.8];
                app.RoughFractalDimension_EditField.BackgroundColor = [0.9 0.9 0.9];
                app.RoughCornerWaveNum_EditField.BackgroundColor    = [0.9 0.9 0.9];
                app.RoughNonLinScalingExp_EditField.BackgroundColor = [0.8 1 0.8];
                app.RoughPreFact_EditField.BackgroundColor          = [0.8 1 0.8];
                app.RoughPreFactVari_EditField.BackgroundColor      = [0.8 1 0.8];
                app.RoughNormalizePreFact_EditField.BackgroundColor = [0.8 1 0.8];  
                app.RoughType_DropDown.Value = '6'; 
            end
            if (app.GridImported == 1)
                app.RoughParasPerFault(:,2)  = 6;  
                app.RoughType_DropDown.Value = '6';    
            end     
        end

        % Value changed function: FricStatFric_EditField
        function FricStatFric_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,2) = app.FricStatFric_EditField.Value;   
            end
        end

        % Value changed function: FricStatFricVari_EditField
        function FricStatFricVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,3) = app.FricStatFricVari_EditField.Value;   
            end
        end

        % Value changed function: FricDynDrop_EditField
        function FricDynDrop_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,4) = app.FricDynDrop_EditField.Value;
            end
        end

        % Value changed function: FricDynDropVari_EditField
        function FricDynDropVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,5) = app.FricDynDropVari_EditField.Value;
            end
        end

        % Value changed function: FricUseSkempton_CheckBox
        function FricUseSkempton_CheckBoxValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,11) = app.FricUseSkempton_CheckBox.Value;
            end
        end

        % Value changed function: FricSkemptonType_DropDown
        function FricSkemptonType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,12) = str2double(app.FricSkemptonType_DropDown.Value);
            end     
        end

        % Button pushed function: FricShowSkempton_ShowButton
        function FricShowSkempton_ShowButtonPushed(app, event)
            FigNum    = app.FigNum_EditField.Value*100+2;
            SKShape   = str2double(app.FricSkemptonType_DropDown.Value);
            Geotherm  = app.FricGeoTherm_EditField.Value;

            minTemp   = 0;              maxTemp   = 500;                TempStps  = 100;
            TempVals  = linspace(minTemp,maxTemp,TempStps);
            
            DepVals   = TempVals./Geotherm;
            SK_Vals   = zeros(size(TempVals));
            
            for i = 1 : size(TempVals,2)
                if     (SKShape == 1)
                    SK_Vals(1,i) = app.Skempton_p1(DepVals(1,i));  
                elseif (SKShape == 2)
                    SK_Vals(1,i) = app.Skempton_p2(DepVals(1,i));  
                elseif (SKShape == 3)
                    SK_Vals(1,i) = app.Skempton_p3(DepVals(1,i));  
                end
            end
            figure(FigNum); clf; hold on; 
            xlabel('Skempton factor'); ylabel('depth (km)');
            plot(SK_Vals,DepVals,'.-','linewidth',2);
            set(gca,'ydir','reverse');
        end

        % Value changed function: FricUseAmB_CheckBox
        function FricUseAmB_CheckBoxValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,8) = app.FricUseAmB_CheckBox.Value;
            end
        end

        % Value changed function: FricAmBType_DropDown
        function FricAmBType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,9) = str2double(app.FricAmBType_DropDown.Value);        
            end
        end

        % Button pushed function: FricShowAmB_ShowButton
        function FricShowAmB_ShowButtonPushed(app, event)
           
            FigNum   = app.FigNum_EditField.Value*100+1;

            AmBShape = str2double(app.FricAmBType_DropDown.Value);
            Geotherm = app.FricGeoTherm_EditField.Value;
            ShiftTemp= app.FricGeoThermStartTemp_EditField.Value;

            minTemp   = 0;          maxTemp   = 500;            TempStps  = 200;
            TempVals  = linspace(minTemp,maxTemp,TempStps);
            DepVals   = TempVals./Geotherm;
            TempVals  = TempVals + ShiftTemp;
            AmB_Vals  = zeros(size(TempVals));
            %-----------------------------------
            if     (AmBShape == 1)
                AmB_Vals = app.AmBparabolic(TempVals);   
            elseif (AmBShape == 2)
                for i = 1 : size(TempVals,2)
                    if     ((TempVals(1,i) >= app.AmB_p1_temp(1,1)) && (TempVals(1,i) <= app.AmB_p1_temp(1,2)))
                        AmB_Vals(1,i) = app.AmB_p1(TempVals(1,i));
                    elseif ((TempVals(1,i) >  app.AmB_p2_temp(1,1)) && (TempVals(1,i) <= app.AmB_p2_temp(1,2)))
                        AmB_Vals(1,i) = app.AmB_p2(TempVals(1,i));
                    elseif ((TempVals(1,i) >  app.AmB_p3_temp(1,1)) && (TempVals(1,i) <= app.AmB_p3_temp(1,2)))
                        AmB_Vals(1,i) = app.AmB_p3(TempVals(1,i));
                    elseif ((TempVals(1,i) >  app.AmB_p4_temp(1,1)) && (TempVals(1,i) <= app.AmB_p4_temp(1,2)))
                        AmB_Vals(1,i) = app.AmB_p4(TempVals(1,i));
                    end
                end
            end
            %-----------------------------------
            NegAmBvals  = AmB_Vals(AmB_Vals< 0);
            MeanSeisAmB = mean(NegAmBvals);
            %-----------------------------------
            figure(FigNum); hold on; xlabel('a-b value'); ylabel('depth (km)');
            plot(AmB_Vals,DepVals,'.-','linewidth',2);
            plot([0 0],[min(DepVals) max(DepVals)],'k');
            plot([MeanSeisAmB MeanSeisAmB],[min(DepVals) max(DepVals)],'-.r');
            set(gca,'ydir','reverse');   
        end

        % Value changed function: FricGeoTherm_EditField
        function FricGeoTherm_EditFieldValueChanged(app, event)
             SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,10) = app.FricGeoTherm_EditField.Value;   
            end
        end

        % Value changed function: FricDc_EditField
        function FricDc_EditFieldValueChanged(app, event)
             SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,6) = app.FricDc_EditField.Value;   
            end
        end

        % Value changed function: FricDcVari_EditField
        function FricDcVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,7) = app.FricDcVari_EditField.Value;
            end
        end

        % Button pushed function: LoadFaults_Button
        function LoadFaults_ButtonPushed(app, event)

            [DataName,DataPath]  = uigetfile('*_FLTtrig.dat','Load gridded Fault Fault Model');
            if     (DataName ~=0),      DataName = extractBefore(DataName,"_FLTtrig.dat");        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            app.SaveName_EditField.Value = DataName;
            app.SavePath                 = DataPath;  
            app.RMSoverLength            = struct;
            %----------------------
            Name4ascii     = strcat(CompletePath,'_FLT.txt');
            Name4binary    = strcat(CompletePath,'_FLTtrig.dat');
            %----------------------------------------------------------------
            fidascii    = fopen(Name4ascii ,'r');
            if (fidascii == -1), errordlg('The entered filename has not been found. 1');    return;   end
            %----------------------------------
            nextline               = fgetl(fidascii);               FltSectionNum          = sscanf(nextline,'%*s %d');  
            nextline               = fgetl(fidascii);               app.GridNumber         = sscanf(nextline,'%*s %d');
            if (app.GridNumber <= 0),       disp('Imported Fault Fault model not gridded');      return;             end
            app.FaultValues = nan(FltSectionNum,23);                app.FaultNames          = cell(1,FltSectionNum);
            %-----------------------%-----------------------%-----------------------
            for ii = 1 : app.GridNumber
                nextline                            = fgetl(fidascii);        nextvals                          = sscanf(nextline,'%*s %d %d');
                app.GriddedFault(ii).SizeTrigList   = nextvals(1,1);          app.GriddedFault(ii).SizeVertList = nextvals(2,1);
                nextline                            = fgetl(fidascii);        nextvals                          = sscanf(nextline,'%*s %e %e');
                app.GriddedFault(ii).meanLegLengths = nextvals(1,1);          
                %----------------------
            end
            nextline                = fgetl(fidascii);
            %-----------------------%-----------------------%-----------------------
            for i = 1 : FltSectionNum
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,20)    = sscanf(nextline,'%*s %d');
                FltSecName          = extractAfter(fgetl(fidascii)," ");                     app.FaultNames{1,i}      = FltSecName;
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,1)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,2)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,3)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,4)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,5)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,6)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,7)     = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,21)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,22)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,23)    = sscanf(nextline,'%*s %e');
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,8:10)  = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,11:13) = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,14:16) = sscanf(nextline,'%*s %e %e %e')';
                nextline            = fgetl(fidascii);                                       app.FaultValues(i,17:19) = sscanf(nextline,'%*s %e %e %e')';
                for ii = 1 : app.GridNumber
                    nextline            = fgetl(fidascii);          
                    nextline            = fgetl(fidascii);             
                end
                nextline            = fgetl(fidascii);
            end
            fclose(fidascii);
            %-----------------------%-----------------------%-----------------------
            for (ii = 1 : app.GridNumber)
                app.GriddedFault(ii).TriangleList    =        zeros(app.GriddedFault(ii).SizeTrigList,5);
                app.GriddedFault(ii).GlobTrigCentr   =        zeros(app.GriddedFault(ii).SizeTrigList,3);
                app.GriddedFault(ii).VertexList      =        zeros(app.GriddedFault(ii).SizeVertList,2);
                app.GriddedFault(ii).GlobalVertices  =        zeros(app.GriddedFault(ii).SizeVertList,3);  
            end
            %-----------------------
            fidbin    = fopen(Name4binary ,'rb');
            if (fidbin == -1), errordlg('The entered filename has not been found. 2');    return;   end
            %-----------------------
            for (ii = 1 : app.GridNumber)
                PatchNum = fread(fidbin,1,'int');
                VertNum  = fread(fidbin,1,'int');
                if ((PatchNum ~= app.GriddedFault(ii).SizeTrigList) || (VertNum ~= app.GriddedFault(ii).SizeVertList))
                   disp('patch and/or vertex numbers are not consistent');
                   return;
                end
                app.GriddedFault(ii).TriangleList(:,1)     = fread(fidbin,PatchNum,'int32');
                app.GriddedFault(ii).TriangleList(:,2)     = fread(fidbin,PatchNum,'int32');
                app.GriddedFault(ii).TriangleList(:,3)     = fread(fidbin,PatchNum,'int32');
                app.GriddedFault(ii).TriangleList(:,4)     = fread(fidbin,PatchNum,'int32');
                app.GriddedFault(ii).TriangleList(:,5)     = fread(fidbin,PatchNum,'int32');
                app.GriddedFault(ii).VertexList(:,1)       = fread(fidbin,VertNum,'single');
                app.GriddedFault(ii).VertexList(:,2)       = fread(fidbin,VertNum,'single');
                app.GriddedFault(ii).GlobalVertices(:,1)   = fread(fidbin,VertNum,'single');
                app.GriddedFault(ii).GlobalVertices(:,2)   = fread(fidbin,VertNum,'single');
                app.GriddedFault(ii).GlobalVertices(:,3)   = fread(fidbin,VertNum,'single');
                app.GriddedFault(ii).GlobTrigCentr(:,1)    = fread(fidbin,PatchNum,'single');
                app.GriddedFault(ii).GlobTrigCentr(:,2)    = fread(fidbin,PatchNum,'single');
                app.GriddedFault(ii).GlobTrigCentr(:,3)    = fread(fidbin,PatchNum,'single');   
                
                FltIDnum                                   = fread(fidbin,1,       'int32');
                app.RMSoverLength(ii).TheVal(:,1)          = fread(fidbin,FltIDnum,'single');   
            end
            fclose(fidbin);
            %-----------------------%-----------------------
            for (ii = 1 : app.GridNumber)
                PatchAreas    = zeros(size(app.GriddedFault(ii).TriangleList,1),1);
                %-----------------------------------------
                for i = 1 : size(app.GriddedFault(ii).TriangleList,1)
                    v1    = app.GriddedFault(ii).TriangleList(i,1);
                    v2    = app.GriddedFault(ii).TriangleList(i,2);
                    v3    = app.GriddedFault(ii).TriangleList(i,3);
                    %---------------------------
                    PatchAreas(i,1) = 1/2*norm(cross( (app.GriddedFault(ii).GlobalVertices(v2,:)-app.GriddedFault(ii).GlobalVertices(v1,:))',(app.GriddedFault(ii).GlobalVertices(v3,:)-app.GriddedFault(ii).GlobalVertices(v1,:))')); 
                    %---------------------------
                end
                meanPatchAreas   = nanmean(PatchAreas(:));
                %-----------------------------------------
                app.GriddedFault(ii).MeanPatchSize   = meanPatchAreas;
            end
            %-----------------------%-----------------------
            for (ii = 1 : app.GridNumber)
                SizeTrigList{ii,1} = num2str(app.GriddedFault(ii).SizeTrigList);
            end
            app.ElementGrids_ListBox.Items     = SizeTrigList;
            app.ElementGrids_ListBox.ItemsData = 1:size(SizeTrigList,1);
            app.ElementGrids_ListBox.Value     = 1;

            app.FaultList_ListBox.Items        = app.FaultNames;
            app.FaultList_ListBox.ItemsData    = 1:size(app.FaultNames,2);
            app.FaultList_ListBox.Value        = 1;
            %-----------------------%-----------------------%-----------------------
            %-----------------------%-----------------------%------------------- ----
            RSeed_OldNew = str2double(app.RoughSeed_DropDown.Value);
            RType        = str2double(app.RoughType_DropDown.Value);
            RHexpVarType = str2double(app.RoughHurstVariType_DropDown.Value);
            RPreFVarType = str2double(app.RoughPreFactVariType_DropDown.Value);
            RCorrVarType = str2double(app.RoughCorrLengthVariType_DropDown.Value);

            RCorrInStk   = app.RoughCorrLengthStrike_EditField.Value; 
            RCorrInDip   = app.RoughCorrLengthDip_EditField.Value;
            RCorrVar     = app.RoughCorrLengthVari_EditField.Value;
            RHexpStk     = app.RoughHurstStrike_EditField.Value;
            RHexpDip     = app.RoughHurstDip_EditField.Value;
            RHexpVar     = app.RoughHurstVari_EditField.Value;
            RFractDim    = app.RoughFractalDimension_EditField.Value;
            RCornFreq    = app.RoughCornerWaveNum_EditField.Value;
            RrmdAmp      = app.RoughRMD_ampli_EditField.Value;
            RrmdDcy      = app.RoughRMD_decay_EditField.Value;
            
            RNonLinScl   = app.RoughNonLinScalingExp_EditField.Value;
            RPreFact     = app.RoughPreFact_EditField.Value;
            RPFactVar    = app.RoughPreFactVari_EditField.Value;
            
            SFricType    = str2double(app.FricFrictionType_DropDown.Value);
            SStatFric    = app.FricStatFric_EditField.Value;
            SStatFricVari= app.FricStatFricVari_EditField.Value;
            SDynFric     = app.FricDynDrop_EditField.Value;
            SDynFricVari = app.FricDynDropVari_EditField.Value;
            SCritSlip    = app.FricDc_EditField.Value;
            SCritSlipVari= app.FricDcVari_EditField.Value;
            SUseAmBprof  = app.FricUseAmB_CheckBox.Value;
            SAmBShpe     = str2double(app.FricAmBType_DropDown.Value);
            SGeothermGrd = app.FricGeoTherm_EditField.Value;
            SSkemptonFact= app.FricUseSkempton_CheckBox.Value;
            SSkemptShape = str2double(app.FricSkemptonType_DropDown.Value);
            
            SFractDist   = app.StrengthUseFractalDistrib_CheckBox.Value;
            SSeed_OldNew = str2double(app.StrengthSeed_DropDown.Value);
            SCorrInStk   = app.StrengthCorrLengthStrike_EditField.Value;
            SCorrInDip   = app.StrengthCorrLengthDip_EditField.Value;
            SHexp        = app.StrengthHurstExp_EditField.Value;
            SWatrLevl    = app.StrengthWaterLevel_EditField.Value;
            SWatrLevlVar = app.StrengthWaterLevelVari_EditField;
            %-----------------------%-----%-----------------------%-----
            FltIDUniq   = unique(app.FaultValues(:,20));
            FltUniqnum  = size(FltIDUniq,1);
            %-----------------------%-----%-----------------------%-----
            app.RoughParasPerFault     = zeros(FltUniqnum,19);
            app.StrgthParasPerSectn    = zeros(FltSectionNum,20); 
            %-----------------------%-----%-----------------------%-----
            for (jj = 1 : FltUniqnum)
                
                app.RoughParasPerFault(jj,1)  = RSeed_OldNew;
                app.RoughParasPerFault(jj,2)  = RType;
                app.RoughParasPerFault(jj,3)  = RHexpVarType;
                app.RoughParasPerFault(jj,4)  = RPreFVarType;
                app.RoughParasPerFault(jj,5)  = RCorrVarType;
                app.RoughParasPerFault(jj,6)  = RCorrInStk;
                app.RoughParasPerFault(jj,7)  = RCorrInDip;
                app.RoughParasPerFault(jj,8)  = RCorrVar;
                app.RoughParasPerFault(jj,9)  = RHexpStk;
                app.RoughParasPerFault(jj,10) = RHexpDip;
                app.RoughParasPerFault(jj,11) = RHexpVar;
                app.RoughParasPerFault(jj,12) = RFractDim;
                app.RoughParasPerFault(jj,13) = RCornFreq;
                app.RoughParasPerFault(jj,14) = RrmdAmp;
                app.RoughParasPerFault(jj,15) = RrmdDcy;
                app.RoughParasPerFault(jj,16) = RNonLinScl;
                app.RoughParasPerFault(jj,17) = RPreFact;
                app.RoughParasPerFault(jj,18) = RPFactVar;
                app.RoughParasPerFault(jj,19) = FltIDUniq(jj,1);
                
                app.RandParasRough(jj).Vals   = rng('shuffle'); 
            end
            %-----------------------%-----
            for (jj = 1 : FltSectionNum)
                
                app.StrgthParasPerSectn(jj,1)  = SFricType;
                app.StrgthParasPerSectn(jj,2)  = SStatFric;
                app.StrgthParasPerSectn(jj,3)  = SStatFricVari;
                app.StrgthParasPerSectn(jj,4)  = SDynFric;
                app.StrgthParasPerSectn(jj,5)  = SDynFricVari;
                app.StrgthParasPerSectn(jj,6)  = SCritSlip;
                app.StrgthParasPerSectn(jj,7)  = SCritSlipVari;
                
                app.StrgthParasPerSectn(jj,8)  = SUseAmBprof;
                app.StrgthParasPerSectn(jj,9)  = SAmBShpe;
                app.StrgthParasPerSectn(jj,10) = SGeothermGrd;
                app.StrgthParasPerSectn(jj,11) = SSkemptonFact;
                app.StrgthParasPerSectn(jj,12) = SSkemptShape;
                
                app.StrgthParasPerSectn(jj,13) = SFractDist;
                app.StrgthParasPerSectn(jj,14) = SSeed_OldNew;
                app.StrgthParasPerSectn(jj,15) = SCorrInStk;
                app.StrgthParasPerSectn(jj,16) = SCorrInDip; 
                app.StrgthParasPerSectn(jj,17) = SHexp;
                app.StrgthParasPerSectn(jj,18) = SWatrLevl;
                app.StrgthParasPerSectn(jj,19) = SWatrLevlVar;
               
                app.StrgthParasPerSectn(jj,20) = app.FaultValues(jj,20); %the fault number...
                app.RandParasStrgt(jj).Vals    = rng('shuffle');
            end
            %-----------------------%-----
            for (jj = 1 : FltSectionNum)
                SelSections = find(app.StrgthParasPerSectn(:,20) == jj);
                for (ii = 1 : size(SelSections,1))
                    app.StrgthParasPerSectn(SelSections(ii,1),13)  = app.StrgthParasPerSectn(SelSections(1,1),13); %useFractDistribCheck
                    app.StrgthParasPerSectn(SelSections(ii,1),14)  = app.StrgthParasPerSectn(SelSections(1,1),14); %newSeedCheck
                    app.StrgthParasPerSectn(SelSections(ii,1),15)  = app.StrgthParasPerSectn(SelSections(1,1),15); %CorrInStk
                    app.StrgthParasPerSectn(SelSections(ii,1),16)  = app.StrgthParasPerSectn(SelSections(1,1),16); %CorrInDip
                    app.StrgthParasPerSectn(SelSections(ii,1),17) = app.StrgthParasPerSectn(SelSections(1,1),17); %HurstExp
                    app.StrgthParasPerSectn(SelSections(ii,1),18) = app.StrgthParasPerSectn(SelSections(1,1),18); %Waterlevel
                    app.StrgthParasPerSectn(SelSections(ii,1),19) = app.StrgthParasPerSectn(SelSections(1,1),19); %WL_vari
                    app.RandParasStrgt(SelSections(ii,1)).Vals    = app.RandParasStrgt(SelSections(1,1)).Vals; %seed value for FractDistrib
                end
            end
            %-----------------------%-----%-----------------------%-----       
            for (ii = 1 : app.GridNumber)    
                app.GriddedFault(ii).LocalPosVals  = zeros(size(app.GriddedFault(ii).GlobalVertices,1),3);
                app.GriddedFault(ii).RoughPosVals  = zeros(size(app.GriddedFault(ii).GlobalVertices,1),4);
                app.GriddedFault(ii).LocalCentVals = zeros(app.GriddedFault(ii).SizeTrigList,2);
                app.GriddedFault(ii).RoughCentVals = zeros(app.GriddedFault(ii).SizeTrigList,3);
                app.GriddedFault(ii).StrikeDipVals = zeros(app.GriddedFault(ii).SizeTrigList,2);
                app.GriddedFault(ii).FaultCentCoord= zeros(FltSectionNum,3);
                app.GriddedFault(ii).RotMat        = zeros(FltSectionNum,9);
                app.GriddedFault(ii).RotMatT       = zeros(FltSectionNum,9);
                app.GriddedFault(ii).FricVals      = zeros(app.GriddedFault(ii).SizeTrigList,3); %(a-b) strength and asperity/random field factors
                %-----------------------%-----%-----------------------%-----
                for (jj = 1 : FltUniqnum)
                    SelectedPatchPos = find(app.GriddedFault(ii).TriangleList(:,5) == FltIDUniq(jj,1));
                    SelectedPatches  = app.GriddedFault(ii).TriangleList(SelectedPatchPos,1:3);
                    %now I know the ID of the vertices => need to get their locations (X/Y/Z coordinates) next; make SelectedPatches a list 
                    SelVerticesList  = unique(SelectedPatches(:)); 
                    SelCoordinates   = app.GriddedFault(ii).GlobalVertices(SelVerticesList,1:3);  
                    
                    app.GriddedFault(ii).FaultCentCoord(jj,1:3) = [mean(SelCoordinates(:,1)), mean(SelCoordinates(:,2)), mean(SelCoordinates(:,3))];
                    SelCoordinates(:,1)   = SelCoordinates(:,1) - app.GriddedFault(ii).FaultCentCoord(jj,1);
                    SelCoordinates(:,2)   = SelCoordinates(:,2) - app.GriddedFault(ii).FaultCentCoord(jj,2);
                    SelCoordinates(:,3)   = SelCoordinates(:,3) - app.GriddedFault(ii).FaultCentCoord(jj,3);
                    %----------------------------------------
                    FaultPCA    = pca(SelCoordinates);
                    %pc1        = FaultPCA(:,1); %if combined length > combined width, then this is in strike direction (i.e., close to it)
                    %pc2        = FaultPCA(:,2); %if combined length > combined width, then this is in dip direction (i.e., close to it)
                    PlaneNormal = FaultPCA(:,3)./norm(FaultPCA(:,3));%this is fault normal; best to use only this one! => from it derive strike direction and up-dip direction
            
                    if (PlaneNormal(3,1) < 0),                                      PlaneNormal = PlaneNormal.*-1;          end
                    if (PlaneNormal(3,1) > 0)
                        TempVect = [PlaneNormal(1,1);PlaneNormal(2,1); 0];          TempVect    = TempVect./norm(TempVect);
                        StkVect  = cross(PlaneNormal,TempVect);                     StkVect     = StkVect./norm(StkVect);
                    else
                        TempVect = [0; 0; 1];                                       StkVect     = cross(TempVect,PlaneNormal);
                        StkVect  = StkVect./norm(StkVect);
                    end
                    DipVect = cross(PlaneNormal,StkVect);                           DipVect     = DipVect./norm(DipVect);
            
                    app.GriddedFault(ii).RotMat(jj,1:9)  = [PlaneNormal(1,1), PlaneNormal(2,1), PlaneNormal(3,1), StkVect(1,1),     StkVect(2,1), StkVect(3,1), DipVect(1,1),     DipVect(2,1), DipVect(3,1)];                     
                    app.GriddedFault(ii).RotMatT(jj,1:9) = [PlaneNormal(1,1), StkVect(1,1),     DipVect(1,1),     PlaneNormal(2,1), StkVect(2,1), DipVect(2,1), PlaneNormal(3,1), StkVect(3,1), DipVect(3,1)];                     
                    %----------------------------------------     
                    if (sign(app.GriddedFault(ii).RotMatT(jj,1)) ~= sign(app.GriddedFault(1).RotMatT(jj,1)))
                        app.GriddedFault(ii).RotMat(jj,1)  = -1*app.GriddedFault(ii).RotMat(jj,1);            app.GriddedFault(ii).RotMat(jj,4) = -1*app.GriddedFault(ii).RotMat(jj,4);        app.GriddedFault(ii).RotMat(jj,7) = -1*app.GriddedFault(ii).RotMat(jj,7);
                        app.GriddedFault(ii).RotMat(jj,2)  = -1*app.GriddedFault(ii).RotMat(jj,2);            app.GriddedFault(ii).RotMat(jj,5) = -1*app.GriddedFault(ii).RotMat(jj,5);        app.GriddedFault(ii).RotMat(jj,8) = -1*app.GriddedFault(ii).RotMat(jj,8);
                        
                        app.GriddedFault(ii).RotMatT(jj,1) = -1*app.GriddedFault(ii).RotMatT(jj,1);           app.GriddedFault(ii).RotMatT(jj,2)= -1*app.GriddedFault(ii).RotMatT(jj,2);       app.GriddedFault(ii).RotMatT(jj,3)= -1*app.GriddedFault(ii).RotMatT(jj,3);
                        app.GriddedFault(ii).RotMatT(jj,4) = -1*app.GriddedFault(ii).RotMatT(jj,4);           app.GriddedFault(ii).RotMatT(jj,5)= -1*app.GriddedFault(ii).RotMatT(jj,5);       app.GriddedFault(ii).RotMatT(jj,6)= -1*app.GriddedFault(ii).RotMatT(jj,6);
                    end
                    %----------------------------------------     
                    RotMatT                                              = reshape(app.GriddedFault(ii).RotMatT(jj,:),3,3);
                    app.GriddedFault(ii).LocalPosVals(SelVerticesList,:) = (RotMatT*SelCoordinates')';
                    %----------------------------------------   
                    for kk = 1 : size(SelectedPatchPos,1)
                        NextPatch = SelectedPatchPos(kk,1);
                        v1        = app.GriddedFault(ii).TriangleList(NextPatch,1);
                        v2        = app.GriddedFault(ii).TriangleList(NextPatch,2);
                        v3        = app.GriddedFault(ii).TriangleList(NextPatch,3);
                        MeanStkPos= (app.GriddedFault(ii).LocalPosVals(v1,2) +app.GriddedFault(ii).LocalPosVals(v2,2) +app.GriddedFault(ii).LocalPosVals(v3,2))/3;
                        MeanDipPos= (app.GriddedFault(ii).LocalPosVals(v1,3) +app.GriddedFault(ii).LocalPosVals(v2,3) +app.GriddedFault(ii).LocalPosVals(v3,3))/3;
             
                        app.GriddedFault(ii).LocalCentVals(NextPatch,1) = MeanStkPos;
                        app.GriddedFault(ii).LocalCentVals(NextPatch,2) = MeanDipPos;
                    end
                end
                %-----------------------%-----%-----------------------%-----
            end 
            app.GridImported = 1;                       
        end

        % Button pushed function: Plotfaults_Button
        function Plotfaults_ButtonPushed(app, event)
       
            if (app.GridImported == 0),             return;                             end
            
            FigNum          = app.FigNum_EditField.Value;
            SelctdGrid      = app.ElementGrids_ListBox.Value;
            if (~isa(SelctdGrid,'double')),     SelctdGrid = str2double(SelctdGrid);            end         
            ViewAngle       = app.ViewAngle_EditField.Value;        ViewAngle = sscanf(ViewAngle,'%f');
            %-----------------------------------------------
            figure(FigNum); clf; hold on; axis equal;
            xlabel('easting');ylabel('northing'); zlabel('depth');
            %-----------------------------------------------
            AllV1s          = zeros(app.GriddedFault(SelctdGrid).SizeTrigList,3);
            AllV2s          = zeros(app.GriddedFault(SelctdGrid).SizeTrigList,3);
            AllV3s          = zeros(app.GriddedFault(SelctdGrid).SizeTrigList,3);
            PlottedComp     = zeros(app.GriddedFault(SelctdGrid).SizeTrigList,1);
            Cntr            = 1;
            %-----------------------------------------------
            for j = 1 : app.GriddedFault(SelctdGrid).SizeTrigList
              
                AllV1s(Cntr,1) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,1),1);
                AllV1s(Cntr,2) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,2),1);
                AllV1s(Cntr,3) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,3),1);
            
                AllV2s(Cntr,1) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,1),2);
                AllV2s(Cntr,2) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,2),2);
                AllV2s(Cntr,3) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,3),2);
            
                AllV3s(Cntr,1) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,1),3);
                AllV3s(Cntr,2) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,2),3);
                AllV3s(Cntr,3) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,3),3);
                %-----------------------------
                PlottedComp(Cntr,1)   = app.GriddedFault(SelctdGrid).TriangleList(j,5);%rand(1);
                Cntr                  = Cntr +1;
                %-----------------------------
            end
            patch(AllV1s',AllV2s',AllV3s',PlottedComp');
            view(ViewAngle'); 
        end

        % Value changed function: FaultList_ListBox
        function FaultList_ListBoxValueChanged(app, event)
            
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end     
            FltIDUniq     = unique(app.FaultValues(:,20));
            SelFaultIDval = app.FaultValues(SelSection,20);
            SelFaultID    = find(FltIDUniq == SelFaultIDval,1);
            SelGrid       = app.ElementGrids_ListBox.Value;

            app.RoughSeed_DropDown.Value                 = num2str(app.RoughParasPerFault(SelFaultID,1));
            app.RoughType_DropDown.Value                 = num2str(app.RoughParasPerFault(SelFaultID,2));
            app.RoughHurstVariType_DropDown.Value        = num2str(app.RoughParasPerFault(SelFaultID,3));
            app.RoughPreFactVariType_DropDown.Value      = num2str(app.RoughParasPerFault(SelFaultID,4));
            app.RoughCorrLengthVariType_DropDown.Value   = num2str(app.RoughParasPerFault(SelFaultID,5));
            app.RoughCorrLengthStrike_EditField.Value    = app.RoughParasPerFault(SelFaultID,6);
            app.RoughCorrLengthDip_EditField.Value       = app.RoughParasPerFault(SelFaultID,7);
            app.RoughCorrLengthVari_EditField.Value      = app.RoughParasPerFault(SelFaultID,8);
            app.RoughHurstStrike_EditField.Value         = app.RoughParasPerFault(SelFaultID,9);
            app.RoughHurstDip_EditField.Value            = app.RoughParasPerFault(SelFaultID,10);
            app.RoughHurstVari_EditField.Value           = app.RoughParasPerFault(SelFaultID,11);
            app.RoughFractalDimension_EditField.Value    = app.RoughParasPerFault(SelFaultID,12);
            app.RoughCornerWaveNum_EditField.Value       = app.RoughParasPerFault(SelFaultID,13);
            app.RoughRMD_ampli_EditField.Value           = app.RoughParasPerFault(SelFaultID,14);
            app.RoughRMD_decay_EditField.Value           = app.RoughParasPerFault(SelFaultID,15);
            app.RoughNonLinScalingExp_EditField.Value    = app.RoughParasPerFault(SelFaultID,16);
            app.RoughPreFact_EditField.Value             = app.RoughParasPerFault(SelFaultID,17);
            app.RoughPreFactVari_EditField.Value         = app.RoughParasPerFault(SelFaultID,18);
  
            app.FricFrictionType_DropDown.Value          = num2str(app.StrgthParasPerSectn(SelSection,1));   
            
            app.FricStatFric_EditField.Value             = app.StrgthParasPerSectn(SelSection,2);
            app.FricStatFricVari_EditField.Value         = app.StrgthParasPerSectn(SelSection,3);
            app.FricDynDrop_EditField.Value              = app.StrgthParasPerSectn(SelSection,4);
            app.FricDynDropVari_EditField.Value          = app.StrgthParasPerSectn(SelSection,5);
            app.FricDc_EditField.Value                   = app.StrgthParasPerSectn(SelSection,6);     
            app.FricDcVari_EditField.Value               = app.StrgthParasPerSectn(SelSection,7);
            app.FricUseAmB_CheckBox.Value                = app.StrgthParasPerSectn(SelSection,8);
            app.FricAmBType_DropDown.Value               = num2str(app.StrgthParasPerSectn(SelSection,9));
            app.FricGeoTherm_EditField.Value             = app.StrgthParasPerSectn(SelSection,10);
            app.FricUseSkempton_CheckBox.Value           = app.StrgthParasPerSectn(SelSection,11);
            app.FricSkemptonType_DropDown.Value          = num2str(app.StrgthParasPerSectn(SelSection,12));
            app.StrengthUseFractalDistrib_CheckBox.Value = app.StrgthParasPerSectn(SelSection,13);
            app.StrengthSeed_DropDown.Value              = num2str(app.StrgthParasPerSectn(SelSection,14));
            app.StrengthCorrLengthStrike_EditField.Value = app.StrgthParasPerSectn(SelSection,15);
            app.StrengthCorrLengthDip_EditField.Value    = app.StrgthParasPerSectn(SelSection,16);
            app.StrengthHurstExp_EditField.Value         = app.StrgthParasPerSectn(SelSection,17);  
            app.StrengthWaterLevel_EditField.Value       = app.StrgthParasPerSectn(SelSection,18);
            app.StrengthWaterLevelVari_EditField.Value   = app.StrgthParasPerSectn(SelSection,19); 

            app.RMSL_EditField.Value                     = app.RMSoverLength(SelGrid).TheVal(SelFaultID);
        end

        % Button pushed function: GenerateRoughness_Button
        function GenerateRoughness_ButtonPushed(app, event)

            if (app.GridImported == 0),             return;                             end
            
            ShowStats       = app.ShowRoughnessStats_CheckBox.Value;
            PlotRough       = app.PlotRoughness_CheckBox.Value;
            FigNum          = app.FigNum_EditField.Value;  
            FaultNumber     = size(unique(app.FaultValues(:,20)),1);
            LgthScale       = app.RoughLengthScale_EditField.Value;
            SelSection      = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            
            UsedValue4Normalizing = app.RoughNormalizePreFact_EditField.Value;
            rng('shuffle');
            VariationRandomNum    = rand(FaultNumber,5); 
            VariationRandomNumN   = randn(FaultNumber,5); 
            %-----------------------------
            for ii = 1 : FaultNumber
                %gathergathergathergathergathergathergathergathergathergathergathergather
                NewSeedOrNot    = app.RoughParasPerFault(ii,1);
                
                if     (app.RoughParasPerFault(ii,2) == 1),        acf = 'AK'; 
                elseif (app.RoughParasPerFault(ii,2) == 2),        acf = 'EX';
                elseif (app.RoughParasPerFault(ii,2) == 3),        acf = 'FR';
                elseif (app.RoughParasPerFault(ii,2) == 4),        acf = 'GS';
                elseif (app.RoughParasPerFault(ii,2) == 5),        acf = 'CN';    %for Candela et al. 2009
                elseif (app.RoughParasPerFault(ii,2) == 6),        acf = 'RM';    %random midpoint displacement
                end
                    
                HurstExp_vartype= app.RoughParasPerFault(ii,3);                     PreFact_vartype = app.RoughParasPerFault(ii,4);
                CorrLgth_vartype= app.RoughParasPerFault(ii,5);                     CorrLgth_StkAll = app.RoughParasPerFault(ii,6);
                CorrLgth_Dip    = app.RoughParasPerFault(ii,7);                     CorrLgth_vari   = app.RoughParasPerFault(ii,8);
                HurstExp_StkAll = app.RoughParasPerFault(ii,9);                     HurstExp_Dip    = app.RoughParasPerFault(ii,10);
                HurstExp_vari   = app.RoughParasPerFault(ii,11);                    FractDim        = app.RoughParasPerFault(ii,12);
                CnrWaveNum      = app.RoughParasPerFault(ii,13);                    RMDAmpl         = app.RoughParasPerFault(ii,14);
                RMDDecy         = app.RoughParasPerFault(ii,15);                    NonLinSclExp    = app.RoughParasPerFault(ii,16);
                PreFactor       = app.RoughParasPerFault(ii,17);                    PreFact_vari    = app.RoughParasPerFault(ii,18);
                SelFaultID      = app.RoughParasPerFault(ii,19); 
                %gathergathergathergathergathergathergathergathergathergathergathergather
                %prepareprepareprepareprepareprepareprepareprepareprepareprepareprepare
                %------------------------------
                %get all patches/vertices that belong to a given fault; 
                %determine extend of those; important is to know 
                SelectedPatches = app.GriddedFault(1).TriangleList( (app.GriddedFault(1).TriangleList(:,5) == SelFaultID) ,1:3);
                SelVerticesList = unique(SelectedPatches(:)); 
                SeletedVerts    = app.GriddedFault(1).LocalPosVals(SelVerticesList,:); %this is in local coordinate system that is best fitting plane to all fault sections of a given fault 
                
                MinStkLngthVal  = min(SeletedVerts(:,2));                                MinDipLngthVal = min(SeletedVerts(:,3));                                    
                MaxStkLngthVal  = max(SeletedVerts(:,2));                                MaxDipLngthVal = max(SeletedVerts(:,3));                         
                FaultLength     = (MaxStkLngthVal-MinStkLngthVal);                       FaultWidth     = (MaxDipLngthVal-MinDipLngthVal);
                PtchNumInStk    = ceil(FaultLength/LgthScale);                           PtchNumInDip   = ceil(FaultWidth/LgthScale);
                %------------------------------
                if (HurstExp_vartype == 1),     HurstExp_StkAll = HurstExp_StkAll   + HurstExp_vari *VariationRandomNumN(ii,1);            HurstExp_Dip    = HurstExp_Dip  + HurstExp_vari*VariationRandomNumN(ii,2);
                else,                           HurstExp_StkAll = HurstExp_StkAll   + HurstExp_vari *(VariationRandomNum(ii,1)*2 -1);      HurstExp_Dip    = HurstExp_Dip  + HurstExp_vari*(VariationRandomNum(ii,2)*2 -1);
                end
                if (CorrLgth_vartype== 1),      CorrLgth_StkAll = CorrLgth_StkAll   + CorrLgth_vari *VariationRandomNumN(ii,3);            CorrLgth_Dip    = CorrLgth_Dip  + CorrLgth_vari *VariationRandomNumN(ii,4);
                else,                           CorrLgth_StkAll = CorrLgth_StkAll   + CorrLgth_vari *(VariationRandomNum(ii,3)*2 -1);      CorrLgth_Dip    = CorrLgth_Dip  + CorrLgth_vari *(VariationRandomNum(ii,4)*2 -1);     
                end
                if (PreFact_vartype == 1),      PreFactorUsed   = abs(PreFactor     + PreFact_vari  *VariationRandomNumN(ii,5))    * UsedValue4Normalizing; %latter part is normal-distributed value => and std == entered value
                else,                           PreFactorUsed   = abs(PreFactor     + PreFact_vari*(VariationRandomNum(ii,5)*2 -1))* UsedValue4Normalizing; %latter part is value between -1 and +1 ==> then multiplied with range entry
                end   
                %------------------------------
                if (NewSeedOrNot == 1)
                   app.RandParasRough(ii).Vals =  rng('shuffle');
                end
                %------------------------------
                sampsize = [FaultWidth/PtchNumInDip, FaultLength/PtchNumInStk];
                %---------------------------------------
                if     (app.RoughParasPerFault(ii,2) == 1),                                             corr = [CorrLgth_Dip,    CorrLgth_StkAll, HurstExp_StkAll];%default == 1;        ak (van Karman)      
                elseif (app.RoughParasPerFault(ii,2) == 2)||(app.RoughParasPerFault(ii,2) == 4),        corr = [CorrLgth_Dip,    CorrLgth_StkAll                 ];%exponential              gaussian
                elseif (app.RoughParasPerFault(ii,2) == 3),                                             corr = [FractDim,        CnrWaveNum                      ];%fractal
                                                                                                        %kc   = 2*pi/(sqrt(FaultLength*FaultWidth)); %disp(strcat('computed corner wavenumber is : ',num2str(kc)));   
                elseif (app.RoughParasPerFault(ii,2) == 5),                                             corr = [HurstExp_StkAll, HurstExp_Dip          ];  
                elseif (app.RoughParasPerFault(ii,2) == 6),                                             corr = [RMDAmpl,         RMDDecy               ];
                end
                %prepareprepareprepareprepareprepareprepareprepareprepareprepareprepare
                % --------------------------------------
                % GENERATE THE SPATIAL RANDOM FIELDS
                %---------------------------------------
                if (app.RoughParasPerFault(ii,2) < 5)
                    app.RoughFault(ii).FltSurface  = SpecSyn2(app, [FaultWidth FaultLength],sampsize,corr,acf,NonLinSclExp,PreFactorUsed,app.RandParasRough(ii).Vals);
               
                elseif (app.RoughParasPerFault(ii,2) == 5)
                    MaxPntNum                      = max([PtchNumInStk PtchNumInDip]);
                    N                              = nextpow2(MaxPntNum);		% new grid-size                    
                    app.RoughFault(ii).FltSurface  = Synth2DFault_Candela2009(app, N, HurstExp_StkAll, HurstExp_Dip,NonLinSclExp,PreFactorUsed,app.RandParasRough(ii).Vals);
                    CentPos                        = round(size(app.RoughFault(ii).FltSurface)/2);
                    app.RoughFault(ii).FltSurface  = app.RoughFault(ii).FltSurface(CentPos-floor(PtchNumInDip/2) : CentPos+floor(PtchNumInDip/2), CentPos-floor(PtchNumInStk/2) : CentPos+floor(PtchNumInStk/2));
                    
                elseif (app.RoughParasPerFault(ii,2) == 6)   
                    MaxPntNum         = max([PtchNumInStk PtchNumInDip]);
                    N                 = nextpow2(MaxPntNum);		% new grid-size
                    if ((app.Use_SurfTrace_CheckBox.Value == 1) && (app.SurfTraceImported == 0)),           return;             end
                    if (app.Use_SurfTrace_CheckBox.Value == 1)
                        NextFaultTrace = app.SurfTraceVals(ii).TraceVals;
                   %     RMDAmpl        =  (max(NextFaultTrace)-min(NextFaultTrace)) / FaultLength;
                    else
                        NextFaultTrace = [];
                    end    
                    app.RoughFault(ii).FltSurface  = RandomMidPointDisplacement(app, N, [RMDAmpl, RMDDecy], [PtchNumInDip PtchNumInStk], app.RandParasRough(ii).Vals, PreFactorUsed, sampsize, NextFaultTrace);
                end 
                %---------------------------------------
                if (ShowStats == 1),            ShowRoughnessSpectrum(app,ii);             end
                %---------------------------------------
                %for RMS -calculation: 
                PatchNum                = length(app.RoughFault(ii).FltSurface(:));
                FaultArea               = FaultLength*FaultWidth;
                PatchArea               = FaultArea/PatchNum;
                app.RMSoverLength(1).TheVal(ii,1) = sqrt(1/FaultArea *sum(app.RoughFault(ii).FltSurface(:).^2 *PatchArea) )/UsedValue4Normalizing;
                app.RMSL_EditField.Value = app.RMSoverLength(1).TheVal(ii,1);
                disp(strcat('Fault ',num2str(ii),' with RMS value:_',num2str(app.RMSoverLength(1).TheVal(ii,1))));
                %---------------------------------------%---------------------------------------
                if (PlotRough == 1)
                    VectAlongStk = linspace(MinStkLngthVal,MaxStkLngthVal,size(app.RoughFault(ii).FltSurface,2));
                    VectAlongDip = linspace(MinDipLngthVal,MaxDipLngthVal,size(app.RoughFault(ii).FltSurface,1));

                    figure(FigNum*100+ii); clf; hold on; axis equal;
                    imagesc(VectAlongStk,VectAlongDip,app.RoughFault(ii).FltSurface);
                    view(0,90);
                    axis image;
                    xlabel('Distance along fault strike (km)');
                    ylabel('Distance along fault dip (km)');
                    colorbar;
                    maxval = max(abs(app.RoughFault(ii).FltSurface(:)));
                    caxis([-maxval maxval]);
                end
                %---------------------------------------  
            end
            
            app.RoughnessGenerated   = 1;          
        end

        % Button pushed function: CombinewGrid_Button
        function CombinewGrid_ButtonPushed(app, event)
            
            if (app.GridImported       == 0),               return;                             end
            if (app.RoughnessGenerated == 0),               return;                             end
            
            PlotRough       = app.PlotRoughness_CheckBox.Value;
            FigNum          = app.FigNum_EditField.Value;   
            ViewAngle       = str2num(app.ViewAngle_EditField.Value);   
            FaultNumber     = size(unique(app.FaultValues(:,20)),1);

            SelctdGrid      = app.ElementGrids_ListBox.Value;
            if (~isa(SelctdGrid,'double')),     SelctdGrid = str2double(SelctdGrid);            end
            MeanPatchSize   = app.GriddedFault(SelctdGrid).MeanPatchSize;   
            
            UsedValue4Normalizing = app.RoughNormalizePreFact_EditField.Value;
            %-------------------------------%-------------------------------
            for ii = 1 : FaultNumber
                
                SelFaultID       = app.RoughParasPerFault(ii,19);
                SelectedPatchPos = find(app.GriddedFault(SelctdGrid).TriangleList(:,5) == SelFaultID);
                SelectedPatches  = app.GriddedFault(SelctdGrid).TriangleList(SelectedPatchPos,1:3);
                SelVerticesList  = unique(SelectedPatches(:)); 
                
                SeletedVerts     = app.GriddedFault(SelctdGrid).LocalPosVals(SelVerticesList,1:3);
                
                MinStkLngthVal   = min(SeletedVerts(:,2));                                MaxStkLngthVal = max(SeletedVerts(:,2));
                MinDipLngthVal   = min(SeletedVerts(:,3));                                MaxDipLngthVal = max(SeletedVerts(:,3));
                FaultLength      = (MaxStkLngthVal-MinStkLngthVal);                       FaultWidth     = (MaxDipLngthVal-MinDipLngthVal);
                %-----------------------------
                ApproxSquareNum     = (FaultLength*FaultWidth)/MeanPatchSize;
                AspectRatio         = FaultLength/FaultWidth;
                PtchNumInDipregrid  = round(sqrt((ApproxSquareNum)/AspectRatio));
                PtchNumInStkregrid  = round((ApproxSquareNum)/PtchNumInDipregrid);
                %-----------------------------
                VectAlongStkregrid  = linspace(MinStkLngthVal,MaxStkLngthVal,PtchNumInStkregrid);
                VectAlongDipregrid  = linspace(MinDipLngthVal,MaxDipLngthVal,PtchNumInDipregrid);
                Regridded           = imresize(app.RoughFault(ii).FltSurface,[PtchNumInDipregrid,PtchNumInStkregrid],'bicubic');
                %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                [X,Y]               = meshgrid(VectAlongStkregrid,VectAlongDipregrid);
                VertexHeight        = interp2(X,Y, Regridded ,SeletedVerts(:,2),SeletedVerts(:,3),'spline'); %this is important for potentially required extrapolation
                
                %-----------------------------
                %-----------------------------
                FaultArea = 0;
                for i = 1 : size(SelectedPatches,1)
                    v1    = SelectedPatches(i,1);           v2    = SelectedPatches(i,2);           v3    = SelectedPatches(i,3);
                    %---------------------------
                    %https://www.mathworks.com/matlabcentral/answers/271797-surface-area-of-a-3d-surface
                    FaultArea = FaultArea + 1/2*norm(cross((app.GriddedFault(SelctdGrid).LocalPosVals(v2,:)-app.GriddedFault(SelctdGrid).LocalPosVals(v1,:)),(app.GriddedFault(SelctdGrid).LocalPosVals(v3,:)-app.GriddedFault(SelctdGrid).LocalPosVals(v1,:))));   
                end
                PatchArea = FaultArea/size(SelectedPatches,1);
                app.RMSoverLength(SelctdGrid).TheVal(ii,1) = sqrt(1/FaultArea *sum(VertexHeight.^2 *PatchArea) )/UsedValue4Normalizing;
                %-----------------------------
                %-----------------------------
                SeletedVerts(:,1)   = SeletedVerts(:,1) +VertexHeight;
                %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                RotMat              = reshape(app.GriddedFault(SelctdGrid).RotMat(ii,:),3,3); 
            
                app.GriddedFault(SelctdGrid).RoughPosVals(SelVerticesList,1:3) = (RotMat*SeletedVerts')';
                app.GriddedFault(SelctdGrid).RoughPosVals(SelVerticesList,1)   = app.GriddedFault(SelctdGrid).RoughPosVals(SelVerticesList,1) +app.GriddedFault(SelctdGrid).FaultCentCoord(ii,1);
                app.GriddedFault(SelctdGrid).RoughPosVals(SelVerticesList,2)   = app.GriddedFault(SelctdGrid).RoughPosVals(SelVerticesList,2) +app.GriddedFault(SelctdGrid).FaultCentCoord(ii,2);
                app.GriddedFault(SelctdGrid).RoughPosVals(SelVerticesList,3)   = app.GriddedFault(SelctdGrid).RoughPosVals(SelVerticesList,3) +app.GriddedFault(SelctdGrid).FaultCentCoord(ii,3);
                app.GriddedFault(SelctdGrid).RoughPosVals(SelVerticesList,4)   = VertexHeight; 
            end
            %-----------------------------%-----------------------------
            MinDepth = max(app.GriddedFault(SelctdGrid).RoughPosVals(:,3));
            if (MinDepth > 0)
                app.GriddedFault(SelctdGrid).RoughPosVals(:,3) = app.GriddedFault(SelctdGrid).RoughPosVals(:,3)-MinDepth -0.01;
            end
            %-----------------------------%-----------------------------
            for i = 1 : size(app.GriddedFault(SelctdGrid).TriangleList,1)
                v1   = app.GriddedFault(SelctdGrid).TriangleList(i,1);
                v2   = app.GriddedFault(SelctdGrid).TriangleList(i,2);
                v3   = app.GriddedFault(SelctdGrid).TriangleList(i,3);
            
                Vert1 = app.GriddedFault(SelctdGrid).RoughPosVals(v1,:);   
                Vert2 = app.GriddedFault(SelctdGrid).RoughPosVals(v2,:);   
                Vert3 = app.GriddedFault(SelctdGrid).RoughPosVals(v3,:);   
            
                app.GriddedFault(SelctdGrid).RoughCentVals(i,1) = (Vert1(1,1)+Vert2(1,1)+Vert3(1,1))/3;
                app.GriddedFault(SelctdGrid).RoughCentVals(i,2) = (Vert1(1,2)+Vert2(1,2)+Vert3(1,2))/3;
                app.GriddedFault(SelctdGrid).RoughCentVals(i,3) = (Vert1(1,3)+Vert2(1,3)+Vert3(1,3))/3;
                %-----------------------------
            end
            %-----------------------------%-----------------------------
            if (PlotRough == 1)
                figure(FigNum*10); clf; hold on; axis equal;
                view(ViewAngle);
                SectionNumber = max(app.GriddedFault(SelctdGrid).TriangleList(:,4));
                for ii = 1 : SectionNumber
                    NextSection = find(app.GriddedFault(SelctdGrid).TriangleList(:,4) == ii);
                    trisurf(app.GriddedFault(SelctdGrid).TriangleList(NextSection,1:3),app.GriddedFault(SelctdGrid).RoughPosVals(:,1),app.GriddedFault(SelctdGrid).RoughPosVals(:,2),app.GriddedFault(SelctdGrid).RoughPosVals(:,3),app.GriddedFault(SelctdGrid).RoughPosVals(:,4));%,'linestyle','none');
                end
             %   plot3(app.GriddedFault(SelctdGrid).RoughCentVals(:,1),app.GriddedFault(SelctdGrid).RoughCentVals(:,2),app.GriddedFault(SelctdGrid).RoughCentVals(:,3),'o');
            end
            %-----------------------------%-----------------------------          
        end

        % Button pushed function: GenerateStrength_Button
        function GenerateStrength_ButtonPushed(app, event)
            if (app.GridImported       == 0),               return;                             end
            PlotStuff    = app.PlotStrength_CheckBox.Value;
            Density      = app.HalfspaceDensity_EditField.Value;
            AddSigN      = app.HalfspaceOverburden_EditField.Value;
            %-----------------------------%-----------------------------
            MeanAmBseis     = zeros(size(app.StrgthParasPerSectn,1),1);
            for ii = 1 : size(app.StrgthParasPerSectn,1)
                AmBshape  = app.StrgthParasPerSectn(ii,9);
                minTemp   = 0;
                maxTemp   = 500;
                TempStps  = 200;
                TempVals  = linspace(minTemp,maxTemp,TempStps) +app.FricGeoThermStartTemp_EditField.Value;
                AmB_Vals  = zeros(size(TempVals));
                %-----------------------------------
                if     (AmBshape == 1)
                    AmB_Vals = app.AmBparabolic(TempVals);   
                elseif (AmBshape == 2)
                    for i = 1 : size(TempVals,2)
                        if     ((TempVals(1,i) >= app.AmB_p1_temp(1,1)) && (TempVals(1,i) <= app.AmB_p1_temp(1,2)))
                            AmB_Vals(1,i) = app.AmB_p1(TempVals(1,i));
                        elseif ((TempVals(1,i) >  app.AmB_p2_temp(1,1)) && (TempVals(1,i) <= app.AmB_p2_temp(1,2)))
                            AmB_Vals(1,i) = app.AmB_p2(TempVals(1,i));
                        elseif ((TempVals(1,i) >  app.AmB_p3_temp(1,1)) && (TempVals(1,i) <= app.AmB_p3_temp(1,2)))
                            AmB_Vals(1,i) = app.AmB_p3(TempVals(1,i));
                        elseif ((TempVals(1,i) >  app.AmB_p4_temp(1,1)) && (TempVals(1,i) <= app.AmB_p4_temp(1,2)))
                            AmB_Vals(1,i) = app.AmB_p4(TempVals(1,i));
                        end
                    end
                end
                %-----------------------------------
                NegAmBvals        = AmB_Vals(AmB_Vals< 0);
                MeanAmBseis(ii,1) = mean(NegAmBvals); 
            end
            %-----------------------------%-----------------------------
            for ii = 1 : app.GridNumber
                for jj = 1 : app.GriddedFault(ii).SizeTrigList
                    Depth       = app.GriddedFault(ii).GlobTrigCentr(jj,3).*-1000; %is now a positive number -in meters
                    SectionID   = app.GriddedFault(ii).TriangleList(jj,4); 
                    
                    StatFric    = app.StrgthParasPerSectn(SectionID,2);
                    StatFricVari= app.StrgthParasPerSectn(SectionID,3);
                    DynFChg     = app.StrgthParasPerSectn(SectionID,4);
                    DynFChgVar  = app.StrgthParasPerSectn(SectionID,5);
                    
                    if (app.CallStrengthFromSave == 1)
                        StatFricVari= 0;
                        DynFChgVar  = 0;
                    end
                    
                    UseAmB      = app.StrgthParasPerSectn(SectionID,8);
                    AmBshape    = app.StrgthParasPerSectn(SectionID,9);
                    Geotherm    = app.StrgthParasPerSectn(SectionID,10);
                    UseSkemp    = app.StrgthParasPerSectn(SectionID,11);
                    SkempShpe   = app.StrgthParasPerSectn(SectionID,12);
                    
                    TempAtDepth = Geotherm*Depth/1000 +app.FricGeoThermStartTemp_EditField.Value;
                    %------------------------------------
                    if (UseAmB == 1)
                        if     (AmBshape == 1)
                            AmB_Val = app.AmBparabolic(TempAtDepth);  
                            AmB_fact= AmB_Val/MeanAmBseis(SectionID,1);
                        elseif (AmBshape == 2) 
                            if     ((TempAtDepth >= app.AmB_p1_temp(1,1)) && (TempAtDepth <= app.AmB_p1_temp(1,2))),            AmB_Val = app.AmB_p1(TempAtDepth);
                            elseif ((TempAtDepth >  app.AmB_p2_temp(1,1)) && (TempAtDepth <= app.AmB_p2_temp(1,2))),            AmB_Val = app.AmB_p2(TempAtDepth);
                            elseif ((TempAtDepth >  app.AmB_p3_temp(1,1)) && (TempAtDepth <= app.AmB_p3_temp(1,2))),            AmB_Val = app.AmB_p3(TempAtDepth);
                            elseif ((TempAtDepth >  app.AmB_p4_temp(1,1)) && (TempAtDepth <= app.AmB_p4_temp(1,2))),            AmB_Val = app.AmB_p4(TempAtDepth);
                            end
                            AmB_fact= AmB_Val/MeanAmBseis(SectionID,1);
                        end 
                    else
                        AmB_fact = 1;
                    end
                    %------------------------------------
                    DynChange  = (DynFChg +DynFChgVar*(rand(1)*2-1))/100; %change of friction coefficient when going from stat to dynamic 
                    %------------------------------------
                    if (UseSkemp == 1)
                        if     (SkempShpe == 1),            SKval = app.Skempton_p1(Depth);  
                        elseif (SkempShpe == 2),            SKval = app.Skempton_p2(Depth);  
                        elseif (SkempShpe == 3),            SKval = app.Skempton_p3(Depth);  
                        end
                        StatFric_eff = StatFric*SKval;
                    else
                        StatFric_eff = StatFric;
                    end
                    app.GriddedFault(ii).FricVals(jj,1) = (StatFric_eff*(1 - (rand(1)*2-1)*StatFricVari/100)); %this is the used value for static friction coefficient for given cell
                    app.GriddedFault(ii).FricVals(jj,2) = app.GriddedFault(ii).FricVals(jj,1)*DynChange*AmB_fact; %this is change in friction coeficient as function of depth i.e., a-b profile
                    %------------------------------------
                end
                app.GriddedFault(ii).FricVals(:,3) = 1;  %starting with assumption that all patches/cells are in seismogenic zone
            end
            %-----------------------------%-----------------------------
            FltIDUniq   = unique(app.FaultValues(:,20));
            FaultNumber = size(FltIDUniq,1);

            MinPatchSize    = 1E+99;
            for ii = 1 : app.GridNumber  %selecting the highest resolution grid...
                if (app.GriddedFault(ii).MeanPatchSize < MinPatchSize)
                    MinPatchSize   = app.GriddedFault(ii).MeanPatchSize;
                    SmallestGrid   = ii;
                end
            end
            %------------------------------------
            for ii = 1 : FaultNumber
                SelSections     = find(app.StrgthParasPerSectn(:,20) == FltIDUniq(ii,1));
                SelStrgthValues = app.StrgthParasPerSectn(SelSections(1,1),:);
                if (SelStrgthValues(1,13) == 1) %use fractal strength distribution 
                    acf              = 'AK';
                    UseNewSeed       = SelStrgthValues(1,14);
                    CorrInStk        = SelStrgthValues(1,15);
                    CorrInDip        = SelStrgthValues(1,16);
                    HurstExp         = SelStrgthValues(1,17);
                    WaterLevel       = SelStrgthValues(1,18);
                    WaterLvari       = SelStrgthValues(1,19);
            
                    if (app.CallStrengthFromSave == 1)
                        WaterLvari   = 0;
                    end
                    
                    SelectedPatchPos = find(app.GriddedFault(SmallestGrid).TriangleList(:,5) == FltIDUniq(ii,1));
                    SelectedVertIndx = app.GriddedFault(SmallestGrid).TriangleList(SelectedPatchPos,1:3);
                    SelVerticesList  = unique(SelectedVertIndx(:)); 
                    SeletedVerts     = app.GriddedFault(SmallestGrid).LocalPosVals(SelVerticesList,:);
            
                    MinStkLngthVal   = min(SeletedVerts(:,2));                                MaxStkLngthVal = max(SeletedVerts(:,2));
                    MinDipLngthVal   = min(SeletedVerts(:,3));                                MaxDipLngthVal = max(SeletedVerts(:,3));
                    FaultLength      = (MaxStkLngthVal-MinStkLngthVal);                       FaultWidth     = (MaxDipLngthVal-MinDipLngthVal);
            
                    ApproxSquareNum  = (FaultLength*FaultWidth)/MinPatchSize;
                    AspectRatio      = FaultLength/FaultWidth;
                    PtchNumInDip     = round(sqrt((ApproxSquareNum*10)/AspectRatio));
                    PtchNumInStk     = round((ApproxSquareNum*10)/PtchNumInDip);
                    %------------------------------
                    if (UseNewSeed == 1)
                        for (jj = 1 : size(SelSections,1))
                            app.RandParasStrgt(jj).Vals =  rng('shuffle'); %give all sections of a fault the same random numbers for field generation, is actually used only once -for the entire fault with all its sections- but seems cleaner to do it that way 
                        end
                    end
                    %------------------------------
                    sampsize   = [FaultWidth/PtchNumInDip, FaultLength/PtchNumInStk];
                    %---------------------------------------
                    corr       = [CorrInDip,    CorrInStk, HurstExp];%default == 1;        ak (van Karman) 
                    ShowStats  = 0;
                    % --------------------------------------
                    % GENERATE THE SPATIAL RANDOM FIELDS
                    RoughFric = SpecSyn2(app, [FaultWidth FaultLength],sampsize,corr,acf,1, 1, app.RandParasStrgt(SelSections(1,1)).Vals); % the "100" is a dummy for figure number, not used
                    % MADE THE SPATIAL RANDOM FIELD
                    %---------------------------------------
                    for kk = 1 : app.GridNumber
            
                        PatchPosSelGrid     = find(app.GriddedFault(kk).TriangleList(:,5) == FltIDUniq(ii,1));
                        LocalPosSelGrid     = app.GriddedFault(kk).LocalCentVals(PatchPosSelGrid,:);
                        MeanPatchSize       = app.GriddedFault(kk).MeanPatchSize;
                        
                        ApproxSquareNum     = (FaultLength*FaultWidth)/MeanPatchSize;
                        AspectRatio         = FaultLength/FaultWidth;
                        PtchNumInDipregrid  = round(sqrt((ApproxSquareNum)/AspectRatio));
                        PtchNumInStkregrid  = round((ApproxSquareNum)/PtchNumInDipregrid);
                        %-----------------------------
                        VectAlongStkregrid  = linspace(MinStkLngthVal,MaxStkLngthVal,PtchNumInStkregrid);
                        VectAlongDipregrid  = linspace(MinDipLngthVal,MaxDipLngthVal,PtchNumInDipregrid);
                        %regridding....regridding....regridding....regridding....
                        RegriddedRoughFric  = imresize(RoughFric,[PtchNumInDipregrid,PtchNumInStkregrid],'bicubic');
                        %regridding....regridding....regridding....regridding....
                        RegriddedRoughFric  = RegriddedRoughFric - min(RegriddedRoughFric(:));
                        %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                        [X,Y]               = meshgrid(VectAlongStkregrid, VectAlongDipregrid);
                        RoughFricChng       = interp2(X,Y, RegriddedRoughFric ,LocalPosSelGrid(:,1),LocalPosSelGrid(:,2),'spline');  
                        %interpolatinginterpolatinginterpolatinginterpolatinginterpolatinginterpolating
                        %-----------------------------
                        WaterLevel               = WaterLevel +rand(1)*WaterLvari;
                        WaterLevel(WaterLevel>99)= 99;
                        WaterLevel(WaterLevel<0) = 0;
                        
                        IsInSeis          = app.GriddedFault(kk).FricVals(PatchPosSelGrid, 2)  > 0; %those with an actual stress drop
                        IsSeisIndex       = PatchPosSelGrid(IsInSeis);
            
                        RghFricInSeis     = RoughFricChng(IsInSeis);
                        CutoffValue       = prctile(RghFricInSeis,WaterLevel);
                        RghFricInSeis(RghFricInSeis<CutoffValue) = CutoffValue;
                        RghFricInSeis     = RghFricInSeis - CutoffValue;
                        RghFricInSeis     = RghFricInSeis./mean(RghFricInSeis(:));
                        %-----------------------------
                        app.GriddedFault(kk).FricVals(IsSeisIndex, 3) = RghFricInSeis;%factor has now an average of 1 when considering the seimogenic zone...
                        %----------------------------- 
                    end
                end
            end
            %-----------------------------%-----------------------------
            for kk = 1 : app.GridNumber
            %----------------------------- 
                app.GriddedFault(kk).FricVals(:,2)         = app.GriddedFault(kk).FricVals(:,1) - app.GriddedFault(kk).FricVals(:,3).*app.GriddedFault(kk).FricVals(:,2); 
                app.GriddedFault(kk).FricVals(app.GriddedFault(kk).FricVals(:,2) < 0,2) = 0;
                %added this line here to make sure that dyn coeff of friction is not negative; could happen to seismogenic patches/cells as a result of the water-level scaling as well as (a-b) scaling if a very high coseis change in friction is assumed (then I would have patches with complete breakdown in friction coefficient) 
                %app.GriddedFault(kk).FricVals(:,2)         = app.GriddedFault(kk).FricVals(:,1) - app.GriddedFault(kk).FricVals(:,2);
                %now I changed from relative value (delta_mue) to absolute value for dynamic friction coefficient 
                %----------------------------- 
                IsInSeis  = find(app.GriddedFault(kk).FricVals(:,2) < app.GriddedFault(kk).FricVals(:,1)); %if I have an actual stress drop
                IsNotSeis = find(app.GriddedFault(kk).FricVals(:,2) >=app.GriddedFault(kk).FricVals(:,1));
              %  IsInSeis  = find(app.GriddedFault(kk).FricVals(:,2) <=app.GriddedFault(kk).FricVals(:,1)); %if I have an actual stress drop
              %  IsNotSeis = find(app.GriddedFault(kk).FricVals(:,2) > app.GriddedFault(kk).FricVals(:,1));
                app.GriddedFault(kk).FricVals(IsInSeis,3)  = 1;
                app.GriddedFault(kk).FricVals(IsNotSeis,3) = 0;  
            end
            %------------------------------------------------------------
            PosStrength  = (app.GriddedFault(app.GridNumber).FricVals(:,1)-app.GriddedFault(app.GridNumber).FricVals(:,2)).*(Density.*app.Gravity.*-app.GriddedFault(app.GridNumber).GlobTrigCentr(:,3).*1000./1E+6 +AddSigN);
            
            PosStrength  = PosStrength(PosStrength  >= 0);
            MeanStrength = mean(PosStrength);
            app.AverageStrength_EditField.Value = MeanStrength;
            app.StrengthGenerated  = 1;
            %-----------------------------%-----------------------------   
            if (PlotStuff == 1)
                PlotStrength_ButtonPushed(app, event);
            end

        end

        % Button pushed function: PlotStrength_Button
        function PlotStrength_ButtonPushed(app, event)
     
            if (app.GridImported       == 0),               return;                             end
            if (app.StrengthGenerated  == 0),               return;                             end   
            
            FigNum          = app.FigNum_EditField.Value*10+4;
            ViewAngle       = app.ViewAngle_EditField.Value;       ViewAngle = sscanf(ViewAngle,'%f');
            PlotWRough      = app.PlotWithRoughness_CheckBox.Value;
            Density         = app.HalfspaceDensity_EditField.Value;
            AddSigN         = app.HalfspaceOverburden_EditField.Value;
            SelctdGrid      = app.ElementGrids_ListBox.Value;
            if (~isa(SelctdGrid,'double')),     SelctdGrid = str2double(SelctdGrid);            end
            
            AllV1s          = zeros(app.GriddedFault(SelctdGrid).SizeTrigList,3);
            AllV2s          = zeros(app.GriddedFault(SelctdGrid).SizeTrigList,3);
            AllV3s          = zeros(app.GriddedFault(SelctdGrid).SizeTrigList,3);
            PlottedComp     = zeros(app.GriddedFault(SelctdGrid).SizeTrigList,1);
            Cntr            = 1;
            %------------------------
            if ((PlotWRough == 1) && (sum(app.GriddedFault(SelctdGrid).RoughPosVals(:))~=0))
            
                for j = 1 : app.GriddedFault(SelctdGrid).SizeTrigList
                    AllV1s(Cntr,1) = app.GriddedFault(SelctdGrid).RoughPosVals(app.GriddedFault(SelctdGrid).TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.GriddedFault(SelctdGrid).RoughPosVals(app.GriddedFault(SelctdGrid).TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.GriddedFault(SelctdGrid).RoughPosVals(app.GriddedFault(SelctdGrid).TriangleList(j,3),1);
            
                    AllV2s(Cntr,1) = app.GriddedFault(SelctdGrid).RoughPosVals(app.GriddedFault(SelctdGrid).TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.GriddedFault(SelctdGrid).RoughPosVals(app.GriddedFault(SelctdGrid).TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.GriddedFault(SelctdGrid).RoughPosVals(app.GriddedFault(SelctdGrid).TriangleList(j,3),2);
            
                    AllV3s(Cntr,1) = app.GriddedFault(SelctdGrid).RoughPosVals(app.GriddedFault(SelctdGrid).TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.GriddedFault(SelctdGrid).RoughPosVals(app.GriddedFault(SelctdGrid).TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.GriddedFault(SelctdGrid).RoughPosVals(app.GriddedFault(SelctdGrid).TriangleList(j,3),3);
                    %-----------------------------
                    PlottedComp(Cntr,1)   = (app.GriddedFault(SelctdGrid).FricVals(j,1)-app.GriddedFault(SelctdGrid).FricVals(j,2)) .*(Density.*app.Gravity.*-app.GriddedFault(SelctdGrid).GlobTrigCentr(j,3).*1000./1E+6 +AddSigN);      
                    Cntr                  = Cntr +1;
                    %-----------------------------
                end
                DepthVals      = app.GriddedFault(SelctdGrid).RoughCentVals(:,3);
            else
                for j = 1 : app.GriddedFault(SelctdGrid).SizeTrigList
                    AllV1s(Cntr,1) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,1),1);
                    AllV1s(Cntr,2) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,2),1);
                    AllV1s(Cntr,3) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,3),1);
            
                    AllV2s(Cntr,1) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,1),2);
                    AllV2s(Cntr,2) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,2),2);
                    AllV2s(Cntr,3) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,3),2);
            
                    AllV3s(Cntr,1) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,1),3);
                    AllV3s(Cntr,2) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,2),3);
                    AllV3s(Cntr,3) = app.GriddedFault(SelctdGrid).GlobalVertices(app.GriddedFault(SelctdGrid).TriangleList(j,3),3);
                    %-----------------------------
                    PlottedComp(Cntr,1)   = (app.GriddedFault(SelctdGrid).FricVals(j,1)-app.GriddedFault(SelctdGrid).FricVals(j,2)) .*(Density.*app.Gravity.*-app.GriddedFault(SelctdGrid).GlobTrigCentr(j,3).*1000./1E+6 +AddSigN);     
                    Cntr                  = Cntr +1;
                    %-----------------------------
                end
                DepthVals      = app.GriddedFault(SelctdGrid).GlobTrigCentr(:,3);      
            end
            PlottedCompStdDev = std(PlottedComp);
            %-----------------------------
            figure(FigNum); clf; hold on; axis equal;
            xlabel('easting');ylabel('northing'); zlabel('depth');
            title('Coseismic stress change/strength in MPa');
            patch(AllV1s',AllV2s',AllV3s',PlottedComp');
            view(ViewAngle');
            colormap(app.Color_Blue2Red);
           % caxis([-PlottedCompStdDev*2 PlottedCompStdDev*2]);
            %colormap('hot');
            colorbar;
            %-----------------------------
            FricVals1 = app.GriddedFault(SelctdGrid).FricVals(:,1);%.*(Density.*app.Gravity.*-app.GriddedFault(SelctdGrid).RoughCentVals(:,3).*1000./1E+6 +AddSigN);
            FricVals2 = app.GriddedFault(SelctdGrid).FricVals(:,2);%.*(Density.*app.Gravity.*-app.GriddedFault(SelctdGrid).RoughCentVals(:,3).*1000./1E+6 +AddSigN);
            figure(FigNum+1); clf; hold on; 
            xlabel('Stat. friction coeff (in blue) and coseismic change of coeff. (in red)'); ylabel('Depth (km)');
            plot(FricVals1,DepthVals,'ob','markersize',8);
            plot((FricVals2),DepthVals,'xr','markersize',8);
            plot([0 0],[min(DepthVals) max(DepthVals)],'k');                
        end

        % Value changed function: FricFrictionType_DropDown
        function FricFrictionType_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            SelFault = app.FaultValues(SelSection,20);
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelFault,1) = str2double(app.FricFrictionType_DropDown.Value); 
            end
        end

        % Button pushed function: SaveRoughStrenth_Button
        function SaveRoughStrenth_ButtonPushed(app, event)
 
            CompletePath   = strcat(app.SavePath,app.SaveName_EditField.Value); 
            %-----------------------
            Name4ascii     = strcat(CompletePath,'_Summary_RoughStrength.txt');
            %-----------------------
            NumOfMods2Save = app.SaveModels2Generate_EditField.Value;
            SelGridOrAll   = app.SaveAllOrOneGrid_DropDown.Value;
            if (~isa(SelGridOrAll,'double')),     SelGridOrAll = str2double(SelGridOrAll);          end
            SelctdGrid     = app.ElementGrids_ListBox.Value;
            if (~isa(SelctdGrid,'double')),       SelctdGrid   = str2double(SelctdGrid);            end
            %-------------------------------
            if (NumOfMods2Save > 1)
                app.PlotRoughness_CheckBox.Value     = 0;
                app.PlotStrength_CheckBox.Value      = 0;
                app.PlotWithRoughness_CheckBox.Value = 0;
                drawnow;
            end
            %-------------------------------    
            Density   = app.HalfspaceDensity_EditField.Value;
            AddSigN   = app.HalfspaceOverburden_EditField.Value;
            ShearMod  = app.HalfspaceShearMod_EditField.Value;
            Poisson   = app.HalfspacePoisson_EditField.Value;
            
            RandmzeFrc= app.FricAllowReset_CheckBox.Value;
            %-------------------------------%-------------------------------    
            FltNum    = size(app.FaultNames,2);
            if (SelGridOrAll == 1),         UseTheseGrids = SelctdGrid; 
            else,                           UseTheseGrids = 1:app.GridNumber;
            end
            %--------------------------------------%-----------------------------------
            fidascii    = fopen(Name4ascii ,'w');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            
            fprintf(fidascii,'Half-space_medium_density(kg/m^3):   %10.4e\n', Density);
            fprintf(fidascii,'Half-space_added_NormalStress(MPa):  %10.4e\n', AddSigN);
            fprintf(fidascii,'Half-space_shear_modulus(GPa):       %10.4e\n', ShearMod);
            fprintf(fidascii,'Half-space_poisson_ratio:            %10.4e\n', Poisson);
            
            fprintf(fidascii,'Randomize_FricStrengthBetweenEQS: %d\n',RandmzeFrc);
            FltIDUniq   = unique(app.FaultValues(:,20));
            
            for i = 1 : FltNum
                FaultIDpos = find(FltIDUniq == app.FaultValues(i,20),1);
                
                fprintf(fidascii,'Fault_ID_number:       %d\n',    app.FaultValues(i,20));
                fprintf(fidascii,'Fault_section_name:    %s\n',    app.FaultNames{1,i});
                fprintf(fidascii,'Roughness Parameters:    \n');
                fprintf(fidascii,'Roughness_Type:        %d\n',    app.RoughParasPerFault(FaultIDpos,2));
                fprintf(fidascii,'HurstExp_vari_Type:    %d\n',    app.RoughParasPerFault(FaultIDpos,3));
                fprintf(fidascii,'PreFact_vari_Type:     %d\n',    app.RoughParasPerFault(FaultIDpos,4));
                fprintf(fidascii,'CorrLgth_vari_Type:    %d\n',    app.RoughParasPerFault(FaultIDpos,5));
                fprintf(fidascii,'CorrLgth_in_Strike:    %10.4e\n',app.RoughParasPerFault(FaultIDpos,6));
                fprintf(fidascii,'CorrLgth_in_Dip:       %10.4e\n',app.RoughParasPerFault(FaultIDpos,7));
                fprintf(fidascii,'CorrLgth_vari_Amount:  %10.4e\n',app.RoughParasPerFault(FaultIDpos,8));
                fprintf(fidascii,'HurstExponent_Strike:  %10.4e\n',app.RoughParasPerFault(FaultIDpos,9));
                fprintf(fidascii,'HurstExponent_Dip:     %10.4e\n',app.RoughParasPerFault(FaultIDpos,10));
                fprintf(fidascii,'HurstExp_vari_Amount:  %10.4e\n',app.RoughParasPerFault(FaultIDpos,11));
                fprintf(fidascii,'Fractal_Dimension:     %10.4e\n',app.RoughParasPerFault(FaultIDpos,12));
                fprintf(fidascii,'Corner_Frequency:      %10.4e\n',app.RoughParasPerFault(FaultIDpos,13));
                fprintf(fidascii,'RandMidDisp_Ampli:     %10.4e\n',app.RoughParasPerFault(FaultIDpos,14));
                fprintf(fidascii,'RandMidDisp_Decay:     %10.4e\n',app.RoughParasPerFault(FaultIDpos,15));
                fprintf(fidascii,'NonLin_ScaleFactor:    %10.4e\n',app.RoughParasPerFault(FaultIDpos,16));
                fprintf(fidascii,'PreFactor:             %10.4e\n',app.RoughParasPerFault(FaultIDpos,17));
                fprintf(fidascii,'PreFator_vari_Amount:  %10.4e\n',app.RoughParasPerFault(FaultIDpos,18));
               
                fprintf(fidascii,'Strength/Friction Parameters:    \n');
                fprintf(fidascii,'Used_FrictionType_(1==stat/dyn;2==slip-weak;3==velo-weak:      %d\n',    app.StrgthParasPerSectn(i,1));
                fprintf(fidascii,'StaticFric_value:       %10.4e\n',app.StrgthParasPerSectn(i,2));
                fprintf(fidascii,'StaticFric_vari_value:  %10.4e\n',app.StrgthParasPerSectn(i,3));
                fprintf(fidascii,'DynamicFric_value:      %10.4e\n',app.StrgthParasPerSectn(i,2)*(1-app.StrgthParasPerSectn(i,4)/100));
                fprintf(fidascii,'DynFric_valchg_vari:    %10.4e\n',app.StrgthParasPerSectn(i,5));
                fprintf(fidascii,'Critical_SlipDistance:  %10.4e\n',app.StrgthParasPerSectn(i,6));
                fprintf(fidascii,'Critical_SlipDistVari:  %10.4e\n',app.StrgthParasPerSectn(i,7));
                fprintf(fidascii,'Use_(a-b)_Profile:      %d\n',    app.StrgthParasPerSectn(i,8));
                fprintf(fidascii,'(a-b)_shape:            %d\n',    app.StrgthParasPerSectn(i,9));
                fprintf(fidascii,'Geothermal_Gradient:    %10.4e\n',app.StrgthParasPerSectn(i,10));
                
                fprintf(fidascii,'Use_SkemptonFactor:     %d\n',    app.StrgthParasPerSectn(i,11));
                fprintf(fidascii,'SkempFact_shape:        %d\n',    app.StrgthParasPerSectn(i,12));
                fprintf(fidascii,'Use_FractStrgth:        %d\n',    app.StrgthParasPerSectn(i,13));
                fprintf(fidascii,'CorrLgth_in_Strike:     %10.4e\n',app.StrgthParasPerSectn(i,15));
                fprintf(fidascii,'CorrLgth_in_Dip:        %10.4e\n',app.StrgthParasPerSectn(i,16));
                fprintf(fidascii,'HurstExponent:          %10.4e\n',app.StrgthParasPerSectn(i,17));
                fprintf(fidascii,'Strength_WaterLevel:    %10.4e\n',app.StrgthParasPerSectn(i,18));
                fprintf(fidascii,'Strength_WaterL_vari:   %10.4e\n',app.StrgthParasPerSectn(i,19));
                fprintf(fidascii,'\n');      
            end
            fclose(fidascii);
            
            app.CallStrengthFromSave = 1;      
            %--------------------------------------%-----------------------------------
            for i = 1 : NumOfMods2Save
                app.SaveCurrentModelNum_EditField.Value = i;
                drawnow;
                %------------------------------%------------------------------
 
                GenerateRoughness_ButtonPushed(app, event);
                GenerateStrength_ButtonPushed(app, event);
                %------------------------------
                Name4binary_R  = strcat(CompletePath,'_',num2str(i),'_Roughn.dat');
                Name4binary_S  = strcat(CompletePath,'_',num2str(i),'_Strgth.dat');
                fidbinR        = fopen(Name4binary_R,'wb'); 
                fidbinS        = fopen(Name4binary_S,'wb'); 
                if (fidbinR == -1), errordlg('The entered filename has not been found.');    return;   end
                if (fidbinS == -1), errordlg('The entered filename has not been found.');    return;   end
                %------------------------------
                for (ii = 1 : size(UseTheseGrids,2))
                    NextGrid = UseTheseGrids(1,ii);
                    app.ElementGrids_ListBox.Value = UseTheseGrids(1,ii);  
                    drawnow;
                    %------------------------------%---------------
                    CombinewGrid_ButtonPushed(app,event);
                    %------------------------------%---------------
                    fwrite(fidbinR,size(app.GriddedFault(NextGrid).TriangleList,1),'int32');
                    fwrite(fidbinR,size(app.GriddedFault(NextGrid).VertexList,1),  'int32');
                    fwrite(fidbinR,app.GriddedFault(NextGrid).TriangleList(:,1),   'int32');%patch
                    fwrite(fidbinR,app.GriddedFault(NextGrid).TriangleList(:,2),   'int32');%patch
                    fwrite(fidbinR,app.GriddedFault(NextGrid).TriangleList(:,3),   'int32');%patch
                    fwrite(fidbinR,app.GriddedFault(NextGrid).TriangleList(:,4),   'int32');%patch
                    fwrite(fidbinR,app.GriddedFault(NextGrid).TriangleList(:,5),   'int32');%patch
                    fwrite(fidbinR,app.GriddedFault(NextGrid).VertexList(:,1),     'single');%vertex
                    fwrite(fidbinR,app.GriddedFault(NextGrid).VertexList(:,2),     'single');%vertex    
                    fwrite(fidbinR,app.GriddedFault(NextGrid).RoughPosVals(:,1),   'single');%vertex
                    fwrite(fidbinR,app.GriddedFault(NextGrid).RoughPosVals(:,2),   'single');%vertex    
                    fwrite(fidbinR,app.GriddedFault(NextGrid).RoughPosVals(:,3),   'single');%vertex 
                    fwrite(fidbinR,app.GriddedFault(NextGrid).RoughCentVals(:,1),  'single');%patch
                    fwrite(fidbinR,app.GriddedFault(NextGrid).RoughCentVals(:,2),  'single');%patch    
                    fwrite(fidbinR,app.GriddedFault(NextGrid).RoughCentVals(:,3),  'single');%patch         
                    
                    maxdepth = max(app.GriddedFault(NextGrid).RoughPosVals(:,3));
                    maxdepth2= max(app.GriddedFault(NextGrid).RoughCentVals(:,3));
                    
                    FltIDnum = size(unique(app.GriddedFault(NextGrid).TriangleList(:,5)),1);
                    
                    fwrite(fidbinR,FltIDnum,                                       'int32');
                    fwrite(fidbinR,app.RMSoverLength(NextGrid).TheVal(:,1),        'single'); % length is fault number
                    
                    fwrite(fidbinS,size(app.GriddedFault(NextGrid).TriangleList,1),'int32');
                    fwrite(fidbinS,app.GriddedFault(NextGrid).FricVals(:,1),       'single'); %realization of static coeff. of friction
                    fwrite(fidbinS,app.GriddedFault(NextGrid).FricVals(:,2),       'single'); %realization of coseismic change of friction coefficient 
                    fwrite(fidbinS,app.GriddedFault(NextGrid).FricVals(:,3),       'int32'); %if patch is in seis. or not i.e., if it has positive coseis. change or negative one
                    %------------------------------%---------------
                end
                fclose(fidbinR);
                fclose(fidbinS);
                %------------------------------%------------------------------   
            end
            %--------------------------------------%-----------------------------------  
            app.CallStrengthFromSave = 0; 
        end

        % Value changed function: StrengthUseFractalDistrib_CheckBox
        function StrengthUseFractalDistrib_CheckBoxValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,13) = app.StrengthUseFractalDistrib_CheckBox.Value;     
            end
        end

        % Value changed function: StrengthSeed_DropDown
        function StrengthSeed_DropDownValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,14) = str2double(app.StrengthSeed_DropDown.Value);  
            end
        end

        % Value changed function: StrengthCorrLengthStrike_EditField
        function StrengthCorrLengthStrike_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,15) = app.StrengthCorrLengthStrike_EditField.Value;    
            end
        end

        % Value changed function: StrengthCorrLengthDip_EditField
        function StrengthCorrLengthDip_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,16) = app.StrengthCorrLengthDip_EditField.Value;   
            end
        end

        % Value changed function: StrengthHurstExp_EditField
        function StrengthHurstExp_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,17) = app.StrengthHurstExp_EditField.Value; 
            end
        end

        % Value changed function: StrengthWaterLevel_EditField
        function StrengthWaterLevel_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,18) = app.StrengthWaterLevel_EditField.Value; 
            end
        end

        % Value changed function: StrengthWaterLevelVari_EditField
        function StrengthWaterLevelVari_EditFieldValueChanged(app, event)
            SelSection   = app.FaultList_ListBox.Value;
            if (~isa(SelSection,'double')),     SelSection = str2double(SelSection);            end
            if (app.GridImported == 1)
                app.StrgthParasPerSectn(SelSection,19) = app.StrengthWaterLevelVari_EditField.Value;    
            end
        end

        % Button pushed function: LoadRoughnessStrength_Button
        function LoadRoughnessStrength_ButtonPushed(app, event)
            %only to be able to visualize... => means to load the strength etc files
            
            [DataName,DataPath]  = uigetfile(strcat(app.SaveName_EditField.Value,'*_Roughn.dat'),'Load gridded Fault Fault Model');
            if     (DataName ~=0),      DataName = extractBefore(DataName,'_Roughn.dat');        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
    
            Name4binary_R        = strcat(CompletePath,'_Roughn.dat');
            Name4binary_S        = strcat(CompletePath,'_Strgth.dat');
            
            %-----------------------
            fidbinR    = fopen(Name4binary_R,'rb');
            if (fidbinR == -1), errordlg('The entered filename has not been found. 2');    return;   end
            fidbinS    = fopen(Name4binary_S ,'rb');
            if (fidbinS == -1), errordlg('The entered filename has not been found. 3');    return;   end    
            %-----------------------
            for (ii = 1 : app.GridNumber)
                PatchNum = fread(fidbinR,1,'int');
                VertNum  = fread(fidbinR,1,'int');
                if ((PatchNum ~= app.GriddedFault(ii).SizeTrigList) || (VertNum ~= app.GriddedFault(ii).SizeVertList))
                   disp('patch and/or vertex numbers are not consistent');
                   return;
                end
                app.GriddedFault(ii).TriangleList(:,1)     = fread(fidbinR,PatchNum,'int32');
                app.GriddedFault(ii).TriangleList(:,2)     = fread(fidbinR,PatchNum,'int32');
                app.GriddedFault(ii).TriangleList(:,3)     = fread(fidbinR,PatchNum,'int32');
                app.GriddedFault(ii).TriangleList(:,4)     = fread(fidbinR,PatchNum,'int32');
                app.GriddedFault(ii).TriangleList(:,5)     = fread(fidbinR,PatchNum,'int32');
                app.GriddedFault(ii).VertexList(:,1)       = fread(fidbinR,VertNum,'single');
                app.GriddedFault(ii).VertexList(:,2)       = fread(fidbinR,VertNum,'single');
                app.GriddedFault(ii).GlobalVertices(:,1)   = fread(fidbinR,VertNum,'single');
                app.GriddedFault(ii).GlobalVertices(:,2)   = fread(fidbinR,VertNum,'single');
                app.GriddedFault(ii).GlobalVertices(:,3)   = fread(fidbinR,VertNum,'single');
                app.GriddedFault(ii).GlobTrigCentr(:,1)    = fread(fidbinR,PatchNum,'single');
                app.GriddedFault(ii).GlobTrigCentr(:,2)    = fread(fidbinR,PatchNum,'single');
                app.GriddedFault(ii).GlobTrigCentr(:,3)    = fread(fidbinR,PatchNum,'single');  
 
                TempBlahValue                              = fread(fidbinR,1,            'int32');
                app.RMSoverLength(ii).TheVal(:,1)          = fread(fidbinR,TempBlahValue,'single');
                
                PatchNumS = fread(fidbinS,1,'int'); %not needed now... -still need to load to advance the file pointer...
                app.GriddedFault(ii).FricVals(:,1)        = fread(fidbinS,PatchNumS,'single');
                app.GriddedFault(ii).FricVals(:,2)        = fread(fidbinS,PatchNumS,'single');
                app.GriddedFault(ii).FricVals(:,3)        = fread(fidbinS,PatchNumS,'int32');
                
            end
            fclose(fidbinR);
            fclose(fidbinS);
            app.GridImported       = 1;
            app.StrengthGenerated  = 1;
            
        end

        % Button pushed function: ExtractSave_SurfTrace_Button
        function ExtractSave_SurfTrace_ButtonPushed(app, event)
            if (app.RoughnessGenerated == 0),   return;             end
            
            [DataName,DataPath]  = uiputfile('FaultTraces.mat','Save/Extract fault traces');
            if     (DataName ~=0),      CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
       
            FltIDUniq   = unique(app.FaultValues(:,20));
            FaultNumber = size(FltIDUniq,1);
      
            for (ii = 1 : FaultNumber)  
                FaultTrace(ii).TraceVals = app.RoughFault(ii).FltSurface(end,:);
            end
            RMD_Ampl = app.RoughRMD_ampli_EditField.Value;
            RMD_Decy = app.RoughRMD_ampli_EditField.Value;
            RoughScl = app.RoughLengthScale_EditField.Value;
            NormPreF = app.RoughNormalizePreFact_EditField.Value;
            
            save(CompletePath,'FaultTrace', 'RMD_Ampl', 'RMD_Decy', 'RoughScl', 'NormPreF');
        end

        % Button pushed function: Load_SurfTrace_Button
        function Load_SurfTrace_ButtonPushed(app, event)
            if (app.GridImported == 0),   return;             end
            
            [DataName,DataPath]  = uigetfile('FaultTraces.mat','Load fault trace');
            if     (DataName ~=0),      CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            
            load(CompletePath);
            
            for (ii = 1 : size(FaultTrace,2))
                app.SurfTraceVals(ii).TraceVals = FaultTrace(ii).TraceVals;
            end
            
            if (exist('RMD_Ampl','var')),   app.RoughRMD_ampli_EditField.Value        =  RMD_Ampl;            end
            if (exist('RMD_Decy','var')),   app.RoughRMD_ampli_EditField.Value        =  RMD_Decy;            end
            if (exist('RoughScl','var')),   app.RoughLengthScale_EditField.Value      =  RoughScl;            end
            if (exist('NormPreF','var')),   app.RoughNormalizePreFact_EditField.Value =  NormPreF;            end

            app.SurfTraceImported = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Panel = uipanel(app.UIFigure);
            app.LoadFaults_Button = uibutton(app.Panel, 'push');
            app.LoadFaults_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadFaults_ButtonPushed, true);
            app.LoadFaults_Button.Text = 'Load faults';
            app.Plotfaults_Button = uibutton(app.Panel, 'push');
            app.Plotfaults_Button.ButtonPushedFcn = createCallbackFcn(app, @Plotfaults_ButtonPushed, true);
            app.Plotfaults_Button.Text = 'Plot faults';
            app.ElementListBoxLabel = uilabel(app.Panel);
            app.ElementListBoxLabel.Text = 'Element #';
            app.ElementGrids_ListBox = uilistbox(app.Panel);
            app.ElementGrids_ListBox.Items = {'0'};
            app.ElementGrids_ListBox.Value = '0';
            app.FaultlistListBoxLabel = uilabel(app.Panel);
            app.FaultlistListBoxLabel.Text = 'Fault list';
            app.FaultList_ListBox = uilistbox(app.Panel);
            app.FaultList_ListBox.Items = {'Fault name'};
            app.FaultList_ListBox.ItemsData = {'1'};
            app.FaultList_ListBox.ValueChangedFcn = createCallbackFcn(app, @FaultList_ListBoxValueChanged, true);
            app.FaultList_ListBox.Value = '1';
            app.ViewangleEditFieldLabel = uilabel(app.Panel);
            app.ViewangleEditFieldLabel.Text = 'View angle';
            app.ViewAngle_EditField = uieditfield(app.Panel, 'text');
            app.ViewAngle_EditField.Value = '40 20';
            app.FigEditFieldLabel = uilabel(app.Panel);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigNum_EditField = uieditfield(app.Panel, 'numeric');
            app.FigNum_EditField.Limits = [1 Inf];
            app.FigNum_EditField.RoundFractionalValues = 'on';
            app.FigNum_EditField.ValueDisplayFormat = '%d';
            app.FigNum_EditField.Value = 2;
            app.Panel_2 = uipanel(app.UIFigure);
            app.RoughSeed_DropDown = uidropdown(app.Panel_2);
            app.RoughSeed_DropDown.Items = {'New seed', 'Previous seed'};
            app.RoughSeed_DropDown.ItemsData = {'1', '2'};
            app.RoughSeed_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughSeed_DropDownValueChanged, true);
            app.RoughSeed_DropDown.Value = '1';
            app.RoughType_DropDown = uidropdown(app.Panel_2);
            app.RoughType_DropDown.Items = {'anisotrop. von Karman ACF', 'exponential ACF', 'fractal roughness', 'Gaussian roughness', 'anisotrop., Candela et al. 2009', 'random midpoint displ.', ''};
            app.RoughType_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.RoughType_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughType_DropDownValueChanged, true);
            app.RoughType_DropDown.Value = '1';
            app.andEditFieldLabel = uilabel(app.Panel_2);
            app.andEditFieldLabel.Text = 'and';
            app.RoughCorrLengthStrike_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughCorrLengthStrike_EditField.Limits = [0 Inf];
            app.RoughCorrLengthStrike_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughCorrLengthStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughCorrLengthStrike_EditFieldValueChanged, true);
            app.RoughCorrLengthStrike_EditField.Value = 10;
            app.CorrlengthinstrikeanddipkmEditFieldLabel = uilabel(app.Panel_2);
            app.CorrlengthinstrikeanddipkmEditFieldLabel.Text = 'Corr-length (in strike and dip, km)';
            app.RoughCorrLengthDip_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughCorrLengthDip_EditField.Limits = [0 Inf];
            app.RoughCorrLengthDip_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughCorrLengthDip_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughCorrLengthDip_EditFieldValueChanged, true);
            app.RoughCorrLengthDip_EditField.Value = 5;
            app.CorrlengthvariabilitykmEditFieldLabel = uilabel(app.Panel_2);
            app.CorrlengthvariabilitykmEditFieldLabel.Text = 'Corr-length variability (km)';
            app.RoughCorrLengthVari_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughCorrLengthVari_EditField.Limits = [0 Inf];
            app.RoughCorrLengthVari_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughCorrLengthVari_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughCorrLengthVari_EditFieldValueChanged, true);
            app.RoughCorrLengthVariType_DropDown = uidropdown(app.Panel_2);
            app.RoughCorrLengthVariType_DropDown.Items = {'uniform', 'normal'};
            app.RoughCorrLengthVariType_DropDown.ItemsData = {'1', '2'};
            app.RoughCorrLengthVariType_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughCorrLengthVariType_DropDownValueChanged, true);
            app.RoughCorrLengthVariType_DropDown.Value = '1';
            app.RoughHurstVariType_DropDown = uidropdown(app.Panel_2);
            app.RoughHurstVariType_DropDown.Items = {'uniform', 'normal'};
            app.RoughHurstVariType_DropDown.ItemsData = {'1', '2'};
            app.RoughHurstVariType_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughHurstVariType_DropDownValueChanged, true);
            app.RoughHurstVariType_DropDown.Value = '1';
            app.andEditField_2Label = uilabel(app.Panel_2);
            app.andEditField_2Label.Text = 'and';
            app.RoughHurstStrike_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughHurstStrike_EditField.Limits = [0 1];
            app.RoughHurstStrike_EditField.ValueDisplayFormat = '%2.2f';
            app.RoughHurstStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughHurstStrike_EditFieldValueChanged, true);
            app.RoughHurstStrike_EditField.Value = 0.8;
            app.HurstexpinstrikeanddipLabel = uilabel(app.Panel_2);
            app.HurstexpinstrikeanddipLabel.Text = 'Hurst exp. (in strike and dip)';
            app.RoughHurstDip_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughHurstDip_EditField.Limits = [0 1];
            app.RoughHurstDip_EditField.ValueDisplayFormat = '%2.2f';
            app.RoughHurstDip_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughHurstDip_EditFieldValueChanged, true);
            app.RoughHurstDip_EditField.Value = 0.8;
            app.HurstexpvariabilityLabel = uilabel(app.Panel_2);
            app.HurstexpvariabilityLabel.Text = 'Hurst exp. variability';
            app.RoughHurstVari_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughHurstVari_EditField.Limits = [0 1];
            app.RoughHurstVari_EditField.ValueDisplayFormat = '%2.2f';
            app.RoughHurstVari_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughHurstVari_EditFieldValueChanged, true);
            app.FractaldimensionEditFieldLabel = uilabel(app.Panel_2);
            app.FractaldimensionEditFieldLabel.Text = 'Fractal dimension';
            app.RoughFractalDimension_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughFractalDimension_EditField.Limits = [0 Inf];
            app.RoughFractalDimension_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughFractalDimension_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughFractalDimension_EditFieldValueChanged, true);
            app.RoughFractalDimension_EditField.Value = 2;
            app.CornerwavenumberEditFieldLabel = uilabel(app.Panel_2);
            app.CornerwavenumberEditFieldLabel.Text = 'Corner wave number';
            app.RoughCornerWaveNum_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughCornerWaveNum_EditField.Limits = [0 Inf];
            app.RoughCornerWaveNum_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughCornerWaveNum_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughCornerWaveNum_EditFieldValueChanged, true);
            app.RoughCornerWaveNum_EditField.Value = 0.01;
            app.nonlinscalingexpEditFieldLabel = uilabel(app.Panel_2);
            app.nonlinscalingexpEditFieldLabel.Text = 'non-lin. scaling exp.';
            app.RoughNonLinScalingExp_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughNonLinScalingExp_EditField.Limits = [0 Inf];
            app.RoughNonLinScalingExp_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughNonLinScalingExp_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughNonLinScalingExp_EditFieldValueChanged, true);
            app.RoughNonLinScalingExp_EditField.Value = 1;
            app.PrefactorforunitlengthisfractionEditFieldLabel = uilabel(app.Panel_2);
            app.PrefactorforunitlengthisfractionEditFieldLabel.Text = 'Pre-factor (for unit length, is fraction)';
            app.RoughPreFact_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughPreFact_EditField.Limits = [0 Inf];
            app.RoughPreFact_EditField.ValueDisplayFormat = '%4.3f';
            app.RoughPreFact_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughPreFact_EditFieldValueChanged, true);
            app.RoughPreFact_EditField.Value = 0.01;
            app.PrefactorvariabilityEditFieldLabel = uilabel(app.Panel_2);
            app.PrefactorvariabilityEditFieldLabel.Text = 'Pre-factor variability';
            app.RoughPreFactVari_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughPreFactVari_EditField.ValueDisplayFormat = '%4.3f';
            app.RoughPreFactVari_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughPreFactVari_EditFieldValueChanged, true);
            app.RoughPreFactVariType_DropDown = uidropdown(app.Panel_2);
            app.RoughPreFactVariType_DropDown.Items = {'uniform', 'normal'};
            app.RoughPreFactVariType_DropDown.ItemsData = {'1', '2'};
            app.RoughPreFactVariType_DropDown.ValueChangedFcn = createCallbackFcn(app, @RoughPreFactVariType_DropDownValueChanged, true);
            app.RoughPreFactVariType_DropDown.Value = '1';
            app.GenerateRoughness_Button = uibutton(app.Panel_2, 'push');
            app.GenerateRoughness_Button.ButtonPushedFcn = createCallbackFcn(app, @GenerateRoughness_ButtonPushed, true);
            app.GenerateRoughness_Button.Text = 'Generate Roughness';
            app.CombinewGrid_Button = uibutton(app.Panel_2, 'push');
            app.CombinewGrid_Button.ButtonPushedFcn = createCallbackFcn(app, @CombinewGrid_ButtonPushed, true);
            app.CombinewGrid_Button.Text = 'Combine w/ Grid';
            app.RMSLEditFieldLabel = uilabel(app.Panel_2);
            app.RMSLEditFieldLabel.Text = 'RMS/L';
            app.RMSL_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RMSL_EditField.Limits = [0 Inf];
            app.RMSL_EditField.ValueDisplayFormat = '%4.4f';
            app.PlotRoughness_CheckBox = uicheckbox(app.Panel_2);
            app.PlotRoughness_CheckBox.Text = 'Plot roughness';
            app.PlotRoughness_CheckBox.Value = true;
            app.ShowRoughnessStats_CheckBox = uicheckbox(app.Panel_2);
            app.ShowRoughnessStats_CheckBox.Text = 'Show roughness stats';
            app.normalizeprefactorwithlengthkmLabel = uilabel(app.Panel_2);
            app.normalizeprefactorwithlengthkmLabel.Text = 'normalize pre-factor with length (km)';
            app.RoughNormalizePreFact_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughNormalizePreFact_EditField.Limits = [0 Inf];
            app.RoughNormalizePreFact_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughNormalizePreFact_EditField.Value = 40;
            app.RMDamplitudeLabel = uilabel(app.Panel_2);
            app.RMDamplitudeLabel.Text = 'RMD amplitude';
            app.RoughRMD_ampli_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughRMD_ampli_EditField.Limits = [0 Inf];
            app.RoughRMD_ampli_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughRMD_ampli_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughRMD_ampli_EditFieldValueChanged, true);
            app.RoughRMD_ampli_EditField.Value = 0.01;
            app.RMDdecayLabel = uilabel(app.Panel_2);
            app.RMDdecayLabel.Text = 'RMD decay';
            app.RoughRMD_decay_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughRMD_decay_EditField.Limits = [0 Inf];
            app.RoughRMD_decay_EditField.ValueDisplayFormat = '%4.2f';
            app.RoughRMD_decay_EditField.ValueChangedFcn = createCallbackFcn(app, @RoughRMD_decay_EditFieldValueChanged, true);
            app.RoughRMD_decay_EditField.Value = 0.8;
            app.Load_SurfTrace_Button = uibutton(app.Panel_2, 'push');
            app.Load_SurfTrace_Button.ButtonPushedFcn = createCallbackFcn(app, @Load_SurfTrace_ButtonPushed, true);
            app.Load_SurfTrace_Button.Text = 'Load digitized fault surface trace(s)';
            app.Use_SurfTrace_CheckBox = uicheckbox(app.Panel_2);
            app.Use_SurfTrace_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Use_SurfTrace_CheckBoxValueChanged, true);
            app.Use_SurfTrace_CheckBox.Text = 'utilize trace(s)';
            app.ExtractSave_SurfTrace_Button = uibutton(app.Panel_2, 'push');
            app.ExtractSave_SurfTrace_Button.ButtonPushedFcn = createCallbackFcn(app, @ExtractSave_SurfTrace_ButtonPushed, true);
            app.ExtractSave_SurfTrace_Button.Text = 'Extract/Save fault surface trace(s)';
            app.Use_SurfTraceCopyDown_CheckBox = uicheckbox(app.Panel_2);
            app.Use_SurfTraceCopyDown_CheckBox.Text = 'copy trace down';
            app.kmroughnesslengthscaleLabel = uilabel(app.Panel_2);
            app.kmroughnesslengthscaleLabel.Text = 'km, roughness length scale';
            app.RoughLengthScale_EditField = uieditfield(app.Panel_2, 'numeric');
            app.RoughLengthScale_EditField.Limits = [0 Inf];
            app.RoughLengthScale_EditField.ValueDisplayFormat = '%5.1f';
            app.RoughLengthScale_EditField.Value = 1;
            app.Use_OnlyCopy_CheckBox = uicheckbox(app.Panel_2);
            app.Use_OnlyCopy_CheckBox.Text = 'only copy';
            app.Panel_3 = uipanel(app.UIFigure);
            app.Panel_3.Tooltip = {'this is given as a FRACTION of  "mean leg length" => if that is 1000m, then a 3e-5 gives a D_c value of 0.03m'};
            app.staticfrictioncoeffEditFieldLabel = uilabel(app.Panel_3);
            app.staticfrictioncoeffEditFieldLabel.Text = 'static friction coeff. +/-';
            app.FricStatFric_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricStatFric_EditField.Limits = [0 Inf];
            app.FricStatFric_EditField.ValueDisplayFormat = '%4.2f';
            app.FricStatFric_EditField.ValueChangedFcn = createCallbackFcn(app, @FricStatFric_EditFieldValueChanged, true);
            app.FricStatFric_EditField.Value = 0.5;
            app.variationEditFieldLabel = uilabel(app.Panel_3);
            app.variationEditFieldLabel.Text = '% variation';
            app.FricStatFricVari_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricStatFricVari_EditField.Limits = [0 Inf];
            app.FricStatFricVari_EditField.ValueDisplayFormat = '%4.2f';
            app.FricStatFricVari_EditField.ValueChangedFcn = createCallbackFcn(app, @FricStatFricVari_EditFieldValueChanged, true);
            app.FricStatFricVari_EditField.Value = 3;
            app.EditFieldLabel = uilabel(app.Panel_3);
            app.EditFieldLabel.Text = '+/-';
            app.FricDynDrop_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricDynDrop_EditField.Limits = [0 Inf];
            app.FricDynDrop_EditField.ValueDisplayFormat = '%4.2f';
            app.FricDynDrop_EditField.ValueChangedFcn = createCallbackFcn(app, @FricDynDrop_EditFieldValueChanged, true);
            app.FricDynDrop_EditField.Value = 10;
            app.changeinEQfulldropEditFieldLabel = uilabel(app.Panel_3);
            app.changeinEQfulldropEditFieldLabel.Text = '%  change in EQ (full drop)';
            app.FricDynDropVari_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricDynDropVari_EditField.Limits = [0 Inf];
            app.FricDynDropVari_EditField.ValueDisplayFormat = '%4.2f';
            app.FricDynDropVari_EditField.ValueChangedFcn = createCallbackFcn(app, @FricDynDropVari_EditFieldValueChanged, true);
            app.FricDynDropVari_EditField.Value = 3;
            app.FricUseSkempton_CheckBox = uicheckbox(app.Panel_3);
            app.FricUseSkempton_CheckBox.ValueChangedFcn = createCallbackFcn(app, @FricUseSkempton_CheckBoxValueChanged, true);
            app.FricUseSkempton_CheckBox.Text = 'use Skempton factor';
            app.FricSkemptonType_DropDown = uidropdown(app.Panel_3);
            app.FricSkemptonType_DropDown.Items = {'0.3, uniform', '0.5, uniform', '0.7, uniform'};
            app.FricSkemptonType_DropDown.ItemsData = {'1', '2', '3'};
            app.FricSkemptonType_DropDown.ValueChangedFcn = createCallbackFcn(app, @FricSkemptonType_DropDownValueChanged, true);
            app.FricSkemptonType_DropDown.Value = '1';
            app.FricShowSkempton_ShowButton = uibutton(app.Panel_3, 'push');
            app.FricShowSkempton_ShowButton.ButtonPushedFcn = createCallbackFcn(app, @FricShowSkempton_ShowButtonPushed, true);
            app.FricShowSkempton_ShowButton.Text = 'Show';
            app.FricUseAmB_CheckBox = uicheckbox(app.Panel_3);
            app.FricUseAmB_CheckBox.ValueChangedFcn = createCallbackFcn(app, @FricUseAmB_CheckBoxValueChanged, true);
            app.FricUseAmB_CheckBox.Text = 'use (a-b) profile';
            app.FricUseAmB_CheckBox.Value = true;
            app.FricAmBType_DropDown = uidropdown(app.Panel_3);
            app.FricAmBType_DropDown.Items = {'parabolic', 'piecewise linear'};
            app.FricAmBType_DropDown.ItemsData = {'1'};
            app.FricAmBType_DropDown.ValueChangedFcn = createCallbackFcn(app, @FricAmBType_DropDownValueChanged, true);
            app.FricAmBType_DropDown.Value = '1';
            app.FricShowAmB_ShowButton = uibutton(app.Panel_3, 'push');
            app.FricShowAmB_ShowButton.ButtonPushedFcn = createCallbackFcn(app, @FricShowAmB_ShowButtonPushed, true);
            app.FricShowAmB_ShowButton.Text = 'Show';
            app.geothermgradientKkmforabprofileEditFieldLabel = uilabel(app.Panel_3);
            app.geothermgradientKkmforabprofileEditFieldLabel.Text = 'geotherm gradient (K/km), for (a-b) profile';
            app.FricGeoTherm_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricGeoTherm_EditField.Limits = [0 Inf];
            app.FricGeoTherm_EditField.ValueDisplayFormat = '%4.2f';
            app.FricGeoTherm_EditField.ValueChangedFcn = createCallbackFcn(app, @FricGeoTherm_EditFieldValueChanged, true);
            app.FricGeoTherm_EditField.Value = 22;
            app.PlotStrength_CheckBox = uicheckbox(app.Panel_3);
            app.PlotStrength_CheckBox.Text = 'Plot strength';
            app.PlotStrength_CheckBox.Value = true;
            app.PlotWithRoughness_CheckBox = uicheckbox(app.Panel_3);
            app.PlotWithRoughness_CheckBox.Text = 'Plot with roughness';
            app.averagestrengthacrossallcellsEditFieldLabel = uilabel(app.Panel_3);
            app.averagestrengthacrossallcellsEditFieldLabel.Text = 'average strength across all cells';
            app.AverageStrength_EditField = uieditfield(app.Panel_3, 'numeric');
            app.AverageStrength_EditField.Limits = [0 Inf];
            app.AverageStrength_EditField.ValueDisplayFormat = '%4.2f';
            app.GenerateStrength_Button = uibutton(app.Panel_3, 'push');
            app.GenerateStrength_Button.ButtonPushedFcn = createCallbackFcn(app, @GenerateStrength_ButtonPushed, true);
            app.GenerateStrength_Button.Text = 'Generate strength';
            app.PlotStrength_Button = uibutton(app.Panel_3, 'push');
            app.PlotStrength_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotStrength_ButtonPushed, true);
            app.PlotStrength_Button.Text = 'Plot strength';
            app.FricFrictionType_DropDown = uidropdown(app.Panel_3);
            app.FricFrictionType_DropDown.Items = {'static/dynamic friction', 'slip-weakening', 'velocity-weakening'};
            app.FricFrictionType_DropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.FricFrictionType_DropDown.ValueChangedFcn = createCallbackFcn(app, @FricFrictionType_DropDownValueChanged, true);
            app.FricFrictionType_DropDown.Value = '2';
            app.forweakeinguseLabel = uilabel(app.Panel_3);
            app.forweakeinguseLabel.Text = 'for weakeing use:';
            app.D_cLabel = uilabel(app.Panel_3);
            app.D_cLabel.Tooltip = {'this is given as a FRACTION of  "mean leg length" => if that is 1000m, then a 3e-5 gives a D_c value of 0.03m'};
            app.D_cLabel.Text = 'D_c (*) +/-';
            app.FricDc_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricDc_EditField.Limits = [0 Inf];
            app.FricDc_EditField.ValueDisplayFormat = '%2.2e';
            app.FricDc_EditField.ValueChangedFcn = createCallbackFcn(app, @FricDc_EditFieldValueChanged, true);
            app.FricDc_EditField.Tooltip = {'this is given as a FRACTION of  "mean leg length" => if that is 1000m, then a 3e-5 gives a D_c value of 0.03m'};
            app.FricDc_EditField.Value = 0.0001;
            app.variEditFieldLabel = uilabel(app.Panel_3);
            app.variEditFieldLabel.Text = '% vari.';
            app.FricDcVari_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricDcVari_EditField.Limits = [0 Inf];
            app.FricDcVari_EditField.ValueDisplayFormat = '%4.2f';
            app.FricDcVari_EditField.ValueChangedFcn = createCallbackFcn(app, @FricDcVari_EditFieldValueChanged, true);
            app.FricDcVari_EditField.Value = 70;
            app.FricAllowReset_CheckBox = uicheckbox(app.Panel_3);
            app.FricAllowReset_CheckBox.Text = 'reset friction parameters after slip-event on cell';
            app.FricAllowReset_CheckBox.Value = true;
            app.degreestartingtemperatureatsurfaceLabel = uilabel(app.Panel_3);
            app.degreestartingtemperatureatsurfaceLabel.Text = 'degree starting temperature (at surface)';
            app.FricGeoThermStartTemp_EditField = uieditfield(app.Panel_3, 'numeric');
            app.FricGeoThermStartTemp_EditField.ValueDisplayFormat = '%4.2f';
            app.AssignFricManually_Button = uibutton(app.Panel_3, 'push');
            app.AssignFricManually_Button.Visible = 'off';
            app.AssignFricManually_Button.Text = 'Assign manually (for sel. fault)';
            app.Strgth_AssignManually_CheckBox = uicheckbox(app.Panel_3);
            app.Strgth_AssignManually_CheckBox.Visible = 'off';
            app.Strgth_AssignManually_CheckBox.Text = '';
            app.Strgth_AssignManually_CheckBox.Value = true;
            app.SaveAllOrOneGrid_DropDown = uidropdown(app.UIFigure);
            app.SaveAllOrOneGrid_DropDown.Items = {'only selected grid', 'all imported grids'};
            app.SaveAllOrOneGrid_DropDown.ItemsData = {'1', '2', '3', '4', '5'};
            app.SaveAllOrOneGrid_DropDown.Value = '1';
            app.ofmodelstogenerateEditFieldLabel = uilabel(app.UIFigure);
            app.ofmodelstogenerateEditFieldLabel.Text = '# of models to generate';
            app.SaveModels2Generate_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveModels2Generate_EditField.Limits = [1 Inf];
            app.SaveModels2Generate_EditField.RoundFractionalValues = 'on';
            app.SaveModels2Generate_EditField.ValueDisplayFormat = '%d';
            app.SaveModels2Generate_EditField.Value = 1;
            app.currentmodelEditFieldLabel = uilabel(app.UIFigure);
            app.currentmodelEditFieldLabel.Text = 'current model';
            app.SaveCurrentModelNum_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SaveCurrentModelNum_EditField.Limits = [1 Inf];
            app.SaveCurrentModelNum_EditField.RoundFractionalValues = 'on';
            app.SaveCurrentModelNum_EditField.ValueDisplayFormat = '%d';
            app.SaveCurrentModelNum_EditField.Editable = 'off';
            app.SaveCurrentModelNum_EditField.Value = 1;
            app.SaveRoughStrenth_Button = uibutton(app.UIFigure, 'push');
            app.SaveRoughStrenth_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveRoughStrenth_ButtonPushed, true);
            app.SaveRoughStrenth_Button.Text = 'Save rough/strength model';
            app.sameforallsectionsoffaultPanel = uipanel(app.UIFigure);
            app.sameforallsectionsoffaultPanel.Title = 'same for all sections of fault';
            app.StrengthUseFractalDistrib_CheckBox = uicheckbox(app.sameforallsectionsoffaultPanel);
            app.StrengthUseFractalDistrib_CheckBox.ValueChangedFcn = createCallbackFcn(app, @StrengthUseFractalDistrib_CheckBoxValueChanged, true);
            app.StrengthUseFractalDistrib_CheckBox.Text = 'use fractal strength distribution';
            app.StrengthSeed_DropDown = uidropdown(app.sameforallsectionsoffaultPanel);
            app.StrengthSeed_DropDown.Items = {'New seed', 'Previous seed'};
            app.StrengthSeed_DropDown.ItemsData = {'1', '2'};
            app.StrengthSeed_DropDown.ValueChangedFcn = createCallbackFcn(app, @StrengthSeed_DropDownValueChanged, true);
            app.StrengthSeed_DropDown.Value = '1';
            app.andEditField_3Label = uilabel(app.sameforallsectionsoffaultPanel);
            app.andEditField_3Label.Text = 'and';
            app.StrengthCorrLengthStrike_EditField = uieditfield(app.sameforallsectionsoffaultPanel, 'numeric');
            app.StrengthCorrLengthStrike_EditField.Limits = [0 Inf];
            app.StrengthCorrLengthStrike_EditField.ValueDisplayFormat = '%4.2f';
            app.StrengthCorrLengthStrike_EditField.ValueChangedFcn = createCallbackFcn(app, @StrengthCorrLengthStrike_EditFieldValueChanged, true);
            app.StrengthCorrLengthStrike_EditField.Value = 10;
            app.CorrlengthinstrikeanddipkmEditField_2Label = uilabel(app.sameforallsectionsoffaultPanel);
            app.CorrlengthinstrikeanddipkmEditField_2Label.Text = 'Corr-length (in strike and dip, km)';
            app.StrengthCorrLengthDip_EditField = uieditfield(app.sameforallsectionsoffaultPanel, 'numeric');
            app.StrengthCorrLengthDip_EditField.Limits = [0 Inf];
            app.StrengthCorrLengthDip_EditField.ValueDisplayFormat = '%4.2f';
            app.StrengthCorrLengthDip_EditField.ValueChangedFcn = createCallbackFcn(app, @StrengthCorrLengthDip_EditFieldValueChanged, true);
            app.StrengthCorrLengthDip_EditField.Value = 5;
            app.HurstexponentEditFieldLabel = uilabel(app.sameforallsectionsoffaultPanel);
            app.HurstexponentEditFieldLabel.Text = 'Hurst exponent';
            app.StrengthHurstExp_EditField = uieditfield(app.sameforallsectionsoffaultPanel, 'numeric');
            app.StrengthHurstExp_EditField.Limits = [0 1];
            app.StrengthHurstExp_EditField.ValueDisplayFormat = '%2.2f';
            app.StrengthHurstExp_EditField.ValueChangedFcn = createCallbackFcn(app, @StrengthHurstExp_EditFieldValueChanged, true);
            app.StrengthHurstExp_EditField.Value = 0.999;
            app.waterlevelcutoffLabel = uilabel(app.sameforallsectionsoffaultPanel);
            app.waterlevelcutoffLabel.Text = '"water-level" cutoff (%)';
            app.StrengthWaterLevel_EditField = uieditfield(app.sameforallsectionsoffaultPanel, 'numeric');
            app.StrengthWaterLevel_EditField.Limits = [0 Inf];
            app.StrengthWaterLevel_EditField.ValueDisplayFormat = '%4.2f';
            app.StrengthWaterLevel_EditField.ValueChangedFcn = createCallbackFcn(app, @StrengthWaterLevel_EditFieldValueChanged, true);
            app.variabilityEditFieldLabel = uilabel(app.sameforallsectionsoffaultPanel);
            app.variabilityEditFieldLabel.Text = '% variability';
            app.StrengthWaterLevelVari_EditField = uieditfield(app.sameforallsectionsoffaultPanel, 'numeric');
            app.StrengthWaterLevelVari_EditField.Limits = [0 Inf];
            app.StrengthWaterLevelVari_EditField.ValueDisplayFormat = '%4.2f';
            app.StrengthWaterLevelVari_EditField.ValueChangedFcn = createCallbackFcn(app, @StrengthWaterLevelVari_EditFieldValueChanged, true);
            app.addLabel = uilabel(app.UIFigure);
            app.addLabel.Text = 'add';
            app.tooverburdenstressMPaEditFieldLabel = uilabel(app.UIFigure);
            app.tooverburdenstressMPaEditFieldLabel.Text = 'to overburden stress (MPa)';
            app.HalfspaceOverburden_EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfspaceOverburden_EditField.Limits = [0 Inf];
            app.HalfspaceOverburden_EditField.ValueDisplayFormat = '%4.2f';
            app.HalfspaceOverburden_EditField.Value = 5;
            app.shearmodulusGPaLabel = uilabel(app.UIFigure);
            app.shearmodulusGPaLabel.Text = 'shear modulus (GPa)';
            app.HalfspaceShearMod_EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfspaceShearMod_EditField.Limits = [0 Inf];
            app.HalfspaceShearMod_EditField.ValueDisplayFormat = '%4.2f';
            app.HalfspaceShearMod_EditField.Value = 30;
            app.PoissonsratioEditFieldLabel = uilabel(app.UIFigure);
            app.PoissonsratioEditFieldLabel.Text = 'Poisson''s ratio';
            app.HalfspacePoisson_EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfspacePoisson_EditField.Limits = [0 Inf];
            app.HalfspacePoisson_EditField.ValueDisplayFormat = '%4.2f';
            app.HalfspacePoisson_EditField.Value = 0.25;
            app.SavenameLabel = uilabel(app.UIFigure);
            app.SavenameLabel.Text = 'Save name';
            app.SaveName_EditField = uieditfield(app.UIFigure, 'text');
            app.LoadRoughnessStrength_Button = uibutton(app.UIFigure, 'push');
            app.LoadRoughnessStrength_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadRoughnessStrength_ButtonPushed, true);
            app.LoadRoughnessStrength_Button.Text = 'Load prev. realization';
            app.halfspacedensitykgm3EditFieldLabel = uilabel(app.UIFigure);
            app.halfspacedensitykgm3EditFieldLabel.Text = 'half-space density (kg/m^3)';
            app.HalfspaceDensity_EditField = uieditfield(app.UIFigure, 'numeric');
            app.HalfspaceDensity_EditField.Limits = [0 Inf];
            app.HalfspaceDensity_EditField.ValueDisplayFormat = '%5.1f';
            app.HalfspaceDensity_EditField.Value = 2700;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = B_RoughStrength_Apr2022

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end