classdef Z_DigitizeFaults_MCQsim_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotConnectedFaults_Button  matlab.ui.control.Button
        RemoveSelSection_Button     matlab.ui.control.Button
        SaveFaultModel_Button       matlab.ui.control.Button
        AutoConnectSegments_Button  matlab.ui.control.Button
        Panel_2                     matlab.ui.container.Panel
        TextArea                    matlab.ui.control.TextArea
        SlipRate_EditField          matlab.ui.control.NumericEditField
        SlipratemmyrLabel           matlab.ui.control.Label
        SlipRake_EditField          matlab.ui.control.NumericEditField
        RakedegLabel                matlab.ui.control.Label
        FaultOrSeisWidth_DropDown   matlab.ui.control.DropDown
        TextArea_2                  matlab.ui.control.TextArea
        Name_EditField              matlab.ui.control.EditField
        NameEditFieldLabel          matlab.ui.control.Label
        FaultDip_EditField          matlab.ui.control.NumericEditField
        DipdegLabel                 matlab.ui.control.Label
        Width_EditField             matlab.ui.control.NumericEditField
        kmLabel                     matlab.ui.control.Label
        TraceFaults_Button          matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        LoadFaultModel_Button       matlab.ui.control.Button
        FigNumMap_EditField         matlab.ui.control.NumericEditField
        FigEditFieldLabel           matlab.ui.control.Label
        MaxEasting_EditField        matlab.ui.control.NumericEditField
        kmEditField_4Label          matlab.ui.control.Label
        MinEasting_EditField        matlab.ui.control.NumericEditField
        kmEditField_3Label          matlab.ui.control.Label
        MinNorthing_EditField       matlab.ui.control.NumericEditField
        kmEditField_2Label          matlab.ui.control.Label
        MaxNorthing_EditField       matlab.ui.control.NumericEditField
        kmEditFieldLabel            matlab.ui.control.Label
        maxEastingButton            matlab.ui.control.Button
        minEastingButton            matlab.ui.control.Button
        minNorthingButton           matlab.ui.control.Button
        maxNorthingButton           matlab.ui.control.Button
        PlotFaultMap_Button         matlab.ui.control.Button
        LoadFaultMap_Button         matlab.ui.control.Button
        PlotDigitizedFaults_Button  matlab.ui.control.Button
        TracedFaults_ListBox        matlab.ui.control.ListBox
        TracedfaultsListBoxLabel    matlab.ui.control.Label
        AxisEqual_CheckBox          matlab.ui.control.CheckBox
        FigNumFaults_EditField      matlab.ui.control.NumericEditField
        FigEditFieldLabel_3         matlab.ui.control.Label
    end

    
    properties (Access = private)
        GreenButton     = [0.7569    0.8667    0.7765];
        RedButton       = [0.9255    0.8392    0.8392];
        GrayButton      = [0.9059    0.9059    0.9059];
        MaxSectionID    = 0;
        AllSegmNames    = {'no fault traced'};
        SegmRelation    = [];

        TheBaseMap      = [];
        EastExtend      = [];
        NorthExtend     = [];
        
        MinNorthingMouse= [];
        MaxNorthingMouse= [];
        MinEastingMouse = [];
        MaxEastingMouse = [];
        dE              = [];
        dN              = [];
        Evect           = [];
        Nvect           = [];
        
        FaultOnMap      = struct;   
    end
    
    methods (Access = private)
        
        function Compute_dE_dN(app)
            app.dE       = (app.MaxEasting_EditField.Value -app.MinEasting_EditField.Value) /(app.MaxEastingMouse-app.MinEastingMouse);
            app.dN       = (app.MaxNorthing_EditField.Value-app.MinNorthing_EditField.Value)/(app.MaxNorthingMouse-app.MinNorthingMouse);
            
            StartEvalue  = app.MinEasting_EditField.Value - app.dE*(app.MinEastingMouse-1);
            EndEvalue    = StartEvalue  + app.dE*size(app.TheBaseMap,2) ;
            app.Evect    = linspace(StartEvalue, EndEvalue, size(app.TheBaseMap,2));
            
            StartNvalue  = app.MinNorthing_EditField.Value - app.dN*(app.MinNorthingMouse-1);
            EndNvalue    = StartNvalue  + app.dN*size(app.TheBaseMap,1) ;
            app.Nvect    = linspace(StartNvalue, EndNvalue, size(app.TheBaseMap,1));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFaultMap_Button
        function LoadFaultMap_ButtonPushed(app, event)

            [DataName,DataPath]  = uigetfile({'*.jpg;*.tif;*.png;*.gif'},'Load fault base map');
            FullPath             = strcat(DataPath,DataName);
            %-----------------------------------------------
            if (DataName ==0),          return;                     end
            %-----------------------------------------------
            app.TheBaseMap   = flipud(imread(FullPath));
            app.EastExtend   = 1:size(app.TheBaseMap,2);
            app.NorthExtend  = 1:size(app.TheBaseMap,1);
            %-----------------------------------------------
            app.LoadFaultMap_Button.BackgroundColor = app.GrayButton;
            app.PlotFaultMap_Button.BackgroundColor = app.GreenButton;
            %-----------------------------------------------           
        end

        % Button pushed function: PlotFaultMap_Button
        function PlotFaultMap_ButtonPushed(app, event)
            FigNumMap   = app.FigNumMap_EditField.Value;
            %-----------------------------------------------
            ButtonColor = app.PlotFaultMap_Button.BackgroundColor;
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),          return;                 end
            %-----------------------------------------------
            figure(FigNumMap); clf; hold on; xlabel('Easting'); ylabel('Northing'); axis equal;
            imagesc(app.EastExtend,app.NorthExtend,app.TheBaseMap);
            %-----------------------------------------------
            app.minNorthingButton.BackgroundColor   = app.GreenButton;
            app.maxNorthingButton.BackgroundColor   = app.GreenButton;
            app.minEastingButton.BackgroundColor    = app.GreenButton;
            app.maxEastingButton.BackgroundColor    = app.GreenButton;
            %-----------------------------------------------
        end

        % Button pushed function: maxNorthingButton
        function maxNorthingButtonPushed(app, event)
            FigNumMap   = app.FigNumMap_EditField.Value;
            %-----------------------------------------------
            ButtonColor = app.maxNorthingButton.BackgroundColor;
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),          return;                 end
            %-----------------------------------------------
            figure(FigNumMap);
            %-----------------------------------------------
            [~,y,but] = ginput(1);
            if (but == 1)
                app.MaxNorthingMouse  = y;
                app.maxNorthingButton.BackgroundColor = app.GrayButton;
            end
            %-----------------------------------------------
            MinEButt = sum(abs(app.minEastingButton.BackgroundColor  - app.GrayButton));
            MaxEButt = sum(abs(app.maxEastingButton.BackgroundColor  - app.GrayButton));
            MinNButt = sum(abs(app.minNorthingButton.BackgroundColor - app.GrayButton));
            MaxNButt = sum(abs(app.maxNorthingButton.BackgroundColor - app.GrayButton));
            if ((MinEButt+MaxEButt+MinNButt+MaxNButt) < 1E-3)
                app.TraceFaults_Button.BackgroundColor    = app.GreenButton;
                app.LoadFaultModel_Button.BackgroundColor = app.GreenButton;
                Compute_dE_dN(app);
            end
            %-----------------------------------------------       
        end

        % Button pushed function: minNorthingButton
        function minNorthingButtonPushed(app, event)
            FigNumMap   = app.FigNumMap_EditField.Value;
            %-----------------------------------------------
            ButtonColor = app.minNorthingButton.BackgroundColor;
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),          return;                 end
            %-----------------------------------------------
            figure(FigNumMap);
            %-----------------------------------------------
            [~,y,but] = ginput(1);
            if (but == 1)
                app.MinNorthingMouse  = y;
                app.minNorthingButton.BackgroundColor = app.GrayButton;
            end
            %-----------------------------------------------
            MinEButt = sum(abs(app.minEastingButton.BackgroundColor  - app.GrayButton));
            MaxEButt = sum(abs(app.maxEastingButton.BackgroundColor  - app.GrayButton));
            MinNButt = sum(abs(app.minNorthingButton.BackgroundColor - app.GrayButton));
            MaxNButt = sum(abs(app.maxNorthingButton.BackgroundColor - app.GrayButton));
            if ((MinEButt+MaxEButt+MinNButt+MaxNButt) < 1E-3)
                app.LoadFaultModel_Button.BackgroundColor = app.GreenButton;
                app.TraceFaults_Button.BackgroundColor    = app.GreenButton;
                Compute_dE_dN(app);
            end
            %-----------------------------------------------
        end

        % Button pushed function: minEastingButton
        function minEastingButtonPushed(app, event)
            FigNumMap   = app.FigNumMap_EditField.Value;
            %-----------------------------------------------
            ButtonColor = app.minEastingButton.BackgroundColor;
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),          return;                 end
            %-----------------------------------------------
            figure(FigNumMap);
            %-----------------------------------------------
            [x,~,but] = ginput(1);
            if (but == 1)
                app.MinEastingMouse  = x;
                app.minEastingButton.BackgroundColor = app.GrayButton;
            end
            %-----------------------------------------------
            MinEButt = sum(abs(app.minEastingButton.BackgroundColor  - app.GrayButton));
            MaxEButt = sum(abs(app.maxEastingButton.BackgroundColor  - app.GrayButton));
            MinNButt = sum(abs(app.minNorthingButton.BackgroundColor - app.GrayButton));
            MaxNButt = sum(abs(app.maxNorthingButton.BackgroundColor - app.GrayButton));
            if ((MinEButt+MaxEButt+MinNButt+MaxNButt) < 1E-3)
                app.LoadFaultModel_Button.BackgroundColor = app.GreenButton;
                app.TraceFaults_Button.BackgroundColor    = app.GreenButton;
                Compute_dE_dN(app);
            end
            %-----------------------------------------------    
        end

        % Button pushed function: maxEastingButton
        function maxEastingButtonPushed(app, event)
            FigNumMap   = app.FigNumMap_EditField.Value;
            %-----------------------------------------------
            ButtonColor = app.maxEastingButton.BackgroundColor;
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),          return;                 end
            %-----------------------------------------------
            figure(FigNumMap);
            %-----------------------------------------------
            [x,~,but] = ginput(1);
            if (but == 1)
                app.MaxEastingMouse  = x;
                app.maxEastingButton.BackgroundColor = app.GrayButton;
            end
            %-----------------------------------------------
            MinEButt = sum(abs(app.minEastingButton.BackgroundColor  - app.GrayButton));
            MaxEButt = sum(abs(app.maxEastingButton.BackgroundColor  - app.GrayButton));
            MinNButt = sum(abs(app.minNorthingButton.BackgroundColor - app.GrayButton));
            MaxNButt = sum(abs(app.maxNorthingButton.BackgroundColor - app.GrayButton));
            if ((MinEButt+MaxEButt+MinNButt+MaxNButt) < 1E-3)
                app.LoadFaultModel_Button.BackgroundColor = app.GreenButton;
                app.TraceFaults_Button.BackgroundColor    = app.GreenButton;
                Compute_dE_dN(app);
            end
            %-----------------------------------------------    
        end

        % Button pushed function: TraceFaults_Button
        function TraceFaults_ButtonPushed(app, event)

            ButtonColor = app.TraceFaults_Button.BackgroundColor;
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),          return;                 end
            FigNum     = app.FigNumMap_EditField.Value;
            FaultName  = app.Name_EditField.Value;
            
            Width      = app.Width_EditField.Value;
            FaultDip   = app.FaultDip_EditField.Value*pi/180;
            Rate       = app.SlipRate_EditField.Value;
            Rake       = app.SlipRake_EditField.Value;
            if (str2double(app.FaultOrSeisWidth_DropDown.Value) == 1)
                FaultWidth = Width;    
            else
                FaultWidth = Width/sin(FaultDip);
            end
            %----------------------------------------------
            NextSectionID     = app.MaxSectionID  +1;
            app.MaxSectionID  = app.MaxSectionID  +1;
            %----------------------------------------------
            figure(FigNum); hold on;
            %--------- now the figure is plotted --- now I can draw the feature...
            TempVals      = [];        counter        = 0;   
            TempHandlePt  = [];        TempHandleLine = [];     
            %...........................
            %disp('Left mouse button to add tracing point; Right mouse to remove last tracing point; Space bar to stop tracing'); 
            %...........................
            but            = 1;
            while but ~= 32
                  [xi,yi,but] = ginput(1);
                if     but == 3 %remove a point
                    but = 1;
                    if (size(TempVals,1) == 1),           TempVals = [];                    counter = 0;            delete(TempHandlePt(1,1));      TempHandlePt = [];                                          end
                    if (size(TempVals,1) >=2),            TempVals = TempVals(1:end-1,:);   counter = counter -1;   delete(TempHandlePt(end,1));    TempHandlePt = TempHandlePt(1:end-1,1);                     end
                    if (size(TempHandleLine,1) == 1),     delete(TempHandleLine(1,1));      TempHandleLine = [];                          end 
                    if (size(TempHandleLine,1) >= 2),     delete(TempHandleLine(end,1));    TempHandleLine = TempHandleLine(1:end-1,1);   end 
                elseif but == 32 %this is the space bar
                    Picked = size(TempVals,1);
                    if (Picked <=1), errordlg('define at least two points for feature trace'); uiwait; but = 1; end
                else
                    TempVals     = [TempVals;xi, yi];                                                  
                    TempHandle   = plot(xi,yi,'o','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',6);
                    TempHandlePt = [TempHandlePt; TempHandle];
                    counter      = counter  +1;
                    if counter >=2
                        TempHandle    = plot([TempVals(counter-1,1), TempVals(counter,1)],[TempVals(counter-1,2), TempVals(counter,2)],'-r','linewidth',2);
                        TempHandleLine= [TempHandleLine; TempHandle];
                    end 
                    but = 1;
                end
            end
            %----------------------------------------------
            app.FaultOnMap(NextSectionID).MapLine     = TempHandleLine;
            app.FaultOnMap(NextSectionID).MapPnts     = TempHandlePt;
            app.FaultOnMap(NextSectionID).ThePoints   = TempVals;
            app.FaultOnMap(NextSectionID).SegmentNum  = size(TempVals,1)-1;
            %----------------------------------------------
            for j = 1 : app.FaultOnMap(NextSectionID).SegmentNum 
                %--------------------------------------------------
                FltPntStartTop     = [app.Evect(1,round(app.FaultOnMap(NextSectionID).ThePoints(j,  1))), app.Nvect(1,round(app.FaultOnMap(NextSectionID).ThePoints(j,  2)))];
                FltPntEndTop       = [app.Evect(1,round(app.FaultOnMap(NextSectionID).ThePoints(j+1,1))), app.Nvect(1,round(app.FaultOnMap(NextSectionID).ThePoints(j+1,2)))];
                %-----------------------
                FaultCent          = [(FltPntStartTop(1,1)+FltPntEndTop(1,1))/2 ; (FltPntStartTop(1,2)+FltPntEndTop(1,2))/2; 0];
                FltVect            = [(FltPntEndTop(1,1)  -FltPntStartTop(1,1)) , (FltPntEndTop(1,2)  -FltPntStartTop(1,2))];
                FaultLength        = sqrt(sum(FltVect.^2));
                FltVect            = FltVect./FaultLength; %normalized the length
                FaultStrike        = atan2(FltVect(1,1),FltVect(1,2));
                if (FaultStrike < 0),   FaultStrike = 2*pi + FaultStrike;           end
                %-----------------------
                LocalY             = [sin(FaultStrike), cos(FaultStrike), 0]; %is strike dir
                LocalX             = [sin(FaultDip)*cos(FaultStrike), -sin(FaultDip)*sin(FaultStrike), cos(FaultDip)]; %is fault normal
                LocalZ             = cross(LocalX,LocalY); %is up-dip dir
                RotMat             = [LocalY;LocalZ;LocalX];
                RotMatT            = RotMat';
                %-----------------------
                FltPntStartTop_Loc = [-FaultLength/2 0 0];
                FltPntEndTop_Loc   = [+FaultLength/2 0 0];
                FltPntStartBot_Loc = [-FaultLength/2 -FaultWidth 0];
                FltPntEndBot_Loc   = [+FaultLength/2 -FaultWidth 0];
            
                FltPntStartTop     = RotMatT*FltPntStartTop_Loc' +FaultCent;
                FltPntStartBot     = RotMatT*FltPntStartBot_Loc' +FaultCent;
                FltPntEndTop       = RotMatT*FltPntEndTop_Loc'   +FaultCent;
                FltPntEndBot       = RotMatT*FltPntEndBot_Loc'   +FaultCent;
                %--------------------------------------------------
                app.FaultOnMap(NextSectionID).SegmValues(j).SegmName    = strcat(FaultName,'_ID_',num2str(NextSectionID),'_',num2str(j));
                app.FaultOnMap(NextSectionID).SegmValues(j).VertexCoord = [FltPntStartTop'; FltPntStartBot'; FltPntEndBot';FltPntEndTop'];
                app.FaultOnMap(NextSectionID).SegmValues(j).VertexShift = zeros(4,3);
                app.FaultOnMap(NextSectionID).SegmValues(j).Strike      = FaultStrike;
                app.FaultOnMap(NextSectionID).SegmValues(j).Dip         = FaultDip;
                app.FaultOnMap(NextSectionID).SegmValues(j).Length      = FaultLength;
                app.FaultOnMap(NextSectionID).SegmValues(j).Width       = FaultWidth;
                app.FaultOnMap(NextSectionID).SegmValues(j).SlipRate    = Rate;
                app.FaultOnMap(NextSectionID).SegmValues(j).Rake        = Rake;
                app.FaultOnMap(NextSectionID).SegmValues(j).FaultCent   = FaultCent;
                app.FaultOnMap(NextSectionID).SegmValues(j).RotMat      = RotMat;
                %-------------------------------------------------- 
            end
            %-------------------------------------------------- 
            SegmentCounter       = 0;
            for i = 1 : app.MaxSectionID
                for j = 1 : app.FaultOnMap(i).SegmentNum
                    SegmentCounter = SegmentCounter +1;
                    app.AllSegmNames{SegmentCounter}   = app.FaultOnMap(i).SegmValues(j).SegmName;
                    app.SegmRelation(SegmentCounter,:) = [i, j];
                end
            end
            app.TracedFaults_ListBox.Items     = app.AllSegmNames;
            app.TracedFaults_ListBox.ItemsData = 1:size(app.AllSegmNames,2);
            app.TracedFaults_ListBox.Value     = 1;
            %----------------------------------------------
            app.PlotDigitizedFaults_Button.BackgroundColor = app.GreenButton;
            app.AutoConnectSegments_Button.BackgroundColor = app.GreenButton;
            app.SaveFaultModel_Button.BackgroundColor      = app.GreenButton;  
        end

        % Button pushed function: PlotDigitizedFaults_Button
        function PlotDigitizedFaults_ButtonPushed(app, event)
            ButtonColor = app.PlotDigitizedFaults_Button.BackgroundColor;
            if (app.MaxSectionID == 0),                                 return;                 end
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),        return;                 end
            %----------------------------------
            AxEqual = app.AxisEqual_CheckBox.Value;
            FigNum  = app.FigNumFaults_EditField.Value;
            %----------------------------------
            figure(FigNum); clf; hold on; 
            if (AxEqual == 1),              axis equal;                     end
            %----------------------------------
            SelectedPos     = app.TracedFaults_ListBox.Value;
            SelectedSection = app.SegmRelation(SelectedPos,1);
            SelectedSegment = app.SegmRelation(SelectedPos,2);
            %----------------------------------
            for i = 1 : app.MaxSectionID
                for j = 1 : app.FaultOnMap(i).SegmentNum
                    Xcoords            = app.FaultOnMap(i).SegmValues(j).VertexCoord(:,1);          Xcoords = [Xcoords; Xcoords(1,1)];
                    Ycoords            = app.FaultOnMap(i).SegmValues(j).VertexCoord(:,2);          Ycoords = [Ycoords; Ycoords(1,1)];
                    Zcoords            = app.FaultOnMap(i).SegmValues(j).VertexCoord(:,3);          Zcoords = [Zcoords; Zcoords(1,1)];
                    
                    if ((i == SelectedSection) && (j == SelectedSegment)),      EdgeColor = 'red';          LineWidth = 2;
                    else,                                                       EdgeColor = 'black';        LineWidth = 1;      
                    end
                    patch(Xcoords,Ycoords,Zcoords,rand(1,3),'EdgeColor',EdgeColor, 'LineWidth', LineWidth);
                end
            end    


        end

        % Button pushed function: AutoConnectSegments_Button
        function AutoConnectSegments_ButtonPushed(app, event)
            ButtonColor = app.AutoConnectSegments_Button.BackgroundColor;
            if (app.MaxSectionID == 0),                                 return;                 end
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),          return;                 end
            %----------------------------------
            %http://www.mathebibel.de/normalenform-in-koordinatenform
            %https://www.youtube.com/watch?v=oOyB5S0v_lUs
            %ok... so the idea is  to take two neighboring segments of the same fault
            %(section) and then a) determine their schnittgerade; this can be done via
            %the fault normals and a stuetzvektor -all already given
            %formulate both faults that way, then substitute them into each other...
            %
            %but first, check if strike and dip of the two segments are the same; if
            %they are the same, it may be that there is no intersection line or an
            %infinite amount of them => both is not what I want => in those case, I
            %will simply add a small fraction to the component that is equal; this will
            %not have any relevant effect on the outcome and is basically never needed
            %anyways (b/c I trace by hand, it would be quite a coincidence to have
            %parallel strands..)
            for i = 1 : app.MaxSectionID
                SelectedSegNum = app.FaultOnMap(i).SegmentNum;
            
                for ii = 1 : SelectedSegNum-1
                    app.FaultOnMap(i).SegmValues(ii).VertexShift = zeros(4,3);
                    %---------------------------------------------------
                    SegA_RotMat    = app.FaultOnMap(i).SegmValues(ii).RotMat;
                    SegA_Normal    = app.FaultOnMap(i).SegmValues(ii).RotMat(3,:);
                    SegA_Cent      = app.FaultOnMap(i).SegmValues(ii).FaultCent';
                    SegA_dValue    = sum(SegA_Normal.*SegA_Cent);
                    SegA_Vertices  = app.FaultOnMap(i).SegmValues(ii).VertexCoord;%this is     upper-left,   lower-left,    lower-right,   upper-right
                    LowLVectPntA   = SegA_Vertices(2,:); %one of the two lower vertices serves as stuetzvektor
                    %---------------------------------------------------
                    SegB_RotMat    = app.FaultOnMap(i).SegmValues(ii+1).RotMat;
                    SegB_Normal    = app.FaultOnMap(i).SegmValues(ii+1).RotMat(3,:);
                    SegB_Cent      = app.FaultOnMap(i).SegmValues(ii+1).FaultCent';
                    SegB_dValue    = sum(SegB_Normal.*SegB_Cent);
                    SegB_Vertices  = app.FaultOnMap(i).SegmValues(ii+1).VertexCoord;%this is     upper-left,   lower-left,    lower-right,   upper-right
                    LowLVectPntB   = SegB_Vertices(2,:); %one of the two lower vertices serves as stuetzvektor
                    %---------------------------------------------------
                    LineVect       = cross(SegA_Normal,SegB_Normal); %schnittgerade ist senkrecht zu den beiden normalen => ergo...
                    if sum(LineVect) == 0     
                        SegA_Normal = SegA_Normal +rand(1,3)/1E-6; %need to check this further; may just don't do those... =>
                        %disp('needed to adjust the normal to connect points...');
                    end
                    LineVect      = cross(SegA_Normal,SegB_Normal);
                    LineVect      = LineVect./sqrt(sum(LineVect.^2));
                    %http://www.nibis.de/~lbs-gym/Vektorpdf/SchnittEbenen.pdf
                    LinePoint(1,3)     = 0; %can fix one number (assuming the fault is not horizontal!
                    LinePoint(1,2)     = (SegB_dValue/SegB_Normal(1,1) -SegA_dValue/SegA_Normal(1,1))  / (SegB_Normal(1,2)/SegB_Normal(1,1) -SegA_Normal(1,2)/SegA_Normal(1,1));
                    LinePoint(1,1)     = (SegA_dValue-SegA_Normal(1,2)*LinePoint(1,2))/SegA_Normal(1,1);
                    %---------------------------------------------------
                    %in the end really simple: since I know that the LowLVectLines are horizontal and I know their depth; I just need to figure out the
                    %point along the LineVect at which I reach that depth = >that is then my schnittpunkt... :)
                    ScaleFact_A           = LowLVectPntA(1,3)/LineVect(1,3);
                    ScaleFact_B           = LowLVectPntB(1,3)/LineVect(1,3);
                    
                    SP_SegA_LineVect      = LinePoint  + ScaleFact_A*LineVect;
                    SP_SegB_LineVect      = LinePoint  + ScaleFact_B*LineVect;
                     %---------------------------------------------------
                     MeanSP = (SP_SegA_LineVect + SP_SegB_LineVect)./2; %this is only relevant if the two faults have different fault width or dips 
                     %---------------------------------------------------
                    %rotate the MeanSP into both local coordinate systems
                    %before, subract the center position; then rotate
                    %determine distance (in local coordinates) to both lower points;
                    %the closer one will get the shift values                           
                    SP_localSegA  = SegA_RotMat*(MeanSP            -SegA_Cent)';     SP_localSegB  = SegB_RotMat*(MeanSP            -SegB_Cent)';
                    % upper-left,   lower-left,    lower-right,   upper-right
                    LLpntLoc_A    = SegA_RotMat*(SegA_Vertices(2,:)-SegA_Cent)';     LLpntLoc_B    = SegB_RotMat*(SegB_Vertices(2,:)-SegB_Cent)';
                    LRpntLoc_A    = SegA_RotMat*(SegA_Vertices(3,:)-SegA_Cent)';     LRpntLoc_B    = SegB_RotMat*(SegB_Vertices(3,:)-SegB_Cent)';
                    
                    PosDiff_A_LR  = [(SP_localSegA - LRpntLoc_A)', sqrt(sum((LinePoint - SegA_Vertices(4,:)).^2))]; %this is vector from LR to SP of A and then the length of vector from stuetzvector der schnittgerade zu den beiden oberen punkten
                    PosDiff_A_LL  = [(SP_localSegA - LLpntLoc_A)', sqrt(sum((LinePoint - SegA_Vertices(1,:)).^2))]; %this is vector from LL to SP of A and then the length of vector from stuetzvector der schnittgerade zu den beiden oberen punkten
                    PosDiff_B_LR  = [(SP_localSegB - LRpntLoc_B)', sqrt(sum((LinePoint - SegB_Vertices(4,:)).^2))]; %this is vector from LR to SP of B and then the length of vector from stuetzvector der schnittgerade zu den beiden oberen punkten
                    PosDiff_B_LL  = [(SP_localSegB - LLpntLoc_B)', sqrt(sum((LinePoint - SegB_Vertices(1,:)).^2))]; %this is vector from LL to SP of B and then the length of vector from stuetzvector der schnittgerade zu den beiden oberen punkten
                    %---------------------------------------------------
                    if (PosDiff_A_LR(1,4) < PosDiff_A_LL(1,4)),     app.FaultOnMap(i).SegmValues(ii).VertexShift(3,:)   = PosDiff_A_LR(1,1:3);
                    else,                                           app.FaultOnMap(i).SegmValues(ii).VertexShift(2,:)   = PosDiff_A_LL(1,1:3);
                    end
                    if (PosDiff_B_LR(1,4) < PosDiff_B_LL(1,4)),     app.FaultOnMap(i).SegmValues(ii+1).VertexShift(3,:) = PosDiff_B_LR(1,1:3);
                    else,                                           app.FaultOnMap(i).SegmValues(ii+1).VertexShift(2,:) = PosDiff_B_LL(1,1:3);
                    end
                    %---------------------------------------------------
                    
                    if ii == 2
                        SegC_RotMat       = app.FaultOnMap(i).SegmValues(ii-1).RotMat;
                        SegC_Normal       = app.FaultOnMap(i).SegmValues(ii-1).RotMat(3,:);
                        SegC_Cent         = app.FaultOnMap(i).SegmValues(ii-1).FaultCent';
                        SegC_dValue       = sum(SegC_Normal.*SegC_Cent);
                        SegC_Vertices     = app.FaultOnMap(i).SegmValues(ii-1).VertexCoord;%this is     upper-left,   lower-left,    lower-right,   upper-right
                        LowLVectPntC      = SegC_Vertices(2,:); %one of the two lower vertices serves as stuetzvektor
                        %-------------------
                        LineVect2         = cross(SegA_Normal,SegC_Normal); %schnittgerade ist senkrecht zu den beiden normalen => ergo...
                        if sum(LineVect2) == 0,      
                            SegA_Normal   = SegA_Normal +rand(1,3)/1E-6; %need to check this further; may just don't do those... =>
                            disp('needed to adjust the normal to connect points...');
                        end
                        LineVect2         = cross(SegA_Normal,SegC_Normal);
                        LineVect2         = LineVect2./sqrt(sum(LineVect2.^2));
                        %-------------------
                        LinePoint2(1,3)   = 0; %can fix one number (assuming the fault is not horizontal!
                        LinePoint2(1,2)   = (SegC_dValue/SegC_Normal(1,1) -SegA_dValue/SegA_Normal(1,1))  / (SegC_Normal(1,2)/SegC_Normal(1,1) -SegA_Normal(1,2)/SegA_Normal(1,1));
                        LinePoint2(1,1)   = (SegA_dValue-SegA_Normal(1,2)*LinePoint2(1,2))/SegA_Normal(1,1);
                        ScaleFact_A2      = LowLVectPntA(1,3)/LineVect2(1,3);
                        ScaleFact_C       = LowLVectPntC(1,3)/LineVect2(1,3);
                        SP_SegA2_LineVect2= LinePoint2 + ScaleFact_A2*LineVect2;
                        SP_SegC_LineVect2 = LinePoint2 + ScaleFact_C*LineVect2;
                        
                        MeanSP_2          = (SP_SegA2_LineVect2 + SP_SegC_LineVect2)./2; %this is only relevant if the two faults have different fault width or dips 
                        SP_localSegA2     = SegA_RotMat*(MeanSP_2          -SegA_Cent)';        SP_localSegC  = SegC_RotMat*(MeanSP_2          -SegC_Cent)';
                        LLpntLoc_C        = SegC_RotMat*(SegC_Vertices(2,:)-SegC_Cent)';        LRpntLoc_C    = SegC_RotMat*(SegC_Vertices(3,:)-SegC_Cent)';
            
                        PosDiff_A2_LR = [(SP_localSegA2 - LRpntLoc_A)', sqrt(sum((LinePoint2 - SegA_Vertices(4,:)).^2))]; %this is vector from LR to SP of A and then the length of vector from stuetzvector der schnittgerade zu den beiden oberen punkten
                        PosDiff_A2_LL = [(SP_localSegA2 - LLpntLoc_A)', sqrt(sum((LinePoint2 - SegA_Vertices(1,:)).^2))]; %this is vector from LL to SP of A and then the length of vector from stuetzvector der schnittgerade zu den beiden oberen punkten
                        PosDiff_C_LR  = [(SP_localSegC - LRpntLoc_C)', sqrt(sum((LinePoint2 - SegC_Vertices(4,:)).^2))]; %this is vector from LR to SP of B and then the length of vector from stuetzvector der schnittgerade zu den beiden oberen punkten
                        PosDiff_C_LL  = [(SP_localSegC - LLpntLoc_C)', sqrt(sum((LinePoint2 - SegC_Vertices(1,:)).^2))]; %this is vector from LL to SP of B and then the length of vector from stuetzvector der schnittgerade zu den beiden oberen punkten
                        %---------------------------------------------------
                        if (PosDiff_A2_LR(1,4) < PosDiff_A2_LL(1,4)),   app.FaultOnMap(i).SegmValues(ii).VertexShift(3,:)   = PosDiff_A2_LR(1,1:3);
                        else,                                           app.FaultOnMap(i).SegmValues(ii).VertexShift(2,:)   = PosDiff_A2_LL(1,1:3);
                        end
                        if (PosDiff_C_LR(1,4) < PosDiff_C_LL(1,4)),     app.FaultOnMap(i).SegmValues(ii-1).VertexShift(3,:) = PosDiff_C_LR(1,1:3);
                        else,                                           app.FaultOnMap(i).SegmValues(ii-1).VertexShift(2,:) = PosDiff_C_LL(1,1:3);
                        end
                        %---------------------------------------------------
                    end    
                end
            end     
            app.PlotConnectedFaults_Button.BackgroundColor = app.GreenButton;
        end

        % Button pushed function: SaveFaultModel_Button
        function SaveFaultModel_ButtonPushed(app, event)
            ButtonColor = app.SaveFaultModel_Button.BackgroundColor;
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),        return;           end
            if (app.MaxSectionID == 0),                                 return;           end
            [DataName,DataPath]  = uiputfile('*.*','Save Fault Model setup');
            if     (DataName ~=0),      DataName = strtok(DataName,'.');        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            %-----------------------
            Name4ascii  = strcat(CompletePath,'_FLT.txt');
            fidascii    = fopen(Name4ascii ,'w');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            %----------------------------------------------------------------
            FltNames   = app.AllSegmNames;
            FltNum     = size(FltNames,2);
            FltCounter = 0;
            %-----------------------
            fprintf(fidascii,'Number_of_fault_segments: %d\n',FltNum);
            fprintf(fidascii,'Combined_number_of_trig_patches_and_vertices:  0 0\n');
            fprintf(fidascii,'Mean_leg_length_and_area:  0.0 0.0\n');

            
            for i = 1 : app.MaxSectionID
                for j = 1 : app.FaultOnMap(i).SegmentNum
                    FltCounter = FltCounter +1;   
                    fprintf(fidascii,'Fault_ID_number: %d\n',i);
                    fprintf(fidascii,'Fault_section_name: %s\n',FltNames{1,FltCounter});
                    fprintf(fidascii,'Easting_of_fault_top_center:  %10.4e\n',app.FaultOnMap(i).SegmValues(j).FaultCent(1,1));
                    fprintf(fidascii,'Northing_of_fault_top_center: %10.4e\n',app.FaultOnMap(i).SegmValues(j).FaultCent(2,1));
                    fprintf(fidascii,'Depth_of_fault_top_center:    %10.4e\n',app.FaultOnMap(i).SegmValues(j).FaultCent(3,1));
                    fprintf(fidascii,'Length_of_fault_section:      %10.4e\n',app.FaultOnMap(i).SegmValues(j).Length);
                    fprintf(fidascii,'Width_of_fault_section:       %10.4e\n',app.FaultOnMap(i).SegmValues(j).Width);
                    fprintf(fidascii,'Strike_of_fault_section:      %10.4e\n',app.FaultOnMap(i).SegmValues(j).Strike*180/pi);
                    fprintf(fidascii,'Dip_of_fault_section:         %10.4e\n',app.FaultOnMap(i).SegmValues(j).Dip*180/pi);
                    fprintf(fidascii,'SlipRate_of_fault_section:    %10.4e\n',app.FaultOnMap(i).SegmValues(j).SlipRate);
                    fprintf(fidascii,'Rake_of_fault_section:        %10.4e\n',app.FaultOnMap(i).SegmValues(j).Rake);
                    fprintf(fidascii,'StressRate_of_fault_section:  0.0000e+00\n');
                    fprintf(fidascii,'Shift_of_lowerleft_vertex_(E/N/Z):    %10.4e   %10.4e   %10.4e\n',app.FaultOnMap(i).SegmValues(j).VertexShift(2,:));
                    fprintf(fidascii,'Shift_of_lowerright_vertex_(E/N/Z):   %10.4e   %10.4e   %10.4e\n',app.FaultOnMap(i).SegmValues(j).VertexShift(3,:));
                    fprintf(fidascii,'Shift_of_upperright_vertex_(E/N/Z):   %10.4e   %10.4e   %10.4e\n',app.FaultOnMap(i).SegmValues(j).VertexShift(4,:));
                    fprintf(fidascii,'Shift_of_upperleft_vertex_(E/N/Z):    %10.4e   %10.4e   %10.4e\n',app.FaultOnMap(i).SegmValues(j).VertexShift(1,:));
                    fprintf(fidascii,'Triangle_patch_number_of_section:   0\n');
                    fprintf(fidascii,'Triangle_vertex_number_of_section:  0\n');
                    fprintf(fidascii,'\n');      
                end
            end
        end

        % Value changed function: TracedFaults_ListBox
        function TracedFaults_ListBoxValueChanged(app, event)
            value           = app.TracedFaults_ListBox.Value;
            SelectedSection = app.SegmRelation(value,1);
            SelectedSegment = app.SegmRelation(value,2);
            app.FaultOrSeisWidth_DropDown.Value = num2str(1);
            app.Width_EditField.Value           = app.FaultOnMap(SelectedSection).SegmValues(SelectedSegment).Width; 
            app.FaultDip_EditField.Value        = app.FaultOnMap(SelectedSection).SegmValues(SelectedSegment).Dip*180/pi;  
            app.SlipRate_EditField.Value        = app.FaultOnMap(SelectedSection).SegmValues(SelectedSegment).SlipRate;
            app.SlipRake_EditField.Value        = app.FaultOnMap(SelectedSection).SegmValues(SelectedSegment).Rake; 
        end

        % Button pushed function: RemoveSelSection_Button
        function RemoveSelSection_ButtonPushed(app, event)
            if (app.MaxSectionID == 0),         return;             end

            button = questdlg('Really delete fault section?','Delete Fault','Yes','No','No');
            if (strcmp(button,'Yes') == 0),         return;             end
            %-------------------------------------------------- 
            value           = app.TracedFaults_ListBox.Value;
            RemovedSection  = app.SegmRelation(value,1);
            %--------------------------------------------------           
            if ~isempty(app.FaultOnMap(RemovedSection).MapLine),        delete(app.FaultOnMap(RemovedSection).MapLine);           end  
            if ~isempty(app.FaultOnMap(RemovedSection).MapPnts),        delete(app.FaultOnMap(RemovedSection).MapPnts);           end  
            %-------------------------------------------------- 
            CombSegmentNum  = size(app.SegmRelation,1);
            NewSectionNum   = app.MaxSectionID -1;     
            NewSegNames     = [];
            if (NewSectionNum == 0)
                %-------------------------------------------------- 
                app.AllSegmNames               = {'No segment traced'};
                app.SegmRelation               = [];
                app.FaultOnMap(RemovedSection) = [];
                app.MaxSectionID               = 0;
                %-------------------------------------------------- 
                app.TracedFaults_ListBox.Items     = app.AllSegmNames;
                app.TracedFaults_ListBox.ItemsData = 1:size(app.AllSegmNames,2);
                app.TracedFaults_ListBox.Value     = 1;
            else
                SegmentCounter = 0;
                SkippedValue   = 0;
                for i = 1 : CombSegmentNum
                    SegsSection  = app.SegmRelation(i,1);
                    SegsSegment  = app.SegmRelation(i,2);
                    if (SegsSection ~= RemovedSection)
                        SegmentCounter              = SegmentCounter +1;
                        NewSegNames{SegmentCounter} = app.FaultOnMap(SegsSection).SegmValues(SegsSegment).SegmName;
                        %------------------------
                        if (SkippedValue == 0),         NewSegRelat(SegmentCounter,:) = [SegsSection,   SegsSegment];
                        else,                           NewSegRelat(SegmentCounter,:) = [SegsSection-1, SegsSegment];
                        end
                        %------------------------
                    else
                        SkippedValue = 1;
                    end  
                end   
                %-------------------------------------------------- 
                app.SegmRelation               = NewSegRelat;
                app.AllSegmNames               = NewSegNames;
                app.FaultOnMap(RemovedSection) = [];
                app.MaxSectionID               = app.MaxSectionID - 1;
                %-------------------------------------------------- 
                app.TracedFaults_ListBox.Items     = app.AllSegmNames;
                app.TracedFaults_ListBox.ItemsData = 1:size(app.AllSegmNames,2);
                app.TracedFaults_ListBox.Value     = 1;
            end 
        end

        % Button pushed function: PlotConnectedFaults_Button
        function PlotConnectedFaults_ButtonPushed(app, event)

            ButtonColor = app.PlotConnectedFaults_Button.BackgroundColor;
            if (app.MaxSectionID == 0),                             return;                 end
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),    return;                 end
            %----------------------------------
            AxEqual = app.AxisEqual_CheckBox.Value;
            FigNum  = app.FigNumFaults_EditField.Value;
            %----------------------------------
            figure(FigNum); clf; hold on; 
            if (AxEqual == 1),              axis equal;                     end
            %----------------------------------
            SelectedPos     = app.TracedFaults_ListBox.Value;
            SelectedSection = app.SegmRelation(SelectedPos,1);
            SelectedSegment = app.SegmRelation(SelectedPos,2);

            for i = 1 : app.MaxSectionID
                for j = 1 : app.FaultOnMap(i).SegmentNum
                    
                    FaultLength = app.FaultOnMap(i).SegmValues(j).Length;
                    FaultWidth  = app.FaultOnMap(i).SegmValues(j).Width;
                    FaultCent   = app.FaultOnMap(i).SegmValues(j).FaultCent;
                    %-----------------------% upper-left,   lower-left,    lower-right,   upper-right
                    FltPntStartTop_Loc = [-FaultLength/2           0 0] +app.FaultOnMap(i).SegmValues(j).VertexShift(1,:); %this is actually dummy -top positions never changed... is here just for clarity of code structure
                    FltPntStartBot_Loc = [-FaultLength/2 -FaultWidth 0] +app.FaultOnMap(i).SegmValues(j).VertexShift(2,:);
                    FltPntEndBot_Loc   = [+FaultLength/2 -FaultWidth 0] +app.FaultOnMap(i).SegmValues(j).VertexShift(3,:);
                    FltPntEndTop_Loc   = [+FaultLength/2           0 0] +app.FaultOnMap(i).SegmValues(j).VertexShift(4,:); %this is actually dummy -top positions never changed...
            
                    FltPntStartTop     = app.FaultOnMap(i).SegmValues(j).RotMat' *FltPntStartTop_Loc' +FaultCent;
                    FltPntStartBot     = app.FaultOnMap(i).SegmValues(j).RotMat' *FltPntStartBot_Loc' +FaultCent;
                    FltPntEndTop       = app.FaultOnMap(i).SegmValues(j).RotMat' *FltPntEndTop_Loc'   +FaultCent;
                    FltPntEndBot       = app.FaultOnMap(i).SegmValues(j).RotMat' *FltPntEndBot_Loc'   +FaultCent;
                    %--------------------------------------------------
                    Xcoords            = [FltPntStartTop(1,1), FltPntStartBot(1,1), FltPntEndBot(1,1), FltPntEndTop(1,1), FltPntStartTop(1,1)];
                    Ycoords            = [FltPntStartTop(2,1), FltPntStartBot(2,1), FltPntEndBot(2,1), FltPntEndTop(2,1), FltPntStartTop(2,1)];
                    Zcoords            = [FltPntStartTop(3,1), FltPntStartBot(3,1), FltPntEndBot(3,1), FltPntEndTop(3,1), FltPntStartTop(3,1)];
                    %--------------------------------------------------
                    if ((i == SelectedSection) && (j == SelectedSegment)),      EdgeColor = 'red';          LineWidth = 1;
                    else,                                                       EdgeColor = 'black';        LineWidth = 1;      
                    end
                    %--------------------------------------------------
                    patch(Xcoords,Ycoords,Zcoords,rand(1,3),'EdgeColor',EdgeColor, 'LineWidth', LineWidth);
                end
            end   
        end

        % Button pushed function: LoadFaultModel_Button
        function LoadFaultModel_ButtonPushed(app, event)
            FigNumMap   = app.FigNumMap_EditField.Value;
            %-----------------------------------------------
            ButtonColor = app.LoadFaultModel_Button.BackgroundColor;
            if (abs(sum(ButtonColor - app.GreenButton)) > 1E-3),          return;                 end
            %-----------------------------------------------    
            [DataName,DataPath]  = uigetfile('*_FLT.txt','Load Fault Model setup');
            if     (DataName ~=0),      DataName = extractBefore(DataName,"_FLT.txt");        CompletePath = strcat(DataPath,DataName); 
            elseif (DataName ==0),      return;         
            end
            %-----------------------
            Name4ascii  = strcat(CompletePath,'_FLT.txt');
            fidascii    = fopen(Name4ascii ,'r');
            if (fidascii == -1), errordlg('The entered filename has not been found.');    return;   end
            %----------------------------------------------------------------
            nextline               = fgetl(fidascii);               FltNum             = sscanf(nextline,'%*s %d');  
            %-----------------------%-----------------------%-----------------------
            nextline         = fgetl(fidascii);      
            nextline         = fgetl(fidascii);           
            nextline         = fgetl(fidascii);
            %-----------------------%-----------------------%-----------------------
            app.AllSegmNames  = {};
            app.SegmRelation  = [];
            app.FaultOnMap    = struct;
            app.MaxSectionID  = 1;
            SegmentCounter    = 0;
            TempVals          = [];         
            TempHandlePt      = [];            
            TempHandleLine    = [];   
            
            figure(FigNumMap); hold on;
            
            for i = 1 : FltNum
                nextline      = fgetl(fidascii);                                               SectionID         = sscanf(nextline,'%*s %d');     
                if (SectionID == app.MaxSectionID)    
                    SegmentCounter                              = SegmentCounter+1;      
                    app.FaultOnMap(app.MaxSectionID).SegmentNum = SegmentCounter;
                else                                  
                    SegmentCounter    = 1;                     
                    app.MaxSectionID  = app.MaxSectionID +1;
                    TempVals          = [];         
                    TempHandlePt      = [];            
                    TempHandleLine    = [];   
                end
                
                app.SegmRelation(i,:) = [app.MaxSectionID, SegmentCounter];
                FltSecName            = extractAfter(fgetl(fidascii)," ");                           
                app.AllSegmNames{i}   = FltSecName;
                app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).SegmName = FltSecName;
                
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).FaultCent(1,1)     = sscanf(nextline,'%*s %e');
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).FaultCent(2,1)     = sscanf(nextline,'%*s %e');
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).FaultCent(3,1)     = sscanf(nextline,'%*s %e');
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Length             = sscanf(nextline,'%*s %e');
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Width              = sscanf(nextline,'%*s %e');
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Strike             = sscanf(nextline,'%*s %e');
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Dip                = sscanf(nextline,'%*s %e');
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).SlipRate           = sscanf(nextline,'%*s %e');
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Rake               = sscanf(nextline,'%*s %e');
                nextline       = fgetl(fidascii);
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).VertexShift(2,:)   = sscanf(nextline,'%*s %e %e %e')';
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).VertexShift(3,:)   = sscanf(nextline,'%*s %e %e %e')';
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).VertexShift(4,:)   = sscanf(nextline,'%*s %e %e %e')';
                nextline       = fgetl(fidascii);         app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).VertexShift(1,:)   = sscanf(nextline,'%*s %e %e %e')';
                nextline       = fgetl(fidascii);
                nextline       = fgetl(fidascii);           
                nextline       = fgetl(fidascii);
                %---------------------------------------------
                app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Strike = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Strike *pi/180;
                app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Dip    = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Dip *pi/180;
                
                FaultLength = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Length;
                FaultWidth  = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Width;
                FaultCent   = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).FaultCent;
                FaultStrike = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Strike;
                FaultDip    = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).Dip;
                
                if (FaultStrike < 0),   FaultStrike = 2*pi + FaultStrike;           end
                %---------------------------------------------
                LocalY             = [sin(FaultStrike), cos(FaultStrike), 0]; %is strike dir
                LocalX             = [sin(FaultDip)*cos(FaultStrike), -sin(FaultDip)*sin(FaultStrike), cos(FaultDip)]; %is fault normal
                LocalZ             = cross(LocalX,LocalY); %is up-dip dir
                RotMat             = [LocalY;LocalZ;LocalX];
                app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).RotMat = RotMat;
                %-----------------------% upper-left,   lower-left,    lower-right,   upper-right
                FltPntStartTop_Loc = [-FaultLength/2           0 0];
                FltPntStartBot_Loc = [-FaultLength/2 -FaultWidth 0];
                FltPntEndBot_Loc   = [+FaultLength/2 -FaultWidth 0];
                FltPntEndTop_Loc   = [+FaultLength/2           0 0];
                FltPntStartTop     = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).RotMat' *FltPntStartTop_Loc' +FaultCent;
                FltPntStartBot     = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).RotMat' *FltPntStartBot_Loc' +FaultCent;
                FltPntEndTop       = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).RotMat' *FltPntEndTop_Loc'   +FaultCent;
                FltPntEndBot       = app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).RotMat' *FltPntEndBot_Loc'   +FaultCent;
                
                app.FaultOnMap(app.MaxSectionID).SegmValues(SegmentCounter).VertexCoord = [FltPntStartTop'; FltPntStartBot'; FltPntEndBot';FltPntEndTop'];
                %--------------------------------------------------
                TempVals      = [TempVals; (FltPntStartTop(1,1)-app.Evect(1,1))/app.dE, (FltPntStartTop(2,1)-app.Nvect(1,1))/app.dN];
                TempHandle    = plot(TempVals(end,1), TempVals(end,2),'o','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',6);
                TempHandlePt  = [TempHandlePt; TempHandle];
                
                TempVals      = [TempVals; (FltPntEndTop(1,1)-app.Evect(1,1))/app.dE,   (FltPntEndTop(2,1)-app.Nvect(1,1))/app.dN];
                TempHandle    = plot(TempVals(end,1), TempVals(end,2),'o','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',6);
                TempHandlePt  = [TempHandlePt; TempHandle];
              
                TempHandle    = plot([TempVals(end-1,1), TempVals(end,1)],[TempVals(end-1,2), TempVals(end,2)],'-r','linewidth',2);
                TempHandleLine= [TempHandleLine; TempHandle];
             
                %--------------------------------------------------
                app.FaultOnMap(app.MaxSectionID).MapLine     = TempHandleLine;
                app.FaultOnMap(app.MaxSectionID).MapPnts     = TempHandlePt;
                app.FaultOnMap(app.MaxSectionID).ThePoints   = TempVals;  
            end
            fclose(fidascii);  
            %----------------------------------------------
            app.TracedFaults_ListBox.Items     = app.AllSegmNames;
            app.TracedFaults_ListBox.ItemsData = 1:size(app.AllSegmNames,2);
            app.TracedFaults_ListBox.Value     = 1;
           
            app.PlotDigitizedFaults_Button.BackgroundColor = app.GreenButton;
            app.AutoConnectSegments_Button.BackgroundColor = app.GreenButton;
            app.PlotConnectedFaults_Button.BackgroundColor = app.GreenButton;
            app.SaveFaultModel_Button.BackgroundColor      = app.GreenButton;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FigEditFieldLabel_3 = uilabel(app.UIFigure);
            app.FigEditFieldLabel_3.Text = 'Fig #';
            app.FigNumFaults_EditField = uieditfield(app.UIFigure, 'numeric');
            app.FigNumFaults_EditField.Limits = [0 Inf];
            app.FigNumFaults_EditField.RoundFractionalValues = 'on';
            app.FigNumFaults_EditField.ValueDisplayFormat = '%d';
            app.FigNumFaults_EditField.Value = 3;
            app.AxisEqual_CheckBox = uicheckbox(app.UIFigure);
            app.AxisEqual_CheckBox.Text = 'axis equal';
            app.AxisEqual_CheckBox.Value = true;
            app.TracedfaultsListBoxLabel = uilabel(app.UIFigure);
            app.TracedfaultsListBoxLabel.Text = 'Traced faults';
            app.TracedFaults_ListBox = uilistbox(app.UIFigure);
            app.TracedFaults_ListBox.Items = {'no fault traced'};
            app.TracedFaults_ListBox.ValueChangedFcn = createCallbackFcn(app, @TracedFaults_ListBoxValueChanged, true);
            app.TracedFaults_ListBox.Value = 'no fault traced';
            app.PlotDigitizedFaults_Button = uibutton(app.UIFigure, 'push');
            app.PlotDigitizedFaults_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotDigitizedFaults_ButtonPushed, true);
            app.PlotDigitizedFaults_Button.Text = '3.5) Plot digitized faults';
            app.Panel = uipanel(app.UIFigure);
            app.LoadFaultMap_Button = uibutton(app.Panel, 'push');
            app.LoadFaultMap_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadFaultMap_ButtonPushed, true);
            app.LoadFaultMap_Button.Text = '0) Load fault map';
            app.PlotFaultMap_Button = uibutton(app.Panel, 'push');
            app.PlotFaultMap_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotFaultMap_ButtonPushed, true);
            app.PlotFaultMap_Button.Text = '1) Plot fault map';
            app.maxNorthingButton = uibutton(app.Panel, 'push');
            app.maxNorthingButton.ButtonPushedFcn = createCallbackFcn(app, @maxNorthingButtonPushed, true);
            app.maxNorthingButton.Text = '2) max. Northing';
            app.minNorthingButton = uibutton(app.Panel, 'push');
            app.minNorthingButton.ButtonPushedFcn = createCallbackFcn(app, @minNorthingButtonPushed, true);
            app.minNorthingButton.Text = '2) min. Northing';
            app.minEastingButton = uibutton(app.Panel, 'push');
            app.minEastingButton.ButtonPushedFcn = createCallbackFcn(app, @minEastingButtonPushed, true);
            app.minEastingButton.Text = '2) min. Easting';
            app.maxEastingButton = uibutton(app.Panel, 'push');
            app.maxEastingButton.ButtonPushedFcn = createCallbackFcn(app, @maxEastingButtonPushed, true);
            app.maxEastingButton.Text = '2) max. Easting';
            app.kmEditFieldLabel = uilabel(app.Panel);
            app.kmEditFieldLabel.Text = 'km';
            app.MaxNorthing_EditField = uieditfield(app.Panel, 'numeric');
            app.MaxNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.MaxNorthing_EditField.Value = 100;
            app.kmEditField_2Label = uilabel(app.Panel);
            app.kmEditField_2Label.Text = 'km';
            app.MinNorthing_EditField = uieditfield(app.Panel, 'numeric');
            app.MinNorthing_EditField.ValueDisplayFormat = '%6.3f';
            app.kmEditField_3Label = uilabel(app.Panel);
            app.kmEditField_3Label.Text = 'km';
            app.MinEasting_EditField = uieditfield(app.Panel, 'numeric');
            app.MinEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.kmEditField_4Label = uilabel(app.Panel);
            app.kmEditField_4Label.Text = 'km';
            app.MaxEasting_EditField = uieditfield(app.Panel, 'numeric');
            app.MaxEasting_EditField.ValueDisplayFormat = '%6.3f';
            app.MaxEasting_EditField.Value = 100;
            app.FigEditFieldLabel = uilabel(app.Panel);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.FigNumMap_EditField = uieditfield(app.Panel, 'numeric');
            app.FigNumMap_EditField.Limits = [0 Inf];
            app.FigNumMap_EditField.RoundFractionalValues = 'on';
            app.FigNumMap_EditField.ValueDisplayFormat = '%d';
            app.FigNumMap_EditField.Value = 2;
            app.LoadFaultModel_Button = uibutton(app.Panel, 'push');
            app.LoadFaultModel_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadFaultModel_ButtonPushed, true);
            app.LoadFaultModel_Button.Text = 'Load fault model';
            app.Panel_2 = uipanel(app.UIFigure);
            app.TraceFaults_Button = uibutton(app.Panel_2, 'push');
            app.TraceFaults_Button.ButtonPushedFcn = createCallbackFcn(app, @TraceFaults_ButtonPushed, true);
            app.TraceFaults_Button.Text = '3) Trace faults';
            app.kmLabel = uilabel(app.Panel_2);
            app.kmLabel.Text = 'km';
            app.Width_EditField = uieditfield(app.Panel_2, 'numeric');
            app.Width_EditField.Limits = [0 Inf];
            app.Width_EditField.ValueDisplayFormat = '%5.2f';
            app.Width_EditField.Value = 15;
            app.DipdegLabel = uilabel(app.Panel_2);
            app.DipdegLabel.Text = 'Dip (deg.)';
            app.FaultDip_EditField = uieditfield(app.Panel_2, 'numeric');
            app.FaultDip_EditField.Limits = [0 90];
            app.FaultDip_EditField.ValueDisplayFormat = '%4.2f';
            app.FaultDip_EditField.Value = 90;
            app.NameEditFieldLabel = uilabel(app.Panel_2);
            app.NameEditFieldLabel.Text = 'Name';
            app.Name_EditField = uieditfield(app.Panel_2, 'text');
            app.Name_EditField.Value = 'fault';
            app.TextArea_2 = uitextarea(app.Panel_2);
            app.TextArea_2.Value = {'For tracing: "left-mouse" => add point; "right-mouse" => remove point; "space" => stop tracing'};
            app.FaultOrSeisWidth_DropDown = uidropdown(app.Panel_2);
            app.FaultOrSeisWidth_DropDown.Items = {'Fault width', 'Seis. width'};
            app.FaultOrSeisWidth_DropDown.ItemsData = {'1', '2'};
            app.FaultOrSeisWidth_DropDown.Value = '1';
            app.RakedegLabel = uilabel(app.Panel_2);
            app.RakedegLabel.Text = 'Rake (deg.)';
            app.SlipRake_EditField = uieditfield(app.Panel_2, 'numeric');
            app.SlipRake_EditField.Limits = [0 Inf];
            app.SlipRake_EditField.ValueDisplayFormat = '%5.2f';
            app.SlipratemmyrLabel = uilabel(app.Panel_2);
            app.SlipratemmyrLabel.Text = 'Slip-rate (mm/yr)';
            app.SlipRate_EditField = uieditfield(app.Panel_2, 'numeric');
            app.SlipRate_EditField.Limits = [0 Inf];
            app.SlipRate_EditField.ValueDisplayFormat = '%5.2f';
            app.SlipRate_EditField.Value = 10;
            app.TextArea = uitextarea(app.Panel_2);
            app.TextArea.Value = {'Assign to each fault section:'};
            app.AutoConnectSegments_Button = uibutton(app.UIFigure, 'push');
            app.AutoConnectSegments_Button.ButtonPushedFcn = createCallbackFcn(app, @AutoConnectSegments_ButtonPushed, true);
            app.AutoConnectSegments_Button.Text = '4) Auto-connect segments';
            app.SaveFaultModel_Button = uibutton(app.UIFigure, 'push');
            app.SaveFaultModel_Button.ButtonPushedFcn = createCallbackFcn(app, @SaveFaultModel_ButtonPushed, true);
            app.SaveFaultModel_Button.Text = '5) Save fault model';
            app.RemoveSelSection_Button = uibutton(app.UIFigure, 'push');
            app.RemoveSelSection_Button.ButtonPushedFcn = createCallbackFcn(app, @RemoveSelSection_ButtonPushed, true);
            app.RemoveSelSection_Button.Text = {'remove sel. fault'; ''};
            app.PlotConnectedFaults_Button = uibutton(app.UIFigure, 'push');
            app.PlotConnectedFaults_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotConnectedFaults_ButtonPushed, true);
            app.PlotConnectedFaults_Button.Text = '4.5) Plot connected segs.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Z_DigitizeFaults_MCQsim_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end