classdef R2_LoadPlotResults_Apr2022 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LoadandparsecatalogPanel     matlab.ui.container.Panel
        LoadCatalog_Button           matlab.ui.control.Button
        removeEQswithlessthanLabel   matlab.ui.control.Label
        LoadCatRemoveEQ_EditField    matlab.ui.control.NumericEditField
        LoadCatGroupTime_CheckBox    matlab.ui.control.CheckBox
        LoadCatGroupDist_CheckBox    matlab.ui.control.CheckBox
        maxdistEditFieldLabel        matlab.ui.control.Label
        LoadCatMaxDist_EditField     matlab.ui.control.NumericEditField
        progressLabel                matlab.ui.control.Label
        LoadCat_Lamp                 matlab.ui.control.Lamp
        meanleglengthLabel           matlab.ui.control.Label
        LoadMeanLegLegth_EditField   matlab.ui.control.NumericEditField
        minpatchslipLabel            matlab.ui.control.Label
        LoadMinPatchSlip_EditField   matlab.ui.control.NumericEditField
        catalogLabel                 matlab.ui.control.Label
        SelectCatalog_edit           matlab.ui.control.NumericEditField
        ofLabel                      matlab.ui.control.Label
        TotalCatalogs_edit           matlab.ui.control.NumericEditField
        EQnumberLabel                matlab.ui.control.Label
        EQnumber_EditField           matlab.ui.control.NumericEditField
        starttimeLabel               matlab.ui.control.Label
        StartTime_EditField          matlab.ui.control.NumericEditField
        endtimeLabel                 matlab.ui.control.Label
        EndTime_EditField            matlab.ui.control.NumericEditField
        andmagnitudesbetweenLabel    matlab.ui.control.Label
        MinMagn_EditField            matlab.ui.control.NumericEditField
        andLabel                     matlab.ui.control.Label
        MaxMagn_EditField            matlab.ui.control.NumericEditField
        PlotperpatchinformationmeanandvariabilityofselparameterPanel  matlab.ui.container.Panel
        PlotOnFault_Button           matlab.ui.control.Button
        PlotComp2Plot_DropDown       matlab.ui.control.DropDown
        FigEditFieldLabel            matlab.ui.control.Label
        PlotFigNum_EditField         matlab.ui.control.NumericEditField
        viewangleEditFieldLabel      matlab.ui.control.Label
        PlotViewAngle_EditField      matlab.ui.control.EditField
        ColormapDropDownLabel        matlab.ui.control.Label
        PlotColorMap_DropDown        matlab.ui.control.DropDown
        holdonCheckBox               matlab.ui.control.CheckBox
        CalcPerPatch_Button          matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        PlotMagVsFreq_Button         matlab.ui.control.Button
        PlotTimeVsMagn_Button        matlab.ui.control.Button
        holdon1CheckBox              matlab.ui.control.CheckBox
        FigEditFieldLabel_2          matlab.ui.control.Label
        PlotFigNum1_EditField        matlab.ui.control.NumericEditField
        PlotMagVsRuptArea_Button     matlab.ui.control.Button
        PlotnormSlipvsTime_Button    matlab.ui.control.Button
        binLabel                     matlab.ui.control.Label
        PlotBinNum_EditField         matlab.ui.control.NumericEditField
        PlotInterEventTimes_Button   matlab.ui.control.Button
        rangingfromLabel             matlab.ui.control.Label
        PlotBinMinValue_EditField    matlab.ui.control.NumericEditField
        toLabel                      matlab.ui.control.Label
        PlotBinMaxValue_EditField    matlab.ui.control.NumericEditField
        PlotMeanSlip_Button          matlab.ui.control.Button
        SelectSinglePatch_Button     matlab.ui.control.Button
        SelectHighlightPatch_Button  matlab.ui.control.Button
        SelectPlotSlipVsTime_Button  matlab.ui.control.Button
        PatchLabel                   matlab.ui.control.Label
        SelectPatchID_edit           matlab.ui.control.NumericEditField
        onfaultsectionLabel          matlab.ui.control.Label
        Select_onSectn_EditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        BlueWhiteRed = [0.0784    0.1686    0.5490;  0         0    1.0000;      0.0333    0.0333    1.0000; 0.0667    0.0667    1.0000;  0.1000    0.1000    1.0000; 0.1333    0.1333    1.0000;...
                        0.1667    0.1667    1.0000;  0.2000    0.2000    1.0000; 0.2333    0.2333    1.0000; 0.2667    0.2667    1.0000;  0.3000    0.3000    1.0000; 0.3333    0.3333    1.0000;...
                        0.3667    0.3667    1.0000;  0.4000    0.4000    1.0000; 0.4333    0.4333    1.0000; 0.4667    0.4667    1.0000;  0.5000    0.5000    1.0000; 0.5333    0.5333    1.0000;...
                        0.5667    0.5667    1.0000;  0.6000    0.6000    1.0000; 0.6333    0.6333    1.0000; 0.6667    0.6667    1.0000;  0.7000    0.7000    1.0000; 0.7333    0.7333    1.0000;...
                        0.7667    0.7667    1.0000;  0.8000    0.8000    1.0000; 0.8333    0.8333    1.0000; 0.8667    0.8667    1.0000;  0.9000    0.9000    1.0000; 0.9333    0.9333    1.0000;...
                        0.9667    0.9667    1.0000;  1.0000    1.0000    1.0000; 1.0000    0.9677    0.9677; 1.0000    0.9355    0.9355;  1.0000    0.9032    0.9032; 1.0000    0.8710    0.8710;...
                        1.0000    0.8387    0.8387;  1.0000    0.8065    0.8065; 1.0000    0.7742    0.7742; 1.0000    0.7419    0.7419;  1.0000    0.7097    0.7097; 1.0000    0.6774    0.6774;...
                        1.0000    0.6452    0.6452;  1.0000    0.6129    0.6129; 1.0000    0.5806    0.5806; 1.0000    0.5484    0.5484;  1.0000    0.5161    0.5161; 1.0000    0.4839    0.4839;...
                        1.0000    0.4516    0.4516;  1.0000    0.4194    0.4194; 1.0000    0.3871    0.3871; 1.0000    0.3548    0.3548;  1.0000    0.3226    0.3226; 1.0000    0.2903    0.2903;...
                        1.0000    0.2581    0.2581;  1.0000    0.2258    0.2258; 1.0000    0.1935    0.1935; 1.0000    0.1613    0.1613;  1.0000    0.1290    0.1290; 1.0000    0.0968    0.0968;...
                        1.0000    0.0645    0.0645;  1.0000    0.0323    0.0323; 1.0000         0         0; 0.8471    0.1608         0];
    
        Cols4Slip    = [1.0000    1.0000    1.0000;  0.8890    0.9670    1.0000; 0.7780    0.9330    1.0000; 0.6670    0.9000    1.0000;  0.5560    0.8670    1.0000; 0.4440    0.8330    1.0000;...
                        0.3330    0.8000    1.0000;  0.2220    0.7670    1.0000; 0.1110    0.7330    1.0000
                        0         0.7000    1.0000;  0         0.7110    0.8890; 0         0.7220    0.7780; 0         0.7330    0.6670;  0         0.7440    0.5560; 0         0.7560    0.4440;...
                        0         0.7670    0.3330;  0         0.7780    0.2220; 0         0.7890    0.1110; 0         0.8000         0;  0.0560    0.8220         0; 0.1110    0.8440    0;...
                        0.1670    0.8670         0;  0.2220    0.8890         0; 0.2780    0.9110         0; 0.3330    0.9330         0;  0.3890    0.9560         0; 0.4440    0.9780    0;...
                        0.5000    1.0000         0;  0.5560    1.0000         0; 0.6110    1.0000         0; 0.6670    1.0000         0;  0.7220    1.0000         0; 0.7780    1.0000    0;...
                        0.8330    1.0000         0;  0.8890    1.0000         0; 0.9440    1.0000         0; 1.0000    1.0000         0;  1.0000    0.9440         0; 1.0000    0.8890    0;...
                        1.0000    0.8330         0;  1.0000    0.7780         0; 1.0000    0.7220         0; 1.0000    0.6670         0;  1.0000    0.6110         0; 1.0000    0.5560    0;...
                        1.0000    0.5000         0;  1.0000    0.4440         0; 1.0000    0.3890         0; 1.0000    0.3330         0;  1.0000    0.2780         0; 1.0000    0.2220    0;...
                        1.0000    0.1670         0;  1.0000    0.1110         0; 1.0000    0.0560         0; 1.0000         0         0;  0.9440         0         0; 0.8890         0    0;...
                        0.8330         0         0;  0.7780         0         0; 0.7220         0         0; 0.6670         0         0;  0.6110         0         0; 0.5560         0    0;...
                        0.5000         0         0];


        ClnCatalog  = struct;
        PerPatch    = struct;
        FaultGeom   = struct;
        ShearMod    = 0;
        DeltaT      = 0;
        CatImported = 0;
        PerPatchDone= 0;
        EQcount     = 0;
        SelPatchList= [];
        CVvals_Slip = [];
        CVvals_iEQt = [];
        CVvals_dTau = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadCatGroupTime_CheckBox
        function LoadCatGroupTime_CheckBoxValueChanged(app, event)
            app.LoadCatGroupTime_CheckBox.Value = 1;
            app.LoadCatGroupDist_CheckBox.Value = 0;          
        end

        % Value changed function: LoadCatGroupDist_CheckBox
        function LoadCatGroupDist_CheckBoxValueChanged(app, event)
            app.LoadCatGroupTime_CheckBox.Value = 0;
            app.LoadCatGroupDist_CheckBox.Value = 1;
        end

        % Value changed function: SelectCatalog_edit
        function SelectCatalog_editValueChanged(app, event)
            value = app.SelectCatalog_edit.Value;
            if ((value > app.TotalCatalogs_edit.Value) || (value < 1)),         return;             end
            
            app.EQnumber_EditField.Value  = size(app.ClnCatalog(value).EQs,2);
            app.StartTime_EditField.Value = min(vertcat(app.ClnCatalog(value).EQs(:).EQtime)); 
            app.EndTime_EditField.Value   = max(vertcat(app.ClnCatalog(value).EQs(:).EQtime)); 
        end

        % Value changed function: SelectPatchID_edit
        function SelectPatchID_editValueChanged(app, event)
            if ((app.SelectPatchID_edit.Value > 0) && (app.SelectPatchID_edit.Value <= size(app.FaultGeom.TriangleList,1)))
                app.SelPatchList = app.SelectPatchID_edit.Value;                
            else
                app.SelPatchList = 0;
                app.SelectPatchID_edit.Value = 0;
            end
        end

        % Button pushed function: LoadCatalog_Button
        function LoadCatalog_ButtonPushed(app, event)
            app.LoadCat_Lamp.Color = 'red';         drawnow;   
            app.CatImported        = 0;
            GroupByTime = app.LoadCatGroupTime_CheckBox.Value;
            GroupDist   = app.LoadCatMaxDist_EditField.Value;
            MinPatchNum = app.LoadCatRemoveEQ_EditField.Value;
            MinPatchSlp = app.LoadMinPatchSlip_EditField.Value;
            clc;
            %----------------------------------------------------------
            [LoadName,LoadPath] = uigetfile('*Catalog.dat','Load EQ catalog', 'MultiSelect', 'on');
            %----------------------------------------------------------
            if (iscellstr(LoadName)),           LoadNum = size(LoadName,2); disp('Loading multiple catalogs at once');  disp('MAKE SURE THAT THEY ALL SHARE THE SAME GRID I.E., WERE GRIDDED IN THE SAME WAY.'); disp('THIS IS IMPORTANT TO ENSURE THAT PATCH NUMBERS ARE THE SAME FOR EACH CATALOG.')
            else,                               LoadNum = 1;        if   (LoadName ==0) ,           return;                 end
            end
            app.LoadCat_Lamp.Color = 'yellow';      app.TotalCatalogs_edit.Value = LoadNum;              drawnow;
            %----------------------------------------------------------
            app.ClnCatalog     = struct;  
            app.FaultGeom      = struct;

            for i = 1 : LoadNum
                if (iscellstr(LoadName)),   NextName = LoadName{1,i};
                else,                       NextName = LoadName;
                end
                app.SelectCatalog_edit.Value = i;       drawnow;
                
                CtlgPath       = strcat(LoadPath,NextName);
                fidbinCat      = fopen(CtlgPath,'rb');
                %---------------------------------------------------------------------
                EQnumRaw       = fread(fidbinCat,1,'int');
                PatchNum       = fread(fidbinCat,1,'int');
                VertNum        = fread(fidbinCat,1,'int');
                app.ShearMod   = fread(fidbinCat,1,'single');
                app.DeltaT     = fread(fidbinCat,1,'single');
                %-----------------------------------------
                if (app.DeltaT > 1000),         app.DeltaT = 1;             end  %if it is LARGE (is measured in seconds) => then don't use it!        
                %-----------------------------------------   
                app.FaultGeom.SizeTrigList       = PatchNum;
                app.FaultGeom.SizeVertList       = VertNum;
                %-----------------------
                app.FaultGeom.TriangleList       = zeros(PatchNum,5);
                app.FaultGeom.GlobTrigCentr      = zeros(PatchNum,3);
                app.FaultGeom.VertexList         = zeros(VertNum,2);
                app.FaultGeom.GlobalVertices     = zeros(VertNum,3); 
                app.FaultGeom.TriangleAreas      = zeros(PatchNum,1);
                app.FaultGeom.IsSurfPatch        = zeros(PatchNum,1);
                app.FaultGeom.LocalTrigCentr     = zeros(PatchNum,2);  
                %-----------------------
                app.FaultGeom.TriangleList(:,1)  = fread(fidbinCat,PatchNum,'int32');
                app.FaultGeom.TriangleList(:,2)  = fread(fidbinCat,PatchNum,'int32');
                app.FaultGeom.TriangleList(:,3)  = fread(fidbinCat,PatchNum,'int32');
                app.FaultGeom.TriangleList(:,4)  = fread(fidbinCat,PatchNum,'int32');
                app.FaultGeom.TriangleList(:,5)  = fread(fidbinCat,PatchNum,'int32');
                app.FaultGeom.VertexList(:,1)    = fread(fidbinCat,VertNum,'single');
                app.FaultGeom.VertexList(:,2)    = fread(fidbinCat,VertNum,'single');
                app.FaultGeom.GlobalVertices(:,1)= fread(fidbinCat,VertNum,'single');
                app.FaultGeom.GlobalVertices(:,2)= fread(fidbinCat,VertNum,'single');
                app.FaultGeom.GlobalVertices(:,3)= fread(fidbinCat,VertNum,'single');
                app.FaultGeom.GlobTrigCentr(:,1) = fread(fidbinCat,PatchNum,'single');
                app.FaultGeom.GlobTrigCentr(:,2) = fread(fidbinCat,PatchNum,'single');
                app.FaultGeom.GlobTrigCentr(:,3) = fread(fidbinCat,PatchNum,'single'); 
                app.FaultGeom.TriangleList(:,1)  = app.FaultGeom.TriangleList(:,1) +1;
                app.FaultGeom.TriangleList(:,2)  = app.FaultGeom.TriangleList(:,2) +1;
                app.FaultGeom.TriangleList(:,3)  = app.FaultGeom.TriangleList(:,3) +1;
                app.FaultGeom.TriangleList(:,4)  = app.FaultGeom.TriangleList(:,4) +1;
                app.FaultGeom.TriangleList(:,5)  = app.FaultGeom.TriangleList(:,5) +1;
                %----------------------------------------- %want to determine the local trig center next      
                for ii = 1 : PatchNum
                    Vert1 = app.FaultGeom.TriangleList(ii,1);
                    Vert2 = app.FaultGeom.TriangleList(ii,2);
                    Vert3 = app.FaultGeom.TriangleList(ii,3);
                    
                    app.FaultGeom.LocalTrigCentr(ii,1) = (app.FaultGeom.VertexList(Vert1,1)+app.FaultGeom.VertexList(Vert2,1)+app.FaultGeom.VertexList(Vert3,1))/3;
                    app.FaultGeom.LocalTrigCentr(ii,2) = (app.FaultGeom.VertexList(Vert1,2)+app.FaultGeom.VertexList(Vert2,2)+app.FaultGeom.VertexList(Vert3,2))/3;  
                end 
                
                MeanLegLength = 0;
                
                for ii = 1 : size(app.FaultGeom.TriangleList,1)
                    v1    = app.FaultGeom.TriangleList(ii,1);
                    v2    = app.FaultGeom.TriangleList(ii,2);
                    v3    = app.FaultGeom.TriangleList(ii,3);
                    %---------------------------
                    %https://www.mathworks.com/matlabcentral/answers/271797-surface-area-of-a-3d-surface
                    app.FaultGeom.TriangleAreas(ii,1) = 1E-6 *1/2*norm(cross((app.FaultGeom.GlobalVertices(v2,:)-app.FaultGeom.GlobalVertices(v1,:)),(app.FaultGeom.GlobalVertices(v3,:)-app.FaultGeom.GlobalVertices(v1,:))));   
        
                    Leg12   = sqrt((app.FaultGeom.GlobalVertices(v1,1)-app.FaultGeom.GlobalVertices(v2,1))^2 +(app.FaultGeom.GlobalVertices(v1,2)-app.FaultGeom.GlobalVertices(v2,2))^2 +(app.FaultGeom.GlobalVertices(v1,3)-app.FaultGeom.GlobalVertices(v2,3))^2 ); 
                    Leg13   = sqrt((app.FaultGeom.GlobalVertices(v1,1)-app.FaultGeom.GlobalVertices(v3,1))^2 +(app.FaultGeom.GlobalVertices(v1,2)-app.FaultGeom.GlobalVertices(v3,2))^2 +(app.FaultGeom.GlobalVertices(v1,3)-app.FaultGeom.GlobalVertices(v3,3))^2 ); 
                    Leg23   = sqrt((app.FaultGeom.GlobalVertices(v2,1)-app.FaultGeom.GlobalVertices(v3,1))^2 +(app.FaultGeom.GlobalVertices(v2,2)-app.FaultGeom.GlobalVertices(v3,2))^2 +(app.FaultGeom.GlobalVertices(v2,3)-app.FaultGeom.GlobalVertices(v3,3))^2 ); 
                    MeanLegLength = MeanLegLength + mean([Leg12,Leg13,Leg23]);
                    %--------------------------- necessary for distance check if it is going to be used
                    
                    tester = 0;
                    if (abs(app.FaultGeom.GlobalVertices(v1,3)) <= mean([Leg12,Leg13,Leg23])/2),         tester = tester +1;         end
                    if (abs(app.FaultGeom.GlobalVertices(v2,3)) <= mean([Leg12,Leg13,Leg23])/2),         tester = tester +1;         end
                    if (abs(app.FaultGeom.GlobalVertices(v3,3)) <= mean([Leg12,Leg13,Leg23])/2),         tester = tester +1;         end
                
                    if (tester > 1)
                        app.FaultGeom.IsSurfPatch(i,1) = 1;
                    end
                end
                MeanLegLength                        = MeanLegLength/size(app.FaultGeom.TriangleList,1);
                app.LoadMeanLegLegth_EditField.Value = MeanLegLength;       drawnow;
                %---------------------------------------------------------------------          
                disp(strcat('Number of "raw"/unparsed earthquakes:',num2str(EQnumRaw)));
                EQcount            = 0;
                %-----------------------------------------
                for ii = 1 : EQnumRaw
                    
                    Time    = fread(fidbinCat,1,      'single');        Magn    = fread(fidbinCat,1,      'single');
                    PtNum   = fread(fidbinCat,1,      'int32');         MRFNum  = fread(fidbinCat,1,      'int32');
                    MRFval  = fread(fidbinCat,MRFNum, 'single');
                    %---------------------------------------
                    PtchID = fread(fidbinCat,PtNum,   'int32');         PtchID = PtchID +1; %this is again b/c of C vs. matlab notation
                    t0     = fread(fidbinCat,PtNum,   'int32');         delt_t = fread(fidbinCat,PtNum,   'single');
                    slip_h = fread(fidbinCat,PtNum,   'single');        slip_v = fread(fidbinCat,PtNum,   'single');
                    StabTyp= fread(fidbinCat,PtNum,   'int32');
                    %----------------------
                    Slip_c = sqrt(slip_h.^2 +slip_v.^2);                UseMinSlip = find (Slip_c > MinPatchSlp);
                    %----------------------
                    PtchID = PtchID(UseMinSlip);                        delt_t = delt_t(UseMinSlip);
                    Slip_c = Slip_c(UseMinSlip);
                    %----------------------
                    PtNum  = size(PtchID,1);
                    Time   = round(Time*1000)/1000;
                    %---------------------------------------%---------------------------------------
                    if (GroupByTime == 1)
                        if (PtNum >= MinPatchNum)
                            EQcount = EQcount +1;
                            
                            app.ClnCatalog(i).EQs(EQcount).EQtime   = Time;
                            app.ClnCatalog(i).EQs(EQcount).Magn     = Magn;
                            app.ClnCatalog(i).EQs(EQcount).meanSlip  = mean(Slip_c);
                            app.ClnCatalog(i).EQs(EQcount).PtchID   = PtchID';
                            app.ClnCatalog(i).EQs(EQcount).Delt_t   = delt_t';
                            app.ClnCatalog(i).EQs(EQcount).Slip_c   = Slip_c';

                            RuptArea = 0;
                            for kk = 1 : PtNum
                                NextPtchID = PtchID(kk,1);
                                PatchPos   = find(PtchID == NextPtchID,1,'first');
                                RuptArea   = RuptArea + app.FaultGeom.TriangleAreas(PatchPos,1);
                            end  
                            app.ClnCatalog(i).EQs(EQcount).RuptArea = RuptArea;
                        end
                    else
                    %---------------------------------------%---------------------------------------
                        TempEQcount    = 1;
                        subEQlist      = zeros(PtNum, 7);
                        
                        subEQlist(:,1) = PtchID;
                        subEQlist(:,2) = app.FaultGeom.GlobTrigCentr(PtchID,1);
                        subEQlist(:,3) = app.FaultGeom.GlobTrigCentr(PtchID,2);
                        subEQlist(:,4) = app.FaultGeom.GlobTrigCentr(PtchID,3);
                        subEQlist(:,5) = zeros(PtNum,1); %this is tempEQID
                        subEQlist(:,6) = zeros(PtNum,1); %this is "still looking"
                        subEQlist(:,7) = app.FaultGeom.TriangleAreas(PtchID,1);
                        %------------------%------------%------------------%------------
                        subEQlist(1,5) = TempEQcount;
                        StillOpen      = (PtNum > 1); % if I have at least one other patch activated in same "raw" EQ
                        %------------------%------------%------------------%------------
                        while (StillOpen == true)
                            NextTrigs2Test = subEQlist(subEQlist(:,5)           == TempEQcount,:);
                            NextTrigs2Test = NextTrigs2Test(NextTrigs2Test(:,6) ==     0      ,:);
                            Trigs2TestWith = subEQlist(subEQlist(:,5)           ==     0      ,:);
                            
                            FoundOne  = 0;
                            for (k = 1 : size(NextTrigs2Test,1))
                                CurrMastrPos             = find(subEQlist(:,1) == NextTrigs2Test(k,1),1,'first');
                                subEQlist(CurrMastrPos,6)= 1; %if this triangle was already tested against the list of "still open"
                                Dists                    = sqrt( (Trigs2TestWith(:,2)-NextTrigs2Test(k,2)).^2 + (Trigs2TestWith(:,3)-NextTrigs2Test(k,3)).^2 + (Trigs2TestWith(:,4)-NextTrigs2Test(k,4)).^2);
    
                                for (kk = 1 : size(Trigs2TestWith,1))
                                    if (Dists(kk,1) <= MeanLegLength*GroupDist)
                                        FoundOne                  = 1;
                                        CurrSlavePos              = find(subEQlist(:,1) == Trigs2TestWith(kk,1),1,'first');
                                        subEQlist(CurrSlavePos,5) = TempEQcount;
                                    end
                                end 
                            end
                            StillOpen = (min(subEQlist(:,5)) == 0);
                            if ((StillOpen == true) && (FoundOne == 0))
                                
                                TempEQcount              = TempEQcount +1;
                                Trigs2TestWith           = subEQlist(subEQlist(:,5) == 0,:);
                                CurrSlavePos             = find(subEQlist(:,1) == Trigs2TestWith(1,1),1,'first');
                                subEQlist(CurrSlavePos,5)= TempEQcount;
                            end
                            StillOpen = (min(subEQlist(:,5)) == 0);
                        end
                        %------------------%------------%------------------%------------
                        SubEventID = unique(subEQlist(:,5));
                        for k = min(SubEventID) : max(SubEventID)
                            SelPatches = subEQlist(subEQlist(:,5) == k,:);
                            RuptArea   = 0;
                            if (size(SelPatches,1) >= MinPatchNum)
                                EQcount = EQcount +1;

                                for kk = 1 : size(SelPatches,1)
                                    NextPtchID = SelPatches(kk,1);
                                    PatchPos   = find(PtchID == NextPtchID,1,'first');
                                    app.ClnCatalog(i).EQs(EQcount).PtchID(kk) = PtchID(PatchPos);
                                    app.ClnCatalog(i).EQs(EQcount).Delt_t(kk) = delt_t(PatchPos);
                                    app.ClnCatalog(i).EQs(EQcount).Slip_c(kk) = Slip_c(PatchPos);
                                    RuptArea   = RuptArea   + SelPatches(kk,7);
                                end  
                                Moment  = app.ShearMod*RuptArea*1e+6*mean(app.ClnCatalog(i).EQs(EQcount).Slip_c(:));
                                Magn    = (log10(Moment)-9.1)/1.5;
                                
                                app.ClnCatalog(i).EQs(EQcount).EQtime   = Time;
                                app.ClnCatalog(i).EQs(EQcount).Magn     = Magn;
                                app.ClnCatalog(i).EQs(EQcount).meanSlip  = mean(Slip_c);
                                app.ClnCatalog(i).EQs(EQcount).RuptArea = RuptArea;
                                %------------------%------------------
                            end
                        end  
                    end
                end
                fclose(fidbinCat);
                disp(strcat('Imported Events: ',num2str(EQcount)));
            end

            app.EQnumber_EditField.Value  = EQcount;
            app.StartTime_EditField.Value = min(vertcat(app.ClnCatalog(end).EQs(:).EQtime)); 
            app.EndTime_EditField.Value   = max(vertcat(app.ClnCatalog(end).EQs(:).EQtime)); 
            app.LoadCat_Lamp.Color        = 'green';     drawnow;    
            app.CatImported               = 1; 
            disp('done');
        end

        % Button pushed function: PlotMagVsFreq_Button
        function PlotMagVsFreq_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            MinMag  = app.MinMagn_EditField.Value;              MaxMag  = app.MaxMagn_EditField.Value;
            MinTime = app.StartTime_EditField.Value;            MaxTime = app.EndTime_EditField.Value;
            CatNum  = app.SelectCatalog_edit.Value;             FigNum  = app.PlotFigNum1_EditField.Value;
            BinNum  = app.PlotBinNum_EditField.Value; 
            %---------------------%-------
            figure(FigNum*10+1); hold on;   
            %---------------------%-------
            if (app.holdon1CheckBox.Value == 0)
                SelTime = vertcat(app.ClnCatalog(CatNum).EQs(:).EQtime);
                SelMagn = vertcat(app.ClnCatalog(CatNum).EQs(:).Magn);
                SelComb = [SelTime, SelMagn];
    
                SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                SelComb = SelComb(:,2);
                %---------------------%-------
                if (BinNum > 1),    BinValues = linspace(MinMag,MaxMag,BinNum);
                else,               BinValues = linspace(min(SelComb),max(SelComb),round(sqrt(size(SelComb,1))));
                end
                %---------------------%-------
                [SelYvals,SelXvals] = histcounts(SelComb,BinValues);        
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                SelYvals = SelYvals./sum(SelYvals); 
                SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                plot(SelXvals, SelYvals,'o-');          
            else
                for i = 1 : app.TotalCatalogs_edit.Value
                    SelTime = vertcat(app.ClnCatalog(i).EQs(:).EQtime);
                    SelMagn = vertcat(app.ClnCatalog(i).EQs(:).Magn);
                    SelComb = [SelTime, SelMagn];
        
                    SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                    SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                    SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                    SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                    SelComb = SelComb(:,2);
                    %---------------------%-------
                    if (BinNum > 1),    BinValues = linspace(MinMag,MaxMag,BinNum);
                    else,               BinValues = linspace(min(SelComb),max(SelComb),round(sqrt(size(SelComb,1))));
                    end
                    %---------------------%-------
                    [SelYvals,SelXvals] = histcounts(SelComb,BinValues);        
                    SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                    SelXvals            = SelXvals(1:end-1);
                    SelYvals = SelYvals./sum(SelYvals); 
                    SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                    plot(SelXvals, SelYvals,'o-','linewidth',1);     
                end 
            end
            %---------------------%-------
            grid on;
            set(gca,'XScale','linear','YScale','log');
        end

        % Button pushed function: PlotTimeVsMagn_Button
        function PlotTimeVsMagn_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            MinMag  = app.MinMagn_EditField.Value;              MaxMag  = app.MaxMagn_EditField.Value;
            MinTime = app.StartTime_EditField.Value;            MaxTime = app.EndTime_EditField.Value;
            CatNum  = app.SelectCatalog_edit.Value;             FigNum  = app.PlotFigNum1_EditField.Value;
            %---------------------%-------
            figure(FigNum*10+2); hold on;
            if (app.holdonCheckBox.Value == 0),     clf;    end     
            %---------------------%-------
            if (app.holdon1CheckBox.Value == 0)
                SelTime = vertcat(app.ClnCatalog(CatNum).EQs(:).EQtime);
                SelMagn = vertcat(app.ClnCatalog(CatNum).EQs(:).Magn);
                SelComb = [SelTime, SelMagn];
    
                SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                %---------------------%-------
                plot(SelComb(:,1), SelComb(:,2),'o-');          
            else
                for i = 1 : app.TotalCatalogs_edit.Value
                    SelTime = vertcat(app.ClnCatalog(i).EQs(:).EQtime);
                    SelMagn = vertcat(app.ClnCatalog(i).EQs(:).Magn);
                    SelComb = [SelTime, SelMagn];
        
                    SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                    SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                    SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                    SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                    %---------------------%-------
                    plot(SelComb(:,1), SelComb(:,2),'o-');        
                end 
            end
            %---------------------%-------

        end

        % Button pushed function: PlotMagVsRuptArea_Button
        function PlotMagVsRuptArea_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            MinMag  = app.MinMagn_EditField.Value;              MaxMag  = app.MaxMagn_EditField.Value;
            MinTime = app.StartTime_EditField.Value;            MaxTime = app.EndTime_EditField.Value;
            CatNum  = app.SelectCatalog_edit.Value;             FigNum  = app.PlotFigNum1_EditField.Value;
            %---------------------%-------
            figure(FigNum*10+3); hold on;
            if (app.holdonCheckBox.Value == 0),     clf;    end    
            %---------------------%-------
            if (app.holdon1CheckBox.Value == 0)
                SelTime = vertcat(app.ClnCatalog(CatNum).EQs(:).EQtime);
                SelMagn = vertcat(app.ClnCatalog(CatNum).EQs(:).Magn);
                SelRA   = vertcat(app.ClnCatalog(CatNum).EQs(:).RuptArea);
                SelComb = [SelTime, SelMagn, SelRA];
    
                SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                %---------------------%-------
                plot(SelComb(:,2), SelComb(:,3),'o');          
            else
                for i = 1 : app.TotalCatalogs_edit.Value
                    SelTime = vertcat(app.ClnCatalog(i).EQs(:).EQtime);
                    SelMagn = vertcat(app.ClnCatalog(i).EQs(:).Magn);
                    SelRA   = vertcat(app.ClnCatalog(i).EQs(:).RuptArea);
                    SelComb = [SelTime, SelMagn, SelRA];
        
                    SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                    SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                    SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                    SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                    %---------------------%-------
                    plot(SelComb(:,2), SelComb(:,3),'o');        
                end 
            end
            %---------------------%-------
            grid on;
            set(gca,'XScale','linear','YScale','log');         
        end

        % Button pushed function: PlotnormSlipvsTime_Button
        function PlotnormSlipvsTime_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            MinMag  = app.MinMagn_EditField.Value;              MaxMag  = app.MaxMagn_EditField.Value;
            MinTime = app.StartTime_EditField.Value;            MaxTime = app.EndTime_EditField.Value;
            CatNum  = app.SelectCatalog_edit.Value;             FigNum  = app.PlotFigNum1_EditField.Value;
            
            CombArea = sum(app.FaultGeom.TriangleAreas(:,1));
            
            %---------------------%-------
            figure(FigNum*10+4); hold on;
            if (app.holdonCheckBox.Value == 0),     clf;    MeanAndStd = zeros(1,4);     
            else,                                           MeanAndStd = zeros(app.TotalCatalogs_edit.Value,4);     
            end
            %---------------------%-------
            if (app.holdon1CheckBox.Value == 0)
                SelTime = vertcat(app.ClnCatalog(CatNum).EQs(:).EQtime);
                SelMagn = vertcat(app.ClnCatalog(CatNum).EQs(:).Magn);
                SelSlip = vertcat(app.ClnCatalog(CatNum).EQs(:).meanSlip);
                SelArea = vertcat(app.ClnCatalog(CatNum).EQs(:).RuptArea);
                SelComb = [SelTime, SelMagn, SelSlip, SelArea];
    
                SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                SelComb(:,3) = SelComb(:,3).*(SelComb(:,4)./CombArea);%*1.4;
                
                MeanAndStd(1,1) = mean(SelComb(:,3));                MeanAndStd(1,2) = std(SelComb(:,3));
                MeanAndStd(1,3) = mean(diff(SelComb(:,1)));          MeanAndStd(1,4) = std(diff(SelComb(:,1)));
                
                SelComb(:,3) = cumsum(SelComb(:,3));
                %jetzt dann die linien malen... bzw. in liste packen
                Xvalues            = zeros(50000,2);
                Yvalues            = zeros(50000,2);
                counter            = 1;
                Xvalues(counter,1) = SelComb(1,1);                  Xvalues(counter,2) = SelComb(1,1);
                Yvalues(counter,1) = 0;                             Yvalues(counter,2) = SelComb(1,3);
                
                for iii = 2 : 1 :size(SelComb,1)
                    counter = counter +1;
                    Xvalues(counter,1) = SelComb(iii-1,1);          Xvalues(counter,2) = SelComb(iii,1);
                    Yvalues(counter,1) = SelComb(iii-1,3);          Yvalues(counter,2) = SelComb(iii-1,3);
                    counter = counter +1;
                    Xvalues(counter,1) = SelComb(iii,1);            Xvalues(counter,2) = SelComb(iii,1);
                    Yvalues(counter,1) = SelComb(iii-1,3);          Yvalues(counter,2) = SelComb(iii,3); 
                end
                Xvalues = Xvalues(1:counter,:);
                Yvalues = Yvalues(1:counter,:);
                 plot(Xvalues',Yvalues','-','color',[0 0 0 1],'linewidth',2); 
                % plot(SelComb(:,1),SelComb(:,3),'-','color',[0 0 0 0.1],'linewidth',2);      
            else
                for i = 1 : app.TotalCatalogs_edit.Value
                
                    SelTime = vertcat(app.ClnCatalog(i).EQs(:).EQtime);
                    SelMagn = vertcat(app.ClnCatalog(i).EQs(:).Magn);
                    SelSlip = vertcat(app.ClnCatalog(i).EQs(:).meanSlip);
                    SelArea = vertcat(app.ClnCatalog(i).EQs(:).RuptArea);
                    SelComb = [SelTime, SelMagn, SelSlip, SelArea];
        
                    SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                    SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                    SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                    SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                    SelComb(:,3) = SelComb(:,3).*(SelComb(:,4)./CombArea);%*1.4;
                    
                    MeanAndStd(i,1) = mean(SelComb(:,3));                MeanAndStd(i,2) = std(SelComb(:,3));
                    MeanAndStd(i,3) = mean(diff(SelComb(:,1)));          MeanAndStd(i,4) = std(diff(SelComb(:,1)));
                    
                    SelComb(:,3) = cumsum(SelComb(:,3));
                    %jetzt dann die linien malen... bzw. in liste packen
                    Xvalues            = zeros(50000,2);
                    Yvalues            = zeros(50000,2);
                    counter            = 1;
                    Xvalues(counter,1) = SelComb(1,1);                  Xvalues(counter,2) = SelComb(1,1);
                    Yvalues(counter,1) = 0;                             Yvalues(counter,2) = SelComb(1,3);
                    
                    for iii = 2 : 1 :size(SelComb,1)
                        counter = counter +1;
                        Xvalues(counter,1) = SelComb(iii-1,1);          Xvalues(counter,2) = SelComb(iii,1);
                        Yvalues(counter,1) = SelComb(iii-1,3);          Yvalues(counter,2) = SelComb(iii-1,3);
                        counter = counter +1;
                        Xvalues(counter,1) = SelComb(iii,1);            Xvalues(counter,2) = SelComb(iii,1);
                        Yvalues(counter,1) = SelComb(iii-1,3);          Yvalues(counter,2) = SelComb(iii,3); 
                    end
                    Xvalues = Xvalues(1:counter,:);
                    Yvalues = Yvalues(1:counter,:);
                    plot(Xvalues',Yvalues','-','color',[0 0 0 0.1],'linewidth',2); 
                  %  plot(SelComb(:,1),SelComb(:,3),'o-','color',[0 0 0 0.1],'linewidth',2); 
                
                end
                
            end
            MeanAndStd
            mean(MeanAndStd,1)

            figure(FigNum*100+4); hold on; xlabel('Recurrence Time (years)'); ylabel('Slip amount (m)')
            for ii = 1 : size(MeanAndStd,1)
                plot([MeanAndStd(ii,3)-MeanAndStd(ii,4) MeanAndStd(ii,3)+MeanAndStd(ii,4)] ,[MeanAndStd(ii,1) MeanAndStd(ii,1)],'-k'); 
                plot([MeanAndStd(ii,3) MeanAndStd(ii,3)] ,[MeanAndStd(ii,1)-MeanAndStd(ii,2) MeanAndStd(ii,1)+MeanAndStd(ii,2)],'-k');
                plot(MeanAndStd(ii,3),MeanAndStd(ii,1),'ok','MarkerFaceColor','k');
            end
                    
            
        end

        % Button pushed function: PlotInterEventTimes_Button
        function PlotInterEventTimes_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            MinMag  = app.MinMagn_EditField.Value;              MaxMag  = app.MaxMagn_EditField.Value;
            MinTime = app.StartTime_EditField.Value;            MaxTime = app.EndTime_EditField.Value;
            CatNum  = app.SelectCatalog_edit.Value;             FigNum  = app.PlotFigNum1_EditField.Value;
            BinNum  = app.PlotBinNum_EditField.Value; 
            %---------------------%-------
            figure(FigNum*10+5); hold on;
            if (app.holdonCheckBox.Value == 0),     clf;    end         
            %---------------------%-------
            if (app.holdon1CheckBox.Value == 0)
                SelTime = vertcat(app.ClnCatalog(CatNum).EQs(:).EQtime);
                SelMagn = vertcat(app.ClnCatalog(CatNum).EQs(:).Magn);
                SelComb = [SelTime, SelMagn];
    
                SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                SelComb = diff(SelComb(:,1));
                %---------------------%-------
                if (BinNum > 1),    BinValues = linspace(app.PlotBinMinValue_EditField.Value,app.PlotBinMaxValue_EditField.Value,BinNum);
                else,               BinValues = linspace(min(SelComb),max(SelComb),round(sqrt(size(SelComb,1))));
                end
                %---------------------%-------
                [SelYvals,SelXvals] = histcounts(SelComb,BinValues);        
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                plot(SelXvals, SelYvals,'o-','LineWidth',2);             
            else
                for i = 1 : app.TotalCatalogs_edit.Value
                    SelTime = vertcat(app.ClnCatalog(i).EQs(:).EQtime);
                    SelMagn = vertcat(app.ClnCatalog(i).EQs(:).Magn);
                    SelComb = [SelTime, SelMagn];
        
                    SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                    SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                    SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                    SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                    SelComb = diff(SelComb(:,1));
                    %---------------------%-------
                    if (BinNum > 1),    BinValues = linspace(app.PlotBinMinValue_EditField.Value,app.PlotBinMaxValue_EditField.Value,BinNum);
                    else,               BinValues = linspace(min(SelComb),max(SelComb),round(sqrt(size(SelComb,1))));
                    end
                    %---------------------%-------
                    [SelYvals,SelXvals] = histcounts(SelComb,BinValues);        
                    SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                    SelXvals            = SelXvals(1:end-1);
                    plot(SelXvals, SelYvals,'o-','LineWidth',2);                 
                end 
            end


        end

        % Button pushed function: PlotMeanSlip_Button
        function PlotMeanSlip_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            MinMag  = app.MinMagn_EditField.Value;              MaxMag  = app.MaxMagn_EditField.Value;
            MinTime = app.StartTime_EditField.Value;            MaxTime = app.EndTime_EditField.Value;
            CatNum  = app.SelectCatalog_edit.Value;             FigNum  = app.PlotFigNum1_EditField.Value;
            BinNum  = app.PlotBinNum_EditField.Value; 
            %---------------------%-------
            figure(FigNum*10+5); hold on;
            if (app.holdonCheckBox.Value == 0),     clf;    end         
            %---------------------%-------
            if (app.holdon1CheckBox.Value == 0)
                SelTime = vertcat(app.ClnCatalog(CatNum).EQs(:).EQtime);
                SelMagn = vertcat(app.ClnCatalog(CatNum).EQs(:).Magn);
                SelSlip = vertcat(app.ClnCatalog(CatNum).EQs(:).meanSlip);
                SelComb = [SelTime, SelMagn, SelSlip];
    
                SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                SelComb = SelComb(:,3);
                %---------------------%-------
                if (BinNum > 1),    BinValues = linspace(app.PlotBinMinValue_EditField.Value,app.PlotBinMaxValue_EditField.Value,BinNum);
                else,               BinValues = linspace(min(SelComb),max(SelComb),round(sqrt(size(SelComb,1))));
                end
                %---------------------%-------
                [SelYvals,SelXvals] = histcounts(SelComb,BinValues);        
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                plot(SelXvals, SelYvals,'o-','LineWidth',2);             
            else
                for i = 1 : app.TotalCatalogs_edit.Value
                    SelTime = vertcat(app.ClnCatalog(i).EQs(:).EQtime);
                    SelMagn = vertcat(app.ClnCatalog(i).EQs(:).Magn);
                    SelSlip = vertcat(app.ClnCatalog(i).EQs(:).meanSlip);
                    SelComb = [SelTime, SelMagn, SelSlip];
        
                    SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                    SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                    SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                    SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                    SelComb = SelComb(:,3);
                    %---------------------%-------
                    if (BinNum > 1),    BinValues = linspace(app.PlotBinMinValue_EditField.Value,app.PlotBinMaxValue_EditField.Value,BinNum);
                    else,               BinValues = linspace(min(SelComb),max(SelComb),round(sqrt(size(SelComb,1))));
                    end
                    %---------------------%-------
                    [SelYvals,SelXvals] = histcounts(SelComb,BinValues);        
                    SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                    SelXvals            = SelXvals(1:end-1);
                    plot(SelXvals, SelYvals,'o-','LineWidth',2);                 
                end 
            end    
        end

        % Button pushed function: CalcPerPatch_Button
        function CalcPerPatch_ButtonPushed(app, event)

            if (app.CatImported == 0),              return;                 end

            MinMag  = app.MinMagn_EditField.Value;              MaxMag  = app.MaxMagn_EditField.Value;
            MinTime = app.StartTime_EditField.Value;            MaxTime = app.EndTime_EditField.Value;
            CatNum  = app.TotalCatalogs_edit.Value;           
            %---------------------------------------- 
            app.PerPatch                             = struct;
            app.PerPatch(app.FaultGeom.SizeTrigList) = struct;
            app.PerPatch(1).MeanSlip = [];                      app.PerPatch(1).CVSlip   = [];
            app.PerPatch(1).MeanTime = [];                      app.PerPatch(1).CVTime   = [];
            app.PerPatch(1).MeandTau = [];                      app.PerPatch(1).CVdTau   = [];
            %---------------------------------------- 
            for i = 1 : CatNum
                IndexList = zeros(app.FaultGeom.SizeTrigList,1);
                SlipMatrx = zeros(app.FaultGeom.SizeTrigList,round(size(app.ClnCatalog(i).EQs,2)/10));
                dTauMatrx = zeros(app.FaultGeom.SizeTrigList,round(size(app.ClnCatalog(i).EQs,2)/10));
                TimeMatrx = zeros(app.FaultGeom.SizeTrigList,round(size(app.ClnCatalog(i).EQs,2)/10));

                for ii = 1 : size(app.ClnCatalog(i).EQs,2)
                    NextTime = app.ClnCatalog(i).EQs(ii).EQtime;
                    NextMagn = app.ClnCatalog(i).EQs(ii).Magn;
                    if (NextTime>=MinTime) && (NextTime<=MaxTime) && (NextMagn>=MinMag) && (NextMagn<=MaxMag)
                        for iii = 1 : size(app.ClnCatalog(i).EQs(ii).PtchID,2)
                            NxtPatch                                = app.ClnCatalog(i).EQs(ii).PtchID(iii);
                            IndexList(NxtPatch)                     = IndexList(NxtPatch) +1;
                            SlipMatrx(NxtPatch,IndexList(NxtPatch)) = app.ClnCatalog(i).EQs(ii).Slip_c(iii);
                            dTauMatrx(NxtPatch,IndexList(NxtPatch)) = app.ClnCatalog(i).EQs(ii).Delt_t(iii);
                            TimeMatrx(NxtPatch,IndexList(NxtPatch)) = app.ClnCatalog(i).EQs(ii).EQtime;
                        end
                    end
                end 
                for ii = 1 : app.FaultGeom.SizeTrigList
                    app.PerPatch(ii).MeanSlip(i) = mean(SlipMatrx(ii,1:IndexList(ii)));
                    app.PerPatch(ii).CVSlip(i)   = std(SlipMatrx(ii,1:IndexList(ii)))/mean(SlipMatrx(ii,1:IndexList(ii)));
                    app.PerPatch(ii).MeandTau(i) = mean(dTauMatrx(ii,1:IndexList(ii)));
                    app.PerPatch(ii).CVdTau(i)   = std(dTauMatrx(ii,1:IndexList(ii)))/mean(dTauMatrx(ii,1:IndexList(ii)));

                    app.PerPatch(ii).MeanTime(i) = mean(diff(TimeMatrx(ii,1:IndexList(ii))));
                    app.PerPatch(ii).CVTime(i)   = std(diff(TimeMatrx(ii,1:IndexList(ii))))/mean(diff(TimeMatrx(ii,1:IndexList(ii))));
                end
            end  
            %---------------------------------------- 
            app.PerPatchDone = 1;
            disp('done');
        end

        % Button pushed function: PlotOnFault_Button
        function PlotOnFault_ButtonPushed(app, event)
            if (app.PerPatchDone == 0),      return;             end           
            %---------------------------------------- 
            
            FigNum    = app.PlotFigNum_EditField.Value;             
            ViewA     = app.PlotViewAngle_EditField.Value;              ViewA    = sscanf(ViewA,'%f')';
            SelCol    = str2double(app.PlotColorMap_DropDown.Value);    SelCols  = app.PlotColorMap_DropDown.Items{SelCol};
            SelNum    = app.SelectCatalog_edit.Value;                   CatNum   = app.TotalCatalogs_edit.Value;      
            ListSel   = str2double(app.PlotComp2Plot_DropDown.Value);    
            %-----------------------------------------------
            MeanVals = zeros( app.FaultGeom.SizeTrigList,2);
            VariVals = zeros( app.FaultGeom.SizeTrigList,2);
            %-----------------------------------------------
            if  (ListSel == 1)
                if (app.holdonCheckBox.Value == 1)
                    for i = 1 : app.FaultGeom.SizeTrigList
                       MeanVals(i,1) = mean(app.PerPatch(i).MeanSlip);
                       MeanVals(i,2) = mean(app.PerPatch(i).CVSlip);
                       VariVals(i,1) = std(app.PerPatch(i).MeanSlip);
                       VariVals(i,2) = std(app.PerPatch(i).CVSlip);
                    end
                else
                    for i = 1 : app.FaultGeom.SizeTrigList
                        MeanVals(i,1) = app.PerPatch(i).MeanSlip(SelNum);
                        MeanVals(i,2) = app.PerPatch(i).CVSlip(SelNum);
                        VariVals(i,1) = 0;
                        VariVals(i,2) = 0;
                    end
                end
            elseif (ListSel == 2)
               if (app.holdonCheckBox.Value == 1)
                    for i = 1 : app.FaultGeom.SizeTrigList
                       MeanVals(i,1) = mean(app.PerPatch(i).MeandTau);
                       MeanVals(i,2) = mean(app.PerPatch(i).CVdTau);
                       VariVals(i,1) = std(app.PerPatch(i).MeandTau);
                       VariVals(i,2) = std(app.PerPatch(i).CVdTau);
                    end
               else
                   for i = 1 : app.FaultGeom.SizeTrigList
                        MeanVals(i,1) = app.PerPatch(i).MeandTau(SelNum);
                        MeanVals(i,2) = app.PerPatch(i).CVdTau(SelNum);
                        VariVals(i,1) = 0;
                        VariVals(i,2) = 0;
                   end
               end
            elseif (ListSel == 3)
                if (app.holdonCheckBox.Value == 1)
                    for i = 1 : app.FaultGeom.SizeTrigList
                       MeanVals(i,1) = mean(app.PerPatch(i).MeanTime);
                       MeanVals(i,2) = mean(app.PerPatch(i).CVTime);
                       VariVals(i,1) = std(app.PerPatch(i).MeanTime);
                       VariVals(i,2) = std(app.PerPatch(i).CVTime);
                    end
                else
                    for i = 1 : app.FaultGeom.SizeTrigList
                        MeanVals(i,1) = app.PerPatch(i).MeanTime(SelNum);
                        MeanVals(i,2) = app.PerPatch(i).CVTime(SelNum);
                        VariVals(i,1) = 0;
                        VariVals(i,2) = 0;
                    end
                end
                
            end
            %-----------------------------------------------
            AllV1s  = zeros(app.FaultGeom.SizeTrigList,3);
            AllV2s  = zeros(app.FaultGeom.SizeTrigList,3);
            AllV3s  = zeros(app.FaultGeom.SizeTrigList,3);
            %-----------------------------------------------
            for j = 1 : app.FaultGeom.SizeTrigList
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),3);
                %-----------------------------
            end
            %-----------------------------------------------
            if (SelCol < 5),        colormap(SelCols);
            elseif (SelCol == 5),   colormap(app.BlueWhiteRed);
            elseif (SelCol == 6),   colormap(app.Cols4Slip);
            end 
            %-----------------------------------------------
            figure(FigNum); 
            subplot(2,2,1); hold on; axis equal; axis tight; view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');   title('mean of mean value');
            patch(AllV1s',AllV2s',AllV3s',MeanVals(:,1)); colorbar;
             
            subplot(2,2,2);  hold on; axis equal; axis tight; view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');   title('std of mean value');
            patch(AllV1s',AllV2s',AllV3s', VariVals(:,1)); colorbar;
            
            subplot(2,2,3);  hold on; axis equal; axis tight; view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');   title('mean of CV value');
            patch(AllV1s',AllV2s',AllV3s',MeanVals(:,2)); colorbar;
             
            subplot(2,2,4);  hold on; axis equal; axis tight; view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');   title('std of CV value');
            patch(AllV1s',AllV2s',AllV3s', VariVals(:,2)); colorbar;

        end

        % Button pushed function: SelectSinglePatch_Button
        function SelectSinglePatch_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            SelSectn  = app.Select_onSectn_EditField.Value;
            %---------------------------------------- 
            FigNum    = app.PlotFigNum_EditField.Value; 
            ViewA     = app.PlotViewAngle_EditField.Value;          ViewA  = sscanf(ViewA,'%f')';
            %-----------------------------------------------
            SelSecPos = (app.FaultGeom.TriangleList(:,4) == SelSectn);
            SelectPtch= app.FaultGeom.TriangleList(SelSecPos,:); 
            SelPtchNum= size(SelectPtch,1);
            
            AllV1s    = zeros(SelPtchNum,3);
            AllV2s    = zeros(SelPtchNum,3);
            AllV3s    = zeros(SelPtchNum,3);
            %-----------------------------------------------
            for j = 1 : SelPtchNum
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(SelectPtch(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(SelectPtch(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(SelectPtch(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(SelectPtch(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(SelectPtch(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(SelectPtch(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(SelectPtch(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(SelectPtch(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(SelectPtch(j,3),3);
            end
            %-----------------------------
            figure(FigNum); clf; hold on;   view(ViewA);
            patch(AllV1s',AllV2s',AllV3s',[0.8 0.8 0.8]); 
            drawnow;
            axis equal;
            %-----------------------------           
            datacursormode on;
            dcm_obj = datacursormode(gcf);
    
            waitforbuttonpress;
            f = getCursorInfo(dcm_obj);
            PointCoord    = f.Position;
            
            PointDistance = sqrt((app.FaultGeom.GlobTrigCentr(:,1) - PointCoord(1,1)).^2 +(app.FaultGeom.GlobTrigCentr(:,2) - PointCoord(1,2)).^2 +(app.FaultGeom.GlobTrigCentr(:,3) - PointCoord(1,3)).^2);
            MinDistPos    = find(PointDistance == min(PointDistance(:)),1,'first');
            
            app.SelectPatchID_edit.Value = MinDistPos;
            app.SelPatchList             = MinDistPos;
            plot3(app.FaultGeom.GlobTrigCentr(MinDistPos,1), app.FaultGeom.GlobTrigCentr(MinDistPos,2), app.FaultGeom.GlobTrigCentr(MinDistPos,3), 'ro','MarkerFaceColor','red'); 
 
        end

        % Button pushed function: SelectHighlightPatch_Button
        function SelectHighlightPatch_ButtonPushed(app, event)
            if isempty(app.SelPatchList),                                               return;             end
            if (max(app.SelPatchList(:)) > size(app.FaultGeom.TriangleList,1)),         return;             end
            %-----------------------------------------------
            FigNum    = app.PlotFigNum_EditField.Value; 
            ViewA     = app.PlotViewAngle_EditField.Value;          ViewA  = sscanf(ViewA,'%f')';
            %-----------------------------------------------
            SelSectn  = app.Select_onSectn_EditField.Value;
            SelSecPos = (app.FaultGeom.TriangleList(:,4) == SelSectn);
            SelectPtch= app.FaultGeom.TriangleList(SelSecPos,:); 
            SelPtchNum= size(SelectPtch,1);
            
            AllV1s    = zeros(SelPtchNum,3);
            AllV2s    = zeros(SelPtchNum,3);
            AllV3s    = zeros(SelPtchNum,3);
            %-----------------------------------------------
            for j = 1 : SelPtchNum
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(SelectPtch(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(SelectPtch(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(SelectPtch(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(SelectPtch(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(SelectPtch(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(SelectPtch(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(SelectPtch(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(SelectPtch(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(SelectPtch(j,3),3);
            end
            %-----------------------------
            figure(FigNum); clf; hold on;   view(ViewA);
            patch(AllV1s',AllV2s',AllV3s',[0.8 0.8 0.8]); 
            drawnow;
            axis equal;
            Vx       = zeros(size(app.SelPatchList,1),4);
            Vy       = zeros(size(app.SelPatchList,1),4);
            Vz       = zeros(size(app.SelPatchList,1),4);
            %----------------------------
            for i = 1 : size(app.SelPatchList,1)
                Vx(i,1)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),1),1);
                Vx(i,2)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),2),1);
                Vx(i,3)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),3),1);
                Vx(i,4)  = Vx(i,1);
                Vy(i,1)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),1),2);
                Vy(i,2)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),2),2);
                Vy(i,3)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),3),2);
                Vy(i,4)  = Vy(i,1);
                Vz(i,1)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),1),3);
                Vz(i,2)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),2),3);
                Vz(i,3)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(app.SelPatchList(i,1),3),3);
                Vz(i,4)  = Vz(i,1);
            end
            plot3(Vx',Vy',Vz','-r','LineWidth',2);           
        end

        % Button pushed function: SelectPlotSlipVsTime_Button
        function SelectPlotSlipVsTime_ButtonPushed(app, event)
            %to show step-plot (time-vs-slip etc..)

            if (app.CatImported == 0),      return;             end           
            MinMag   = app.MinMagn_EditField.Value;             MaxMag  = app.MaxMagn_EditField.Value;
            MinTime  = app.StartTime_EditField.Value;           MaxTime = app.EndTime_EditField.Value;
            CatNum   = app.SelectCatalog_edit.Value;            FigNum  = app.PlotFigNum1_EditField.Value;
            BinNum   = app.PlotBinNum_EditField.Value; 
            
            SelPatch = app.SelectPatchID_edit.Value;
            %---------------------%-------
            if (app.holdonCheckBox.Value == 0)
                MeanAndStd = zeros(1,4);  
                
                SelTime = zeros(20000,1);
                SelMagn = zeros(20000,1);
                SelSlip = zeros(20000,1);
                SelArea = zeros(20000,1);
                cntr    = 1;
                for ii = 1 : size(app.ClnCatalog(CatNum).EQs,2)
                    PatchIsIn = find(app.ClnCatalog(CatNum).EQs(ii).PtchID == SelPatch,1);
                    
                    if (~isempty(PatchIsIn))
                        SelTime(cntr) = app.ClnCatalog(CatNum).EQs(ii).EQtime;
                        SelMagn(cntr) = app.ClnCatalog(CatNum).EQs(ii).Magn;
                        SelArea(cntr) = app.ClnCatalog(CatNum).EQs(ii).RuptArea;
                        SelSlip(cntr) = app.ClnCatalog(CatNum).EQs(ii).Slip_c(PatchIsIn);
                        cntr          = cntr +1;
                    end 
                end
                
                SelComb = [SelTime, SelMagn, SelSlip, SelArea];
                SelComb = SelComb(1:cntr-1,:);
    
                SelComb = SelComb(SelComb(:,1) >= MinTime,:);
                SelComb = SelComb(SelComb(:,1) <= MaxTime,:);
                SelComb = SelComb(SelComb(:,2) >= MinMag, :);
                SelComb = SelComb(SelComb(:,2) <= MaxMag, :);
                
                MeanAndStd(1,1) = mean(SelComb(:,3));                MeanAndStd(1,2) = std(SelComb(:,3))/mean(SelComb(:,3));
                MeanAndStd(1,3) = mean(diff(SelComb(:,1)));          MeanAndStd(1,4) = std(diff(SelComb(:,1)))/mean(diff(SelComb(:,1)));
                
                SelComb(:,3)   = cumsum(SelComb(:,3));
                %jetzt dann die linien malen... bzw. in liste packen
                Xvalues            = zeros(50000,2);
                Yvalues            = zeros(50000,2);
                counter            = 1;
                Xvalues(counter,1) = SelComb(1,1);                  Xvalues(counter,2) = SelComb(1,1);
                Yvalues(counter,1) = 0;                             Yvalues(counter,2) = SelComb(1,3);
                
                for iii = 2 : 1 :size(SelComb,1)
                    counter = counter +1;
                    Xvalues(counter,1) = SelComb(iii-1,1);          Xvalues(counter,2) = SelComb(iii,1);
                    Yvalues(counter,1) = SelComb(iii-1,3);          Yvalues(counter,2) = SelComb(iii-1,3);
                    counter = counter +1;
                    Xvalues(counter,1) = SelComb(iii,1);            Xvalues(counter,2) = SelComb(iii,1);
                    Yvalues(counter,1) = SelComb(iii-1,3);          Yvalues(counter,2) = SelComb(iii,3); 
                end
                Xvalues = Xvalues(1:counter,:);
                Yvalues = Yvalues(1:counter,:);
                %---------------------%-------%---------------------%-------
                figure(FigNum*10+4); clf;   hold on;
                plot(Xvalues',Yvalues','-','color',[0 0 0 0.1],'linewidth',2); 
                %---------------------%-------%---------------------%-------
                figure(FigNum*10+1); clf;   hold on;   
                
                if (BinNum > 1),    BinValues = linspace(MinMag,MaxMag,BinNum);
                else,               BinValues = linspace(min(SelComb(:,2)),max(SelComb(:,2)),round(sqrt(size(SelComb,1))));
                end
                %---------------------%-------
                [SelYvals,SelXvals] = histcounts(SelComb(:,2),BinValues);        
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                %SelYvals = SelYvals./sum(SelYvals); 
                %SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                plot(SelXvals, SelYvals,'o-');          
   
            else
                MeanAndStd = zeros(app.TotalCatalogs_edit.Value,4);  
                
                
                for i = 1 : app.TotalCatalogs_edit.Value
                    SelTime = zeros(20000,1);
                    SelMagn = zeros(20000,1);
                    SelSlip = zeros(20000,1);
                    SelArea = zeros(20000,1);
                    cntr    = 1;
                    for ii = 1 : size(app.ClnCatalog(i).EQs,2)
                        PatchIsIn = find(app.ClnCatalog(i).EQs(ii).PtchID == SelPatch,1);
                        
                        if (~isempty(PatchIsIn))
                            SelTime(cntr) = app.ClnCatalog(i).EQs(ii).EQtime;
                            SelMagn(cntr) = app.ClnCatalog(i).EQs(ii).Magn;
                            SelArea(cntr) = app.ClnCatalog(i).EQs(ii).RuptArea;
                            SelSlip(cntr) = app.ClnCatalog(i).EQs(ii).Slip_c(PatchIsIn);
                            cntr          = cntr +1;
                        end 
                    end
                    
                    SelComb = [SelTime, SelMagn, SelSlip, SelArea];
                    SelComb = SelComb(1:cntr-1,:);
                    
                    MeanAndStd(i,1) = mean(SelComb(:,3));                MeanAndStd(i,2) = std(SelComb(:,3));
                    MeanAndStd(i,3) = mean(diff(SelComb(:,1)));          MeanAndStd(i,4) = std(diff(SelComb(:,1)));
                    
                    SelComb(:,3) = cumsum(SelComb(:,3));
                    %jetzt dann die linien malen... bzw. in liste packen
                    Xvalues            = zeros(50000,2);
                    Yvalues            = zeros(50000,2);
                    counter            = 1;
                    Xvalues(counter,1) = SelComb(1,1);                  Xvalues(counter,2) = SelComb(1,1);
                    Yvalues(counter,1) = 0;                             Yvalues(counter,2) = SelComb(1,3);
                    
                    for iii = 2 : 1 :size(SelComb,1)
                        counter = counter +1;
                        Xvalues(counter,1) = SelComb(iii-1,1);          Xvalues(counter,2) = SelComb(iii,1);
                        Yvalues(counter,1) = SelComb(iii-1,3);          Yvalues(counter,2) = SelComb(iii-1,3);
                        counter = counter +1;
                        Xvalues(counter,1) = SelComb(iii,1);            Xvalues(counter,2) = SelComb(iii,1);
                        Yvalues(counter,1) = SelComb(iii-1,3);          Yvalues(counter,2) = SelComb(iii,3); 
                    end
                    Xvalues = Xvalues(1:counter,:);
                    Yvalues = Yvalues(1:counter,:);
                    %---------------------%-------%---------------------%------- 
                    figure(FigNum*10+4);        hold on;
                    plot(Xvalues',Yvalues','-','color',[0 0 0 0.1],'linewidth',2); 
                    %---------------------%-------%---------------------%-------
                    figure(FigNum*10+1);        hold on;   
                    
                    if (BinNum > 1),    BinValues = linspace(MinMag,MaxMag,BinNum);
                    else,               BinValues = linspace(min(SelComb(:,2)),max(SelComb(:,2)),round(sqrt(size(SelComb,1))));
                    end
                    %---------------------%-------
                
                    [SelYvals,SelXvals] = histcounts(SelComb(:,2),BinValues);        
                    SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                    SelXvals            = SelXvals(1:end-1);
                    %SelYvals = SelYvals./sum(SelYvals); 
                    %SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                    plot(SelXvals, SelYvals,'o-');     
                    
                end
 
                figure(FigNum*100+4); hold on; xlabel('Recurrence Time (years)'); ylabel('Slip amount (m)')
                for ii = 1 : size(MeanAndStd,1)
                    plot([MeanAndStd(ii,3)-MeanAndStd(ii,4) MeanAndStd(ii,3)+MeanAndStd(ii,4)] ,[MeanAndStd(ii,1) MeanAndStd(ii,1)],'-k'); 
                    plot([MeanAndStd(ii,3) MeanAndStd(ii,3)] ,[MeanAndStd(ii,1)-MeanAndStd(ii,2) MeanAndStd(ii,1)+MeanAndStd(ii,2)],'-k');
                     plot(MeanAndStd(ii,3),MeanAndStd(ii,1),'ok','MarkerFaceColor','k');
                end
                    
                    
%                 subplot(2,2,1); histogram(MeanAndStd(:,1),'BinMethod','sqrt'); title('Mean slip (meters)');
%                 subplot(2,2,2); histogram(MeanAndStd(:,2),'BinMethod','sqrt'); title('CV slip (dimensionless)');
%                 subplot(2,2,3); histogram(MeanAndStd(:,3),'BinMethod','sqrt'); title('Mean recurrence (years)');
%                 subplot(2,2,4); histogram(MeanAndStd(:,4),'BinMethod','sqrt'); title('CV recurrence (dimensionless)');
%                    
                
                
            end
            MeanAndStd
            mean(MeanAndStd,1)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadandparsecatalogPanel = uipanel(app.UIFigure);
            app.LoadandparsecatalogPanel.Title = 'Load and parse catalog';
            app.LoadCatalog_Button = uibutton(app.LoadandparsecatalogPanel, 'push');
            app.LoadCatalog_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadCatalog_ButtonPushed, true);
            app.LoadCatalog_Button.Text = 'Load catalog';
            app.removeEQswithlessthanLabel = uilabel(app.LoadandparsecatalogPanel);
            app.removeEQswithlessthanLabel.Text = 'remove EQs with less than';
            app.LoadCatRemoveEQ_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadCatRemoveEQ_EditField.Limits = [1 Inf];
            app.LoadCatRemoveEQ_EditField.RoundFractionalValues = 'on';
            app.LoadCatRemoveEQ_EditField.ValueDisplayFormat = '%d';
            app.LoadCatRemoveEQ_EditField.Value = 5;
            app.LoadCatGroupTime_CheckBox = uicheckbox(app.LoadandparsecatalogPanel);
            app.LoadCatGroupTime_CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCatGroupTime_CheckBoxValueChanged, true);
            app.LoadCatGroupTime_CheckBox.Text = 'group by time';
            app.LoadCatGroupTime_CheckBox.Value = true;
            app.LoadCatGroupDist_CheckBox = uicheckbox(app.LoadandparsecatalogPanel);
            app.LoadCatGroupDist_CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCatGroupDist_CheckBoxValueChanged, true);
            app.LoadCatGroupDist_CheckBox.Text = 'group by distance';
            app.maxdistEditFieldLabel = uilabel(app.LoadandparsecatalogPanel);
            app.maxdistEditFieldLabel.Text = 'max. dist.';
            app.LoadCatMaxDist_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadCatMaxDist_EditField.Limits = [0 Inf];
            app.LoadCatMaxDist_EditField.ValueDisplayFormat = '%4.2f';
            app.LoadCatMaxDist_EditField.Tooltip = {'this is fraction of mean leglength'};
            app.LoadCatMaxDist_EditField.Value = 1;
            app.progressLabel = uilabel(app.LoadandparsecatalogPanel);
            app.progressLabel.Text = 'progress';
            app.LoadCat_Lamp = uilamp(app.LoadandparsecatalogPanel);
            app.meanleglengthLabel = uilabel(app.LoadandparsecatalogPanel);
            app.meanleglengthLabel.Text = 'mean leg length';
            app.LoadMeanLegLegth_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadMeanLegLegth_EditField.Limits = [0 Inf];
            app.LoadMeanLegLegth_EditField.ValueDisplayFormat = '%4.2f';
            app.LoadMeanLegLegth_EditField.Editable = 'off';
            app.LoadMeanLegLegth_EditField.Tooltip = {'mean leg length of triangular patches'};
            app.minpatchslipLabel = uilabel(app.LoadandparsecatalogPanel);
            app.minpatchslipLabel.Text = 'min. patch slip';
            app.LoadMinPatchSlip_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadMinPatchSlip_EditField.ValueDisplayFormat = '%5.2f';
            app.LoadMinPatchSlip_EditField.Value = 0.05;
            app.catalogLabel = uilabel(app.LoadandparsecatalogPanel);
            app.catalogLabel.Text = 'catalog';
            app.SelectCatalog_edit = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.SelectCatalog_edit.Limits = [0 Inf];
            app.SelectCatalog_edit.RoundFractionalValues = 'on';
            app.SelectCatalog_edit.ValueDisplayFormat = '%d';
            app.SelectCatalog_edit.ValueChangedFcn = createCallbackFcn(app, @SelectCatalog_editValueChanged, true);
            app.SelectCatalog_edit.Value = 1;
            app.ofLabel = uilabel(app.LoadandparsecatalogPanel);
            app.ofLabel.Text = 'of';
            app.TotalCatalogs_edit = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.TotalCatalogs_edit.Limits = [0 Inf];
            app.TotalCatalogs_edit.RoundFractionalValues = 'on';
            app.TotalCatalogs_edit.ValueDisplayFormat = '%d';
            app.TotalCatalogs_edit.Editable = 'off';
            app.TotalCatalogs_edit.Value = 1;
            app.EQnumberLabel = uilabel(app.LoadandparsecatalogPanel);
            app.EQnumberLabel.Text = 'EQ number';
            app.EQnumber_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.EQnumber_EditField.Limits = [0 Inf];
            app.EQnumber_EditField.RoundFractionalValues = 'on';
            app.EQnumber_EditField.ValueDisplayFormat = '%d';
            app.EQnumber_EditField.Editable = 'off';
            app.starttimeLabel = uilabel(app.LoadandparsecatalogPanel);
            app.starttimeLabel.Text = 'start time';
            app.StartTime_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.StartTime_EditField.Limits = [0 Inf];
            app.StartTime_EditField.ValueDisplayFormat = '%6.3f';
            app.endtimeLabel = uilabel(app.LoadandparsecatalogPanel);
            app.endtimeLabel.Text = 'end time';
            app.EndTime_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.EndTime_EditField.Limits = [0 Inf];
            app.EndTime_EditField.ValueDisplayFormat = '%6.3f';
            app.andmagnitudesbetweenLabel = uilabel(app.LoadandparsecatalogPanel);
            app.andmagnitudesbetweenLabel.Text = 'and magnitudes between';
            app.MinMagn_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.MinMagn_EditField.ValueDisplayFormat = '%5.2f';
            app.MinMagn_EditField.Value = 3;
            app.andLabel = uilabel(app.LoadandparsecatalogPanel);
            app.andLabel.Text = 'and';
            app.MaxMagn_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.MaxMagn_EditField.ValueDisplayFormat = '%5.2f';
            app.MaxMagn_EditField.Value = 8;
            app.PlotperpatchinformationmeanandvariabilityofselparameterPanel = uipanel(app.UIFigure);
            app.PlotperpatchinformationmeanandvariabilityofselparameterPanel.Title = 'Plot "per patch" (mean/variability of sel. parameter, along fault and for all catalogs (if "hold on" is selected)';
            app.PlotOnFault_Button = uibutton(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel, 'push');
            app.PlotOnFault_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotOnFault_ButtonPushed, true);
            app.PlotOnFault_Button.Text = 'Plot "on fault"';
            app.PlotComp2Plot_DropDown = uidropdown(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel);
            app.PlotComp2Plot_DropDown.Items = {'slip variation', 'stress drop variation', 'inter-event time variation'};
            app.PlotComp2Plot_DropDown.ItemsData = {'1', '2', '3'};
            app.PlotComp2Plot_DropDown.Value = '1';
            app.FigEditFieldLabel = uilabel(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.PlotFigNum_EditField = uieditfield(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel, 'numeric');
            app.PlotFigNum_EditField.Limits = [1 Inf];
            app.PlotFigNum_EditField.RoundFractionalValues = 'on';
            app.PlotFigNum_EditField.ValueDisplayFormat = '%d';
            app.PlotFigNum_EditField.Value = 3;
            app.viewangleEditFieldLabel = uilabel(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel);
            app.viewangleEditFieldLabel.Text = 'view angle';
            app.PlotViewAngle_EditField = uieditfield(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel, 'text');
            app.PlotViewAngle_EditField.Value = '70 20';
            app.ColormapDropDownLabel = uilabel(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.PlotColorMap_DropDown = uidropdown(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel);
            app.PlotColorMap_DropDown.Items = {'parula', 'jet', 'hot', 'gray', 'blue-white-red', 'slip', 'contour(per section)', ''};
            app.PlotColorMap_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.PlotColorMap_DropDown.Value = '1';
            app.holdonCheckBox = uicheckbox(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel);
            app.holdonCheckBox.Text = 'hold on';
            app.holdonCheckBox.Value = true;
            app.CalcPerPatch_Button = uibutton(app.PlotperpatchinformationmeanandvariabilityofselparameterPanel, 'push');
            app.CalcPerPatch_Button.ButtonPushedFcn = createCallbackFcn(app, @CalcPerPatch_ButtonPushed, true);
            app.CalcPerPatch_Button.Text = 'Calculate "per patch" stuff';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Plot for entire catalog (not per-patch), all catalogs if "hold on" is selected, else only the selected catalog (above)';
            app.PlotMagVsFreq_Button = uibutton(app.Panel, 'push');
            app.PlotMagVsFreq_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotMagVsFreq_ButtonPushed, true);
            app.PlotMagVsFreq_Button.Text = 'Plot magnitude-frequency distrib.';
            app.PlotTimeVsMagn_Button = uibutton(app.Panel, 'push');
            app.PlotTimeVsMagn_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotTimeVsMagn_ButtonPushed, true);
            app.PlotTimeVsMagn_Button.Text = 'Plot time vs. magnitude (raw catalog)';
            app.holdon1CheckBox = uicheckbox(app.Panel);
            app.holdon1CheckBox.Text = 'hold on';
            app.holdon1CheckBox.Value = true;
            app.FigEditFieldLabel_2 = uilabel(app.Panel);
            app.FigEditFieldLabel_2.Text = 'Fig #';
            app.PlotFigNum1_EditField = uieditfield(app.Panel, 'numeric');
            app.PlotFigNum1_EditField.Limits = [1 Inf];
            app.PlotFigNum1_EditField.RoundFractionalValues = 'on';
            app.PlotFigNum1_EditField.ValueDisplayFormat = '%d';
            app.PlotFigNum1_EditField.Value = 2;
            app.PlotMagVsRuptArea_Button = uibutton(app.Panel, 'push');
            app.PlotMagVsRuptArea_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotMagVsRuptArea_ButtonPushed, true);
            app.PlotMagVsRuptArea_Button.Text = 'Plot magnitude vs. RA (scatter)';
            app.PlotnormSlipvsTime_Button = uibutton(app.Panel, 'push');
            app.PlotnormSlipvsTime_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotnormSlipvsTime_ButtonPushed, true);
            app.PlotnormSlipvsTime_Button.Text = 'Plot (normalized) slip vs. time for entire catalog';
            app.binLabel = uilabel(app.Panel);
            app.binLabel.Text = 'bin #';
            app.PlotBinNum_EditField = uieditfield(app.Panel, 'numeric');
            app.PlotBinNum_EditField.Limits = [1 Inf];
            app.PlotBinNum_EditField.ValueDisplayFormat = '%d';
            app.PlotBinNum_EditField.Value = 1;
            app.PlotInterEventTimes_Button = uibutton(app.Panel, 'push');
            app.PlotInterEventTimes_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotInterEventTimes_ButtonPushed, true);
            app.PlotInterEventTimes_Button.Text = 'Plot inter-event times (hist)';
            app.rangingfromLabel = uilabel(app.Panel);
            app.rangingfromLabel.Text = 'ranging from';
            app.PlotBinMinValue_EditField = uieditfield(app.Panel, 'numeric');
            app.PlotBinMinValue_EditField.ValueDisplayFormat = '%5.1f';
            app.PlotBinMinValue_EditField.Value = 1;
            app.toLabel = uilabel(app.Panel);
            app.toLabel.Text = 'to';
            app.PlotBinMaxValue_EditField = uieditfield(app.Panel, 'numeric');
            app.PlotBinMaxValue_EditField.ValueDisplayFormat = '%5.1f';
            app.PlotBinMaxValue_EditField.Value = 2;
            app.PlotMeanSlip_Button = uibutton(app.Panel, 'push');
            app.PlotMeanSlip_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotMeanSlip_ButtonPushed, true);
            app.PlotMeanSlip_Button.Text = 'Plot mean slip (hist)';
            app.SelectSinglePatch_Button = uibutton(app.UIFigure, 'push');
            app.SelectSinglePatch_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectSinglePatch_ButtonPushed, true);
            app.SelectSinglePatch_Button.Text = 'Select single patch';
            app.SelectHighlightPatch_Button = uibutton(app.UIFigure, 'push');
            app.SelectHighlightPatch_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectHighlightPatch_ButtonPushed, true);
            app.SelectHighlightPatch_Button.Text = 'Highlight patch(es)';
            app.SelectPlotSlipVsTime_Button = uibutton(app.UIFigure, 'push');
            app.SelectPlotSlipVsTime_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectPlotSlipVsTime_ButtonPushed, true);
            app.SelectPlotSlipVsTime_Button.Text = 'Plot slip vs. time and MFR';
            app.PatchLabel = uilabel(app.UIFigure);
            app.PatchLabel.Text = 'Patch';
            app.SelectPatchID_edit = uieditfield(app.UIFigure, 'numeric');
            app.SelectPatchID_edit.Limits = [0 Inf];
            app.SelectPatchID_edit.RoundFractionalValues = 'on';
            app.SelectPatchID_edit.ValueDisplayFormat = '%d';
            app.SelectPatchID_edit.ValueChangedFcn = createCallbackFcn(app, @SelectPatchID_editValueChanged, true);
            app.SelectPatchID_edit.Editable = 'off';
            app.SelectPatchID_edit.Value = 1;
            app.onfaultsectionLabel = uilabel(app.UIFigure);
            app.onfaultsectionLabel.Text = 'on fault section';
            app.Select_onSectn_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Select_onSectn_EditField.Limits = [1 Inf];
            app.Select_onSectn_EditField.RoundFractionalValues = 'on';
            app.Select_onSectn_EditField.ValueDisplayFormat = '%d';
            app.Select_onSectn_EditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = R2_LoadPlotResults_Apr2022

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end