classdef R_LoadPlotResultsMulti_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImportedparsedEQcatalogPanel  matlab.ui.container.Panel
        PlotScaling_Button            matlab.ui.control.Button
        FigEditField_2Label           matlab.ui.control.Label
        FigNumScaling_EditField       matlab.ui.control.NumericEditField
        symbolDropDownLabel           matlab.ui.control.Label
        Scaling_Symbol_DropDown       matlab.ui.control.DropDown
        scaleDropDownLabel            matlab.ui.control.Label
        ScalingXscale_DropDown        matlab.ui.control.DropDown
        scaleDropDown_2Label          matlab.ui.control.Label
        ScalingYscale_DropDown        matlab.ui.control.DropDown
        xaxisDropDownLabel            matlab.ui.control.Label
        ScalingXvalue_DropDown        matlab.ui.control.DropDown
        yaxisDropDownLabel            matlab.ui.control.Label
        ScalingYvalue_DropDown        matlab.ui.control.DropDown
        importedcatalogsLabel         matlab.ui.control.Label
        CatalogNumValue_EditField     matlab.ui.control.NumericEditField
        catalognamesListBoxLabel      matlab.ui.control.Label
        CatalogNames_ListBox          matlab.ui.control.ListBox
        colorLabel                    matlab.ui.control.Label
        ScalingColorvalue_DropDown    matlab.ui.control.DropDown
        scaleDropDownLabel_2          matlab.ui.control.Label
        ScalingColorscale_DropDown    matlab.ui.control.DropDown
        minvalueEditFieldLabel_2      matlab.ui.control.Label
        PlotBinMinValue_EditField     matlab.ui.control.NumericEditField
        maxvalueLabel                 matlab.ui.control.Label
        PlotBinMaxValue_EditField     matlab.ui.control.NumericEditField
        binLabel                      matlab.ui.control.Label
        PlotBinNum_EditField          matlab.ui.control.NumericEditField
        ColormapDropDownLabel         matlab.ui.control.Label
        PlotColorMap_DropDown         matlab.ui.control.DropDown
        importedEQsLabel              matlab.ui.control.Label
        EQ_NumValue_EditField         matlab.ui.control.NumericEditField
        plotLabel                     matlab.ui.control.Label
        EQ2Plot_NumValue_EditField    matlab.ui.control.NumericEditField
        ofEQsLabel                    matlab.ui.control.Label
        useallCheckBox                matlab.ui.control.CheckBox
        LoadandparsecatalogPanel      matlab.ui.container.Panel
        LoadCatalog_Button            matlab.ui.control.Button
        RemoveEQswithlessthanEditFieldLabel  matlab.ui.control.Label
        LoadCatRemoveEQ_EditField     matlab.ui.control.NumericEditField
        LoadCatGroupTime_CheckBox     matlab.ui.control.CheckBox
        LoadCatGroupDist_CheckBox     matlab.ui.control.CheckBox
        maxdistEditFieldLabel         matlab.ui.control.Label
        LoadCatMaxDist_EditField      matlab.ui.control.NumericEditField
        CatalogimportedLampLabel      matlab.ui.control.Label
        LoadCat_Lamp                  matlab.ui.control.Lamp
        meanleglengthLabel            matlab.ui.control.Label
        LoadMeanLegLegth_EditField    matlab.ui.control.NumericEditField
        EQsintablewithMagnitudeLabel  matlab.ui.control.Label
        LoadMinMag4Table_EditField    matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        BlueWhiteRed = [0.0784    0.1686    0.5490;  0         0    1.0000;      0.0333    0.0333    1.0000; 0.0667    0.0667    1.0000;  0.1000    0.1000    1.0000; 0.1333    0.1333    1.0000;...
                        0.1667    0.1667    1.0000;  0.2000    0.2000    1.0000; 0.2333    0.2333    1.0000; 0.2667    0.2667    1.0000;  0.3000    0.3000    1.0000; 0.3333    0.3333    1.0000;...
                        0.3667    0.3667    1.0000;  0.4000    0.4000    1.0000; 0.4333    0.4333    1.0000; 0.4667    0.4667    1.0000;  0.5000    0.5000    1.0000; 0.5333    0.5333    1.0000;...
                        0.5667    0.5667    1.0000;  0.6000    0.6000    1.0000; 0.6333    0.6333    1.0000; 0.6667    0.6667    1.0000;  0.7000    0.7000    1.0000; 0.7333    0.7333    1.0000;...
                        0.7667    0.7667    1.0000;  0.8000    0.8000    1.0000; 0.8333    0.8333    1.0000; 0.8667    0.8667    1.0000;  0.9000    0.9000    1.0000; 0.9333    0.9333    1.0000;...
                        0.9667    0.9667    1.0000;  1.0000    1.0000    1.0000; 1.0000    0.9677    0.9677; 1.0000    0.9355    0.9355;  1.0000    0.9032    0.9032; 1.0000    0.8710    0.8710;...
                        1.0000    0.8387    0.8387;  1.0000    0.8065    0.8065; 1.0000    0.7742    0.7742; 1.0000    0.7419    0.7419;  1.0000    0.7097    0.7097; 1.0000    0.6774    0.6774;...
                        1.0000    0.6452    0.6452;  1.0000    0.6129    0.6129; 1.0000    0.5806    0.5806; 1.0000    0.5484    0.5484;  1.0000    0.5161    0.5161; 1.0000    0.4839    0.4839;...
                        1.0000    0.4516    0.4516;  1.0000    0.4194    0.4194; 1.0000    0.3871    0.3871; 1.0000    0.3548    0.3548;  1.0000    0.3226    0.3226; 1.0000    0.2903    0.2903;...
                        1.0000    0.2581    0.2581;  1.0000    0.2258    0.2258; 1.0000    0.1935    0.1935; 1.0000    0.1613    0.1613;  1.0000    0.1290    0.1290; 1.0000    0.0968    0.0968;...
                        1.0000    0.0645    0.0645;  1.0000    0.0323    0.0323; 1.0000         0         0; 0.8471    0.1608         0];
    
        Cols4Slip    = [1.0000    1.0000    1.0000;  0.8890    0.9670    1.0000; 0.7780    0.9330    1.0000; 0.6670    0.9000    1.0000;  0.5560    0.8670    1.0000; 0.4440    0.8330    1.0000;...
                        0.3330    0.8000    1.0000;  0.2220    0.7670    1.0000; 0.1110    0.7330    1.0000
                        0         0.7000    1.0000;  0         0.7110    0.8890; 0         0.7220    0.7780; 0         0.7330    0.6670;  0         0.7440    0.5560; 0         0.7560    0.4440;...
                        0         0.7670    0.3330;  0         0.7780    0.2220; 0         0.7890    0.1110; 0         0.8000         0;  0.0560    0.8220         0; 0.1110    0.8440    0;...
                        0.1670    0.8670         0;  0.2220    0.8890         0; 0.2780    0.9110         0; 0.3330    0.9330         0;  0.3890    0.9560         0; 0.4440    0.9780    0;...
                        0.5000    1.0000         0;  0.5560    1.0000         0; 0.6110    1.0000         0; 0.6670    1.0000         0;  0.7220    1.0000         0; 0.7780    1.0000    0;...
                        0.8330    1.0000         0;  0.8890    1.0000         0; 0.9440    1.0000         0; 1.0000    1.0000         0;  1.0000    0.9440         0; 1.0000    0.8890    0;...
                        1.0000    0.8330         0;  1.0000    0.7780         0; 1.0000    0.7220         0; 1.0000    0.6670         0;  1.0000    0.6110         0; 1.0000    0.5560    0;...
                        1.0000    0.5000         0;  1.0000    0.4440         0; 1.0000    0.3890         0; 1.0000    0.3330         0;  1.0000    0.2780         0; 1.0000    0.2220    0;...
                        1.0000    0.1670         0;  1.0000    0.1110         0; 1.0000    0.0560         0; 1.0000         0         0;  0.9440         0         0; 0.8890         0    0;...
                        0.8330         0         0;  0.7780         0         0; 0.7220         0         0; 0.6670         0         0;  0.6110         0         0; 0.5560         0    0;...
                        0.5000         0         0];
        
        Cln         = struct; 
        RMSFault    = struct;
        MeanLeg     = struct;
        CatNames    = {};
        ShearMod    = 0;
        DeltaT      = 0;
        CatImported = 0;
        EQcount     = 0;
        selectedRow = 1;
        selectedCol = 1;
    
        ExtraParamter = []; % Description
    end
    
    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadCatGroupTime_CheckBox
        function LoadCatGroupTime_CheckBoxValueChanged(app, event)
            app.LoadCatGroupTime_CheckBox.Value = 1;
            app.LoadCatGroupDist_CheckBox.Value = 0;          
        end

        % Value changed function: LoadCatGroupDist_CheckBox
        function LoadCatGroupDist_CheckBoxValueChanged(app, event)
            app.LoadCatGroupTime_CheckBox.Value = 0;
            app.LoadCatGroupDist_CheckBox.Value = 1;
        end

        % Button pushed function: LoadCatalog_Button
        function LoadCatalog_ButtonPushed(app, event)
            app.LoadCat_Lamp.Color = 'red';         drawnow;   
            app.CatImported        = 0;
            GroupByTime = app.LoadCatGroupTime_CheckBox.Value;
            GroupDist   = app.LoadCatMaxDist_EditField.Value;
            MinPatchNum = app.LoadCatRemoveEQ_EditField.Value;
            %---------------------------------------------------------------------
            [LoadName,LoadPath] = uigetfile('*Catalog.dat','Load EQ catalog','MultiSelect',"on");
            if iscell(LoadName)
                FileNumber   = size(LoadName,2);
                app.CatNames = {};
                for (i = 1 : FileNumber)
                    CtlgPaths{i} = strcat(LoadPath,LoadName{i});
                    CutName      = strfind(LoadName{i},'_');                    GeomName  = LoadName{i};
                    GeomName     = GeomName(1:CutName(end-1)-1);                GeomName2  = strcat(GeomName,'_Roughn.dat');                    
                    GeomPaths{i} = strcat(LoadPath,GeomName2);
                    CatsInList   = size(app.CatNames,2);
                    if isempty(CatsInList),     CatsInList = 0;         end
                    app.CatNames{CatsInList+1} = GeomName;
                end
            else
                if     (LoadName ~=0) 
                    app.CatNames = {};
                    FileNumber   = 1;
                    CtlgPaths{1} = strcat(LoadPath,LoadName);
                    CutName      = strfind(LoadName,'_');                       GeomName  = LoadName;  
                    GeomName     = GeomName(1:CutName(end-1)-1);                GeomName2  = strcat(GeomName,'_Roughn.dat');                 
                    GeomPaths{1}  = strcat(LoadPath,GeomName2);
                    CatsInList   = size(app.CatNames,2);
                    if isempty(CatsInList),     CatsInList = 0;         end
                    app.CatNames{CatsInList+1} = GeomName;
                    
                elseif (LoadName ==0),      return;         
                end
            end
            %---------------------------------------------------------------------
            %---------------------------------------------------------------------
            %---------------------------------------------------------------------
            for (i_File = 1 : FileNumber)

                %---------------------------------------------------------------------
                fidbinCat    = fopen(CtlgPaths{i_File},'rb');
                app.LoadCat_Lamp.Color = 'yellow';      drawnow;
                %---------------------------------------------------------------------
                EQnumRaw       = fread(fidbinCat,1,'int');
                FltGridnum     = fread(fidbinCat,1,'int');%the fault grid that is used
                app.ShearMod   = fread(fidbinCat,1,'single');
                app.DeltaT     = fread(fidbinCat,1,'single');

                FaultGeom               = struct;
                app.Cln(i_File).Catalog = struct; 
                %---------------------------------------------------------------------
                fidbinGeom = fopen(GeomPaths{i_File},'rb');
                %-----------------------
                for (ii = 1 : FltGridnum) %is always overwritten until I reach the grid that I want to use, typically I'd probably only do one...
                    PatchNum = fread(fidbinGeom,1,'int');
                    VertNum  = fread(fidbinGeom,1,'int');
                    FaultGeom.SizeTrigList       = PatchNum;
                    FaultGeom.SizeVertList       = VertNum;
                    %-----------------------
                    FaultGeom.TriangleList       = zeros(PatchNum,5);
                    FaultGeom.GlobTrigCentr      = zeros(PatchNum,3);
                    FaultGeom.VertexList         = zeros(VertNum,2);
                    FaultGeom.GlobalVertices     = zeros(VertNum,3); 
                    FaultGeom.TriangleAreas      = zeros(PatchNum,1);
                    %-----------------------
                    FaultGeom.TriangleList(:,1)  = fread(fidbinGeom,PatchNum,'int');
                    FaultGeom.TriangleList(:,2)  = fread(fidbinGeom,PatchNum,'int');
                    FaultGeom.TriangleList(:,3)  = fread(fidbinGeom,PatchNum,'int');
                    FaultGeom.TriangleList(:,4)  = fread(fidbinGeom,PatchNum,'int');
                    FaultGeom.TriangleList(:,5)  = fread(fidbinGeom,PatchNum,'int');
                    FaultGeom.VertexList(:,1)    = fread(fidbinGeom,VertNum,'single');
                    FaultGeom.VertexList(:,2)    = fread(fidbinGeom,VertNum,'single');
                    FaultGeom.GlobalVertices(:,1)= fread(fidbinGeom,VertNum,'single');
                    FaultGeom.GlobalVertices(:,2)= fread(fidbinGeom,VertNum,'single');
                    FaultGeom.GlobalVertices(:,3)= fread(fidbinGeom,VertNum,'single');
                    FaultGeom.GlobTrigCentr(:,1) = fread(fidbinGeom,PatchNum,'single');
                    FaultGeom.GlobTrigCentr(:,2) = fread(fidbinGeom,PatchNum,'single');
                    FaultGeom.GlobTrigCentr(:,3) = fread(fidbinGeom,PatchNum,'single');  
                 
                 %   FaultGeom.FltNum               = fread(fidbinGeom,1,     'int');  
                 %   app.RMSFault(i_File).Value(:,1)= fread(fidbinGeom,FaultGeom.FltNum,'single');  
                end
                fclose(fidbinGeom);
                %---------------------------------------------------------------------
                %---------------------------------------------------------------------
                MeanLegLength = 0;
                for i = 1 : size(FaultGeom.TriangleList,1)
                    v1    = FaultGeom.TriangleList(i,1);
                    v2    = FaultGeom.TriangleList(i,2);
                    v3    = FaultGeom.TriangleList(i,3);
                    %---------------------------
                    %https://www.mathworks.com/matlabcentral/answers/271797-surface-area-of-a-3d-surface
                    FaultGeom.TriangleAreas(i,1) = 1/2*norm(cross((FaultGeom.GlobalVertices(v2,:)-FaultGeom.GlobalVertices(v1,:)),(FaultGeom.GlobalVertices(v3,:)-FaultGeom.GlobalVertices(v1,:))));   
        
                    Leg12   = sqrt((FaultGeom.GlobalVertices(v1,1)-FaultGeom.GlobalVertices(v2,1))^2 +(FaultGeom.GlobalVertices(v1,2)-FaultGeom.GlobalVertices(v2,2))^2 +(FaultGeom.GlobalVertices(v1,3)-FaultGeom.GlobalVertices(v2,3))^2 ); 
                    Leg13   = sqrt((FaultGeom.GlobalVertices(v1,1)-FaultGeom.GlobalVertices(v3,1))^2 +(FaultGeom.GlobalVertices(v1,2)-FaultGeom.GlobalVertices(v3,2))^2 +(FaultGeom.GlobalVertices(v1,3)-FaultGeom.GlobalVertices(v3,3))^2 ); 
                    Leg23   = sqrt((FaultGeom.GlobalVertices(v2,1)-FaultGeom.GlobalVertices(v3,1))^2 +(FaultGeom.GlobalVertices(v2,2)-FaultGeom.GlobalVertices(v3,2))^2 +(FaultGeom.GlobalVertices(v2,3)-FaultGeom.GlobalVertices(v3,3))^2 ); 
                    MeanLegLength = MeanLegLength + mean([Leg12,Leg13,Leg23]);
                    %--------------------------- necessary for distance check if it is going to be used
                end
                MeanLegLength                        = MeanLegLength/size(FaultGeom.TriangleList,1);
                app.MeanLeg(i_File).Value            = MeanLegLength;
                app.LoadMeanLegLegth_EditField.Value = MeanLegLength;
                drawnow;
                FaultArea   = sum(FaultGeom.TriangleAreas(:,1));
                PatchArea   = FaultArea/PatchNum;
                
                HorzComp    = [FaultGeom.GlobTrigCentr(:,1),FaultGeom.GlobTrigCentr(:,2)];
                VertComp    = FaultGeom.GlobTrigCentr(:,3);
                HorzDist    = max(pdist2(HorzComp,HorzComp,'euclidean','Largest',1));
                VertDist    = max(pdist(VertComp,'euclidean'));
                FaultLength = HorzDist +MeanLegLength;
                FaultWidth  = VertDist +MeanLegLength;
                %---------------------------------------------------------------------
                %---------------------------------------------------------------------
                %https://www.mathworks.com/help/stats/fitting-an-orthogonal-regression-using-principal-components-analysis.html
                %REMEMBER: ALL THIS IS JUST A HACK B/C I DIDN'T COMPUTE THE RMS BEFORE!
                coeff   = pca(FaultGeom.GlobTrigCentr);
                normal  = coeff(:,3);
                n       = size(FaultGeom.GlobTrigCentr,1);
                meanX   = mean(FaultGeom.GlobTrigCentr,1);
                error   = abs((FaultGeom.GlobTrigCentr - repmat(meanX,n,1))*normal);
                %RMSL1 = sqrt(1/FaultArea *sum(FaultGeom.GlobTrigCentr(:,1).^2 *PatchArea) )/max([FaultLength  FaultWidth]);
                RMSL2                           = sqrt(1/FaultArea *sum(error(:,1).^2 *PatchArea) )/max([FaultLength  FaultWidth]);
                app.RMSFault(i_File).Value(1,1) = RMSL2; %would be done 'per fault -when imported
                

                disp(strcat(app.CatNames{i_File} ,'__RMSL:',num2str(RMSL2)));
                %---------------------------------------------------------------------
                %---------------------------------------------------------------------
                disp(strcat('Number of "raw"/unparsed earthquakes:',num2str(EQnumRaw)));
                EQcount       = 0;
                for i = 1 : EQnumRaw
                    Time    = fread(fidbinCat,1,      'single');
                    Magn    = fread(fidbinCat,1,      'single');
                    PtNum   = fread(fidbinCat,1,      'int');                
                    MRFNum  = fread(fidbinCat,1,      'int');
                    MRFval  = fread(fidbinCat,MRFNum, 'single');
                    %---------------------------------------
                    PtchID = fread(fidbinCat,PtNum,   'int');
                    PtchID = PtchID +1; %this is again b/c of C vs. matlab notation
                    t0     = fread(fidbinCat,PtNum,   'int');
                    delt_t = fread(fidbinCat,PtNum,   'single');
                    slip_h = fread(fidbinCat,PtNum,   'single');
                    slip_v = fread(fidbinCat,PtNum,   'single');
                    StabTyp= fread(fidbinCat,PtNum,   'int');
                    %---------------------------------------%---------------------------------------
                    if (GroupByTime == 1)
                        if (PtNum >= MinPatchNum)
                            EQcount = EQcount +1;
                            %---------------------------------------%----
                            %---------------------------------------%----
                            PatchCent_E = FaultGeom.GlobTrigCentr(PtchID,1);
                            PatchCent_N = FaultGeom.GlobTrigCentr(PtchID,2);
                            PatchCent_Z = FaultGeom.GlobTrigCentr(PtchID,3);  
                            %---------------------------------------%----
                            HorzDist    = max(pdist2([PatchCent_E,PatchCent_N],[PatchCent_E,PatchCent_N],'euclidean','Largest',1));
                            VertDist    = max(pdist(PatchCent_Z,'euclidean'));   
                            
                            RuptLength  = HorzDist +app.MeanLeg(i_File).Value;
                            RuptWidth   = VertDist +app.MeanLeg(i_File).Value;                             
                            RuptArea    = sum(FaultGeom.TriangleAreas(PtchID,1));
                            PatchArea   = RuptArea/PtNum;
                            %---------------------------------------%----
                            if (PtNum <= 3)
                                RMSvalue= app.RMSFault(i_File).Value(1,1); 
                            else
                                %https://www.mathworks.com/help/stats/fitting-an-orthogonal-regression-using-principal-components-analysis.html
                             %  coeff   = pca([PatchCent_E,PatchCent_N,PatchCent_Z]);
                             %  normal  = coeff(:,3);
                             %  meanX   = mean([PatchCent_E,PatchCent_N,PatchCent_Z],1);
                             %  error   = abs(([PatchCent_E,PatchCent_N,PatchCent_Z] - repmat(meanX,PtNum,1))*normal);
                             %  RMSvalue= sqrt(1/RuptArea *sum(error(:,1).^2 *PatchArea) )/max([RuptLength  RuptWidth]);
                            
                                RMSvalue = app.RMSFault(i_File).Value(1,1);
                            
                            end
                            %---------------------------------------%----
                            %---------------------------------------%----
                            app.Cln(i_File).Catalog(EQcount).EQID       = EQcount;
                            app.Cln(i_File).Catalog(EQcount).EQtime     = Time;
                            app.Cln(i_File).Catalog(EQcount).Magn       = Magn;
                            app.Cln(i_File).Catalog(EQcount).RuptArea   = RuptArea;
                            app.Cln(i_File).Catalog(EQcount).PtchNum    = PtNum;
                            app.Cln(i_File).Catalog(EQcount).Delt_t     = mean(delt_t);
                            app.Cln(i_File).Catalog(EQcount).Slip_h     = mean(slip_h);
                            app.Cln(i_File).Catalog(EQcount).Slip_v     = mean(slip_v);
                            app.Cln(i_File).Catalog(EQcount).Slip       = sqrt(mean(slip_h)^2+mean(slip_v)^2);
                            app.Cln(i_File).Catalog(EQcount).RuptDur    = (max(t0)-min(t0)).*app.DeltaT;
                            app.Cln(i_File).Catalog(EQcount).RuptLength = RuptLength;
                            app.Cln(i_File).Catalog(EQcount).RuptWidth  = RuptWidth;
                            app.Cln(i_File).Catalog(EQcount).RMSL       = RMSvalue;
                            
                            %------------------%------------------
                        end
                    else
                    %---------------------------------------%---------------------------------------
                        TempEQcount    = 1;
                        subEQlist      = zeros(PtNum, 7);
                        
                        subEQlist(:,1) = PtchID;
                        subEQlist(:,2) = FaultGeom.GlobTrigCentr(PtchID,1);
                        subEQlist(:,3) = FaultGeom.GlobTrigCentr(PtchID,2);
                        subEQlist(:,4) = FaultGeom.GlobTrigCentr(PtchID,3);
                        subEQlist(:,5) = zeros(PtNum,1); %this is tempEQID
                        subEQlist(:,6) = zeros(PtNum,1); %this is "still looking"
                        subEQlist(:,7) = FaultGeom.TriangleAreas(PtchID,1);
                        %------------------%------------%------------------%------------
                        subEQlist(1,5) = TempEQcount;
                        StillOpen      = (PtNum > 1); % if I have at least one other patch activated in same "raw" EQ
                        %------------------%------------%------------------%------------
                        while (StillOpen == true)
                            NextTrigs2Test = subEQlist(subEQlist(:,5)           == TempEQcount,:);
                            NextTrigs2Test = NextTrigs2Test(NextTrigs2Test(:,6) ==     0      ,:);
                            Trigs2TestWith = subEQlist(subEQlist(:,5)           ==     0      ,:);
                            
                            FoundOne  = 0;
                            for (k = 1 : size(NextTrigs2Test,1))
                                CurrMastrPos             = find(subEQlist(:,1) == NextTrigs2Test(k,1),1,'first');
                                subEQlist(CurrMastrPos,6)= 1; %if this triangle was already tested against the list of "still open"
                                Dists                    = sqrt( (Trigs2TestWith(:,2)-NextTrigs2Test(k,2)).^2 + (Trigs2TestWith(:,3)-NextTrigs2Test(k,3)).^2 + (Trigs2TestWith(:,4)-NextTrigs2Test(k,4)).^2);
    
                                for (kk = 1 : size(Trigs2TestWith,1))
                                    if (Dists(kk,1) <= MeanLegLength*GroupDist)
                                        FoundOne                  = 1;
                                        CurrSlavePos              = find(subEQlist(:,1) == Trigs2TestWith(kk,1),1,'first');
                                        subEQlist(CurrSlavePos,5) = TempEQcount;
                                    end
                                end 
                            end
                            StillOpen = (min(subEQlist(:,5)) == 0);
                            if ((StillOpen == true) && (FoundOne == 0))
                                
                                TempEQcount              = TempEQcount +1;
                                Trigs2TestWith           = subEQlist(subEQlist(:,5) == 0,:);
                                CurrSlavePos             = find(subEQlist(:,1) == Trigs2TestWith(1,1),1,'first');
                                subEQlist(CurrSlavePos,5)= TempEQcount;
                            end
                            StillOpen = (min(subEQlist(:,5)) == 0);
                        end
                        %------------------%------------%------------------%------------
                        SubEventID = unique(subEQlist(:,5));
                        for k = min(SubEventID) : max(SubEventID)
                            SelPatches = subEQlist(subEQlist(:,5) == k,:);
                            
                            if (size(SelPatches,1) >= MinPatchNum)
                                EQcount     = EQcount +1;
                                %---------------------------------------%----
                                PatchCent_E = SelPatches(:,2);
                                PatchCent_N = SelPatches(:,3);
                                PatchCent_Z = SelPatches(:,4);  
                                %---------------------------------------%----
                                HorzDist    = max(pdist2([PatchCent_E,PatchCent_N],[PatchCent_E,PatchCent_N],'euclidean','Largest',1));
                                VertDist    = max(pdist(PatchCent_Z,'euclidean'));   
                                RuptLength  = HorzDist +app.MeanLeg(i_File).Value;
                                RuptWidth   = VertDist +app.MeanLeg(i_File).Value;                             
                                RuptArea    = sum(SelPatches(:,7));
                                PatchArea   = RuptArea/size(SelPatches,1);
                                %------------------%-----------
                                %------------------%-----------
                                if (size(SelPatches,1) <= 3)
                                    RMSvalue = app.RMSFault(i_File).Value(1,1);
                                else
                                    %https://www.mathworks.com/help/stats/fitting-an-orthogonal-regression-using-principal-components-analysis.html
                                    %coeff   = pca([PatchCent_E,PatchCent_N,PatchCent_Z]);
                                    %normal  = coeff(:,3);
                                    %meanX   = mean([PatchCent_E,PatchCent_N,PatchCent_Z],1);
                                    %error   = abs(([PatchCent_E,PatchCent_N,PatchCent_Z]- repmat(meanX,size(SelPatches,1),1))*normal);
                                    %RMSvalue= sqrt(1/RuptArea *sum(error(:,1).^2 *PatchArea) )/max([RuptLength  RuptWidth]);
                                
                                    RMSvalue = app.RMSFault(i_File).Value(1,1);
                                end
                                %------------------%-----------
                                %------------------%-----------
                                temp1 = []; temp2 = [];      temp3 = [];     temp4 = [];
                                for kk = 1 : size(SelPatches,1)
                                    NextPtchID = SelPatches(kk,1);
                                    PatchPos   = find(PtchID == NextPtchID,1,'first');
                                    temp1      = [temp1; t0(PatchPos)];
                                    temp2      = [temp2; delt_t(PatchPos)];
                                    temp3      = [temp3; slip_h(PatchPos)];
                                    temp4      = [temp4; slip_v(PatchPos)];
                                end  
                                %------------------%-----------%-----------
                                Moment                 = app.ShearMod*RuptArea*1E+6*sqrt(mean(temp3)^2 +mean(temp4)^2); %needs to be recalculated => but requires shear modulus... => put into EQ output??      
                                Magn                   = (log10(Moment)-9.1)/1.5;
                                
                                app.Cln(i_File).Catalog(EQcount).EQID       = EQcount;
                                app.Cln(i_File).Catalog(EQcount).EQtime     = Time;
                                app.Cln(i_File).Catalog(EQcount).Magn       = Magn; 
                                app.Cln(i_File).Catalog(EQcount).RuptArea   = RuptArea;
                                app.Cln(i_File).Catalog(EQcount).PtchNum    = size(SelPatches,1);
                                app.Cln(i_File).Catalog(EQcount).Delt_t     = mean(temp2);
                                app.Cln(i_File).Catalog(EQcount).Slip_h     = mean(temp3);
                                app.Cln(i_File).Catalog(EQcount).Slip_v     = mean(temp4);
                                app.Cln(i_File).Catalog(EQcount).Slip       = sqrt(mean(temp3)^2+mean(temp4)^2);
                                app.Cln(i_File).Catalog(EQcount).RuptDur    = (max(temp1)-min(temp1)).*app.DeltaT;
                                app.Cln(i_File).Catalog(EQcount).RuptLength = RuptLength;
                                app.Cln(i_File).Catalog(EQcount).RuptWidth  = RuptWidth;
                                app.Cln(i_File).Catalog(EQcount).RMSL       = RMSvalue;
                                %------------------%------------------
                            end
                        end  
                    end
                end
                %------------------%------------%------------------%------------
                fclose(fidbinCat);
                %------------------%------------%------------------%------------
                %------------------%------------%------------------%------------
                app.EQcount(i_File)    = EQcount;
            end    

            app.CatImported        = 1;
            app.LoadCat_Lamp.Color = 'green';     
            
            app.CatalogNames_ListBox.Items     = app.CatNames;
            app.CatalogNames_ListBox.ItemsData = 1:size(app.CatNames,2);
            app.CatalogNames_ListBox.Value     = size(app.CatNames,2);
            app.CatalogNumValue_EditField.Value= size(app.CatNames,2);
            app.EQ_NumValue_EditField.Value    = sum(app.EQcount(:));
        end

        % Button pushed function: PlotScaling_Button
        function PlotScaling_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end       
            
            FigNum    = app.FigNumScaling_EditField.Value;
            SelX      = str2double(app.ScalingXvalue_DropDown.Value);
            StringX   = app.ScalingXvalue_DropDown.Items{SelX};
            SelY      = str2double(app.ScalingYvalue_DropDown.Value);
            StringY   = app.ScalingYvalue_DropDown.Items{SelY};
            SelSymb   = str2double(app.Scaling_Symbol_DropDown.Value);
            SelSymbs  = app.Scaling_Symbol_DropDown.Items{SelSymb};      
            SclX      = str2double(app.ScalingXscale_DropDown.Value);
            SclXs     = app.ScalingXscale_DropDown.Items{SclX};
            SclY      = str2double(app.ScalingYscale_DropDown.Value);
            SclYs     = app.ScalingYscale_DropDown.Items{SclY};
            
            SelC      = str2double(app.ScalingColorvalue_DropDown.Value);
            StringC   = app.ScalingColorvalue_DropDown.Items{SelC};
            SclC      = str2double(app.ScalingColorscale_DropDown.Value);
            SclCs     = app.ScalingColorscale_DropDown.Items{SclC};
            
            MarkSize  = 8;
            ColMapSel = str2double(app.PlotColorMap_DropDown.Value);
            SelctdList= app.CatalogNames_ListBox.Value;
            Fract2Plot= app.EQ2Plot_NumValue_EditField.Value;
            
            if (SelC < 13)
                if     (ColMapSel == 1), ColMap = parula(128);
                elseif (ColMapSel == 2), ColMap = jet(128);
                elseif (ColMapSel == 3), ColMap = hot(128);
                elseif (ColMapSel == 4), ColMap = gray(128);
                elseif (ColMapSel == 5), ColMap = app.BlueWhiteRed;
                elseif (ColMapSel == 6), ColMap = app.Cols4Slip;    
                end
            else
                ColMap = parula(128);
            end
            
            if (app.useallCheckBox.Value == 1),         SelctdList = 1:size(app.Cln,2);          end
            
            MinBinVal = app.PlotBinMinValue_EditField.Value;
            MaxBinVal = app.PlotBinMaxValue_EditField.Value;
            BinNum    = app.PlotBinNum_EditField.Value;
            %---------------------------------------------------  
            Xcomp     = [];
            Ycomp     = [];
            Ccomp     = [];
            for ii = 1 : size(SelctdList,2) 
                i = SelctdList(ii);
                CtlgLgth =size([app.Cln(i).Catalog(:).EQtime],2);
                %--------------------------------
                if (SelX == 1),        Xcomp = [Xcomp; [app.Cln(i).Catalog(:).EQtime]', ones(CtlgLgth,1).*i];
                elseif (SelX == 2),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).Magn]',ones(CtlgLgth,1).*i];
                elseif (SelX == 3),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).RuptArea]',ones(CtlgLgth,1).*i];
                elseif (SelX == 4),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).PtchNum]',ones(CtlgLgth,1).*i];
                elseif (SelX == 5),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).Delt_t]',ones(CtlgLgth,1).*i];
                elseif (SelX == 6),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).Slip_h]',ones(CtlgLgth,1).*i];
                elseif (SelX == 7),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).Slip_v]',ones(CtlgLgth,1).*i];
                elseif (SelX == 8),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).Slip]',ones(CtlgLgth,1).*i];
                elseif (SelX == 9),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).RuptDur]',ones(CtlgLgth,1).*i];
                elseif (SelX ==10),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).RuptLength]',ones(CtlgLgth,1).*i];
                elseif (SelX ==11),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).RuptWidth]',ones(CtlgLgth,1).*i];
                elseif (SelX ==12),    Xcomp = [Xcomp; [app.Cln(i).Catalog(:).RMSL]',ones(CtlgLgth,1).*i];
                end
                %--------------------------------
                if (SelY == 1),        Ycomp = [Ycomp; [app.Cln(i).Catalog(:).EQtime]', ones(CtlgLgth,1).*i];
                elseif (SelY == 2),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).Magn]', ones(CtlgLgth,1).*i];
                elseif (SelY == 3),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).RuptArea]', ones(CtlgLgth,1).*i];
                elseif (SelY == 4),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).PtchNum]', ones(CtlgLgth,1).*i];
                elseif (SelY == 5),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).Delt_t]', ones(CtlgLgth,1).*i];
                elseif (SelY == 6),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).Slip_h]', ones(CtlgLgth,1).*i];
                elseif (SelY == 7),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).Slip_v]', ones(CtlgLgth,1).*i];
                elseif (SelY == 8),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).Slip]', ones(CtlgLgth,1).*i];
                elseif (SelY == 9),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).RuptDur]', ones(CtlgLgth,1).*i];
                elseif (SelY ==10),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).RuptLength]', ones(CtlgLgth,1).*i];
                elseif (SelY ==11),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).RuptWidth]', ones(CtlgLgth,1).*i];
                elseif (SelY ==12),    Ycomp = [Ycomp; [app.Cln(i).Catalog(:).RMSL]', ones(CtlgLgth,1).*i];
                end
                %--------------------------------
                if (SelC == 1),        Ccomp = [Ccomp; [app.Cln(i).Catalog(:).EQtime]', ones(CtlgLgth,1).*i];
                elseif (SelC == 2),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).Magn]', ones(CtlgLgth,1).*i];
                elseif (SelC == 3),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).RuptArea]', ones(CtlgLgth,1).*i];
                elseif (SelC == 4),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).PtchNum]', ones(CtlgLgth,1).*i];
                elseif (SelC == 5),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).Delt_t]', ones(CtlgLgth,1).*i];
                elseif (SelC == 6),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).Slip_h]', ones(CtlgLgth,1).*i];
                elseif (SelC == 7),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).Slip_v]', ones(CtlgLgth,1).*i];
                elseif (SelC == 8),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).Slip]', ones(CtlgLgth,1).*i];
                elseif (SelC == 9),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).RuptDur]', ones(CtlgLgth,1).*i];
                elseif (SelC ==10),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).RuptLength]', ones(CtlgLgth,1).*i];
                elseif (SelC ==11),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).RuptWidth]', ones(CtlgLgth,1).*i];
                elseif (SelC ==12),    Ccomp = [Ccomp; [app.Cln(i).Catalog(:).RMSL]', ones(CtlgLgth,1).*i];
                end
            end
            %--------------------------------------------------------------
            Fraction2Pick = round(size(Xcomp,1)*(Fract2Plot/100));
            PickedPos     = randi([1 size(Xcomp,1)],Fraction2Pick,1);
            Xcomp         = Xcomp(PickedPos,:);
            if (SelY <=12),       Ycomp = Ycomp(PickedPos,:);          end
            if (SelC <=12),       Ccomp = Ccomp(PickedPos,:);          end
            %--------------------------------%--------------------------------
            %--------------------------------%--------------------------------
            if BinNum > 1
                BinValues = linspace(MinBinVal,MaxBinVal,BinNum);
            else
                BinValues = linspace(min(Xcomp(:,1)),max(Xcomp(:,1)),round(sqrt(size(Xcomp,1))));
            end 
            %--------------------------------%--------------------------------
            %--------------------------------%--------------------------------
            figure(FigNum); clf; hold on;
            if ((SelX == 1) || (SelY == 1))%don't combine catalogs... => treat/plot independently
                for ii = 1 : size(SelctdList,2)
                    i     = SelctdList(ii);
                    NextX = Xcomp(Xcomp(:,2)==i,:);
                    if (SelY<=12)
                        NextY = Ycomp(Xcomp(:,2)==i,:); 
                        if (SelC<=12),      NextC = Ccomp(Xcomp(:,2)==i,:);     else,   NextC = ones(size(NextX,1),1);     end
                    
                        AA    = [NextX,[1:size(NextX,1)]'];                            AA = sortrows(AA,1);
                        NextX = NextX(AA(:,3));     NextY = NextY(AA(:,3));         NextC = NextC(AA(:,3));

                        if (SelC == 13)
                            plot(NextX(:,1),NextY(:,1),SelSymbs);
                        else
                            scatter(NextX(:,1),NextY(:,1),MarkSize,NextC(:,1),'filled');
                        end
                    elseif (SelY == 13)
                    elseif (SelY == 14) 
                    end
                end
            else
                if (SelY <= 12)    
                    if (SelC == 13)
                        plot(Xcomp(:,1),Ycomp(:,1),SelSymbs);
                    else
                        scatter(Xcomp(:,1),Ycomp(:,1),MarkSize,Ccomp(:,1),'filled');
                    end
                elseif SelY == 13
                    for ii = 1 : size(SelctdList,2)
                        i                   = SelctdList(ii);
                        NextX               = Xcomp(Xcomp(:,2)==i,:);
                        [SelYvals,SelXvals] = histcounts(NextX,BinValues);     
                        SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                        SelXvals            = SelXvals(1:end-1);
                        SelYvals            = SelYvals./sum(SelYvals); 
                        
                        if (SelC<=12)      
                            NextC  = Ccomp(Xcomp(:,2)==i,:);  
                            MinRMS = 0;
                            MaxRMS = 0.01;
                            
                            ColPos = round((mean(NextC(:,1))-MinRMS)./(MaxRMS-MinRMS).*size(ColMap,1));
                            if (ColPos < 1),                ColPos = 1;                     end 
                            if (ColPos >= size(ColMap,1)),  ColPos = size(ColMap,1);        end
  
                            
                            plot(SelXvals,SelYvals,SelSymbs,"Color",ColMap(ColPos,:))

                        else   
                            plot(SelXvals, SelYvals,SelSymbs);  
                        end
                    end
                elseif SelY == 14
                    for ii = 1 : size(SelctdList,2)
                        i                   = SelctdList(ii);
                        NextX               = Xcomp(Xcomp(:,2)==i,:);
                        [SelYvals,SelXvals] = histcounts(NextX,BinValues);     
                        SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                        SelXvals            = SelXvals(1:end-1);
                        SelYvals            = SelYvals./sum(SelYvals); 
                        SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                
                        if (SelC<=12)      
                            NextC  = Ccomp(Xcomp(:,2)==i,:);  
                            MinRMS = 0;
                            MaxRMS = 0.01;
                            
                            ColPos = round((mean(NextC(:,1))-MinRMS)./(MaxRMS-MinRMS).*size(ColMap,1));
                            if (ColPos < 1),                ColPos = 1;                     end 
                            if (ColPos >= size(ColMap,1)),  ColPos = size(ColMap,1);        end
  
                            
                            plot(SelXvals,SelYvals,SelSymbs,"Color",ColMap(ColPos,:),'LineWidth',2)

                        else   
                            plot(SelXvals, SelYvals,SelSymbs);  
                        end
                    end 
            end
            end
            
            set(gca,'XScale',SclXs,'YScale',SclYs);
            
            if (SclC == 1),     set(gca,'ColorScale','linear');
            else,               set(gca,'ColorScale','log');
            end
            grid on;
            %---------------------
            xlabel(strcat(StringX,',',SclXs));   ylabel(strcat(StringY,',',SclYs));      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ImportedparsedEQcatalogPanel = uipanel(app.UIFigure);
            app.ImportedparsedEQcatalogPanel.Title = 'Imported/parsed EQ catalog';
            app.PlotScaling_Button = uibutton(app.ImportedparsedEQcatalogPanel, 'push');
            app.PlotScaling_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotScaling_ButtonPushed, true);
            app.PlotScaling_Button.Text = 'Plot scaling rel. 4 sel. catalog(s)';
            app.FigEditField_2Label = uilabel(app.ImportedparsedEQcatalogPanel);
            app.FigEditField_2Label.Text = 'Fig #';
            app.FigNumScaling_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.FigNumScaling_EditField.Limits = [1 Inf];
            app.FigNumScaling_EditField.RoundFractionalValues = 'on';
            app.FigNumScaling_EditField.ValueDisplayFormat = '%d';
            app.FigNumScaling_EditField.Value = 3;
            app.symbolDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.symbolDropDownLabel.Text = 'symbol';
            app.Scaling_Symbol_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.Scaling_Symbol_DropDown.Items = {'-', '--', '-.', '.-', '.', 'o-', 'o'};
            app.Scaling_Symbol_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.Scaling_Symbol_DropDown.Value = '1';
            app.scaleDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.scaleDropDownLabel.Text = 'scale';
            app.ScalingXscale_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingXscale_DropDown.Items = {'linear', 'log'};
            app.ScalingXscale_DropDown.ItemsData = {'1', '2'};
            app.ScalingXscale_DropDown.Value = '1';
            app.scaleDropDown_2Label = uilabel(app.ImportedparsedEQcatalogPanel);
            app.scaleDropDown_2Label.Text = 'scale';
            app.ScalingYscale_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingYscale_DropDown.Items = {'linear', 'log'};
            app.ScalingYscale_DropDown.ItemsData = {'1', '2'};
            app.ScalingYscale_DropDown.Value = '1';
            app.xaxisDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.xaxisDropDownLabel.Text = 'x-axis';
            app.ScalingXvalue_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingXvalue_DropDown.Items = {'time', 'magnitude', 'rupture area', 'slipping patches', 'stress drop', 'horz. slip', 'vert. slip', 'comb. slip', 'rupt. duration', 'rupt. length', 'rupt. width', 'RMS/L'};
            app.ScalingXvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12'};
            app.ScalingXvalue_DropDown.Value = '1';
            app.yaxisDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.yaxisDropDownLabel.Text = 'y-axis';
            app.ScalingYvalue_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingYvalue_DropDown.Items = {'time', 'magnitude', 'rupture area', 'slipping patches', 'stress drop', 'horz. slip', 'vert. slip', 'comb. slip', 'rupt. duration', 'rupt. length', 'rupt. width', 'RMS/L', 'frequency', 'frequency (cum)'};
            app.ScalingYvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14'};
            app.ScalingYvalue_DropDown.Value = '2';
            app.importedcatalogsLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.importedcatalogsLabel.Text = 'imported catalogs';
            app.CatalogNumValue_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.CatalogNumValue_EditField.Limits = [1 Inf];
            app.CatalogNumValue_EditField.RoundFractionalValues = 'on';
            app.CatalogNumValue_EditField.ValueDisplayFormat = '%d';
            app.CatalogNumValue_EditField.Editable = 'off';
            app.CatalogNumValue_EditField.Value = 1;
            app.catalognamesListBoxLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.catalognamesListBoxLabel.Text = 'catalog names';
            app.CatalogNames_ListBox = uilistbox(app.ImportedparsedEQcatalogPanel);
            app.CatalogNames_ListBox.Items = {'none imported'};
            app.CatalogNames_ListBox.Multiselect = 'on';
            app.CatalogNames_ListBox.Value = {'none imported'};
            app.colorLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.colorLabel.Text = 'color';
            app.ScalingColorvalue_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingColorvalue_DropDown.Items = {'time', 'magnitude', 'rupture area', 'slipping patches', 'stress drop', 'horz. slip', 'vert. slip', 'comb. slip', 'rupt. duration', 'rupt. length', 'rupt. width', 'RMS/L', 'none'};
            app.ScalingColorvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.ScalingColorvalue_DropDown.Value = '13';
            app.scaleDropDownLabel_2 = uilabel(app.ImportedparsedEQcatalogPanel);
            app.scaleDropDownLabel_2.Text = 'scale';
            app.ScalingColorscale_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingColorscale_DropDown.Items = {'linear', 'log'};
            app.ScalingColorscale_DropDown.ItemsData = {'1', '2'};
            app.ScalingColorscale_DropDown.Value = '1';
            app.minvalueEditFieldLabel_2 = uilabel(app.ImportedparsedEQcatalogPanel);
            app.minvalueEditFieldLabel_2.Text = 'min. value';
            app.PlotBinMinValue_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.PlotBinMinValue_EditField.ValueDisplayFormat = '%5.2f';
            app.maxvalueLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.maxvalueLabel.Text = 'max. value';
            app.PlotBinMaxValue_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.PlotBinMaxValue_EditField.ValueDisplayFormat = '%5.2f';
            app.PlotBinMaxValue_EditField.Value = 1;
            app.binLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.binLabel.Text = 'bin #';
            app.PlotBinNum_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.PlotBinNum_EditField.Limits = [1 Inf];
            app.PlotBinNum_EditField.ValueDisplayFormat = '%d';
            app.PlotBinNum_EditField.Value = 1;
            app.ColormapDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.PlotColorMap_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.PlotColorMap_DropDown.Items = {'parula', 'jet', 'hot', 'gray', 'blue-white-red', 'slip'};
            app.PlotColorMap_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.PlotColorMap_DropDown.Value = '1';
            app.importedEQsLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.importedEQsLabel.Text = 'imported EQs';
            app.EQ_NumValue_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.EQ_NumValue_EditField.Limits = [1 Inf];
            app.EQ_NumValue_EditField.RoundFractionalValues = 'on';
            app.EQ_NumValue_EditField.ValueDisplayFormat = '%d';
            app.EQ_NumValue_EditField.Editable = 'off';
            app.EQ_NumValue_EditField.Value = 1;
            app.plotLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.plotLabel.Text = 'plot ';
            app.EQ2Plot_NumValue_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.EQ2Plot_NumValue_EditField.Limits = [1 100];
            app.EQ2Plot_NumValue_EditField.RoundFractionalValues = 'on';
            app.EQ2Plot_NumValue_EditField.ValueDisplayFormat = '%d';
            app.EQ2Plot_NumValue_EditField.Value = 10;
            app.ofEQsLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.ofEQsLabel.Text = '% of EQs';
            app.useallCheckBox = uicheckbox(app.ImportedparsedEQcatalogPanel);
            app.useallCheckBox.Text = 'use all';
            app.LoadandparsecatalogPanel = uipanel(app.UIFigure);
            app.LoadandparsecatalogPanel.Title = 'Load and parse catalog';
            app.LoadCatalog_Button = uibutton(app.LoadandparsecatalogPanel, 'push');
            app.LoadCatalog_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadCatalog_ButtonPushed, true);
            app.LoadCatalog_Button.Text = 'Load Catalog';
            app.RemoveEQswithlessthanEditFieldLabel = uilabel(app.LoadandparsecatalogPanel);
            app.RemoveEQswithlessthanEditFieldLabel.Text = 'Remove EQs with less than';
            app.LoadCatRemoveEQ_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadCatRemoveEQ_EditField.Limits = [1 Inf];
            app.LoadCatRemoveEQ_EditField.RoundFractionalValues = 'on';
            app.LoadCatRemoveEQ_EditField.ValueDisplayFormat = '%d';
            app.LoadCatRemoveEQ_EditField.Value = 1;
            app.LoadCatGroupTime_CheckBox = uicheckbox(app.LoadandparsecatalogPanel);
            app.LoadCatGroupTime_CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCatGroupTime_CheckBoxValueChanged, true);
            app.LoadCatGroupTime_CheckBox.Text = 'Group by time';
            app.LoadCatGroupTime_CheckBox.Value = true;
            app.LoadCatGroupDist_CheckBox = uicheckbox(app.LoadandparsecatalogPanel);
            app.LoadCatGroupDist_CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCatGroupDist_CheckBoxValueChanged, true);
            app.LoadCatGroupDist_CheckBox.Text = 'Group by distance';
            app.maxdistEditFieldLabel = uilabel(app.LoadandparsecatalogPanel);
            app.maxdistEditFieldLabel.Text = 'max. dist.';
            app.LoadCatMaxDist_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadCatMaxDist_EditField.Limits = [0 Inf];
            app.LoadCatMaxDist_EditField.ValueDisplayFormat = '%4.2f';
            app.LoadCatMaxDist_EditField.Tooltip = {'this is fraction of mean leglength'};
            app.LoadCatMaxDist_EditField.Value = 1;
            app.CatalogimportedLampLabel = uilabel(app.LoadandparsecatalogPanel);
            app.CatalogimportedLampLabel.Text = 'Catalog imported';
            app.LoadCat_Lamp = uilamp(app.LoadandparsecatalogPanel);
            app.meanleglengthLabel = uilabel(app.LoadandparsecatalogPanel);
            app.meanleglengthLabel.Text = 'mean leg length';
            app.LoadMeanLegLegth_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadMeanLegLegth_EditField.Limits = [0 Inf];
            app.LoadMeanLegLegth_EditField.ValueDisplayFormat = '%4.2f';
            app.LoadMeanLegLegth_EditField.Editable = 'off';
            app.LoadMeanLegLegth_EditField.Tooltip = {'mean leg length of triangular patches'};
            app.EQsintablewithMagnitudeLabel = uilabel(app.LoadandparsecatalogPanel);
            app.EQsintablewithMagnitudeLabel.Text = 'EQs in table with Magnitude >';
            app.LoadMinMag4Table_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadMinMag4Table_EditField.ValueDisplayFormat = '%5.2f';
            app.LoadMinMag4Table_EditField.Value = 6;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = R_LoadPlotResultsMulti_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end