classdef R_LoadPlotResults_2021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlotShowTimeEvol_CheckBox       matlab.ui.control.CheckBox
        PlotSaveMovie_CheckBox          matlab.ui.control.CheckBox
        fpsEditFieldLabel               matlab.ui.control.Label
        PlotMoviFps_EditField           matlab.ui.control.NumericEditField
        secframeEditFieldLabel          matlab.ui.control.Label
        PlotTimeEvolsecPerFrame_EditField  matlab.ui.control.NumericEditField
        ImportedparsedEQcatalogPanel    matlab.ui.container.Panel
        EQtable_UITable                 matlab.ui.control.Table
        PlotScaling_Button              matlab.ui.control.Button
        FigEditField_2Label             matlab.ui.control.Label
        FigNumScaling_EditField         matlab.ui.control.NumericEditField
        symbolDropDownLabel             matlab.ui.control.Label
        Scaling_Symbol_DropDown         matlab.ui.control.DropDown
        scaleDropDownLabel              matlab.ui.control.Label
        ScalingXscale_DropDown          matlab.ui.control.DropDown
        scaleDropDown_2Label            matlab.ui.control.Label
        ScalingYscale_DropDown          matlab.ui.control.DropDown
        xaxisDropDownLabel              matlab.ui.control.Label
        ScalingXvalue_DropDown          matlab.ui.control.DropDown
        yaxisDropDownLabel              matlab.ui.control.Label
        ScalingYvalue_DropDown          matlab.ui.control.DropDown
        loadparameterButton             matlab.ui.control.Button
        plotparameterButton             matlab.ui.control.Button
        forLabel                        matlab.ui.control.Label
        Scaling_PatchOrAll_DropDown     matlab.ui.control.DropDown
        LoadandparsecatalogPanel        matlab.ui.container.Panel
        LoadCatalog_Button              matlab.ui.control.Button
        RemoveEQswithlessthanEditFieldLabel  matlab.ui.control.Label
        LoadCatRemoveEQ_EditField       matlab.ui.control.NumericEditField
        LoadCatGroupTime_CheckBox       matlab.ui.control.CheckBox
        LoadCatGroupDist_CheckBox       matlab.ui.control.CheckBox
        maxdistEditFieldLabel           matlab.ui.control.Label
        LoadCatMaxDist_EditField        matlab.ui.control.NumericEditField
        CatalogimportedLampLabel        matlab.ui.control.Label
        LoadCat_Lamp                    matlab.ui.control.Lamp
        meanleglengthLabel              matlab.ui.control.Label
        LoadMeanLegLegth_EditField      matlab.ui.control.NumericEditField
        EQsintablewithMagnitudeLabel    matlab.ui.control.Label
        LoadMinMag4Table_EditField      matlab.ui.control.NumericEditField
        PlotindividualeventparameterPanel  matlab.ui.container.Panel
        PlotMRF_Button                  matlab.ui.control.Button
        PlotOnFault_Button              matlab.ui.control.Button
        toplotDropDownLabel             matlab.ui.control.Label
        PlotComp2Plot_DropDown          matlab.ui.control.DropDown
        minvalueEditFieldLabel          matlab.ui.control.Label
        PlotMinValue_EditField          matlab.ui.control.NumericEditField
        maxvalueEditFieldLabel          matlab.ui.control.Label
        PlotMaxValue_EditField          matlab.ui.control.NumericEditField
        PlotEQsequencePanel             matlab.ui.container.Panel
        SequencePlot_Button             matlab.ui.control.Button
        StarttimeEditFieldLabel         matlab.ui.control.Label
        Sequence_StartT_EditField       matlab.ui.control.NumericEditField
        EndtimeEditFieldLabel           matlab.ui.control.Label
        Sequence_EndTEditField          matlab.ui.control.NumericEditField
        FigEditFieldLabel               matlab.ui.control.Label
        PlotFigNum_EditField            matlab.ui.control.NumericEditField
        viewangleEditFieldLabel         matlab.ui.control.Label
        PlotViewAngle_EditField         matlab.ui.control.EditField
        ColormapDropDownLabel           matlab.ui.control.Label
        PlotColorMap_DropDown           matlab.ui.control.DropDown
        secframeEditFieldLabel_2        matlab.ui.control.Label
        fpsEditFieldLabel_2             matlab.ui.control.Label
        SelectSinglePatch_Button        matlab.ui.control.Button
        PatchIDLabel                    matlab.ui.control.Label
        SelectPatchID_edit              matlab.ui.control.NumericEditField
        SelectHighlightPatch_Button     matlab.ui.control.Button
        SelectPlotParVsTime_Button      matlab.ui.control.Button
        SelectPlotParaAlongSurf_Button  matlab.ui.control.Button
        binningminvalueLabel            matlab.ui.control.Label
        PlotBinMinValue_EditField       matlab.ui.control.NumericEditField
        maxvalueLabel                   matlab.ui.control.Label
        PlotBinMaxValue_EditField       matlab.ui.control.NumericEditField
        binLabel                        matlab.ui.control.Label
        PlotBinNum_EditField            matlab.ui.control.NumericEditField
        SelectHypoCentDistrib_Button    matlab.ui.control.Button
        minMagnLabel                    matlab.ui.control.Label
        InterEventMinMag_EditField      matlab.ui.control.NumericEditField
        maxMagnLabel                    matlab.ui.control.Label
        InterEventMaxMag_EditField      matlab.ui.control.NumericEditField
        InterEvent_PlotStuff_Button     matlab.ui.control.Button
        FigEditField_2Label_2           matlab.ui.control.Label
        InterEventFigNum_EditField      matlab.ui.control.NumericEditField
        scaleDropDownLabel_2            matlab.ui.control.Label
        InterEvent_Xscale_DropDown      matlab.ui.control.DropDown
        xaxisDropDownLabel_2            matlab.ui.control.Label
        InterEvent_Xvalue_DropDown      matlab.ui.control.DropDown
        yaxisLabel                      matlab.ui.control.Label
        InterEvent_Yvalue_DropDown      matlab.ui.control.DropDown
        colorLabel                      matlab.ui.control.Label
        InterEvent_Cvalue_DropDown      matlab.ui.control.DropDown
        scaleDropDownLabel_3            matlab.ui.control.Label
        InterEvent_Yscale_DropDown      matlab.ui.control.DropDown
        forLabel_2                      matlab.ui.control.Label
        InterEvent_PatchOrAll_DropDown  matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        BlueWhiteRed = [0.0784    0.1686    0.5490;  0         0    1.0000;      0.0333    0.0333    1.0000; 0.0667    0.0667    1.0000;  0.1000    0.1000    1.0000; 0.1333    0.1333    1.0000;...
                        0.1667    0.1667    1.0000;  0.2000    0.2000    1.0000; 0.2333    0.2333    1.0000; 0.2667    0.2667    1.0000;  0.3000    0.3000    1.0000; 0.3333    0.3333    1.0000;...
                        0.3667    0.3667    1.0000;  0.4000    0.4000    1.0000; 0.4333    0.4333    1.0000; 0.4667    0.4667    1.0000;  0.5000    0.5000    1.0000; 0.5333    0.5333    1.0000;...
                        0.5667    0.5667    1.0000;  0.6000    0.6000    1.0000; 0.6333    0.6333    1.0000; 0.6667    0.6667    1.0000;  0.7000    0.7000    1.0000; 0.7333    0.7333    1.0000;...
                        0.7667    0.7667    1.0000;  0.8000    0.8000    1.0000; 0.8333    0.8333    1.0000; 0.8667    0.8667    1.0000;  0.9000    0.9000    1.0000; 0.9333    0.9333    1.0000;...
                        0.9667    0.9667    1.0000;  1.0000    1.0000    1.0000; 1.0000    0.9677    0.9677; 1.0000    0.9355    0.9355;  1.0000    0.9032    0.9032; 1.0000    0.8710    0.8710;...
                        1.0000    0.8387    0.8387;  1.0000    0.8065    0.8065; 1.0000    0.7742    0.7742; 1.0000    0.7419    0.7419;  1.0000    0.7097    0.7097; 1.0000    0.6774    0.6774;...
                        1.0000    0.6452    0.6452;  1.0000    0.6129    0.6129; 1.0000    0.5806    0.5806; 1.0000    0.5484    0.5484;  1.0000    0.5161    0.5161; 1.0000    0.4839    0.4839;...
                        1.0000    0.4516    0.4516;  1.0000    0.4194    0.4194; 1.0000    0.3871    0.3871; 1.0000    0.3548    0.3548;  1.0000    0.3226    0.3226; 1.0000    0.2903    0.2903;...
                        1.0000    0.2581    0.2581;  1.0000    0.2258    0.2258; 1.0000    0.1935    0.1935; 1.0000    0.1613    0.1613;  1.0000    0.1290    0.1290; 1.0000    0.0968    0.0968;...
                        1.0000    0.0645    0.0645;  1.0000    0.0323    0.0323; 1.0000         0         0; 0.8471    0.1608         0];
    
        Cols4Slip    = [1.0000    1.0000    1.0000;  0.8890    0.9670    1.0000; 0.7780    0.9330    1.0000; 0.6670    0.9000    1.0000;  0.5560    0.8670    1.0000; 0.4440    0.8330    1.0000;...
                        0.3330    0.8000    1.0000;  0.2220    0.7670    1.0000; 0.1110    0.7330    1.0000
                        0         0.7000    1.0000;  0         0.7110    0.8890; 0         0.7220    0.7780; 0         0.7330    0.6670;  0         0.7440    0.5560; 0         0.7560    0.4440;...
                        0         0.7670    0.3330;  0         0.7780    0.2220; 0         0.7890    0.1110; 0         0.8000         0;  0.0560    0.8220         0; 0.1110    0.8440    0;...
                        0.1670    0.8670         0;  0.2220    0.8890         0; 0.2780    0.9110         0; 0.3330    0.9330         0;  0.3890    0.9560         0; 0.4440    0.9780    0;...
                        0.5000    1.0000         0;  0.5560    1.0000         0; 0.6110    1.0000         0; 0.6670    1.0000         0;  0.7220    1.0000         0; 0.7780    1.0000    0;...
                        0.8330    1.0000         0;  0.8890    1.0000         0; 0.9440    1.0000         0; 1.0000    1.0000         0;  1.0000    0.9440         0; 1.0000    0.8890    0;...
                        1.0000    0.8330         0;  1.0000    0.7780         0; 1.0000    0.7220         0; 1.0000    0.6670         0;  1.0000    0.6110         0; 1.0000    0.5560    0;...
                        1.0000    0.5000         0;  1.0000    0.4440         0; 1.0000    0.3890         0; 1.0000    0.3330         0;  1.0000    0.2780         0; 1.0000    0.2220    0;...
                        1.0000    0.1670         0;  1.0000    0.1110         0; 1.0000    0.0560         0; 1.0000         0         0;  0.9440         0         0; 0.8890         0    0;...
                        0.8330         0         0;  0.7780         0         0; 0.7220         0         0; 0.6670         0         0;  0.6110         0         0; 0.5560         0    0;...
                        0.5000         0         0];
         
        
        RawCatalog  = struct;
        MRF         = struct;
        ClnCatalog  = struct;
        FaultGeom   = struct;
        ShearMod    = 0;
        DeltaT      = 0;
        CatImported = 0;
        EQcount     = 0;
        selectedRow = 1;
        selectedCol = 1;
    
        ExtraParamter = []; % Description
    end
    
    methods (Access = private)


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: LoadCatGroupTime_CheckBox
        function LoadCatGroupTime_CheckBoxValueChanged(app, event)
            app.LoadCatGroupTime_CheckBox.Value = 1;
            app.LoadCatGroupDist_CheckBox.Value = 0;          
        end

        % Value changed function: LoadCatGroupDist_CheckBox
        function LoadCatGroupDist_CheckBoxValueChanged(app, event)
            app.LoadCatGroupTime_CheckBox.Value = 0;
            app.LoadCatGroupDist_CheckBox.Value = 1;
        end

        % Button pushed function: LoadCatalog_Button
        function LoadCatalog_ButtonPushed(app, event)
            app.LoadCat_Lamp.Color = 'red';         drawnow;   
            app.CatImported        = 0;
            GroupByTime = app.LoadCatGroupTime_CheckBox.Value;
            GroupDist   = app.LoadCatMaxDist_EditField.Value;
            MinPatchNum = app.LoadCatRemoveEQ_EditField.Value;
            MinMag4Tabl = app.LoadMinMag4Table_EditField.Value;
            %---------------------------------------------------------------------
            [LoadName,LoadPath] = uigetfile('*Catalog.dat','Load EQ catalog');
            if     (LoadName ~=0) 
                CtlgPath  = strcat(LoadPath,LoadName);
                CutName   = strfind(LoadName,'_');                          GeomName  = LoadName(1:CutName(end-1)-1);
                GeomName  = strcat(GeomName,'_Roughn.dat');                 GeomPath  = strcat(LoadPath,GeomName);
            elseif (LoadName ==0),      return;         
            end
            %---------------------------------------------------------------------
            fidbinCat    = fopen(CtlgPath,'rb');
            app.LoadCat_Lamp.Color = 'yellow';      drawnow;
            %---------------------------------------------------------------------
            EQnumRaw       = fread(fidbinCat,1,'int');
            FltGridnum     = fread(fidbinCat,1,'int');%the fault grid that is used
            app.ShearMod   = fread(fidbinCat,1,'single');
            app.DeltaT     = fread(fidbinCat,1,'single');

            app.RawCatalog = struct;
            app.MRF        = struct;
            app.ClnCatalog = struct;
            app.FaultGeom  = struct;
                                    
            EQ_Time         = zeros(EQnumRaw*10,1);
            EQ_Magnitude    = zeros(EQnumRaw*10,1);
            EQ_RuptArea     = zeros(EQnumRaw*10,1);
            EQ_ActPtchN     = zeros(EQnumRaw*10,1);
            EQ_MeanStrsDrop = zeros(EQnumRaw*10,1);
            EQ_MeanSlpH     = zeros(EQnumRaw*10,1);
            EQ_MeanSlpV     = zeros(EQnumRaw*10,1);
            EQ_SlipDur      = zeros(EQnumRaw*10,1);
            
            %---------------------------------------------------------------------
            fidbinGeom = fopen(GeomPath,'rb');
            %-----------------------
            for (ii = 1 : FltGridnum) %is always overwritten until I reach the grid that I want to use, typically I'd probably only do one...
                PatchNum = fread(fidbinGeom,1,'int');
                VertNum  = fread(fidbinGeom,1,'int');
                app.FaultGeom.SizeTrigList       = PatchNum;
                app.FaultGeom.SizeVertList       = VertNum;
                %-----------------------
                app.FaultGeom.TriangleList       = zeros(PatchNum,5);
                app.FaultGeom.GlobTrigCentr      = zeros(PatchNum,3);
                app.FaultGeom.VertexList         = zeros(VertNum,2);
                app.FaultGeom.GlobalVertices     = zeros(VertNum,3); 
                app.FaultGeom.TriangleAreas      = zeros(PatchNum,1);
                app.FaultGeom.IsSurfPatch        = zeros(PatchNum,1);
                %-----------------------
                app.FaultGeom.TriangleList(:,1)  = fread(fidbinGeom,PatchNum,'int');
                app.FaultGeom.TriangleList(:,2)  = fread(fidbinGeom,PatchNum,'int');
                app.FaultGeom.TriangleList(:,3)  = fread(fidbinGeom,PatchNum,'int');
                app.FaultGeom.TriangleList(:,4)  = fread(fidbinGeom,PatchNum,'int');
                app.FaultGeom.TriangleList(:,5)  = fread(fidbinGeom,PatchNum,'int');
                app.FaultGeom.VertexList(:,1)    = fread(fidbinGeom,VertNum,'single');
                app.FaultGeom.VertexList(:,2)    = fread(fidbinGeom,VertNum,'single');
                app.FaultGeom.GlobalVertices(:,1)= fread(fidbinGeom,VertNum,'single');
                app.FaultGeom.GlobalVertices(:,2)= fread(fidbinGeom,VertNum,'single');
                app.FaultGeom.GlobalVertices(:,3)= fread(fidbinGeom,VertNum,'single');
                app.FaultGeom.GlobTrigCentr(:,1) = fread(fidbinGeom,PatchNum,'single');
                app.FaultGeom.GlobTrigCentr(:,2) = fread(fidbinGeom,PatchNum,'single');
                app.FaultGeom.GlobTrigCentr(:,3) = fread(fidbinGeom,PatchNum,'single'); 
                FaultNum                         = fread(fidbinGeom,1,       'int');
                app.FaultGeom.RMSvalues(:,1)     = fread(fidbinGeom,FaultNum,'single');   
            end
            
            %app.FaultGeom.MeanRMS = mean(app.FaultGeom.RMSvalues(:,1));
            %what can I do with that value here?; but have to make sure
            %that i can deal with it (havingthe RMS value now in the file).
            
            
            fclose(fidbinGeom);
            %---------------------------------------------------------------------
            MeanLegLength = 0;
            for i = 1 : size(app.FaultGeom.TriangleList,1)
                v1    = app.FaultGeom.TriangleList(i,1);
                v2    = app.FaultGeom.TriangleList(i,2);
                v3    = app.FaultGeom.TriangleList(i,3);
                %---------------------------
                %https://www.mathworks.com/matlabcentral/answers/271797-surface-area-of-a-3d-surface
                app.FaultGeom.TriangleAreas(i,1) = 1/2*norm(cross((app.FaultGeom.GlobalVertices(v2,:)-app.FaultGeom.GlobalVertices(v1,:)),(app.FaultGeom.GlobalVertices(v3,:)-app.FaultGeom.GlobalVertices(v1,:))));   
    
                Leg12   = sqrt((app.FaultGeom.GlobalVertices(v1,1)-app.FaultGeom.GlobalVertices(v2,1))^2 +(app.FaultGeom.GlobalVertices(v1,2)-app.FaultGeom.GlobalVertices(v2,2))^2 +(app.FaultGeom.GlobalVertices(v1,3)-app.FaultGeom.GlobalVertices(v2,3))^2 ); 
                Leg13   = sqrt((app.FaultGeom.GlobalVertices(v1,1)-app.FaultGeom.GlobalVertices(v3,1))^2 +(app.FaultGeom.GlobalVertices(v1,2)-app.FaultGeom.GlobalVertices(v3,2))^2 +(app.FaultGeom.GlobalVertices(v1,3)-app.FaultGeom.GlobalVertices(v3,3))^2 ); 
                Leg23   = sqrt((app.FaultGeom.GlobalVertices(v2,1)-app.FaultGeom.GlobalVertices(v3,1))^2 +(app.FaultGeom.GlobalVertices(v2,2)-app.FaultGeom.GlobalVertices(v3,2))^2 +(app.FaultGeom.GlobalVertices(v2,3)-app.FaultGeom.GlobalVertices(v3,3))^2 ); 
                MeanLegLength = MeanLegLength + mean([Leg12,Leg13,Leg23]);
                %--------------------------- necessary for distance check if it is going to be used
                
                tester = 0;
                if (abs(app.FaultGeom.GlobalVertices(v1,3)) <= mean([Leg12,Leg13,Leg23])/2),         tester = tester +1;         end
                if (abs(app.FaultGeom.GlobalVertices(v2,3)) <= mean([Leg12,Leg13,Leg23])/2),         tester = tester +1;         end
                if (abs(app.FaultGeom.GlobalVertices(v3,3)) <= mean([Leg12,Leg13,Leg23])/2),         tester = tester +1;         end
            
                if (tester > 1)
                    app.FaultGeom.IsSurfPatch(i,1) = 1;
                end
            end
            MeanLegLength                        = MeanLegLength/size(app.FaultGeom.TriangleList,1);
            app.LoadMeanLegLegth_EditField.Value = MeanLegLength;
            %---------------------------------------------------------------------
            disp(strcat('Number of "raw"/unparsed earthquakes:',num2str(EQnumRaw)));
            EQcount       = 0;
            EQinTablcount = 0;
            for i = 1 : EQnumRaw
                Time    = fread(fidbinCat,1,      'single');
                Magn    = fread(fidbinCat,1,      'single');
                PtNum   = fread(fidbinCat,1,      'int');                
                MRFNum  = fread(fidbinCat,1,      'int');
                MRFval  = fread(fidbinCat,MRFNum, 'single');
                
                %---------------------------------------
                PtchID = fread(fidbinCat,PtNum,   'int');
                PtchID = PtchID +1; %this is again b/c of C vs. matlab notation
                t0     = fread(fidbinCat,PtNum,   'int');
                delt_t = fread(fidbinCat,PtNum,   'single');
                slip_h = fread(fidbinCat,PtNum,   'single');
                slip_v = fread(fidbinCat,PtNum,   'single');
                StabTyp= fread(fidbinCat,PtNum,   'int');
                %---------------------------------------%---------------------------------------
                if (GroupByTime == 1)
                    if (PtNum >= MinPatchNum)
                        
                        EQcount = EQcount +1;
                        
                        app.MRF(EQcount).Num           = MRFNum;
                        app.MRF(EQcount).Val           = MRFval;
                       
                        app.ClnCatalog(EQcount).EQID   = EQcount;
                        app.ClnCatalog(EQcount).EQtime = Time;
                        app.ClnCatalog(EQcount).Magn   = Magn;
                        app.ClnCatalog(EQcount).PtchID = PtchID';
                        app.ClnCatalog(EQcount).T0     = t0'.*app.DeltaT;
                        app.ClnCatalog(EQcount).Delt_t = delt_t';
                        app.ClnCatalog(EQcount).Slip_h = slip_h';
                        app.ClnCatalog(EQcount).Slip_v = slip_v';
                        app.ClnCatalog(EQcount).StabT  = StabTyp';
                        
                        RuptArea   = 0;
                        for kk = 1 : PtNum
                            NextPtchID = PtchID(kk,1);
                            PatchPos   = find(PtchID == NextPtchID,1,'first');
                            RuptArea   = RuptArea + app.FaultGeom.TriangleAreas(PatchPos,1);
                        end  
                        app.ClnCatalog(EQcount).RuptArea  = RuptArea;
                        %------------------%------------------
                        if (Magn >= MinMag4Tabl)
                            EQinTablcount                  = EQinTablcount+1;
                            EQ_ID(EQinTablcount)           = EQcount;
                            EQ_Time(EQinTablcount)         = Time;
                            EQ_Magnitude(EQinTablcount)    = Magn;
                            EQ_RuptArea(EQinTablcount)     = RuptArea;
                            EQ_ActPtchN(EQinTablcount)     = PtNum;
                            EQ_MeanStrsDrop(EQinTablcount) = mean(delt_t);
                            EQ_MeanSlpH(EQinTablcount)     = mean(slip_h);
                            EQ_MeanSlpV(EQinTablcount)     = mean(slip_v);
                            EQ_SlipDur(EQinTablcount)      = (max(t0(:)) - min(t0(:))).*app.DeltaT;
                        end
                        %------------------%------------------
                    end
                else
                %---------------------------------------%---------------------------------------
                    TempEQcount    = 1;
                    subEQlist      = zeros(PtNum, 7);
                    
                    subEQlist(:,1) = PtchID;
                    subEQlist(:,2) = app.FaultGeom.GlobTrigCentr(PtchID,1);
                    subEQlist(:,3) = app.FaultGeom.GlobTrigCentr(PtchID,2);
                    subEQlist(:,4) = app.FaultGeom.GlobTrigCentr(PtchID,3);
                    subEQlist(:,5) = zeros(PtNum,1); %this is tempEQID
                    subEQlist(:,6) = zeros(PtNum,1); %this is "still looking"
                    subEQlist(:,7) = app.FaultGeom.TriangleAreas(PtchID,1);
                    %------------------%------------%------------------%------------
                    subEQlist(1,5) = TempEQcount;
                    StillOpen      = (PtNum > 1); % if I have at least one other patch activated in same "raw" EQ
                    %------------------%------------%------------------%------------
                    while (StillOpen == true)
                        NextTrigs2Test = subEQlist(subEQlist(:,5)           == TempEQcount,:);
                        NextTrigs2Test = NextTrigs2Test(NextTrigs2Test(:,6) ==     0      ,:);
                        Trigs2TestWith = subEQlist(subEQlist(:,5)           ==     0      ,:);
                        
                        FoundOne  = 0;
                        for (k = 1 : size(NextTrigs2Test,1))
                            CurrMastrPos             = find(subEQlist(:,1) == NextTrigs2Test(k,1),1,'first');
                            subEQlist(CurrMastrPos,6)= 1; %if this triangle was already tested against the list of "still open"
                            Dists                    = sqrt( (Trigs2TestWith(:,2)-NextTrigs2Test(k,2)).^2 + (Trigs2TestWith(:,3)-NextTrigs2Test(k,3)).^2 + (Trigs2TestWith(:,4)-NextTrigs2Test(k,4)).^2);

                            for (kk = 1 : size(Trigs2TestWith,1))
                                if (Dists(kk,1) <= MeanLegLength*GroupDist)
                                    FoundOne                  = 1;
                                    CurrSlavePos              = find(subEQlist(:,1) == Trigs2TestWith(kk,1),1,'first');
                                    subEQlist(CurrSlavePos,5) = TempEQcount;
                                end
                            end 
                        end
                        StillOpen = (min(subEQlist(:,5)) == 0);
                        if ((StillOpen == true) && (FoundOne == 0))
                            
                            TempEQcount              = TempEQcount +1;
                            Trigs2TestWith           = subEQlist(subEQlist(:,5) == 0,:);
                            CurrSlavePos             = find(subEQlist(:,1) == Trigs2TestWith(1,1),1,'first');
                            subEQlist(CurrSlavePos,5)= TempEQcount;
                        end
                        StillOpen = (min(subEQlist(:,5)) == 0);
                    end
                    %------------------%------------%------------------%------------
                    SubEventID = unique(subEQlist(:,5));
                    for k = min(SubEventID) : max(SubEventID)
                        SelPatches = subEQlist(subEQlist(:,5) == k,:);
                        RuptArea   = 0;
                        if (size(SelPatches,1) >= MinPatchNum)
                            EQcount = EQcount +1;
                            
                            app.MRF(EQcount).Num           = MRFNum;
                            app.MRF(EQcount).Val           = MRFval;
                            app.ClnCatalog(EQcount).EQID   = EQcount;
                            app.ClnCatalog(EQcount).EQtime = Time;
                       
                            for kk = 1 : size(SelPatches,1)
                                NextPtchID = SelPatches(kk,1);
                                PatchPos   = find(PtchID == NextPtchID,1,'first');
                                app.ClnCatalog(EQcount).PtchID(kk) = PtchID(PatchPos);
                                app.ClnCatalog(EQcount).T0(kk)     = t0(PatchPos).*app.DeltaT;
                                app.ClnCatalog(EQcount).Delt_t(kk) = delt_t(PatchPos);
                                app.ClnCatalog(EQcount).Slip_h(kk) = slip_h(PatchPos);
                                app.ClnCatalog(EQcount).Slip_v(kk) = slip_v(PatchPos);
                                app.ClnCatalog(EQcount).StabT(kk)  = StabTyp(PatchPos);
                                RuptArea = RuptArea + SelPatches(kk,7);
                            end  
                            app.ClnCatalog(EQcount).RuptArea  = RuptArea;
                            
                            Moment                 = app.ShearMod*RuptArea*1E+6*mean(sqrt(app.ClnCatalog(EQcount).Slip_h(:).^2 +app.ClnCatalog(EQcount).Slip_v(:).^2)); %needs to be recalculated => but requires shear modulus... => put into EQ output??      
                            Magn                   = (log10(Moment)-9.1)/1.5;
                            
                            app.ClnCatalog(EQcount).Magn = Magn;
                            %------------------%------------------
                            if (Magn >= MinMag4Tabl)
                                EQinTablcount                 = EQinTablcount+1;
                                EQ_ID(EQinTablcount)          = EQcount;
                                EQ_Time(EQinTablcount)        = Time;
                                EQ_Magnitude(EQinTablcount)   = Magn;
                                EQ_RuptArea(EQinTablcount)    = RuptArea;
                                EQ_ActPtchN(EQinTablcount)    = size(SelPatches,1);
                                BLAH = app.ClnCatalog(EQcount).Delt_t(app.ClnCatalog(EQcount).Delt_t > 0.0); %don't use the patches with stress increase for computation of average stress drop
                                EQ_MeanStrsDrop(EQinTablcount)= mean(BLAH);
                                EQ_MeanSlpH(EQinTablcount)    = mean(app.ClnCatalog(EQcount).Slip_h);
                                EQ_MeanSlpV(EQinTablcount)    = mean(app.ClnCatalog(EQcount).Slip_v);
                                EQ_SlipDur(EQinTablcount)     = (max(app.ClnCatalog(EQcount).T0(:)) - min(app.ClnCatalog(EQcount).T0(:))).*app.DeltaT;
                            end
                            %------------------%------------------
                        end
                    end  
                end
            end
            %------------------%------------%------------------%------------
            EQNums          = EQ_ID(1:EQinTablcount)';
            EQ_Time         = EQ_Time(1:EQinTablcount,1);
            EQ_Magnitude    = EQ_Magnitude(1:EQinTablcount,1);
            EQ_RuptArea     = EQ_RuptArea(1:EQinTablcount,1);
            EQ_ActPtchN     = EQ_ActPtchN(1:EQinTablcount,1);
            EQ_MeanStrsDrop = EQ_MeanStrsDrop(1:EQinTablcount,1);
            EQ_MeanSlpH     = EQ_MeanSlpH(1:EQinTablcount,1);
            EQ_MeanSlpV     = EQ_MeanSlpV(1:EQinTablcount,1);
            EQ_SlipDur      = EQ_SlipDur(1:EQinTablcount,1);
            %------------------%------------%------------------%------------
            %------------------%------------%------------------%------------
            fclose(fidbinCat);
            
            app.EQtable_UITable.Data = table(EQNums,EQ_Time, EQ_Magnitude, EQ_RuptArea, EQ_ActPtchN, EQ_MeanStrsDrop/1E+6, EQ_MeanSlpH, EQ_MeanSlpV, EQ_SlipDur);

            %------------------%------------%------------------%------------
            MinVal = +Inf;
            MaxVal = -Inf;
            app.PlotComp2Plot_DropDown.Value = num2str(1);
            for i = 1 : EQcount
                CombVal = sqrt(app.ClnCatalog(i).Slip_h.^2 +app.ClnCatalog(i).Slip_v.^2);    
                MinVal  = min([MinVal; CombVal(:)]);
                MaxVal  = max([MaxVal; CombVal(:)]);
            end
            app.PlotMinValue_EditField.Value = MinVal;
            app.PlotMaxValue_EditField.Value = MaxVal;
            app.Sequence_StartT_EditField.Value = min(EQ_Time(:));
            app.Sequence_EndTEditField.Value    = max(EQ_Time(:));
            %------------------%------------%------------------%------------
            app.CatImported = 1;
            app.EQcount     = EQcount;
            app.LoadCat_Lamp.Color = 'green';     
            
            disp(strcat('Imported Events: ',num2str(EQcount)));
        end

        % Button pushed function: PlotScaling_Button
        function PlotScaling_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end       
 
            FigNum    = app.FigNumScaling_EditField.Value;
            
            PatchOrAll= str2double(app.Scaling_PatchOrAll_DropDown.Value);
            
            SelX      = str2double(app.ScalingXvalue_DropDown.Value);
            StringX   = app.ScalingXvalue_DropDown.Items{SelX};
            SelY      = str2double(app.ScalingYvalue_DropDown.Value);
            StringY   = app.ScalingYvalue_DropDown.Items{SelY};
            SelSymb   = str2double(app.Scaling_Symbol_DropDown.Value);
            SelSymbs  = app.Scaling_Symbol_DropDown.Items{SelSymb};      
            SclX      = str2double(app.ScalingXscale_DropDown.Value);
            SclXs     = app.ScalingXscale_DropDown.Items{SclX};
            SclY      = str2double(app.ScalingYscale_DropDown.Value);
            SclYs     = app.ScalingYscale_DropDown.Items{SclY};
            
            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;
            
            MinBinVal = app.PlotBinMinValue_EditField.Value;
            MaxBinVal = app.PlotBinMaxValue_EditField.Value;
            BinNum    = app.PlotBinNum_EditField.Value;
            %---------------------------------------------------            
            aa = zeros(size(app.ClnCatalog(:),1),1);
            bb = zeros(size(app.ClnCatalog(:),1),1);
            cc = zeros(size(app.ClnCatalog(:),1),1);
            dd = zeros(size(app.ClnCatalog(:),1),1);
            ee = zeros(size(app.ClnCatalog(:),1),1);
            ff = zeros(size(app.ClnCatalog(:),1),1);
            gg = zeros(size(app.ClnCatalog(:),1),1);
            ii = zeros(size(app.ClnCatalog(:),1),1);
            %------------------
            for iii = 1 : size(app.ClnCatalog(:),1)
                aa(iii) = app.ClnCatalog(iii).EQtime;
                bb(iii) = app.ClnCatalog(iii).Magn;
                cc(iii) = app.ClnCatalog(iii).RuptArea;
                dd(iii) = size(unique(app.ClnCatalog(iii).PtchID(:)),1);
                
                BLAH    = app.ClnCatalog(iii).Delt_t(app.ClnCatalog(iii).Delt_t > 0.0);
                ee(iii) = nanmean(BLAH);
                ff(iii) = mean(app.ClnCatalog(iii).Slip_h(:));
                gg(iii) = mean(app.ClnCatalog(iii).Slip_v(:));
                ii(iii) = (max(app.ClnCatalog(iii).T0(:)) - min(app.ClnCatalog(iii).T0(:))).*app.DeltaT;
            end
            hh   = sqrt(ff.^2 + gg.^2);
            %---------------------------------------------------
            data = [aa, bb, cc, dd, ee, ff, gg, hh, ii]; 
            %---------------------------------------------------
            if (PatchOrAll == 2)
   
                SelPatch  = app.SelectPatchID_edit.Value;
                IsIn      = zeros(size(data,1),1);
                
                for (i = 1 : size(data,1))
                    IsIn(i,1) = sum(app.ClnCatalog(i).PtchID == SelPatch);
                end 
                data = data(logical(IsIn),:);
            end
            %---------------------%---------------------
            data = data(data(:,1)>=MinTime,:);
            data = data(data(:,1)<=MaxTime,:);
            %---------------------%---------------------
            
            %---------------------%---------------------
            figure(FigNum); clf;   
            if (SelY < 10)     
                SelXvals  = data(:,SelX);           SelYvals  = data(:,SelY);
                plot(SelXvals, SelYvals, SelSymbs,'color','k'); 
            elseif  (SelY == 10)   
                %---------------------%-------
                if (SelX == 1),     SelData = diff(data(:,SelX));
                else,               SelData = data(:,SelX);
                end
                %---------------------%-------
                if (BinNum > 1),    BinValues = linspace(MinBinVal,MaxBinVal,BinNum);
                else,               BinValues = linspace(min(SelData),max(SelData),round(sqrt(size(SelData,1))));
                end
                %---------------------%-------
                [SelYvals,SelXvals] = histcounts(SelData,BinValues);     
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                SelYvals = SelYvals./sum(SelYvals); 
                bar(SelXvals, SelYvals); 
            elseif  (SelY == 11)
                %---------------------%-------
                if (SelX == 1),     SelData = diff(data(:,SelX));
                else,               SelData = data(:,SelX);
                end
                %---------------------%-------
                if (BinNum > 1),    BinValues = linspace(MinBinVal,MaxBinVal,BinNum);
                else,               BinValues = linspace(min(SelData),max(SelData),round(sqrt(size(SelData,1))));
                end
                %---------------------%-------
                [SelYvals,SelXvals] = histcounts(SelData,BinValues);        
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                SelYvals = SelYvals./sum(SelYvals); 
                SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                plot(SelXvals, SelYvals, SelSymbs,'color','k'); 
            end
          
            set(gca,'XScale',SclXs,'YScale',SclYs);
            grid on;
            %---------------------
            xlabel(strcat(StringX,',',SclXs));   ylabel(strcat(StringY,',',SclYs));      
        end

        % Cell selection callback: EQtable_UITable
        function EQtable_UITableCellSelection(app, event)
            app.selectedRow = event.Indices(1);
            app.selectedCol = event.Indices(2);
        end

        % Value changed function: PlotComp2Plot_DropDown
        function PlotComp2Plot_DropDownValueChanged(app, event)
            value   = str2double(app.PlotComp2Plot_DropDown.Value);
            MinVal  = +Inf;
            MaxVal  = -Inf;
            MinTime = app.Sequence_StartT_EditField.Value;
            MaxTime = app.Sequence_EndTEditField.Value;
            %-------------------------------------
            if (value == 7)
                CombVal = zeros(1,app.FaultGeom.SizeTrigList);
                app.PlotShowTimeEvol_CheckBox.Value = 0;   
            end
            %-------------------------------------
            for i = 1 : app.EQcount
                if     (value == 1),    CombVal = sqrt(app.ClnCatalog(i).Slip_h.^2 +app.ClnCatalog(i).Slip_v.^2);  
                elseif (value == 2),    CombVal = app.ClnCatalog(i).Slip_h; 
                elseif (value == 3),    CombVal = app.ClnCatalog(i).Slip_v; 
                elseif (value == 4),    CombVal = app.ClnCatalog(i).Delt_t/1E+6; 
                elseif (value == 5),    CombVal = app.ClnCatalog(i).T0; 
                elseif (value == 6),    CombVal = app.ClnCatalog(i).StabT;     
                elseif (value == 7)
                    NextTime = app.ClnCatalog(i).EQtime;
                    if ((NextTime >= MinTime) && (NextTime <= MaxTime))
                        CombVal(1,app.ClnCatalog(i).PtchID) = CombVal(1,app.ClnCatalog(i).PtchID) + sqrt(app.ClnCatalog(i).Slip_h.^2 +app.ClnCatalog(i).Slip_v.^2);
                    end
                end
                MinVal  = min([MinVal; CombVal(:)]);
                MaxVal  = max([MaxVal; CombVal(:)]);
            end
            app.PlotMinValue_EditField.Value = MinVal;
            app.PlotMaxValue_EditField.Value = MaxVal;
    
        end

        % Button pushed function: PlotMRF_Button
        function PlotMRF_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end       
            SelRow   = app.selectedRow;    
            SelEQnum = table2array(app.EQtable_UITable.Data(SelRow,1));
            SelTime  = table2array(app.EQtable_UITable.Data(SelRow,2));
            SelMagn  = table2array(app.EQtable_UITable.Data(SelRow,3));
            FigNum   = app.PlotFigNum_EditField.Value;
            %----------------------------------------     
            Yval     = app.MRF(SelEQnum).Val';
            Xval     = (1 : app.MRF(SelEQnum).Num).*app.DeltaT;
            %---------------------------------------- 
            figure(FigNum);         clf;          
            plot(Xval,Yval,'.-k');
            xlabel('Time (sec)');       ylabel('Moment relase (Nm)');    
            title(strcat('Time: ',num2str(SelTime), 'and Magnitude:',num2str(SelMagn)));
        end

        % Button pushed function: PlotOnFault_Button
        function PlotOnFault_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            %---------------------------------------- 
            FigNum    = app.PlotFigNum_EditField.Value; 
            ViewA     = app.PlotViewAngle_EditField.Value;          ViewA  = sscanf(ViewA,'%f')';
            %----------------------------------------            
            SelCol    = str2double(app.PlotColorMap_DropDown.Value);
            SelCols   = app.PlotColorMap_DropDown.Items{SelCol};
            MinVal    = app.PlotMinValue_EditField.Value;
            MaxVal    = app.PlotMaxValue_EditField.Value;
            MinTime = app.Sequence_StartT_EditField.Value;
            MaxTime = app.Sequence_EndTEditField.Value;
            SelRow    = app.selectedRow;    
            ThisEvent = table2array(app.EQtable_UITable.Data(SelRow,1));
            %----------------------------------------         
            Comp2Plot = str2double(app.PlotComp2Plot_DropDown.Value);
            ShowEvol  = app.PlotShowTimeEvol_CheckBox.Value;
            DeltT     = app.PlotTimeEvolsecPerFrame_EditField.Value;
            FrmPs     = app.PlotMoviFps_EditField.Value;
            SaveMovie = app.PlotSaveMovie_CheckBox.Value;
            %----------------------------------------         
            FrameBins = 0 : DeltT : (max(app.ClnCatalog(ThisEvent).T0)+DeltT);
            %----------------------------------------         
            if (ShowEvol == 1) && (SaveMovie == 1)
                [DataName,DataPath]  = uiputfile({'*.*'},'JO!');
                if     (DataName ~=0),                      CompletePath         = strcat(DataPath,DataName); 
                elseif (DataName ==0),                      return;         
                end
                %---------------------%
                FileName       = strcat(CompletePath,'.avi');
                %---------------------%
                FrameBinNum    = size(FrameBins,2);
                F(FrameBinNum) = struct('cdata',[],'colormap',[]);
            end
            
            figure(FigNum); clf; hold on; axis equal; axis tight; view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');
            %-----------------------------------------------
            AllV1s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV2s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV3s          = zeros(app.FaultGeom.SizeTrigList,3);
            PlottedComp     = nan(app.FaultGeom.SizeTrigList,1);
            SelCompWtime    = nan(app.FaultGeom.SizeTrigList,1);
            Patchtime       = nan(app.FaultGeom.SizeTrigList,1);
   
            EventPatches    = app.ClnCatalog(ThisEvent).PtchID;
            if     (Comp2Plot == 1),        PlotComp = sqrt(app.ClnCatalog(ThisEvent).Slip_h.^2 +app.ClnCatalog(ThisEvent).Slip_v.^2);
            elseif (Comp2Plot == 2),        PlotComp =      app.ClnCatalog(ThisEvent).Slip_h;
            elseif (Comp2Plot == 3),        PlotComp =      app.ClnCatalog(ThisEvent).Slip_v;
            elseif (Comp2Plot == 4),        PlotComp =      app.ClnCatalog(ThisEvent).Delt_t/1E+6;
            elseif (Comp2Plot == 5),        PlotComp =      app.ClnCatalog(ThisEvent).T0;     
            elseif (Comp2Plot == 6),        PlotComp =      app.ClnCatalog(ThisEvent).StabT;              
            elseif (Comp2Plot == 7)
                PlottedComp  = zeros(1,app.FaultGeom.SizeTrigList);
                for i = 1 : app.EQcount
                    NextTime = app.ClnCatalog(i).EQtime;
                    if ((NextTime >= MinTime) && (NextTime <= MaxTime))
                        PlottedComp(1,app.ClnCatalog(i).PtchID) = PlottedComp(1,app.ClnCatalog(i).PtchID) + sqrt(app.ClnCatalog(i).Slip_h.^2 +app.ClnCatalog(i).Slip_v.^2);
                    end
                end
            end
            %-----------------------------------------------
            for j = 1 : app.FaultGeom.SizeTrigList
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),3);
                %-----------------------------
                if (Comp2Plot < 7)
                    PosInQke    = find(EventPatches == j);
                    if ~isempty(PosInQke),       PlottedComp(j,1) = PlotComp(PosInQke);         Patchtime(j,1) = app.ClnCatalog(ThisEvent).T0(PosInQke);           end
                end
            end
            %-----------------------------------------------
            if (SelCol < 5),        colormap(SelCols);
            elseif (SelCol == 5),   colormap(app.BlueWhiteRed);
            elseif (SelCol == 6),   colormap(app.Cols4Slip);
            end
            caxis([MinVal MaxVal]);        
            colorbar;
            %-----------------------------
            if (ShowEvol == 0)
                patch(AllV1s',AllV2s',AllV3s',PlottedComp'); 
            else
                %---------------------%%---------------------%
                if (SaveMovie == 1)
                    pos  = get(gca,'position');
                    marg = 30;
                    rect = [-marg, -marg, pos(3)+2*marg, pos(4)+2*marg];
                end
                %---------------------%%---------------------%
                for i = 1 : size(FrameBins,2)
                    NextVal = FrameBins(1,i);
                    CurrSel               = find(Patchtime <= NextVal);
                    SelCompWtime(CurrSel) = PlottedComp(CurrSel); 
                    patch(AllV1s',AllV2s',AllV3s',SelCompWtime'); 
                    drawnow;
                    pause(0.05);
                    if (SaveMovie == 1)
                        F(i) = getframe(gca);
                    end
                end
                %---------------------%%---------------------%
                if (SaveMovie == 1)
                    MovieFrames = F(1,1:end-1);
                    %---------------------%
                    myVideo           = VideoWriter(FileName);
                    myVideo.FrameRate = FrmPs;
                    myVideo.Quality   = 75;    % Default 75
                    %---------------------%
                    open(myVideo);
                    writeVideo(myVideo,MovieFrames);
                    close(myVideo);
                end
                %---------------------%%---------------------%
            end
            disp('done');
        end

        % Button pushed function: SequencePlot_Button
        function SequencePlot_ButtonPushed(app, event)
            
            FigNum    = app.PlotFigNum_EditField.Value; 
            ViewA     = app.PlotViewAngle_EditField.Value;          ViewA  = sscanf(ViewA,'%f')';
            SelCol    = str2double(app.PlotColorMap_DropDown.Value);
            SelCols   = app.PlotColorMap_DropDown.Items{SelCol};
            if (SelCol < 5),        UsedCol = colormap(SelCols);
            elseif (SelCol == 5),   UsedCol = colormap(app.BlueWhiteRed);
            elseif (SelCol == 6),   UsedCol = colormap(app.Cols4Slip);
            end
            %----------------------------------------    
            Comp2Plot = str2double(app.PlotComp2Plot_DropDown.Value);
            
            MinVal    = app.PlotMinValue_EditField.Value;
            MaxVal    = app.PlotMaxValue_EditField.Value;   
            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;
            DeltT     = app.PlotTimeEvolsecPerFrame_EditField.Value;
            FrmPs     = app.PlotMoviFps_EditField.Value;
            SaveMovie = app.PlotSaveMovie_CheckBox.Value;
            %----------------------------------------         
            FrameBins   = MinTime : DeltT : MaxTime;
            FrameBinNum = size(FrameBins,2);
            
            if (SaveMovie == 1)
                [DataName,DataPath]  = uiputfile({'*.*'},'JO!');
                if     (DataName ~=0),                      CompletePath         = strcat(DataPath,DataName); 
                elseif (DataName ==0),                      return;         
                end
                %---------------------%
                FileName       = strcat(CompletePath,'.avi');
                %---------------------%
                
                F(FrameBinNum) = struct('cdata',[],'colormap',[]);
            end
            %-----------------------------------------------
            AllV1s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV2s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV3s          = zeros(app.FaultGeom.SizeTrigList,3);
            for j = 1 : app.FaultGeom.SizeTrigList
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),3);
            end
            %-----------------------------------------------
            figure(FigNum); 

            StartPos = 1;
            for i =  2 : FrameBinNum
                %-----------------------------------------
                clf; hold on; axis equal;  view(ViewA); axis tight;
                xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)'); title(strcat('Time: ',num2str(FrameBins(1,i))));
                colormap(UsedCol);
                caxis([MinVal MaxVal]);        
                colorbar;
                %this needs improvement in terms of correct cutting to the "frame"
                %-----------------------------------------
                TheseEvents  = [];
                MinTime      = FrameBins(1,i-1);             MaxTime = FrameBins(1,i);
                for j = StartPos : size(app.ClnCatalog,2)
                    if ((app.ClnCatalog(j).EQtime >= MinTime) && (app.ClnCatalog(j).EQtime <= MaxTime))
                        TheseEvents = [TheseEvents; j];   %#ok<AGROW>
                    end
                    if (app.ClnCatalog(j).EQtime > MaxTime),        StartPos = j;      break;          end
                end
                %-----------------------------------------
                PlottedComp  = zeros(app.FaultGeom.SizeTrigList,1);
                if ~isempty(TheseEvents)
                    for j = 1 : size(TheseEvents,1)
                        ThisEvent       = TheseEvents(j,1);
                        EventPatches    = app.ClnCatalog(ThisEvent).PtchID;
                        if     (Comp2Plot == 1),        PlotComp = sqrt(app.ClnCatalog(ThisEvent).Slip_h.^2 +app.ClnCatalog(ThisEvent).Slip_v.^2);
                        elseif (Comp2Plot == 2),        PlotComp =      app.ClnCatalog(ThisEvent).Slip_h;
                        elseif (Comp2Plot == 3),        PlotComp =      app.ClnCatalog(ThisEvent).Slip_v;
                        elseif (Comp2Plot == 4),        PlotComp =      app.ClnCatalog(ThisEvent).Delt_t;
                        elseif (Comp2Plot == 5),        PlotComp =      app.ClnCatalog(ThisEvent).T0;    
                        elseif (Comp2Plot == 6),        PlotComp =      app.ClnCatalog(ThisEvent).StabT;    
                        end
                        
                        for k = 1 : app.FaultGeom.SizeTrigList
                            PosInQke    = find(EventPatches == k,1,'first');
                            if ~isempty(PosInQke)       
                                if (Comp2Plot < 6)
                                    PlottedComp(k,1) = PlottedComp(k,1) + PlotComp(PosInQke);         
                                else
                                    PlottedComp(k,1) = max([PlottedComp(k,1), PlotComp(PosInQke)]);     
                                end
                            end
                        end
                    end
                end
                patch(AllV1s',AllV2s',AllV3s',zeros(size(AllV2s,1),1)','facealpha',0); 
                hold on;
                Sel = find(PlottedComp ~= 0);
                patch(AllV1s(Sel,:)',AllV2s(Sel,:)',AllV3s(Sel,:)',PlottedComp(Sel)','facealpha',1); 
                drawnow;
                pause(0.05);
                %-----------------------------------------
                if (SaveMovie == 1)
                    F(i) = getframe(gca);
                end
            end
            %-----------------------------------------------
            if (SaveMovie == 1)
                MovieFrames = F(1,2:end);
                %---------------------%
                myVideo           = VideoWriter(FileName);
                myVideo.FrameRate = FrmPs;
                myVideo.Quality   = 75;    % Default 75
                %---------------------%
                open(myVideo);
                writeVideo(myVideo,MovieFrames);
                close(myVideo);
            end 
        end

        % Button pushed function: loadparameterButton
        function loadparameterButtonPushed(app, event)
            [LoadName,LoadPath] = uigetfile('SelParameter.dat','Load selected parameter');
            if     (LoadName ~=0) 
                ParaPath  = strcat(LoadPath,LoadName);
            elseif (LoadName ==0),      return;         
            end
            
            fidbinPar    = fopen(ParaPath,'rb');
            app.LoadCat_Lamp.Color = 'yellow';      drawnow;
            %---------------------------------------------------------------------
            PtchNum           = fread(fidbinPar,1,'int');
            app.ExtraParamter = fread(fidbinPar,PtchNum,'float');
            blah = app.ExtraParamter;
            meanVal = mean(app.ExtraParamter(:));
            disp(strcat('Average of imported parameter: ',num2str(meanVal)));

            app.LoadCat_Lamp.Color = 'green';      drawnow;
        end

        % Button pushed function: plotparameterButton
        function plotparameterButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            %---------------------------------------- 
            FigNum    = app.PlotFigNum_EditField.Value*10+1; 
            ViewA     = app.PlotViewAngle_EditField.Value;          ViewA  = sscanf(ViewA,'%f')';
            %----------------------------------------            
            SelCol    = str2double(app.PlotColorMap_DropDown.Value);
            SelCols   = app.PlotColorMap_DropDown.Items{SelCol};
            %----------------------------------------         
            figure(FigNum);  hold on; axis equal; axis tight; view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');
            %-----------------------------------------------
            AllV1s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV2s          = zeros(app.FaultGeom.SizeTrigList,3);
            AllV3s          = zeros(app.FaultGeom.SizeTrigList,3);
            PlottedComp     = app.ExtraParamter;
     
       %     PlottedComp     = app.FaultGeom.IsSurfPatch;
            %-----------------------------------------------
            for j = 1 : app.FaultGeom.SizeTrigList
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),3);
            end
            %-----------------------------------------------
            
            if (SelCol < 5),        colormap(SelCols);
            elseif (SelCol == 5),   colormap(app.BlueWhiteRed);
            elseif (SelCol == 6),   colormap(app.Cols4Slip);
            end     
            colorbar;
            %-----------------------------
            patch(AllV1s',AllV2s',AllV3s',PlottedComp');  
        end

        % Button pushed function: SelectSinglePatch_Button
        function SelectSinglePatch_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
            
            FigNum    = app.PlotFigNum_EditField.Value; 
            figure(FigNum);
            %plot3(app.FaultGeom.GlobTrigCentr(:,1), app.FaultGeom.GlobTrigCentr(:,2), app.FaultGeom.GlobTrigCentr(:,3), 'c.'); 
            
            datacursormode on;
            dcm_obj = datacursormode(gcf);
    
            waitforbuttonpress;
            f = getCursorInfo(dcm_obj);
            PointCoord    = f.Position;

            PointDistance = sqrt((app.FaultGeom.GlobTrigCentr(:,1) - PointCoord(1,1)).^2 +(app.FaultGeom.GlobTrigCentr(:,2) - PointCoord(1,2)).^2 +(app.FaultGeom.GlobTrigCentr(:,3) - PointCoord(1,3)).^2);
            MinDistPos    = find(PointDistance == min(PointDistance(:)),1,'first');
            
            app.SelectPatchID_edit.Value = MinDistPos;
            
            plot3(app.FaultGeom.GlobTrigCentr(MinDistPos,1), app.FaultGeom.GlobTrigCentr(MinDistPos,2), app.FaultGeom.GlobTrigCentr(MinDistPos,3), 'ro'); 
 
        end

        % Button pushed function: SelectHighlightPatch_Button
        function SelectHighlightPatch_ButtonPushed(app, event)
            
            PlotOnFault_ButtonPushed(app, event);
            
            SelPatch = app.SelectPatchID_edit.Value;
            Vx(1,1)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(SelPatch,1),1);
            Vx(1,2)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(SelPatch,2),1);
            Vx(1,3)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(SelPatch,3),1);
            Vx(1,4)  = Vx(1,1);
            Vy(1,1)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(SelPatch,1),2);
            Vy(1,2)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(SelPatch,2),2);
            Vy(1,3)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(SelPatch,3),2);
            Vy(1,4)  = Vy(1,1);
            Vz(1,1)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(SelPatch,1),3);
            Vz(1,2)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(SelPatch,2),3);
            Vz(1,3)  = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(SelPatch,3),3);
            Vz(1,4)  = Vz(1,1);
            
            plot3(Vx,Vy,Vz,'-r','LineWidth',2);           
        end

        % Button pushed function: SelectPlotParVsTime_Button
        function SelectPlotParVsTime_ButtonPushed(app, event)
            %to show step-plot (time-vs-slip etc..)
            if (app.CatImported == 0),      return;             end           
            
            Comp2Plot = str2double(app.PlotComp2Plot_DropDown.Value);
            FigNum    = app.PlotFigNum_EditField.Value*10+Comp2Plot;
            SelPatch  = app.SelectPatchID_edit.Value;
            
            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;
            
            ExtractCat= [];
            CatCounter= 1;
            %----------------------------------------
            for i = 1 : size(app.ClnCatalog,2)
                if ((app.ClnCatalog(i).EQtime >= MinTime) && (app.ClnCatalog(i).EQtime <= MaxTime))
                
                    FindPatchInEQ = find(app.ClnCatalog(i).PtchID == SelPatch,1,'first');  
                    
                    if (~isempty(FindPatchInEQ))
                        
                        ExtractCat(CatCounter,1) = app.ClnCatalog(i).EQtime;
                            
                        if     (Comp2Plot == 1),        ExtractCat(CatCounter,2) = sqrt(app.ClnCatalog(i).Slip_h(1,FindPatchInEQ).^2 +app.ClnCatalog(i).Slip_v(1,FindPatchInEQ).^2);
                        elseif (Comp2Plot == 2),        ExtractCat(CatCounter,2) =      app.ClnCatalog(i).Slip_h(1,FindPatchInEQ);
                        elseif (Comp2Plot == 3),        ExtractCat(CatCounter,2) =      app.ClnCatalog(i).Slip_v(1,FindPatchInEQ);
                        elseif (Comp2Plot == 4),        ExtractCat(CatCounter,2) =      app.ClnCatalog(i).Delt_t(1,FindPatchInEQ);
                        elseif (Comp2Plot == 5),        ExtractCat(CatCounter,2) =      app.ClnCatalog(i).T0(1,FindPatchInEQ);    
                        elseif (Comp2Plot == 6),        ExtractCat(CatCounter,2) =      app.ClnCatalog(i).StabT(1,FindPatchInEQ);    
                        else,                           return;
                        end
                       
                        CatCounter = CatCounter+1;
                    end
                end
            end
            %----------------------------------------
            ParameterCV = ExtractCat(:,2);
            TimeCV      = diff(ExtractCat(:,1));
            ParameterCV = std(ParameterCV)/mean(ParameterCV);
            TimeCV      = std(TimeCV)/mean(TimeCV);
            WriteString = ['The inter-event time CV is: ',num2str(TimeCV)];
            disp(WriteString);
            WriteString = ['The CV of selected parameter is: ',num2str(ParameterCV)];
            disp(WriteString);
            %----------------------------------------
            if (~isempty(ExtractCat))
                figure(FigNum); clf;    hold on;
                if (Comp2Plot < 5)
                    ExtractCat(:,2) = cumsum(ExtractCat(:,2));    
                end
                
                for i = 1 : size(ExtractCat,1)
                    plot(ExtractCat(i,1),ExtractCat(i,2),'.k');  
                    
                    if (i > 1)
                        plot([ExtractCat(i-1,1) ExtractCat(i,1)],[ExtractCat(i-1,2) ExtractCat(i-1,2)],'-k','LineWidth',1);    
                        plot([ExtractCat(i,1) ExtractCat(i,1)],  [ExtractCat(i-1,2) ExtractCat(i,2)],'-k','LineWidth',1);   
                        plot(ExtractCat(i,1),ExtractCat(i-1,2),'.k');  
                    end
                end
            end
            %----------------------------------------
            xlabel('Time (years)');ylabel('Selected parameter');
        end

        % Button pushed function: SelectPlotParaAlongSurf_Button
        function SelectPlotParaAlongSurf_ButtonPushed(app, event)
            if (app.CatImported == 0),      return;             end           
          
            Comp2Plot = str2double(app.PlotComp2Plot_DropDown.Value);
            FigNum    = app.PlotFigNum_EditField.Value*10+Comp2Plot;

            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;
            
            SrfPtchPos= find(app.FaultGeom.IsSurfPatch == 1);
            SrfXpos   = app.FaultGeom.GlobTrigCentr(SrfPtchPos,1);
            SrfYpos   = app.FaultGeom.GlobTrigCentr(SrfPtchPos,2);

            figure(FigNum); hold on;
            for (i = 1 : size(SrfPtchPos,1))
                plot3(SrfXpos(i,1),SrfYpos(i,1),0,'ok');    
            end
            drawnow;
            EQcolors = rand(size(app.ClnCatalog,2),3);
            %------------------------------------------------
            for (i = 1 : size(SrfPtchPos,1))
                NextPtch = SrfPtchPos(i,1);
                SrfParam = 0;
                for (j = 1 : size(app.ClnCatalog,2))
                    if ((app.ClnCatalog(j).EQtime >= MinTime) && (app.ClnCatalog(j).EQtime <= MaxTime))
                        EQruptPtch = find(app.ClnCatalog(j).PtchID == NextPtch,1,'first');
                        
                        if ~isnan(EQruptPtch)
                            if     (Comp2Plot == 1),        SrfParam = SrfParam + sqrt(app.ClnCatalog(j).Slip_h(1,EQruptPtch).^2 +app.ClnCatalog(j).Slip_v(1,EQruptPtch).^2);
                            elseif (Comp2Plot == 2),        SrfParam = SrfParam +      app.ClnCatalog(j).Slip_h(1,EQruptPtch);
                            elseif (Comp2Plot == 3),        SrfParam = SrfParam +      app.ClnCatalog(j).Slip_v(1,EQruptPtch);
                            elseif (Comp2Plot == 4),        SrfParam = SrfParam +      app.ClnCatalog(j).Delt_t(1,EQruptPtch);
                            elseif (Comp2Plot == 5),        return;    
                            elseif (Comp2Plot == 6),        return;    
                            else,                           return;
                            end    

                            plot3(SrfXpos(i,1),SrfYpos(i,1),SrfParam,'o','Color',EQcolors(j,:),'MarkerFaceColor',EQcolors(j,:));
                            
                        end
                    end
                end
            end           
        end

        % Button pushed function: SelectHypoCentDistrib_Button
        function SelectHypoCentDistrib_ButtonPushed(app, event)

            FigNum      = app.PlotFigNum_EditField.Value*10+9;
            ViewA       = app.PlotViewAngle_EditField.Value;            ViewA  = sscanf(ViewA,'%f')';
            SelCol      = str2double(app.PlotColorMap_DropDown.Value);
            SelCols     = app.PlotColorMap_DropDown.Items{SelCol};
            %------------------------------------------------        
            MinTime     = app.Sequence_StartT_EditField.Value;
            MaxTime     = app.Sequence_EndTEditField.Value;
            
            AllV1s      = zeros(app.FaultGeom.SizeTrigList,3);
            AllV2s      = zeros(app.FaultGeom.SizeTrigList,3);
            AllV3s      = zeros(app.FaultGeom.SizeTrigList,3);
            PlottedComp = zeros(app.FaultGeom.SizeTrigList,1);
            %------------------------------------------------    
            for j = 1 : app.FaultGeom.SizeTrigList
                AllV1s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),1);
                AllV1s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),1);
                AllV1s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),1);
            
                AllV2s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),2);
                AllV2s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),2);
                AllV2s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),2);
            
                AllV3s(j,1) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,1),3);
                AllV3s(j,2) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,2),3);
                AllV3s(j,3) = app.FaultGeom.GlobalVertices(app.FaultGeom.TriangleList(j,3),3);
            end
            %------------------------------------------------    
            for (j = 1 : size(app.ClnCatalog,2))
                if ((app.ClnCatalog(j).EQtime >= MinTime) && (app.ClnCatalog(j).EQtime <= MaxTime))
                  %  HypoPtchID = app.ClnCatalog(j).PtchID(app.ClnCatalog(j).T0(:) == min(app.ClnCatalog(j).T0(:)));
                    HypoPtchID = app.ClnCatalog(j).PtchID(app.ClnCatalog(j).T0(:) == 0);
                    PlottedComp(HypoPtchID) = PlottedComp(HypoPtchID) +1;
                end
            end
            %------------------------------------------------
            if (SelCol < 5),        colormap(SelCols);
            elseif (SelCol == 5),   colormap(app.BlueWhiteRed);
            elseif (SelCol == 6),   colormap(app.Cols4Slip);
            end  
            min(PlottedComp(:))
            max(PlottedComp(:))
            
            
            figure(FigNum); clf; hold on; axis equal; axis tight; view(ViewA);
            xlabel('Easting (km)');ylabel('Northing (km)'); zlabel('Depth (km)');
            
            patch(AllV1s',AllV2s',AllV3s',PlottedComp'); 
            
        end

        % Button pushed function: InterEvent_PlotStuff_Button
        function InterEvent_PlotStuff_ButtonPushed(app, event)
            %--------------------------------------------------- 
            FigNum     = app.InterEventFigNum_EditField.Value;
            PatchOrAll = str2double(app.Scaling_PatchOrAll_DropDown.Value);
            SelPatch   = app.SelectPatchID_edit.Value;
            SelSymb    = str2double(app.Scaling_Symbol_DropDown.Value);
            SelSymbs   = app.Scaling_Symbol_DropDown.Items{SelSymb};      
            %--------------------------------------------------- 
            Xscale  = str2double(app.InterEvent_Xscale_DropDown.Value);
            SclXs   = app.InterEvent_Xscale_DropDown.Items{Xscale};
            Yscale  = str2double(app.InterEvent_Yscale_DropDown.Value);
            SclYs   = app.InterEvent_Yscale_DropDown.Items{Yscale};
            Xvalue  = str2double(app.InterEvent_Xvalue_DropDown.Value);
            Xstring = app.InterEvent_Xvalue_DropDown.Items{Xvalue}; 
            Yvalue  = str2double(app.InterEvent_Yvalue_DropDown.Value);
            Ystring = app.InterEvent_Yvalue_DropDown.Items{Yvalue}; 
            Cvalue  = str2double(app.InterEvent_Cvalue_DropDown.Value);
            Cstring = app.InterEvent_Cvalue_DropDown.Items{Cvalue};             
            %--------------------------------------------------- 
            MinMagn   = app.InterEventMinMag_EditField.Value;
            MaxMagn   = app.InterEventMaxMag_EditField.Value;         
            MinTime   = app.Sequence_StartT_EditField.Value;
            MaxTime   = app.Sequence_EndTEditField.Value;
            
            MinBinVal = app.PlotBinMinValue_EditField.Value;
            MaxBinVal = app.PlotBinMaxValue_EditField.Value;
            BinNum    = app.PlotBinNum_EditField.Value;
            %---------------------------------------------------            
            aa = zeros(size(app.ClnCatalog(:),1),1);
            bb = zeros(size(app.ClnCatalog(:),1),1);
            cc = zeros(size(app.ClnCatalog(:),1),1);
            %------------------
            irun = 0;
            for iii = 1 : size(app.ClnCatalog(:),1)
                
                if ((app.ClnCatalog(iii).Magn >= MinMagn) &&(app.ClnCatalog(iii).Magn <= MaxMagn) &&(app.ClnCatalog(iii).EQtime >= MinTime)&& (app.ClnCatalog(iii).EQtime <= MaxTime))
                    %--------------------------------
                    if (PatchOrAll == 1),       LetsDoThis = 1;
                    else,                       LetsDoThis = sum(app.ClnCatalog(iii).PtchID == SelPatch);
                    end
                    %--------------------------------
                    if (LetsDoThis > 0)
                        irun = irun +1;
                        aa(irun) = app.ClnCatalog(iii).EQtime;
                        bb(irun) = app.ClnCatalog(iii).Magn;
                        %------------------ 
                        CentPos  = app.FaultGeom.GlobTrigCentr(app.ClnCatalog(iii).PtchID(:),:);
                        SlipAmnt = sqrt(app.ClnCatalog(iii).Slip_h(:).^2 +app.ClnCatalog(iii).Slip_v(:).^2);
                        ScledPos = CentPos.*SlipAmnt;
                        %--------------------------------
                        cc(irun,1:3) = sum(ScledPos)/sum(SlipAmnt(:));
                    end
                    %--------------------------------
                end  
            end
            aa = [0; diff(aa(1:irun)).*31557000]; %time
            bb = bb(1:irun); %magnitude
            cc = sqrt((cc(2:irun,1)-cc(1:irun-1,1)).^2 + (cc(2:irun,2)-cc(1:irun-1,2)).^2 +(cc(2:irun,3)-cc(1:irun-1,3)).^2); %distance
            cc = [0; cc];
            %---------------------------------------------------
            data = [aa,cc, bb]; 
            %---------------------------------------------------
            %---------------------------------------------------
            figure(FigNum); clf;   
            if (Xvalue == Yvalue)
                if (BinNum > 1),    BinValues = linspace(MinBinVal,MaxBinVal,BinNum);
                else,               BinValues = linspace(min(data(:,Xvalue)),max(data(:,Xvalue)),round(sqrt(size(data(:,Xvalue),1))));
                end
                
                [SelYvals,SelXvals] = histcounts(data(:,Xvalue),BinValues);     
                SelXvals(1:end-1)   = SelXvals(1:end-1) + (SelXvals(1,2)-SelXvals(1,1))/2;
                SelXvals            = SelXvals(1:end-1);
                SelYvals = SelYvals./sum(SelYvals); 
                
                SelYvals = fliplr(cumsum(fliplr(SelYvals)));
                
                plot(SelXvals, SelYvals, SelSymbs,'color','k'); 
                
                xlabel(strcat(Xstring,',',SclXs));   ylabel('frequency');      
            else
                SelXvals  = data(:,Xvalue);           SelYvals  = data(:,Yvalue);
               % plot(SelXvals, SelYvals, SelSymbs,'color','k'); 
                
                scatter(SelXvals,SelYvals,12,data(:,Cvalue),'filled');
                xlabel(strcat(Xstring,',',SclXs));   ylabel(strcat(Ystring,',',SclYs));      
            end
            set(gca,'XScale',SclXs,'YScale',SclYs);
            grid on;
            %---------------------


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.PlotShowTimeEvol_CheckBox = uicheckbox(app.UIFigure);
            app.PlotShowTimeEvol_CheckBox.Text = 'show time evol.';
            app.PlotShowTimeEvol_CheckBox.Value = true;
            app.PlotSaveMovie_CheckBox = uicheckbox(app.UIFigure);
            app.PlotSaveMovie_CheckBox.Text = 'save movie';
            app.fpsEditFieldLabel = uilabel(app.UIFigure);
            app.fpsEditFieldLabel.Tooltip = {'FramePerSec/YearPerFrame; first for single-event; second for EQ sequence'};
            app.fpsEditFieldLabel.Text = 'fps';
            app.PlotMoviFps_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotMoviFps_EditField.Limits = [1 Inf];
            app.PlotMoviFps_EditField.RoundFractionalValues = 'on';
            app.PlotMoviFps_EditField.ValueDisplayFormat = '%d';
            app.PlotMoviFps_EditField.Value = 10;
            app.secframeEditFieldLabel = uilabel(app.UIFigure);
            app.secframeEditFieldLabel.Text = 'sec/frame';
            app.PlotTimeEvolsecPerFrame_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotTimeEvolsecPerFrame_EditField.Limits = [0.1 Inf];
            app.PlotTimeEvolsecPerFrame_EditField.ValueDisplayFormat = '%2.1f';
            app.PlotTimeEvolsecPerFrame_EditField.Value = 2;
            app.ImportedparsedEQcatalogPanel = uipanel(app.UIFigure);
            app.ImportedparsedEQcatalogPanel.Title = 'Imported/parsed EQ catalog';
            app.EQtable_UITable = uitable(app.ImportedparsedEQcatalogPanel);
            app.EQtable_UITable.ColumnName = {'EQ#'; 'Time (years)'; 'Magnitude'; 'Rupture Area (km^2)'; 'Slipping patches (#)'; 'Stress drop (MPa)'; 'Horz. slip (m)'; 'Vert. slip (m)'; 'Rupture duration (s)'};
            app.EQtable_UITable.RowName = {};
            app.EQtable_UITable.ColumnSortable = true;
            app.EQtable_UITable.CellSelectionCallback = createCallbackFcn(app, @EQtable_UITableCellSelection, true);
            app.PlotScaling_Button = uibutton(app.ImportedparsedEQcatalogPanel, 'push');
            app.PlotScaling_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotScaling_ButtonPushed, true);
            app.PlotScaling_Button.Text = 'Plot scaling relationship';
            app.FigEditField_2Label = uilabel(app.ImportedparsedEQcatalogPanel);
            app.FigEditField_2Label.Text = 'Fig #';
            app.FigNumScaling_EditField = uieditfield(app.ImportedparsedEQcatalogPanel, 'numeric');
            app.FigNumScaling_EditField.Limits = [1 Inf];
            app.FigNumScaling_EditField.RoundFractionalValues = 'on';
            app.FigNumScaling_EditField.ValueDisplayFormat = '%d';
            app.FigNumScaling_EditField.Value = 3;
            app.symbolDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.symbolDropDownLabel.Text = 'symbol';
            app.Scaling_Symbol_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.Scaling_Symbol_DropDown.Items = {'-', '--', '-.', '.-', '.', 'o-', 'o'};
            app.Scaling_Symbol_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.Scaling_Symbol_DropDown.Value = '1';
            app.scaleDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.scaleDropDownLabel.Text = 'scale';
            app.ScalingXscale_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingXscale_DropDown.Items = {'linear', 'log'};
            app.ScalingXscale_DropDown.ItemsData = {'1', '2'};
            app.ScalingXscale_DropDown.Value = '1';
            app.scaleDropDown_2Label = uilabel(app.ImportedparsedEQcatalogPanel);
            app.scaleDropDown_2Label.Text = 'scale';
            app.ScalingYscale_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingYscale_DropDown.Items = {'linear', 'log'};
            app.ScalingYscale_DropDown.ItemsData = {'1', '2'};
            app.ScalingYscale_DropDown.Value = '1';
            app.xaxisDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.xaxisDropDownLabel.Text = 'x-axis';
            app.ScalingXvalue_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingXvalue_DropDown.Items = {'time', 'magnitude', 'rupture area', 'slipping patches', 'stress drop', 'horz. slip', 'vert. slip', 'comb. slip', 'rupt. duration'};
            app.ScalingXvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.ScalingXvalue_DropDown.Value = '1';
            app.yaxisDropDownLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.yaxisDropDownLabel.Text = 'y-axis';
            app.ScalingYvalue_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.ScalingYvalue_DropDown.Items = {'time', 'magnitude', 'rupture area', 'slipping patches', 'stress drop', 'horz. slip', 'vert. slip', 'comb. slip', 'rupt. duration', 'frequency', 'frequency (cum)'};
            app.ScalingYvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11'};
            app.ScalingYvalue_DropDown.Value = '2';
            app.loadparameterButton = uibutton(app.ImportedparsedEQcatalogPanel, 'push');
            app.loadparameterButton.ButtonPushedFcn = createCallbackFcn(app, @loadparameterButtonPushed, true);
            app.loadparameterButton.Text = 'load parameter';
            app.plotparameterButton = uibutton(app.ImportedparsedEQcatalogPanel, 'push');
            app.plotparameterButton.ButtonPushedFcn = createCallbackFcn(app, @plotparameterButtonPushed, true);
            app.plotparameterButton.Text = 'plot parameter';
            app.forLabel = uilabel(app.ImportedparsedEQcatalogPanel);
            app.forLabel.Text = 'for';
            app.Scaling_PatchOrAll_DropDown = uidropdown(app.ImportedparsedEQcatalogPanel);
            app.Scaling_PatchOrAll_DropDown.Items = {'all events', 'selected patch'};
            app.Scaling_PatchOrAll_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.Scaling_PatchOrAll_DropDown.Value = '1';
            app.LoadandparsecatalogPanel = uipanel(app.UIFigure);
            app.LoadandparsecatalogPanel.Title = 'Load and parse catalog';
            app.LoadCatalog_Button = uibutton(app.LoadandparsecatalogPanel, 'push');
            app.LoadCatalog_Button.ButtonPushedFcn = createCallbackFcn(app, @LoadCatalog_ButtonPushed, true);
            app.LoadCatalog_Button.Text = 'Load Catalog';
            app.RemoveEQswithlessthanEditFieldLabel = uilabel(app.LoadandparsecatalogPanel);
            app.RemoveEQswithlessthanEditFieldLabel.Text = 'Remove EQs with less than';
            app.LoadCatRemoveEQ_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadCatRemoveEQ_EditField.Limits = [1 Inf];
            app.LoadCatRemoveEQ_EditField.RoundFractionalValues = 'on';
            app.LoadCatRemoveEQ_EditField.ValueDisplayFormat = '%d';
            app.LoadCatRemoveEQ_EditField.Value = 1;
            app.LoadCatGroupTime_CheckBox = uicheckbox(app.LoadandparsecatalogPanel);
            app.LoadCatGroupTime_CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCatGroupTime_CheckBoxValueChanged, true);
            app.LoadCatGroupTime_CheckBox.Text = 'Group by time';
            app.LoadCatGroupTime_CheckBox.Value = true;
            app.LoadCatGroupDist_CheckBox = uicheckbox(app.LoadandparsecatalogPanel);
            app.LoadCatGroupDist_CheckBox.ValueChangedFcn = createCallbackFcn(app, @LoadCatGroupDist_CheckBoxValueChanged, true);
            app.LoadCatGroupDist_CheckBox.Text = 'Group by distance';
            app.maxdistEditFieldLabel = uilabel(app.LoadandparsecatalogPanel);
            app.maxdistEditFieldLabel.Text = 'max. dist.';
            app.LoadCatMaxDist_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadCatMaxDist_EditField.Limits = [0 Inf];
            app.LoadCatMaxDist_EditField.ValueDisplayFormat = '%4.2f';
            app.LoadCatMaxDist_EditField.Tooltip = {'this is fraction of mean leglength'};
            app.LoadCatMaxDist_EditField.Value = 1;
            app.CatalogimportedLampLabel = uilabel(app.LoadandparsecatalogPanel);
            app.CatalogimportedLampLabel.Text = 'Catalog imported';
            app.LoadCat_Lamp = uilamp(app.LoadandparsecatalogPanel);
            app.meanleglengthLabel = uilabel(app.LoadandparsecatalogPanel);
            app.meanleglengthLabel.Text = 'mean leg length';
            app.LoadMeanLegLegth_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadMeanLegLegth_EditField.Limits = [0 Inf];
            app.LoadMeanLegLegth_EditField.ValueDisplayFormat = '%4.2f';
            app.LoadMeanLegLegth_EditField.Editable = 'off';
            app.LoadMeanLegLegth_EditField.Tooltip = {'mean leg length of triangular patches'};
            app.EQsintablewithMagnitudeLabel = uilabel(app.LoadandparsecatalogPanel);
            app.EQsintablewithMagnitudeLabel.Text = 'EQs in table with Magnitude >';
            app.LoadMinMag4Table_EditField = uieditfield(app.LoadandparsecatalogPanel, 'numeric');
            app.LoadMinMag4Table_EditField.ValueDisplayFormat = '%5.2f';
            app.LoadMinMag4Table_EditField.Value = 6;
            app.PlotindividualeventparameterPanel = uipanel(app.UIFigure);
            app.PlotindividualeventparameterPanel.Title = 'Plot individual-event parameter';
            app.PlotMRF_Button = uibutton(app.PlotindividualeventparameterPanel, 'push');
            app.PlotMRF_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotMRF_ButtonPushed, true);
            app.PlotMRF_Button.Text = 'Plot MRF';
            app.PlotOnFault_Button = uibutton(app.PlotindividualeventparameterPanel, 'push');
            app.PlotOnFault_Button.ButtonPushedFcn = createCallbackFcn(app, @PlotOnFault_ButtonPushed, true);
            app.PlotOnFault_Button.Text = 'Plot "on fault"';
            app.toplotDropDownLabel = uilabel(app.PlotindividualeventparameterPanel);
            app.toplotDropDownLabel.Text = 'to plot:';
            app.PlotComp2Plot_DropDown = uidropdown(app.PlotindividualeventparameterPanel);
            app.PlotComp2Plot_DropDown.Items = {'comb. slip', 'horz. slip', 'vert. slip', 'stress drop', 'rupture time', 'stability type', 'accum. coseis slip'};
            app.PlotComp2Plot_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.PlotComp2Plot_DropDown.ValueChangedFcn = createCallbackFcn(app, @PlotComp2Plot_DropDownValueChanged, true);
            app.PlotComp2Plot_DropDown.Value = '1';
            app.minvalueEditFieldLabel = uilabel(app.PlotindividualeventparameterPanel);
            app.minvalueEditFieldLabel.Text = 'min. value';
            app.PlotMinValue_EditField = uieditfield(app.PlotindividualeventparameterPanel, 'numeric');
            app.PlotMinValue_EditField.ValueDisplayFormat = '%5.2f';
            app.maxvalueEditFieldLabel = uilabel(app.PlotindividualeventparameterPanel);
            app.maxvalueEditFieldLabel.Text = 'max. value';
            app.PlotMaxValue_EditField = uieditfield(app.PlotindividualeventparameterPanel, 'numeric');
            app.PlotMaxValue_EditField.ValueDisplayFormat = '%5.2f';
            app.PlotEQsequencePanel = uipanel(app.UIFigure);
            app.PlotEQsequencePanel.Title = 'Plot EQ sequence';
            app.SequencePlot_Button = uibutton(app.PlotEQsequencePanel, 'push');
            app.SequencePlot_Button.ButtonPushedFcn = createCallbackFcn(app, @SequencePlot_ButtonPushed, true);
            app.SequencePlot_Button.Text = 'Plot EQ sequence';
            app.StarttimeEditFieldLabel = uilabel(app.PlotEQsequencePanel);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.Sequence_StartT_EditField = uieditfield(app.PlotEQsequencePanel, 'numeric');
            app.Sequence_StartT_EditField.Limits = [0 Inf];
            app.Sequence_StartT_EditField.ValueDisplayFormat = '%5.3f';
            app.EndtimeEditFieldLabel = uilabel(app.PlotEQsequencePanel);
            app.EndtimeEditFieldLabel.Text = 'End time';
            app.Sequence_EndTEditField = uieditfield(app.PlotEQsequencePanel, 'numeric');
            app.Sequence_EndTEditField.Limits = [0 Inf];
            app.Sequence_EndTEditField.ValueDisplayFormat = '%5.3f';
            app.FigEditFieldLabel = uilabel(app.UIFigure);
            app.FigEditFieldLabel.Text = 'Fig #';
            app.PlotFigNum_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotFigNum_EditField.Limits = [1 Inf];
            app.PlotFigNum_EditField.RoundFractionalValues = 'on';
            app.PlotFigNum_EditField.ValueDisplayFormat = '%d';
            app.PlotFigNum_EditField.Value = 2;
            app.viewangleEditFieldLabel = uilabel(app.UIFigure);
            app.viewangleEditFieldLabel.Text = 'view angle';
            app.PlotViewAngle_EditField = uieditfield(app.UIFigure, 'text');
            app.PlotViewAngle_EditField.Value = '70 20';
            app.ColormapDropDownLabel = uilabel(app.UIFigure);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.PlotColorMap_DropDown = uidropdown(app.UIFigure);
            app.PlotColorMap_DropDown.Items = {'parula', 'jet', 'hot', 'gray', 'blue-white-red', 'slip'};
            app.PlotColorMap_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.PlotColorMap_DropDown.Value = '1';
            app.secframeEditFieldLabel_2 = uilabel(app.UIFigure);
            app.secframeEditFieldLabel_2.Text = 'years/frame';
            app.fpsEditFieldLabel_2 = uilabel(app.UIFigure);
            app.fpsEditFieldLabel_2.Tooltip = {'FramePerSec/YearPerFrame; first for single-event; second for EQ sequence'};
            app.fpsEditFieldLabel_2.Text = 'fps';
            app.SelectSinglePatch_Button = uibutton(app.UIFigure, 'push');
            app.SelectSinglePatch_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectSinglePatch_ButtonPushed, true);
            app.SelectSinglePatch_Button.Text = 'Select single patch';
            app.PatchIDLabel = uilabel(app.UIFigure);
            app.PatchIDLabel.Text = 'PatchID';
            app.SelectPatchID_edit = uieditfield(app.UIFigure, 'numeric');
            app.SelectPatchID_edit.Limits = [1 Inf];
            app.SelectPatchID_edit.RoundFractionalValues = 'on';
            app.SelectPatchID_edit.ValueDisplayFormat = '%d';
            app.SelectPatchID_edit.Value = 1;
            app.SelectHighlightPatch_Button = uibutton(app.UIFigure, 'push');
            app.SelectHighlightPatch_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectHighlightPatch_ButtonPushed, true);
            app.SelectHighlightPatch_Button.Text = 'Highlight patch';
            app.SelectPlotParVsTime_Button = uibutton(app.UIFigure, 'push');
            app.SelectPlotParVsTime_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectPlotParVsTime_ButtonPushed, true);
            app.SelectPlotParVsTime_Button.Text = 'Plot parameter vs. time (and get CVs)';
            app.SelectPlotParaAlongSurf_Button = uibutton(app.UIFigure, 'push');
            app.SelectPlotParaAlongSurf_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectPlotParaAlongSurf_ButtonPushed, true);
            app.SelectPlotParaAlongSurf_Button.Text = 'Plot parameter along surface';
            app.binningminvalueLabel = uilabel(app.UIFigure);
            app.binningminvalueLabel.Text = 'binning: min. value';
            app.PlotBinMinValue_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotBinMinValue_EditField.ValueDisplayFormat = '%5.2f';
            app.maxvalueLabel = uilabel(app.UIFigure);
            app.maxvalueLabel.Text = 'max. value';
            app.PlotBinMaxValue_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotBinMaxValue_EditField.ValueDisplayFormat = '%5.2f';
            app.PlotBinMaxValue_EditField.Value = 1;
            app.binLabel = uilabel(app.UIFigure);
            app.binLabel.Text = 'bin #';
            app.PlotBinNum_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotBinNum_EditField.Limits = [1 Inf];
            app.PlotBinNum_EditField.ValueDisplayFormat = '%d';
            app.PlotBinNum_EditField.Value = 1;
            app.SelectHypoCentDistrib_Button = uibutton(app.UIFigure, 'push');
            app.SelectHypoCentDistrib_Button.ButtonPushedFcn = createCallbackFcn(app, @SelectHypoCentDistrib_ButtonPushed, true);
            app.SelectHypoCentDistrib_Button.Text = 'Plot Hypocenter distrib';
            app.minMagnLabel = uilabel(app.UIFigure);
            app.minMagnLabel.Text = 'min. Magn.';
            app.InterEventMinMag_EditField = uieditfield(app.UIFigure, 'numeric');
            app.InterEventMinMag_EditField.ValueDisplayFormat = '%5.2f';
            app.InterEventMinMag_EditField.Value = 5;
            app.maxMagnLabel = uilabel(app.UIFigure);
            app.maxMagnLabel.Text = 'max. Magn.';
            app.InterEventMaxMag_EditField = uieditfield(app.UIFigure, 'numeric');
            app.InterEventMaxMag_EditField.ValueDisplayFormat = '%5.2f';
            app.InterEventMaxMag_EditField.Value = 6;
            app.InterEvent_PlotStuff_Button = uibutton(app.UIFigure, 'push');
            app.InterEvent_PlotStuff_Button.ButtonPushedFcn = createCallbackFcn(app, @InterEvent_PlotStuff_ButtonPushed, true);
            app.InterEvent_PlotStuff_Button.Text = 'Plot inter-event stuff';
            app.FigEditField_2Label_2 = uilabel(app.UIFigure);
            app.FigEditField_2Label_2.Text = 'Fig #';
            app.InterEventFigNum_EditField = uieditfield(app.UIFigure, 'numeric');
            app.InterEventFigNum_EditField.Limits = [1 Inf];
            app.InterEventFigNum_EditField.RoundFractionalValues = 'on';
            app.InterEventFigNum_EditField.ValueDisplayFormat = '%d';
            app.InterEventFigNum_EditField.Value = 5;
            app.scaleDropDownLabel_2 = uilabel(app.UIFigure);
            app.scaleDropDownLabel_2.Text = 'scale';
            app.InterEvent_Xscale_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Xscale_DropDown.Items = {'linear', 'log'};
            app.InterEvent_Xscale_DropDown.ItemsData = {'1', '2'};
            app.InterEvent_Xscale_DropDown.Value = '1';
            app.xaxisDropDownLabel_2 = uilabel(app.UIFigure);
            app.xaxisDropDownLabel_2.Text = 'x-axis';
            app.InterEvent_Xvalue_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Xvalue_DropDown.Items = {'time difference', 'centroid distance', 'magnitude'};
            app.InterEvent_Xvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.InterEvent_Xvalue_DropDown.Value = '1';
            app.yaxisLabel = uilabel(app.UIFigure);
            app.yaxisLabel.Text = 'y-axis';
            app.InterEvent_Yvalue_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Yvalue_DropDown.Items = {'time difference', 'centroid distance', 'magnitude'};
            app.InterEvent_Yvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.InterEvent_Yvalue_DropDown.Value = '1';
            app.colorLabel = uilabel(app.UIFigure);
            app.colorLabel.Text = 'color';
            app.InterEvent_Cvalue_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Cvalue_DropDown.Items = {'time difference', 'centroid distance', 'magnitude'};
            app.InterEvent_Cvalue_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.InterEvent_Cvalue_DropDown.Value = '1';
            app.scaleDropDownLabel_3 = uilabel(app.UIFigure);
            app.scaleDropDownLabel_3.Text = 'scale';
            app.InterEvent_Yscale_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_Yscale_DropDown.Items = {'linear', 'log'};
            app.InterEvent_Yscale_DropDown.ItemsData = {'1', '2'};
            app.InterEvent_Yscale_DropDown.Value = '1';
            app.forLabel_2 = uilabel(app.UIFigure);
            app.forLabel_2.Text = 'for';
            app.InterEvent_PatchOrAll_DropDown = uidropdown(app.UIFigure);
            app.InterEvent_PatchOrAll_DropDown.Items = {'all events', 'selected patch'};
            app.InterEvent_PatchOrAll_DropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.InterEvent_PatchOrAll_DropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = R_LoadPlotResults_2021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end